classdef FigureApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ExportMenu                   matlab.ui.container.Menu
        ExportPlotMenu               matlab.ui.container.Menu
        TemplateMenu                 matlab.ui.container.Menu
        SaveChangesMenu              matlab.ui.container.Menu
        CreateTemplateMenu           matlab.ui.container.Menu
        LoadTemplateMenu             matlab.ui.container.Menu
        ManageTemplatesMenu          matlab.ui.container.Menu
        OptionsMenu                  matlab.ui.container.Menu
        FlagsMenu                    matlab.ui.container.Menu
        AutoDecimalsMenu             matlab.ui.container.Menu
        EditVariablesMenu            matlab.ui.container.Menu
        SettingsMenu                 matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        VariableLimitsPanel          matlab.ui.container.Panel
        VariableLimitsGridLayout     matlab.ui.container.GridLayout
        tlimEditField                matlab.ui.control.NumericEditField
        tlimminEditField             matlab.ui.control.NumericEditField
        MaxLabel                     matlab.ui.control.Label
        MinLabel                     matlab.ui.control.Label
        tlimEditFieldLabel           matlab.ui.control.Label
        TimeAxisLabel                matlab.ui.control.Label
        VariableAxisLabel            matlab.ui.control.Label
        AutoLabel                    matlab.ui.control.Label
        MaxLabel_2                   matlab.ui.control.Label
        MinLabel_2                   matlab.ui.control.Label
        VariableLabel                matlab.ui.control.Label
        LimitsOpener                 matlab.ui.control.StateButton
        ConfiOpener                  matlab.ui.control.StateButton
        PlotPanel                    matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        PlotConfigurationPanel       matlab.ui.container.Panel
        PlotConfigurationGridLayout  matlab.ui.container.GridLayout
        AutoUpdateButton             matlab.ui.control.StateButton
        VariablePanel                matlab.ui.container.Panel
        VariableGridLayout           matlab.ui.container.GridLayout
    end


    properties (Access = public)
        TemplateSelected % Return value from Template Manager
    end

    properties (Access = public)
        AutoUpdate % Timer object needed for the 
        plotmode = true % Simple or complex plot (true: complex)
        projectID
        Data
        Template

        TexFontName = '\fontname{Helvetica}'
        FigureApps = "FigureApps";

        % Selected variable indices
        selection

        % Variables
        NameVar = strings(2,1); % Initialize string column vector
        SelectedVar
        AxisLabelVar = strings(2,1);
        FlagNameVar = strings(2,1);
        UnitVar = strings(2,1);
        LimitTypeVar
        yminVar;
        ymaxVar;
        FlagPointVar;
        DecimalVar;
        RVar;
        GVar;
        BVar;
        RGBVar;
        

        % Template
        name % Template name
        plottitle;
        templateID;
        description;
        last_changed;
        numAxes = 1;
        numphases;
        startTime;
        phaseName;
        phaseTime;
        titlebool;

        xaxisTitle

        % Settings
        % Graph
        graphlinewidth; % Line width
        graphvlinewidth; % Linewidth vertical lines
        graphfontsize; % Font size
        graphtitlefontsize % Title font size

        % Tables
        color
        decimal
        lineStyle

        % Axes
        axisxlabel;
        axisxunit;
        axisytick; %Number of Ticks y-axis
        axisxtick; %Number of Ticks x-axis
        axisyoffset; % Offset Y−axis
        axisylabeloffsetabove; %Y label offset above
        axisylabeloffsetbelow; %Y label offset below
        axislabelfontsize; %Fontsize
        axislinewidth; % Linewidth Axes


        % Flags
        flaglength; % Length of Flag handle
        flagangle; % Angle of Flag handle in °
        f_angle % Tan of angle
        flaglinewidth; % Width of Flag handle
        flagfontsize; %Fontsize

        refreshrate
        tstart
        tend

        Optionsapp;
        Phasesapp;
        Settingsapp

        % Plot Destination
        plotdest

        PlotObjects cell
        LimitObjects cell

        previous_selection = []
        dbpath = "SimulationAppDB.db";

        Table

        autoDecimal = true % automatic determination of decimals 
    end

    properties (Access = private)
        CallingApp
        longTexVariable
        shortTexVariable
        variableUnit

    end

    methods (Access = public)

        function readTemplate(app, templateID)
            try
                % Read plot Template information and settings
                sqlquery = sprintf('SELECT * FROM plot_templateTab WHERE templateID = %g', templateID);
                conn = sqlite(app.dbpath, "readonly");
                app.Template = fetch(conn,sqlquery);
                properties = app.Template.Properties.VariableNames;
                for i = 1:length(properties)
                    app.(properties{i}) = app.Template.(properties{i})(1);
                end
                % Read all property information for variable plotting
                sqlquery = sprintf(['SELECT v.variableID, v.name as "NameVar", v.description, p.selected_axis as "SelectedVar", ' ...
                    'v.shorttex as "FlagNameVar", v.longtex as "AxisLabelVar", ' ...
                    'v.tex_unit as "UnitVar", p.limit_type as "LimitTypeVar", p.ymin as "yminVar", ' ...
                    'p.ymax as "ymaxVar", d.decimal_symbol as "DecimalVar", p.colorID, ' ...
                    'c.R as "RVar", c.G as "GVar", c.B as "BVar", l.linestyle_num as "LineStyleVar" ' ...
                    'FROM plot_variableTab p ' ...
                    'JOIN variableTab v ON v.variableID = p.variableID ' ...
                    'JOIN variable_handlingTab vh ON vh.variableID = v.variableID ' ...
                    'JOIN plot_decimalTab d ON d.decimalID = p.decimalID ' ...
                    'JOIN plot_colorTab c ON c.colorID = p.colorID ' ...
                    'JOIN plot_linestyleTab l ON l.linestyleID = p.linestyleID ' ...
                    'WHERE p.templateID = %g  AND vh.upload_rate = "cyclic" AND vh.organismID = %i'], app.templateID, app.CallingApp.organismID);
                app.Table = fetch(conn,sqlquery);

                % Find pre-selected variables
                app.selection = app.Table.NameVar(find(app.Table.SelectedVar == 1));
                app.numAxes = length(app.selection);
                if app.numAxes > 6
                    app.selection = app.selection(1:6);
                end
                % Define RGB
                app.RGBVar = [app.RVar, app.GVar, app.BVar];
                % % 
                % Plot options
                % Color
                sqlquery = 'SELECT * FROM plot_colorTab';
                app.color = fetch(conn,sqlquery);

                % Decimal
                sqlquery = 'SELECT * FROM plot_decimalTab';
                app.decimal = fetch(conn,sqlquery);

                % Line Style
                sqlquery = 'SELECT * FROM plot_linestyleTab';
                app.lineStyle = fetch(conn,sqlquery);

                % t axis label
                app.xaxisTitle = {app.axisxlabel;append('[', app.axisxunit, ']')};

                % Flag angle
                app.f_angle = tand(double(app.flagangle))*app.flaglength; % Angle

                % Tooltips
                app.SaveChangesMenu.Tooltip = sprintf("Save changes in template: %s", app.name);
                close(conn)
            catch ME
                errordlg(['Error reading Template: ' ME.message], 'Template Error', 'replace');
            end

        end

        function index = IndexOfSelection(app)
            % Determine the indices of the current selection
            if ~isempty(app.selection)
                l = length(app.selection);
                n = zeros(l);
                for i = 1:l
                    n(i) = find(app.Table.NameVar == app.selection(i));
                end
            end
            index = n;
        end

        function Struct = Table2Struct(~, Table) 
            % Function that converts a table with plotting properties into
            % a struct
                properties = Table.Properties.VariableNames;
                for i = 1:size(Table,2)
                    variableName = Table.NameVar(i);
                    for j = 1:length(properties)
                        property = string(properties{j});
                        value = Table{i,j};
                        Struct.(variableName).(property) = value;
                    end
                end
        end
            
        % Function for determining the row index of a variable in the table
        function idx = findVariableIdx(app, variable)
            idx = find(app.Table.NameVar == variable);
        end

        function Table = Struct2Table(~, Struct)
            % Function that converts a struct with plotting properties back
            % into a table

            % Names of the top-level fields (variables)
            variableNames = fieldnames(Struct);

            % Names of the subfields (properties)
            properties = fieldnames(Struct.(variableNames{1}));

            % Preallocate cell array
            C = cell(length(variableNames), length(properties));

            % Fill cell array with the data
            for i = 1:numel(properties)
                property = properties{i};
                for j = 1:numel(variableNames)
                    C{j,i} = Struct.(variableNames{j}).(property);
                end
            end

            % Convert to table with correct variable names
            Table = cell2table(C, 'VariableNames', properties);
        end

        function createLimitsGrid(app)
            % Insert time axis values
            app.tlimEditField.Value = app.tend;
            app.tlimminEditField.Value = app.tstart;

            % Delete all dynamic objects of the variable axis
            delete(app.VariableLimitsGridLayout.Children(12:end))

            % Construct new dynamic objects
            grid = app.VariableLimitsGridLayout;

            % Empty cell containing variable limit objects
            app.LimitObjects = {};

            for i = 1:length(app.selection)
                currentRow = i + 6; % Starting from 7th row
                selectionIndex = findVariableIdx(app, app.selection(i)); % Selected variable idx

                % Label in the first column
                lbl1 = uilabel(grid);
                lbl1.Layout.Row = currentRow;
                lbl1.Layout.Column = 1;
                lbl1.Text = sprintf('%g:', i);

                % Label in the second column
                lbl2 = uilabel(grid);
                lbl2.Layout.Row = currentRow;
                lbl2.Layout.Column = 2;
                LabelText = append(app.TexFontName, app.Table.AxisLabelVar(selectionIndex));
                lbl2.Text = LabelText;
                lbl2.Interpreter = 'Tex';

                % Numeric Edit Field for Minimum in the third column
                minEdit = uieditfield(grid, 'numeric');
                minEdit.Layout.Row = currentRow;
                minEdit.Layout.Column = 3;
                minEdit.Value = app.Table.yminVar(selectionIndex);
                minEdit.ValueChangedFcn = @(src,event) minEditCallback(app, src, event, selectionIndex);

                % Numeric Edit Field for Maximum in the fourth column
                maxEdit = uieditfield(grid, 'numeric');
                maxEdit.Layout.Row = currentRow;
                maxEdit.Layout.Column = 4;
                maxEdit.Value = app.Table.ymaxVar(selectionIndex);
                maxEdit.ValueChangedFcn = @(src,event) maxEditCallback(app, src, event, selectionIndex);

                % Checkbox in the last column
                cb = uicheckbox(grid);
                cb.Layout.Row = currentRow;
                cb.Layout.Column = 6;
                cb.Text = '';
                cb.Value = isequal(app.Table.LimitTypeVar(selectionIndex), 1);
                cb.ValueChangedFcn = @(src, event) LimitscheckboxCallback(app, src, selectionIndex);
                cb.Tooltip = "Auto: Y-axis limits increase when data leaves plot area.";
                cb.UserData = {minEdit, maxEdit}; % Save min and max edit fields in checkbox obj

                % Set enabled state based on LimitTypeVar
                if isequal(app.Table.LimitTypeVar(selectionIndex), 1)
                    minEdit.Enable = 'off';
                    maxEdit.Enable = 'off';
                else
                    minEdit.Enable = 'on';
                    maxEdit.Enable = 'on';
                end

                % Fill limit object cell
                app.LimitObjects(i,:) = {lbl1, lbl2, minEdit, maxEdit, cb};
            end
        end

        function LimitscheckboxCallback(app, src, selectionIndex)
            minField = src.UserData{1};
            maxField = src.UserData{2};
            if src.Value
                app.Table.LimitTypeVar(selectionIndex) = 1;
                minField.Enable = 'off';  % Disable minEdit
                maxField.Enable = 'off';  % Disable maxEdit
                calculateYLimits(app,selectionIndex)
            else
                app.Table.LimitTypeVar(selectionIndex) = 0;
                minField.Enable = 'on';   % Enable minEdit
                maxField.Enable = 'on';   % Enable maxEdit
                minField.Value = app.Table.yminVar(selectionIndex);   % Enable minEdit
                maxField.Value = app.Table.ymaxVar(selectionIndex);   % Enable maxEdit
                app.PlotObjects = {}; % Renew axes
            end
        end

        function minEditCallback(app, src, event, selectionIndex)
            newMin = src.Value;
            currentMax = app.Table.ymaxVar(selectionIndex);
            if newMin < currentMax
                app.Table.yminVar(selectionIndex) = newMin;
            else
                % If new min is greater than or equal to max, reset to previous value
                src.Value = event.PreviousValue;
                warndlg('Minimum value must be less than maximum value.', 'Invalid Input');
            end
            app.PlotObjects = {}; % Renew axes
        end

        function maxEditCallback(app, src, event, selectionIndex)
            newMax = src.Value;
            currentMin = app.Table.yminVar(selectionIndex);
            if newMax > currentMin
                app.Table.ymaxVar(selectionIndex) = newMax;
            else
                % If new max is less than or equal to min, reset to previous value
                src.Value = event.PreviousValue;
                warndlg('Maximum value must be greater than minimum value.', 'Invalid Input');
            end
            app.PlotObjects = {}; % Renew axes
        end

        function createVariableList(app)
            delete(app.VariableGridLayout.Children)
            variableNum = size(app.Table,1);
            gridLayout = app.VariableGridLayout;
            % Set the grid layout to have two columns
            gridLayout.ColumnWidth = {'1x', '7x'};
            gridLayout.RowHeight = repmat({'fit'}, 1, variableNum);
            % Loop over the number of rows
            for i = 1:variableNum
                % Create a checkbox in the first column of the ith row
                cb = uicheckbox(gridLayout, 'Text', '');
                cb.Layout.Row = i;
                cb.Layout.Column = 1;
                cb.Tooltip = app.Table.description(i);
                % Pre picked variables are checked
                if app.Table.SelectedVar(i) == 1
                    cb.Value = true;
                end
                % Assign a callback function to the checkbox
                cb.ValueChangedFcn = @(src, event)variableCheckboxCallback(app, src, event, i);

                % Create a label in the second column of the ith row
                LabelText = append(app.TexFontName, app.Table.AxisLabelVar(i), ' [', app.Table.UnitVar(i),']');
                lbl = uilabel(gridLayout, 'Text', LabelText, Interpreter='Tex');
                lbl.Layout.Row = i;
                lbl.Layout.Column = 2;
            end
        end

        function variableCheckboxCallback(app, src, event, index)
            app.VariablePanel.Enable = "off";
            if src.Value
                % Checkbox is checked
                if length(app.selection) < 6
                    app.Table.SelectedVar(index) = 1;
                else
                    uialert(app.UIFigure, 'You can only plot up to 6 variable at a time.', 'Limit Exceeded');
                    src.Value = event.PreviousValue;
                end
            else
                % Checkbox is unchecked
                app.Table.SelectedVar(index) = 0;
            end
            app.selection = (app.Table.NameVar(find(app.Table.SelectedVar == 1)))';
            app.numAxes = length(app.selection);
            createLimitsGrid(app)
            sortVariables(app)
            app.PlotObjects = {}; % Renew axes
            app.VariablePanel.Enable = "on";
        end     

        function updateOptions(app, propertyName, newValue)
                try
                    sqlquery = sprintf('UPDATE plot_templateTab SET ''%s'' = %g WHERE templateID = %g', propertyName, newValue, app.templateID);
                    conn = sqlite(app.dbpath, "connect");
                    exec(conn,sqlquery)
                    close(conn)
                catch 
                        
                end
        end

    end

    methods (Access =public)

        function deleteAxes(app)
            % Entferne alle existierenden Achsen
            axesHandles = findobj(app.plotdest, 'Type', 'axes');
            delete(axesHandles);
        end

        function AutoUpdateFcn(app,varargin)
                try
                    app.Data = app.CallingApp.v;
                    if ~isempty(app.Data) && ~isempty(app.selection) % Check if data has been recorded
                        if isempty(app.PlotObjects)
                            plotting(app)
                        else
                            updateAxes(app)
                        end
                    end
                catch ME
                    checkUpdater(app)
                    app.AutoUpdateButton.Value = false;
                    stop(app.AutoUpdate)
                    errordlg(['Auto update error: ' ME.message], 'Update Error', 'replace');
                end
        end

            %% Plotting functions
        function plotting(app)
            deleteAxes(app); % Delete axes
            if ~isempty(app.selection) % If at least one variable is checked
                checkLimits(app)
                app.numAxes = length(app.selection);

                app.PlotObjects = {};

                for n = 1:app.numAxes % Loop for all axis
                    % Plot primary axes
                    variableName = app.selection(n);
                    kax = findVariableIdx(app, variableName);
                    t = app.Data.t(1:1:app.CallingApp.nxtidx);
                    d = app.Data.(variableName)(1:app.CallingApp.nxtidx);
                    [Ypos, figurelength] = layout_plot(app,app.numAxes, app.axisyoffset);
                    % Loop to create axes
                    axis = uiaxes(app.plotdest);
                    axis.Color = 'none';
                    if n == 1
                        set(axis,'InnerPosition',[0 0 figurelength Ypos(4)]);
                    else
                        set(axis, 'InnerPosition',Ypos,'YAxisLocation','left');
                    end

                    % Automatic decimal place detection
                    if app.autoDecimal
                        maxLimit = app.Table.ymaxVar(kax);
                        % Determine decimal
                        if maxLimit >= 10
                            app.Table.DecimalVar(kax) = app.decimal.decimal_symbol(1);
                        elseif maxLimit >= 1
                            app.Table.DecimalVar(kax) = app.decimal.decimal_symbol(2);
                        elseif maxLimit >= 0.1
                            app.Table.DecimalVar(kax) = app.decimal.decimal_symbol(3);
                        else
                            app.Table.DecimalVar(kax) = app.decimal.decimal_symbol(4);
                        end
                    end

                    YposN = [Ypos(1)-app.axisyoffset*(n-1) Ypos(2) Ypos(3) Ypos(4)];
                    ylab = [app.Table.AxisLabelVar(kax);append("[",app.Table.UnitVar(kax),"]")];
                    RGB = [app.Table.RVar(kax), app.Table.GVar(kax), app.Table.BVar(kax)];
                    Yaxis = plotgen(app,t,d,axis,RGB,app.graphlinewidth,YposN,app.Table.yminVar(kax),app.Table.ymaxVar(kax),app.tstart,app.tend,app.axisytick,app.graphfontsize,ylab,app.xaxisTitle,app.Table.DecimalVar(kax),app.axislinewidth,app.Table.LineStyleVar(kax),app.axisylabeloffsetabove,n);
                    if app.FlagsMenu.Checked == "on"
                        flagomat3(app,t,d,1,app.Table.ymaxVar(kax),app.Table.yminVar(kax), app.flaglinewidth,RGB,app.flagfontsize,app.Table.FlagNameVar(kax),axis,app.f_angle,app.flaglength,app.tend, app.tstart);
                    end
                    app.PlotObjects(n,:) = {kax, variableName, axis, Yaxis};

                    % Disable all interactivity to avoid callbacks
                    axis.Interactions = []; % Disable interactions
                    axis.HitTest = 'off';
                    disableDefaultInteractivity(axis);
                    Yaxis.Interactions = []; % Disable interactions
                    Yaxis.HitTest = 'off';
                end
                if app.titlebool == 1
                    title(app.PlotObjects{1,3}, app.plottitle,'fontsize',app.graphtitlefontsize,'fontweight','bold')
                    set(get(app.PlotObjects{1,3}, 'title'),'Position',[app.tend/2 (app.Table.ymaxVar(app.selection(1))+0.05*(app.Table.ymaxVar(app.selection(1))-app.Table.yminVar(app.selection(1)))) 1])
                end
            end
        end

        function updateAxes(app)
            % This function is called once all axes are already constructed
            % It rescales and updates the plotted data.
            checkLimits(app)
            [Ypos, ~] = layout_plot(app,app.numAxes, app.axisyoffset);
            for i = 1:size(app.PlotObjects,1)
                try
                % Unpack cell
                    kax = app.PlotObjects{i, 1};
                    variableName = app.PlotObjects{i, 2};
                    axis = app.PlotObjects{i, 3};
                    lne = axis.Children(1);
                    txt = axis.Children(2);
                    dot = axis.Children(3);
                    axisLine = axis.Children(4);
                    Yaxis = app.PlotObjects{i, 4};
                    t = app.Data.t(1:1:app.CallingApp.nxtidx);
                    d = app.Data.(variableName)(1:1:app.CallingApp.nxtidx);
                    axisLine.XData = t;
                    axisLine.YData = d;
                    % Position
                    if i == 1
                        set(axis,'InnerPosition',Ypos)
                    else
                        set(axis, 'InnerPosition',Ypos)
                        set(Yaxis,'InnerPosition',[Ypos(1)-app.axisyoffset*(i-1) Ypos(2) 0.00001 Ypos(4)])
                    end
                    % Flag
                    tmin = app.tstart;
                    tmax = app.tend;
                    dmin = app.Table.yminVar(kax);
                    dmax = app.Table.ymaxVar(kax);
                    if isvalid(txt) % Move variable name position
                        set(txt, 'Position', [t(end)+(tmax-tmin)*app.flaglength, d(end)+(dmax-dmin)*app.flagangle]);
                    end
                    if isvalid(lne) % Move variable line position
                        set(lne, 'XData', [t(end) t(end)+(tmax-tmin)*app.flaglength], 'YData', [d(end) d(end)+(dmax-dmin)*app.flagangle]);
                    end
                    if isvalid(dot) % Move variable marker position
                        set(dot, 'XData', t(end), 'YData', d(end));
                    end
                catch ME
                    fprintf('Error Axis %i. %s\n', i, ME.message)
                end
            end
        end

        function deletePlot(app)
            % Delete all axes objects on plot panel
            for j = 1:app.numAxes
                num = num2str(j);
                delete(app.(['UIAxes' num]));
            end
        end

        function Yaxis = plotgen(app,Tdat,Ydat,axh,color,linewidth,Yposition,Ymin,Ymax,Tmin,Tend,tpaxis,fontsize,Y_label,X_label,Ytickform,linewidth_ax,linestyle,Ylaboff,Axn)
            if linestyle == 0
                plot(axh,Tdat,Ydat,'Color',color,'LineWidth',linewidth);
            end
            if linestyle == 1
                line1='--';
                plot(axh,Tdat,Ydat,line1,'Color',color,'LineWidth',linewidth);
            end
            if linestyle == 2
                line2='-.';
                plot(axh,Tdat,Ydat,line2,'Color',color,'LineWidth',linewidth);
            end
            if Axn == 1
                %xtick=app.startTime:((app.phaseTime(app.numPhases)-app.startTime)/app.axisxtick):app.phaseTime(app.numPhases);
                xtick = app.tlimminEditField.Value:((app.tlimEditField.Value-app.tlimminEditField.Value)/app.axisxtick):app.tlimEditField.Value;
                set(axh,'TickDir','out','LineWidth',linewidth_ax);
                ytickformat(axh,Ytickform)
                tick=Ymin:((Ymax-Ymin)/tpaxis):Ymax;
                set(axh,'Ytick',tick,'Ylim',[Ymin Ymax],'FontSize',fontsize,'Ycolor','black','Visible','on');
                ylabel(axh,Y_label,'Position',[Tmin (Ymax-Ymin)*Ylaboff+Ymax -1],'Rotation',0,'HorizontalAlignment','center','Color',color);
                xlabel(axh,X_label);
                set(axh,'XGrid','on','YGrid','on');
                set(axh,'InnerPosition',Yposition, 'YAxisLocation','left','YLim',[Ymin Ymax], 'Xtick', xtick, 'XLim',[Tmin Tend],'Visible','on');
                Yaxis = [];
            elseif Axn == 2 || Axn == 3 || Axn == 4 ||Axn == 5 || Axn == 6
                set(axh,'YAxisLocation','left','YLim',[Ymin Ymax],'XLim',[Tmin Tend],'Visible','off');
                ytickformat(axh,Ytickform)
                axh_off = uiaxes(app.plotdest);
                axh_off.Interactions = []; % Disable interactions
                set(axh_off,'InnerPosition',[Yposition(1) Yposition(2) 0.0001 Yposition(4)], 'YAxisLocation','left','YLim',[Ymin Ymax],'XTickLabel', [],'Visible','on');
                set(axh_off,'TickDir','out','LineWidth',linewidth_ax);
                tick=Ymin:((Ymax-Ymin)/tpaxis):Ymax;
                set(axh_off,'Ytick',tick,'Ylim',[Ymin Ymax],'FontSize',fontsize,'Ycolor','black');
                ylabel(axh_off,Y_label,'Position',[0 (Ymax-Ymin)*Ylaboff+Ymax -1 ],'Rotation',0,'HorizontalAlignment','center','Color',color);
                ytickformat(axh_off,Ytickform)
                Yaxis = axh_off;
            end
        end

        function [Ypos, figurelength] = layout_plot(app,naxes,Yoffset)
            pos = app.plotdest.Position;
            Xoffset = pos(3)*0.08;
            length = pos(3)*0.975;
            width = pos(4)*0.75;
            figurelength0 = pos(3);
            switch naxes
                case 1
                    figurelength = figurelength0*0.75;
                    Ypos = [Yoffset*1 Xoffset length width];
                case 2
                    figurelength = figurelength0*0.8;
                    Ypos = [Yoffset*2 Xoffset abs(length-Yoffset*2) width];
                case 3
                    figurelength = figurelength0*0.85;
                    Ypos = [Yoffset*3 Xoffset abs(length-Yoffset*3) width];
                case 4
                    figurelength = figurelength0*0.90;
                    Ypos = [Yoffset*4 Xoffset abs(length-Yoffset*4) width];
                case 5
                    figurelength = figurelength0*0.95;
                    Ypos = [Yoffset*5 Xoffset abs(length-Yoffset*5) width];
                case 6
                    figurelength = figurelength0;
                    Ypos = [Yoffset*6 Xoffset abs(length-Yoffset*6) width];
            end
        end

        function flagomat3(~,x,y,fori,ymax,ymin,linewidth_flag,color,fontsize_flag,fname,ax,angle,flag_length,xmax,xmin)
            % Calculation of flag position
            %x_point=((fpoint)/100)*app.phaseTime(app.numPhases);
            x_point = x(end);
            v=x_point*ones(size(x));
            [~, position1]=min(abs(v-x));
            pos1 = position1(1);
            %if app.FlagsSwitch.Value == '1'
            hold(ax, "on")
            plot(ax,x(pos1),y(pos1),"Marker","o", "Color", color, 'MarkerSize',4);
            hold(ax, "off")
                switch fori
                    case 1
                        % Position upper right
                        text(x(pos1)+(xmax-xmin)*flag_length,y(pos1)+(ymax-ymin)*angle,fname,'color',color,'fontsize',fontsize_flag,'HorizontalAlignment','left','parent',ax); %Plotting of flag text
                        line([x(pos1) x(pos1)+(xmax-xmin)*flag_length],[y(pos1) y(pos1)+(ymax-ymin)*angle],'Linewidth',linewidth_flag,'color',color,'parent',ax); %Plotting of flag handle
                    case 2
                        % Position lower right
                        text(x(pos1)+(xmax-xmin)*flag_length,y(pos1)-(ymax-ymin)*angle,fname,'color',color,'fontsize',fontsize_flag,'HorizontalAlignment','left','parent',ax) %Plotting of flag text
                        line([x(pos1) x(pos1)+(xmax-xmin)*flag_length],[y(pos1) y(pos1)-(ymax-ymin)*angle],'Linewidth',linewidth_flag,'color',color,'parent',ax) %Plotting of flag handle
                    case 3
                        % Position lower left
                        text(x(pos1)-(xmax-xmin)*flag_length,y(pos1)-(ymax-ymin)*angle,fname,'color',color,'fontsize',fontsize_flag,'HorizontalAlignment','right','parent',ax) %Plotting of flag text
                        line([x(pos1) x(pos1)-(xmax-xmin)*flag_length],[y(pos1) y(pos1)-(ymax-ymin)*angle],'Linewidth',linewidth_flag,'color',color,'parent',ax) %Plotting of flag handle
                    case 4
                        % Position upper left
                        text(x(pos1)-(xmax-xmin)*flag_length,y(pos1)+(ymax-ymin)*angle,fname,'color',color,'fontsize',fontsize_flag,'HorizontalAlignment','right','parent',ax) %Plotting of flag text
                        line([x(pos1) x(pos1)-(xmax-xmin)*flag_length],[y(pos1) y(pos1)+(ymax-ymin)*angle],'Linewidth',linewidth_flag,'color',color,'parent',ax) %Plotting of flag handle
                end
            %end
        end

        function checkLimits(app)
            % Check if one value is outside the band of ylim
            variableIdx = app.selection;
            changedFlag = false;
            lastLimit = 0; % compare ymax limit order
            for i = 1:numel(variableIdx)
                idx = findVariableIdx(app, variableIdx(i));
                if app.Table.LimitTypeVar(idx) == 1 % Only auto limits
                    data = app.Data.(app.Table.NameVar(idx))(1:app.CallingApp.nxtidx);
                    minLimit = app.Table.yminVar(idx);
                    maxLimit = app.Table.ymaxVar(idx);
                    isOutside = any(data < minLimit | data > maxLimit);
                    if isOutside
                        calculateYLimits(app,idx) % Calculate new y limits
                        changedFlag = true;
                    end
                    % Check if ylimit order is correct
                    if lastLimit < maxLimit
                        lastLimit = maxLimit;
                    else
                        changedFlag = true;
                        lastLimit = maxLimit;
                    end
                end
            end
            if changedFlag % Generate new plot if y-limits were changed
                sortVariables(app)        % Sort by y max limits
                app.PlotObjects = {};     % Create a new plot
            end
        end

        function calculateYLimits(app, idx)
            % Define a helper function to round to the next 10, 20 or 50 value
            function roundedValue = roundToNearest(value)
                if value <= 0.0001
                    roundedValue = 0.0001; % Handle small positive numbers
                else
                    scale = 10^floor(log10(value)); % Find the power of 10 scale
                    value_scaled = value / scale;   % Scale the value to the range 1-10
                    if value_scaled <= 2
                        roundedValue = 2 * scale;
                    elseif value_scaled <= 5
                        roundedValue = 5 * scale;
                    else
                        roundedValue = 10 * scale;
                    end
                end
            end

            % If automatic y limit detection is activated
            if app.Table.LimitTypeVar(idx) == 1
                variable = app.Table.NameVar(idx);
                data = app.Data.(variable)(:);
                % Find the minimum and maximum values in the data
                minValue = min(data);
                maxValue = max(data);

                % Get the current limits
                currentYmin = app.Table.yminVar(idx);
                currentYmax = app.Table.ymaxVar(idx);

                % Calculate new ymax limit
                if maxValue < 0.1 * currentYmax
                    % If maxValue is smaller than 10% of current ymax, adjust it
                    maxLimit = roundToNearest(maxValue * 1.5);  % Adjust ymax based on maxValue
                else
                    maxLimit = roundToNearest(maxValue);  % Normal rounding
                end

                % Calculate new ymin limit
                if minValue >= 0
                    minLimit = 0;  % Default to 0 if minValue is non-negative
                else
                    % Ensure that minLimit has the same decimal scale as maxLimit
                    scale = 10^floor(log10(maxLimit));  % Get the scale of maxLimit
                    minLimit = -ceil(abs(minValue) / scale) * scale;  % Ensure it matches maxLimit's scale
                end

                % Assign new limits
                app.Table.yminVar(idx) = minLimit;
                app.Table.ymaxVar(idx) = maxLimit;

                % Update obj value
                pos = find(variable == app.selection);
                app.LimitObjects{pos, 3}.Value = minLimit;
                app.LimitObjects{pos, 4}.Value = maxLimit;
            end
        end


        function sortVariables(app)
            if ~isempty(app.selection)
            [~, variableIdx] = ismember(app.selection, app.Table.NameVar);
            max_values = app.Table.ymaxVar(variableIdx);
            [~, sorted] = sort(max_values);
            new_selection = variableIdx(sorted);
            if ~isequal(variableIdx, new_selection)
                app.selection = app.Table.NameVar(new_selection);
                app.LimitObjects = app.LimitObjects(sorted,:);
                % Update object row on layout
                for i = 1:size(app.LimitObjects,1)
                    for j = 1:size(app.LimitObjects,2)
                        obj = app.LimitObjects{i,j};
                        obj.Layout.Row = 6+i; % Update row
                        % Update axis number label
                        if j == 1
                            obj.Text = sprintf("%i:",i);
                        end
                    end
                end
                app.PlotObjects = {}; % Rebuild axes
            end
            else
                warndlg("No variable selected. Select a variable.", "Warning");
                checkUpdater(app)
            end
        end

        function checkUpdater(app)
            status = get(app.AutoUpdate, 'Running');
            if status == "on"
                stop(app.AutoUpdate)
                app.AutoUpdateButton.Value = 0;
            else
                start(app.AutoUpdate)
                app.AutoUpdateButton.Value = 1;
            end
        end

        function templateName = openTemplateSaveWindow(app)
            % Initialize output
            templateName = '';

            % Create the figure
            f = figure('Name', 'Save Template', 'Position', [500, 400, 300, 150], ...
                'Resize', 'off', 'NumberTitle', 'off', 'MenuBar', 'none', ...
                'CloseRequestFcn', @cancelCallback);

            % Create label for Name
            uicontrol('Parent', f, 'Style', 'text', 'Position', [20, 90, 50, 20], ...
                'String', 'Name:');

            % Create text edit field
            nameEdit = uicontrol('Parent', f, 'Style', 'edit', 'Position', [80, 90, 200, 25]);

            % Create Save button
            uicontrol('Parent', f, 'Style', 'pushbutton', 'String', 'Save', ...
                'Position', [200, 20, 80, 30], 'Callback', @saveCallback);

            % Create Cancel button
            uicontrol('Parent', f, 'Style', 'pushbutton', 'String', 'Cancel', ...
                'Position', [20, 20, 80, 30], 'Callback', @cancelCallback);

            % Wait for figure to close before returning
            uiwait(f);

            % Callback for Save button
            function saveCallback(~, ~)
                % Get the name from the text field
                templateName = nameEdit.String;
                % Close the figure
                uiresume(f);
                delete(f);
                % Here you can insert the code to execute after saving
            end

            % Callback for Cancel button
            function cancelCallback(~, ~)
                % Close the figure without saving
                templateName = '';  % Ensure output is empty on cancel
                uiresume(f);
                delete(f);
            end
        end

        function TemplateMenuSelected(app, src, ~)
            ID = src.UserData;
            readTemplate(app, ID)
            createVariableList(app)
            createLimitsGrid(app)
            sortVariables(app)
            app.PlotObjects = {}; % Refresh plot
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, CallingApp, projectID, templateID)
            app.projectID = projectID;
            app.UIFigure.Name = "Figure";
            app.CallingApp = CallingApp;
            app.templateID = templateID;
            centerWindow(app.UIFigure)
            app.dbpath = app.CallingApp.dbpath;
            app.plotdest = app.PlotPanel;
            readTemplate(app, app.templateID)
            createVariableList(app)
            createLimitsGrid(app)
            % Create timer object
            app.AutoUpdate = timer(...
                'ExecutionMode', 'fixedRate', ...                           % Run timer repeatedly
                'Period', app.refreshrate, ...                                % Period is dependent on delta t
                'BusyMode', 'drop',...                                     % Queue timer callbacks when busy
                'TimerFcn', @app.AutoUpdateFcn);                                 % Specify callback function
            start(app.AutoUpdate);
            fprintf("Figure %d with Template %d opened.\n", length(app.CallingApp.Figureapps)+1,templateID);
        end

        % Value changed function: ConfiOpener
        function ConfiOpenerValueChanged(app, event)
            value = app.ConfiOpener.Value;
            if value
                app.PlotConfigurationPanel.Visible = "on";
                app.GridLayout.ColumnWidth{5} = 250;
                app.ConfiOpener.Text = ">";
            else
                app.PlotConfigurationPanel.Visible = "off";
                app.GridLayout.ColumnWidth{5} = 0.001;
                app.ConfiOpener.Text = "<";
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.AutoUpdate)
                if app.AutoUpdate.running == "on"
                    stop(app.AutoUpdate)
                end
            end
            delete(app)
            
        end

        % Menu selected function: FlagsMenu
        function FlagsMenuSelected(app, event)
            if app.FlagsMenu.Checked == "on"
                app.FlagsMenu.Checked = "off";
            else
                app.FlagsMenu.Checked = "on";
            end
            app.PlotObjects = {}; % Refresh plot
        end

        % Value changed function: AutoUpdateButton
        function AutoUpdateButtonValueChanged(app, event)
            checkUpdater(app)
        end

        % Value changed function: LimitsOpener
        function LimitsOpenerValueChanged(app, event)
            value = app.LimitsOpener.Value;
            if value
                app.VariableLimitsPanel.Visible = "on";
                app.GridLayout.ColumnWidth{1} = 250;
                app.LimitsOpener.Text = "<";
            else
                app.VariableLimitsPanel.Visible = "off";
                app.GridLayout.ColumnWidth{1} = 0.001;
                app.LimitsOpener.Text = ">";
            end
        end

        % Menu selected function: EditVariablesMenu
        function EditVariablesMenuSelected(app, event)
            editor = FigureAppVariableEditor(app, app.CallingApp);
            waitfor(editor)
            readTemplate(app, app.templateID)
            createVariableList(app)
            createLimitsGrid(app)
            sortVariables(app)
            app.PlotObjects = {}; % Refresh plot
        end

        % Menu selected function: SettingsMenu
        function SettingsMenuSelected(app, event)
            status = get(app.AutoUpdate, 'Running');
            if status == "on"
                stop(app.AutoUpdate)
                app.AutoUpdateButton.Value = 0;
            end
            app.Settingsapp = FigureAppSettings(app, app.templateID);
            waitfor(app.Settingsapp)
            readTemplate(app, app.templateID)
            app.PlotObjects = {}; % Refresh plot
            start(app.AutoUpdate)
            app.AutoUpdateButton.Value = 1;
        end

        % Value changed function: tlimEditField
        function tlimEditFieldValueChanged(app, event)
            value = app.tlimEditField.Value;
            if value > app.tstart
                app.tend = value;
                updateOptions(app,'tend',value)
            else
                app.tlimEditField.Value = app.tend;
                app.tlimEditField.Tooltip = "tmax must be larger than tmin.";
                pause(3)
                app.tlimEditField.Tooltip = "";
            end
            app.PlotObjects = {};
        end

        % Value changed function: tlimminEditField
        function tlimminEditFieldValueChanged(app, event)
            value = app.tlimminEditField.Value;
            if value < app.tend
                app.tstart = value;
                updateOptions(app,'tstart',value)
            else
                app.tlimminEditField.Value = app.tstart;
                app.tlimminEditField.Tooltip = "tmin must be smaller than tmin.";
                pause(3)
                app.tlimminEditField.Tooltip = "";
            end
            app.PlotObjects = {};
        end

        % Menu selected function: AutoDecimalsMenu
        function AutoDecimalsMenuSelected(app, event)
            check = get(app.AutoDecimalsMenu, "Checked");
            if check == "on"
                app.autoDecimal = false;
                app.AutoDecimalsMenu.Checked = "off";
            else
                app.autoDecimal = true;
                app.AutoDecimalsMenu.Checked = "off";
            end
            app.PlotObjects = {}; % Refresh plot
        end

        % Menu selected function: CreateTemplateMenu
        function CreateTemplateMenuSelected(app, event)
            templateName = openTemplateSaveWindow(app);
            if ~isempty(templateName)
                conn = sqlite(app.dbpath, "connect");
                ID = double(table2array(fetch(conn, "SELECT MAX(templateID) FROM plot_templateTab")))+1;
                if isempty(ID)
                    ID = 1;
                end
                temporaryTemplate = app.Template;
                temporaryTemplate.name(1) = templateName;
                temporaryTemplate.templateID(1) = ID;
                properties = temporaryTemplate.Properties.VariableNames;
                for i = 3:length(properties) % Starting from description
                    temporaryTemplate.(properties{i})(1) = app.(properties{i});
                end
                % Add new Template in plot_templateTab
                sqlwrite(conn,"plot_templateTab",temporaryTemplate)
                % Download table from plot_variableTab to save changes
                % made in new Template
                plotVariable = fetch(conn, sprintf('SELECT * FROM plot_variableTab WHERE templateID = %i AND templateID = (SELECT MIN(templateID) FROM plot_variableTab WHERE templateID = %i)', app.templateID, app.templateID));
                plotVariable.selected_axis = app.Table.SelectedVar;
                plotVariable.limit_type = app.Table.LimitTypeVar;
                plotVariable.ymin = app.Table.yminVar;
                plotVariable.ymax = app.Table.ymaxVar;
                new_templateID = repmat(ID,size(plotVariable,1),1);
                plotVariable.templateID = new_templateID;
                % Add new Template in plot_variableTab
                sqlwrite(conn,"plot_variableTab",plotVariable)
                close(conn)
                app.templateID = ID;
            end
        end

        % Menu selected function: ManageTemplatesMenu
        function ManageTemplatesMenuSelected(app, event)
            figure = FigureAppTemplateManager(app, app.CallingApp, app.templateID);
            waitfor(figure)
            if ~isempty(app.TemplateSelected)
                readTemplate(app,app.TemplateSelected)
                createVariableList(app)
                createLimitsGrid(app)
                app.TemplateSelected = [];
            end
        end

        % Menu selected function: LoadTemplateMenu
        function LoadTemplateMenuSelected(app, event)
            conn = sqlite(app.dbpath, "readonly");
            templates = fetch(conn, "SELECT templateID, name, description FROM plot_templateTab");
            children = app.LoadTemplateMenu.Children;
            if numel(children) > 1
                delete(children(1:end-1)) % Delete previous menus
            end
            for i = 1:size(templates,1)
                menu = uimenu(app.LoadTemplateMenu);
                if i == 1 % Turn seperator on for first template
                    menu.Separator = 'on';
                end
                menu.Text = templates.name(i);
                menu.Tooltip = templates.description(i);
                menu.UserData = templates.templateID(i);
                if templates.templateID(i) == app.templateID
                    menu.Checked = 'on';
                end
                menu.MenuSelectedFcn = @(src, event) TemplateMenuSelected(app, src, event);
            end
        end

        % Menu selected function: SaveChangesMenu
        function SaveChangesMenuSelected(app, event)
            for i = 1:size(app.Template,2)
                VariableName = app.Template.Properties.VariableNames;
                if ~isempty(app.(VariableName{i}))
                    app.Template.(VariableName{i}) = app.(VariableName{i});
                end
            end
            conn = sqlite(app.dbpath, "connect");
            % Delete previous entry in database
            exec(conn, sprintf("DELETE FROM plot_templateTab WHERE templateID = %i", app.templateID));
            % Upload updated version
            sqlwrite(conn,'plot_templateTab',app.Template)

            % Update plot_variableTab
            temporaryTab = fetch(conn, sprintf("SELECT p.* FROM plot_variableTab p JOIN variable_handlingTab v ON v.variableID = p.variableID WHERE templateID = %i AND v.visible = 1 AND v.organismID = %i", app.templateID, app.CallingApp.organismID));
            temporaryTab.selected_axis = app.Table.SelectedVar;
            temporaryTab.limit_type = app.Table.LimitTypeVar;
            temporaryTab.ymin = app.Table.yminVar;
            temporaryTab.ymax = app.Table.ymaxVar;
            % Delete previous entry in database
            exec(conn, sprintf("DELETE FROM plot_variableTab WHERE templateID = %i", app.templateID));
            % Upload updated version
            sqlwrite(conn,'plot_variableTab',temporaryTab)
            close(conn)
        end

        % Menu selected function: ExportPlotMenu
        function ExportPlotMenuSelected(app, event)
            if app.AutoUpdate.Running == "on"
                stop(app.AutoUpdate)
            end
            fig = figure('Position',[50 50 1200 700], 'Color',[1, 1, 1]);
            app.plotdest = fig;
            app.PlotObjects = {};
            plotting(app)
            waitfor(fig)
            app.plotdest = app.PlotPanel;
            app.PlotObjects = {};
            start(app.AutoUpdate)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExportMenu = uimenu(app.UIFigure);
            app.ExportMenu.Text = 'Export';
            app.ExportPlotMenu = uimenu(app.ExportMenu);
            app.ExportPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportPlotMenuSelected, true);
            app.ExportPlotMenu.Text = 'Export Plot';
            app.TemplateMenu = uimenu(app.UIFigure);
            app.TemplateMenu.Text = 'Template';
            app.SaveChangesMenu = uimenu(app.TemplateMenu);
            app.SaveChangesMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveChangesMenuSelected, true);
            app.SaveChangesMenu.Text = 'Save Changes';
            app.CreateTemplateMenu = uimenu(app.TemplateMenu);
            app.CreateTemplateMenu.MenuSelectedFcn = createCallbackFcn(app, @CreateTemplateMenuSelected, true);
            app.CreateTemplateMenu.Text = 'Create Template';
            app.LoadTemplateMenu = uimenu(app.TemplateMenu);
            app.LoadTemplateMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadTemplateMenuSelected, true);
            app.LoadTemplateMenu.Text = 'Load Template';
            app.ManageTemplatesMenu = uimenu(app.LoadTemplateMenu);
            app.ManageTemplatesMenu.MenuSelectedFcn = createCallbackFcn(app, @ManageTemplatesMenuSelected, true);
            app.ManageTemplatesMenu.Text = 'Manage Templates';
            app.OptionsMenu = uimenu(app.UIFigure);
            app.OptionsMenu.Text = 'Options';
            app.FlagsMenu = uimenu(app.OptionsMenu);
            app.FlagsMenu.MenuSelectedFcn = createCallbackFcn(app, @FlagsMenuSelected, true);
            app.FlagsMenu.Checked = 'on';
            app.FlagsMenu.Text = 'Flags';
            app.AutoDecimalsMenu = uimenu(app.OptionsMenu);
            app.AutoDecimalsMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoDecimalsMenuSelected, true);
            app.AutoDecimalsMenu.Checked = 'on';
            app.AutoDecimalsMenu.Text = 'Auto Decimals';
            app.EditVariablesMenu = uimenu(app.OptionsMenu);
            app.EditVariablesMenu.MenuSelectedFcn = createCallbackFcn(app, @EditVariablesMenuSelected, true);
            app.EditVariablesMenu.Separator = 'on';
            app.EditVariablesMenu.Text = 'Edit Variables';
            app.SettingsMenu = uimenu(app.OptionsMenu);
            app.SettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @SettingsMenuSelected, true);
            app.SettingsMenu.Text = 'Settings';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.PlotConfigurationPanel = uipanel(app.GridLayout);
            app.PlotConfigurationPanel.Title = 'Plot Configuration';
            app.PlotConfigurationGridLayout = uigridlayout(app.PlotConfigurationPanel);
            app.VariablePanel = uipanel(app.PlotConfigurationGridLayout);
            app.VariableGridLayout = uigridlayout(app.VariablePanel);
            app.AutoUpdateButton = uibutton(app.PlotConfigurationGridLayout, 'state');
            app.AutoUpdateButton.ValueChangedFcn = createCallbackFcn(app, @AutoUpdateButtonValueChanged, true);
            app.AutoUpdateButton.Text = 'Auto Update';
            app.AutoUpdateButton.Value = true;
            app.PlotPanel = uipanel(app.GridLayout);
            app.PlotPanel.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotPanel);
            title(app.UIAxes, 'Cultivation Simulation')
            xlabel(app.UIAxes, 't [h]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.ConfiOpener = uibutton(app.GridLayout, 'state');
            app.ConfiOpener.ValueChangedFcn = createCallbackFcn(app, @ConfiOpenerValueChanged, true);
            app.ConfiOpener.Tooltip = {'Plot configuration'};
            app.ConfiOpener.Text = '>';
            app.ConfiOpener.Value = true;
            app.LimitsOpener = uibutton(app.GridLayout, 'state');
            app.LimitsOpener.ValueChangedFcn = createCallbackFcn(app, @LimitsOpenerValueChanged, true);
            app.LimitsOpener.Text = '<';
            app.LimitsOpener.Value = true;
            app.VariableLimitsPanel = uipanel(app.GridLayout);
            app.VariableLimitsPanel.Title = 'Variable Limits';
            app.VariableLimitsGridLayout = uigridlayout(app.VariableLimitsPanel);
            app.VariableLabel = uilabel(app.VariableLimitsGridLayout);
            app.VariableLabel.Text = 'Variable';
            app.MinLabel_2 = uilabel(app.VariableLimitsGridLayout);
            app.MinLabel_2.Text = 'Min';
            app.MaxLabel_2 = uilabel(app.VariableLimitsGridLayout);
            app.MaxLabel_2.Text = 'Max';
            app.AutoLabel = uilabel(app.VariableLimitsGridLayout);
            app.AutoLabel.Text = 'Auto';
            app.VariableAxisLabel = uilabel(app.VariableLimitsGridLayout);
            app.VariableAxisLabel.Text = 'Variable Axis';
            app.TimeAxisLabel = uilabel(app.VariableLimitsGridLayout);
            app.TimeAxisLabel.Text = ' Time Axis';
            app.tlimEditFieldLabel = uilabel(app.VariableLimitsGridLayout);
            app.tlimEditFieldLabel.Text = 't [h]:';
            app.MinLabel = uilabel(app.VariableLimitsGridLayout);
            app.MinLabel.Text = 'Min';
            app.MaxLabel = uilabel(app.VariableLimitsGridLayout);
            app.MaxLabel.Text = 'Max';
            app.tlimminEditField = uieditfield(app.VariableLimitsGridLayout, 'numeric');
            app.tlimminEditField.ValueChangedFcn = createCallbackFcn(app, @tlimminEditFieldValueChanged, true);
            app.tlimEditField = uieditfield(app.VariableLimitsGridLayout, 'numeric');
            app.tlimEditField.ValueChangedFcn = createCallbackFcn(app, @tlimEditFieldValueChanged, true);
            app.tlimEditField.Value = 5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FigureApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end