classdef FigureAppTemplateManager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SelectTemplateButton            matlab.ui.control.Button
        DeleteTemplateButton            matlab.ui.control.Button
        CloseButton                     matlab.ui.control.Button
        TemplatesListBox                matlab.ui.control.ListBox
        TemplatesListBoxLabel           matlab.ui.control.Label
        CreateNewTemplateButton         matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        InformationTab                  matlab.ui.container.Tab
        SelectedVariablesTextArea       matlab.ui.control.TextArea
        SelectedVariablesTextAreaLabel  matlab.ui.control.Label
        DescriptionTextArea             matlab.ui.control.TextArea
        DescriptionTextAreaLabel        matlab.ui.control.Label
        TemplateNameEditField           matlab.ui.control.EditField
        TemplateNameEditFieldLabel      matlab.ui.control.Label
        SelectedVariablesTab            matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        ContextMenu                     matlab.ui.container.ContextMenu
        EditMenu                        matlab.ui.container.Menu
        DeleteMenu                      matlab.ui.container.Menu
    end

    
    properties (Access = private)
        CallingApp %
        MainApp
        templateID %
        plot_templateTab
        plot_variableTab
        active_template
        templates
        
    end

    methods (Access = private)
        
        function refreshInformation(app)
            conn = sqlite(app.CallingApp.dbpath, "readonly");
            app.plot_templateTab = fetch(conn, "SELECT * FROM plot_templateTab");
            sqlquery = sprintf("SELECT pv.plot_variableID, pv.templateID, pv.variableID, v.name, pv.selected_axis " + ...
                "FROM plot_variableTab pv " + ...
                "JOIN variableTab v ON v.variableID = pv.variableID " + ...
                "JOIN variable_handlingTab vh ON vh.variableID = v.variableID " + ...
                "WHERE vh.upload_rate = 'cyclic' AND vh.organismID = %i", app.MainApp.organismID);
            app.plot_variableTab = fetch(conn, sqlquery);
            close(conn)
            % Fill template list box 
            app.TemplatesListBox.Items = app.plot_templateTab.name(:);
            app.TemplatesListBox.ItemsData = app.plot_templateTab.templateID(:);

        end

        function extractTemplate(app)
            % Find the profile by ID
            idx = find(app.templateID == app.plot_templateTab.templateID);

            if ~isempty(idx)
                profileInfo = app.plot_templateTab(idx, :);

                % Set the template name in the edit field
                app.TemplateNameEditField.Value = profileInfo.name{1}; % Ensure correct indexing with cell array

                % Set the description in the text area
                app.DescriptionTextArea.Value = profileInfo.description{1}; % Ensure correct indexing with cell array

                % Handle selected variables field
                if ~isempty(app.templateID)
                    profile_vars = app.plot_variableTab(app.plot_variableTab.templateID == app.templateID, :);
                    selected = profile_vars(profile_vars.selected_axis == 1, :);

                    if ~isempty(selected)
                        selected_vars = "";

                        % Collect all selected variables as strings
                        for i = 1:height(selected)
                            name = selected.name{i}; % Access table cell content properly
                            variable = sprintf('%s\n', name);
                            selected_vars = append(selected_vars, variable);
                        end

                        % List them in the text area
                        app.SelectedVariablesTextArea.Value = selected_vars;
                    end
                end
            end
        end

        function uniquename = uniqueProfileName(app, name)
            sqlquery = 'SELECT name FROM plot_templateTab ';
            conn = sqlite(app.CallingApp.dbpath, "readonly");
            projectnames = table2array(fetch(conn, sqlquery));
            close(conn)
            if any(strcmp(projectnames, name))
                suffix = 1;
                uniquename = name;
                while any(strcmp(projectnames, uniquename))
                    uniquename = sprintf('%s%d', name, suffix);
                    suffix = suffix + 1;
                    if suffix > 100
                        disp("Name is taken over 100 times.");
                        break;
                    end
                end
            else
                uniquename = name;
            end
        end

        function updateSelectedVariables(app)
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, CallingApp, MainApp, templateID)
            app.CallingApp = CallingApp;
            app.MainApp = MainApp;
            app.templateID = templateID;
            centerWindow(app.UIFigure)
            refreshInformation(app)
            extractTemplate(app)
            app.TemplatesListBox.Value = app.templateID;
        end

        % Value changed function: TemplatesListBox
        function TemplatesListBoxValueChanged(app, event)
            app.templateID = app.TemplatesListBox.Value;
            app.TabGroup.SelectedTab = app.InformationTab;
            refreshInformation(app)
            extractTemplate(app)
        end

        % Button pushed function: CreateNewTemplateButton
        function CreateNewTemplateButtonPushed(app, event)
            % Create a new template which is based on the default template
            conn = sqlite(app.CallingApp.dbpath, "connect");
            new_plot_templateTab  = fetch(conn, "SELECT * FROM plot_templateTab ORDER BY templateID LIMIT 1");
            new_plot_variableTab = fetch(conn, sprintf("SELECT * FROM plot_variableTab WHERE templateID = %i",new_plot_templateTab.templateID(1)));
            new_templateID = double(table2array(fetch(conn, "SELECT MAX(templateID) FROM plot_templateTab"))) + 1;
            new_plot_templateTab.description = "";
            new_plot_templateTab.templateID = new_templateID;
            new_plot_variableTab.templateID = repmat(new_templateID,size(new_plot_variableTab,1),1);
            new_max_ID = double(table2array(fetch(conn, "SELECT MAX(plot_variableID) FROM plot_variableTab")));
            id = 1:size(new_plot_variableTab,1);
            new_plot_variableID = (new_max_ID+id)'; % New primary key for new data
            new_plot_templateTab.name = string(uniqueProfileName(app, "NewTemplate")); % Name it new template
            new_plot_templateTab.last_changed = datetime("now","Format","dd.MM.uuuu HH:mm:ss");
            new_plot_variableTab.plot_variableID = new_plot_variableID;

            % Append new tables to existing one
            sqlwrite(conn, "plot_templateTab", new_plot_templateTab);
            sqlwrite(conn, "plot_variableTab", new_plot_variableTab);
            app.templateID = new_templateID;
            refreshInformation(app)
            extractTemplate(app)
            app.TemplatesListBox.Value = app.templateID;
        end

        % Button pushed function: DeleteTemplateButton
        function DeleteTemplateButtonPushed(app, event)
            response = questdlg(sprintf('Do you really want to delete the template "%s"?', ...
                app.plot_templateTab.name(app.plot_templateTab.templateID == app.templateID)), ...
                'Delete Template', ...   % title
                'Delete', ...
                'Cancel', ...
                'Delete');

            switch response
                case 'Delete'
                    if app.templateID ~= 1
                        % SQL query for profile deletion
                        sqlquery = sprintf('DELETE FROM plot_templateTab WHERE templateID = %i', app.templateID);
                        execpy(app.CallingApp.dbpath, sqlquery);

                        % New selected profile is the default profile
                        app.templateID = app.plot_templateTab.templateID(1);
                        app.TemplatesListBox.Value = app.templateID;
                        refreshInformation(app)
                        extractTemplate(app)

                    else
                        msgbox("The default template cannot be deleted.", "modal");
                    end
                case 'Cancel'
                    return
            end
        end

        % Value changed function: TemplateNameEditField
        function TemplateNameEditFieldValueChanged(app, event)
            value = app.TemplateNameEditField.Value;
            if ~isempty(value) 
                uniquename = uniqueProfileName(app, value);
                sqlquery = sprintf('UPDATE plot_templateTab SET name = "%s" WHERE templateID = %i', uniquename,app.templateID);
                conn = sqlite(app.CallingApp.dbpath, "connect");
                exec(conn,sqlquery);
                close(conn)
                refreshInformation(app)
                extractTemplate(app)
            end

        end

        % Value changed function: DescriptionTextArea
        function DescriptionTextAreaValueChanged(app, event)
            value = string(app.DescriptionTextArea.Value);
            if ~isempty(value) 
                sqlquery = sprintf('UPDATE plot_templateTab SET description = "%s" WHERE templateID = %i', value,app.templateID);
                conn = sqlite(app.CallingApp.dbpath, "connect");
                exec(conn,sqlquery);
                close(conn)
                refreshInformation(app)
                extractTemplate(app)
            end
        end

        % Button down function: SelectedVariablesTab
        function SelectedVariablesTabButtonDown(app, event)
                % Fill table
                data = table;
                idx = (app.plot_variableTab.templateID == app.templateID);
                data.Variable = app.plot_variableTab.name(idx);
                data.Selected = logical(app.plot_variableTab.selected_axis(idx));
                app.UITable.Data = data;
                app.UITable.ColumnEditable = [false true];
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            % Find the rows where templateID matches
            matching_rows = app.plot_variableTab.templateID == app.templateID;
            temp_table = app.plot_variableTab(matching_rows,:);

            % Use indices(1) to find the specific row within the matching rows
            temp_table.selected_axis(indices(1)) = double(newData);
            if sum(temp_table.selected_axis) > 6
                temp_table.selected_axis(indices(1)) = double(false);
                app.UITable.Data.Selected(indices(1)) = false;
                msg = msgbox("You can only select up to six variables at a time.","Warning","non-modal");
                pause(1)
                if isvalid(msg)
                    close(msg)
                end
            else
                sqlquery = sprintf(['UPDATE plot_variableTab ' ...
                    'SET selected_axis = %i ' ...
                    'WHERE templateID = %i AND variableID = %i'], ...
                    double(newData),app.templateID,temp_table.variableID(indices(1)));
                conn = sqlite(app.CallingApp.dbpath, "connect");
                exec(conn,sqlquery);
                close(conn)
            end
            app.plot_variableTab(matching_rows,:) = temp_table;
        end

        % Button down function: InformationTab
        function InformationTabButtonDown(app, event)
            refreshInformation(app)
            extractTemplate(app)
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: SelectTemplateButton
        function SelectTemplateButtonPushed(app, event)
            app.CallingApp.ProfileSelected = app.templateID;
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InformationTab = uitab(app.TabGroup);
            app.InformationTab.Title = 'Information';
            app.InformationTab.ButtonDownFcn = createCallbackFcn(app, @InformationTabButtonDown, true);
            app.TemplateNameEditFieldLabel = uilabel(app.InformationTab);
            app.TemplateNameEditFieldLabel.Text = 'Template Name:';
            app.TemplateNameEditField = uieditfield(app.InformationTab, 'text');
            app.TemplateNameEditField.ValueChangedFcn = createCallbackFcn(app, @TemplateNameEditFieldValueChanged, true);
            app.DescriptionTextAreaLabel = uilabel(app.InformationTab);
            app.DescriptionTextAreaLabel.Text = 'Description:';
            app.DescriptionTextArea = uitextarea(app.InformationTab);
            app.DescriptionTextArea.ValueChangedFcn = createCallbackFcn(app, @DescriptionTextAreaValueChanged, true);
            app.SelectedVariablesTextAreaLabel = uilabel(app.InformationTab);
            app.SelectedVariablesTextAreaLabel.Text = 'Selected Variables:';
            app.SelectedVariablesTextArea = uitextarea(app.InformationTab);
            app.SelectedVariablesTextArea.Editable = 'off';
            app.SelectedVariablesTab = uitab(app.TabGroup);
            app.SelectedVariablesTab.Title = 'Selected Variables';
            app.SelectedVariablesTab.ButtonDownFcn = createCallbackFcn(app, @SelectedVariablesTabButtonDown, true);
            app.UITable = uitable(app.SelectedVariablesTab);
            app.UITable.ColumnName = {'Variable'; 'Selected'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.CreateNewTemplateButton = uibutton(app.UIFigure, 'push');
            app.CreateNewTemplateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNewTemplateButtonPushed, true);
            app.CreateNewTemplateButton.Text = 'Create New Template';
            app.TemplatesListBoxLabel = uilabel(app.UIFigure);
            app.TemplatesListBoxLabel.Text = 'Templates';
            app.TemplatesListBox = uilistbox(app.UIFigure);
            app.TemplatesListBox.ValueChangedFcn = createCallbackFcn(app, @TemplatesListBoxValueChanged, true);
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.DeleteTemplateButton = uibutton(app.UIFigure, 'push');
            app.DeleteTemplateButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteTemplateButtonPushed, true);
            app.DeleteTemplateButton.Text = 'Delete Template';
            app.SelectTemplateButton = uibutton(app.UIFigure, 'push');
            app.SelectTemplateButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTemplateButtonPushed, true);
            app.SelectTemplateButton.Text = 'Select Template';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.EditMenu = uimenu(app.ContextMenu);
            app.EditMenu.Accelerator = 'e';
            app.EditMenu.Text = 'Edit';
            app.DeleteMenu = uimenu(app.ContextMenu);
            app.DeleteMenu.Accelerator = 'd';
            app.DeleteMenu.Text = 'Delete';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FigureAppTemplateManager(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end