classdef StartingScreen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Image                           matlab.ui.control.Image
        ExitButton                      matlab.ui.control.Button
        ModelConfiguratorButton         matlab.ui.control.Button
        LoadProjectButton               matlab.ui.control.Button
        StartNewProjectButton           matlab.ui.control.Button
        QuickStartButton                matlab.ui.control.Button
        Version21Label                  matlab.ui.control.Label
        BiofermentationSimulationLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        SelectProjectApp
        CreateProjectApp
        ConfigureModel
        databaseName = "SimulationAppDB.db"
        
        Controlapp % Control app instance
        

    end

    properties (Access = public)
        %% Database related
        dbfile = 'SimulationAppDB.db' % Databasefile name
        dbpath % will be edited to a fullfilepath

        %% Project related
        selectedproject % The project that is going to be called by the control app
        projectID
    end

    methods (Access = public)


        function uniquename = uniqueProjectname(app, name)
            sqlquery = 'SELECT name FROM projectTab ';
            conn = sqlite(app.dbpath, "readonly");
            projectnames = table2array(fetch(conn, sqlquery));
            close(conn)
            if any(strcmp(projectnames, name))
                suffix = 1;
                uniquename = name;
                while any(strcmp(projectnames, uniquename))
                    uniquename = sprintf('%s_%d', name, suffix);
                    suffix = suffix + 1;
                    if suffix > 100
                        disp("Name is taken over 100 times.");
                        break;
                    end
                end
            else
                uniquename = name;
            end
        end

        function moveDatabaseToAppData(app)
            % This function makes sure that the database and all organism
            % specific files are transfered from the installation file to
            % the AppData/Roaming file. This allows altering data without
            % admin rights

            % Only execute if the app is compiled
            if isdeployed
                % Determine the appropriate directory based on the operating system
                if ispc
                    % Windows: Get the AppData directory for MathWorks
                    appDataDir = fullfile(getenv('APPDATA'), 'MathWorks', 'BioprocessSimulationApp');
                elseif ismac
                    % macOS: Use the user's Library/Application Support directory
                    appDataDir = fullfile(getenv('HOME'), 'Library', 'Application Support', 'MathWorks', 'BioprocessSimulationApp');
                else
                    error('Unsupported operating system.');
                end

                destinationPath = fullfile(appDataDir, app.databaseName);

                % Create the AppData directory if it doesn't exist
                if ~exist(appDataDir, 'dir')
                    mkdir(appDataDir);
                elseif ~exist(fullfile(appDataDir,app.databaseName),"file") % Check if the database already exists in AppData
                    % Define the source (compiled app root) and destination paths for the database file
                    sourcePath = findFileInCtfroot(app,app.databaseName);
                    disp(append("Source path: ", sourcePath))
                    disp(append("Destination path: ", destinationPath))
                    % Copy the database file from the compiled source to the destination
                    try
                        copyfile(sourcePath, destinationPath);
                        % Update the app's database path to point to the new location
                        app.dbpath = destinationPath;
                        disp('Database successfully copied to AppData for write access.');
                    catch ME
                        warning(ME.identifier,'%s', ME.message);
                    end
                end
                % If the database already exists, just update the path
                app.dbpath = destinationPath;
            else % If app is not deployed
                app.dbpath = fullfile(pwd, app.dbfile); % Save full db file path
            end
        end

        function moveOrganismFilesToAppData(app)
            if isdeployed && exist(app.dbpath,"file")
                % Determine the appropriate directory based on the operating system
                if ispc
                    % Windows: Get the AppData directory for MathWorks
                    appDataDir = fullfile(getenv('APPDATA'), 'MathWorks', 'BiofermentationSimulationApp');
                elseif ismac
                    % macOS: Use the user's Library/Application Support directory
                    appDataDir = fullfile(getenv('HOME'), 'Library', 'Application Support', 'MathWorks', 'BioprocessSimulationApp');
                else
                    error('Unsupported operating system.');
                end

                % Determine all function files
                sqlquery = 'SELECT function_file FROM organismTab;';
                conn = sqlite(app.dbpath, "connect");
                T = fetch(conn, sqlquery);
                organism_files = T.function_file;
                organism_files(end+1) = "Additional_functions"; % Also include additional functions file
                for i = 1:length(organism_files)
                    organism = organism_files(i);
                    destinationPath = fullfile(appDataDir, organism);
                    if ~isfolder(fullfile(appDataDir,organism))
                        sourcePath = findFileInCtfroot(app,organism);
                        if isempty(sourcePath)
                            fprintf("%s couldn´t be found.\n", organism)
                        elseif ~isfolder(sourcePath)
                            fprintf("%s couldn´t be found.\n", organism)
                        else
                            try
                                copyfile(sourcePath,destinationPath)
                                fprintf("%s was successfully transfered into the AppData folder!\n", organism)
                            catch
                                fprintf("%s couldnt be transfered into the AppData folder!\n", organism)
                            end
                        end
                    end
                end
            end
        end

        function filePath = findFileInCtfroot(~,fileName)
            % This function searches recursively in the cftroot directory for a specific file.
            %
            % Input:
            %   - fileName: Name of the file to search for (including extension).
            % Output:
            %   - filePath: Full path to the found file, or [] if not found.

            % Get the root path of the cftroot environment
            rootPath = ctfroot;

            % Search recursively for the file
            result = dir(fullfile(rootPath, '**', fileName));

            if ~isempty(result)
                % File found, return the path of the first match
                filePath = fullfile(result(1).folder, result(1).name);
            else
                % File not found
                filePath = [];
                disp(['File "', fileName, '" was not found.']);
            end
        end


    %% XML related functions
    function xmlStruct = readXmlFromDatabase(app, database_name)
        % Connect to database
        conn = sqlite(app.dbpath, 'readonly');

        % SQL-query for the wanted XML string
        query = sprintf('SELECT * FROM XML_database WHERE name = "%s"', database_name);
        data = fetch(conn, query);

        % close connection
        close(conn);

        % extract XML string
        if ~isempty(data)
            xmlStruct = data{1};
        else
            error('Data not found.');
        end
    end

        function updatedXmlString = updateXml(app, xmlString, updates)
            % create XML-DOM-document out of string
            xmlDoc = xmlreadstring(xmlString);

            % get root element
            root = xmlDoc.getDocumentElement();

            % apply changes
            fields = fieldnames(updates);
            for i = 1:numel(fields)
                % search for element
                element = root.getElementsByTagName(fields{i}).item(0);

                if ~isempty(element)
                    % update value
                    element.setTextContent(updates.(fields{i}));
                else
                    warning('Element %s not found.', fields{i});
                end
            end

            % change XML-document back to string
            updatedXmlString = xmlwrite(xmlDoc);
        end

        function writeXmlToDatabase(app, tableName, columnName, rowID, updatedXmlString)
            % Connect to database
            conn = sqlite(app.dbpath, 'connect');

            % SQL-query to updata XML-strings
            query = sprintf('UPDATE %s SET %s = ? WHERE id = %d', tableName, columnName, rowID);
            exec(conn, query, {updatedXmlString});

            % close connection
            close(conn);
        end
    
    end


    methods (Access = private)

        function startProcess(app)
            fprintf("Selected projectID: %s\n", num2str(app.selectedproject));
            % Start control app
            app.Controlapp = ControlApp(app, app.projectID);
            app.UIFigure.Visible = "off";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = "Biofermentation Simulation";
            centerWindow(app.UIFigure)
            moveDatabaseToAppData(app)
            moveOrganismFilesToAppData(app)
            
        end

        % Button pushed function: StartNewProjectButton
        function StartNewProjectButtonPushed(app, event)
            app.selectedproject = [];
            app.UIFigure.Visible = "off";
            app.CreateProjectApp = CreateProject(app);
            waitfor(app.CreateProjectApp);
            app.UIFigure.Visible = "on";
            if ~isempty(app.selectedproject)
                startProcess(app)
            end
        end

        % Button pushed function: LoadProjectButton
        function LoadProjectButtonPushed(app, event)
            app.selectedproject = [];
            app.UIFigure.Visible = "off";
            app.SelectProjectApp = SelectProject(app);
            waitfor(app.SelectProjectApp);
            app.UIFigure.Visible = "on";
            if ~isempty(app.selectedproject)
                startProcess(app)
            end
        end

        % Button pushed function: ModelConfiguratorButton
        function ModelConfiguratorButtonPushed(app, event)
            %app.ModelConfiguratorButton.Enable = "off";
            app.ConfigureModel = ModelConfigurator(app);
            waitfor(app.ConfigureModel)
            %app.ModelConfiguratorButton.Enable = 'on';
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app.UIFigure);
        end

        % Button pushed function: QuickStartButton
        function QuickStartButtonPushed(app, event)
            name = "MyProject";
            author = "John Doe";
            date = datetime("now","Format","dd.MM.uuuu HH:mm:ss.SSS");
            description = sprintf("This is a quick start project created on: %s.", date);

            name = uniqueProjectname(app, name);
            if ~isempty(name)
                % Read model data from first model (display_rank)
                sqlquery = ['SELECT m.modelID, mp.parameterID, mp.value, mp.description, m.organismID, m.bioreactorID ' ...
                    'FROM model_parameterTab mp ' ...
                    'JOIN modelTab m ON m.modelID = mp.modelID ' ...
                    'WHERE mp.modelID = (SELECT modelID FROM modelTab ORDER BY display_rank ASC LIMIT 1);'];
                conn = sqlite(app.dbpath, "connect");
                modelparameterTable = fetch(conn, sqlquery);
                modelID = modelparameterTable.modelID(1);
                modelparameter = modelparameterTable(:,2:4); % Modelparameter without modelID
                organism = modelparameterTable.organismID(1);
                bioreactor = modelparameterTable.bioreactorID(1);
                % Create new project entry
                sqlquery = sprintf(['INSERT INTO projectTab (name, description, author, created_on, recent_use, organismID, bioreactorID, modelID) ' ...
                    'VALUES (''%s'', ''%s'', ''%s'', ''%s'', ''%s'', %i, %i, %i)'], name, description,author, date, date, organism, bioreactor, modelID);
                exec(conn,sqlquery);
                % Read projectID of the new project
                sqlquery = sprintf('SELECT projectID FROM projectTab WHERE name = ''%s''', name);
                T = fetch(conn,sqlquery);
                app.projectID = T.projectID;
                % Create a column with the new projectID
                emptyColumn = ones(height(modelparameter(:,1)),1);
                filledColumn = emptyColumn.*double(app.projectID);
                projectIDcolumn = table(filledColumn);
                data = [projectIDcolumn, modelparameter]; % cut modelID
                % Write parameter information into project_parameter table 
                data.Properties.VariableNames = {'projectID', 'parameterID', 'value', 'description'};
                sqlwrite(conn, 'project_parameterTab', data);
                close(conn)
                app.selectedproject = name;
                startProcess(app)
            else
                disp("Project couldn´t be generated.");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.BiofermentationSimulationLabel = uilabel(app.UIFigure);
            app.BiofermentationSimulationLabel.Text = {'Biofermentation '; 'Simulation'};
            app.Version21Label = uilabel(app.UIFigure);
            app.Version21Label.Text = 'Version 2.1';
            app.QuickStartButton = uibutton(app.UIFigure, 'push');
            app.QuickStartButton.ButtonPushedFcn = createCallbackFcn(app, @QuickStartButtonPushed, true);
            app.QuickStartButton.Text = 'Quick Start';
            app.StartNewProjectButton = uibutton(app.UIFigure, 'push');
            app.StartNewProjectButton.ButtonPushedFcn = createCallbackFcn(app, @StartNewProjectButtonPushed, true);
            app.StartNewProjectButton.Text = 'Start New Project';
            app.LoadProjectButton = uibutton(app.UIFigure, 'push');
            app.LoadProjectButton.ButtonPushedFcn = createCallbackFcn(app, @LoadProjectButtonPushed, true);
            app.LoadProjectButton.Text = 'Load Project';
            app.ModelConfiguratorButton = uibutton(app.UIFigure, 'push');
            app.ModelConfiguratorButton.ButtonPushedFcn = createCallbackFcn(app, @ModelConfiguratorButtonPushed, true);
            app.ModelConfiguratorButton.Text = 'Model Configurator';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'figures', 'BPA_logo.jpg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StartingScreen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end