classdef ControlApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ProjectMenu                     matlab.ui.container.Menu
        StartnewprojectMenu             matlab.ui.container.Menu
        QuickstartMenu                  matlab.ui.container.Menu
        StandardMenu                    matlab.ui.container.Menu
        OpenprojectMenu                 matlab.ui.container.Menu
        SaveandexitMenu                 matlab.ui.container.Menu
        DeleteprojectMenu               matlab.ui.container.Menu
        ExportMenu                      matlab.ui.container.Menu
        OpendatatableMenu               matlab.ui.container.Menu
        ExportdataasMenu                matlab.ui.container.Menu
        xlsxMenu                        matlab.ui.container.Menu
        txtMenu                         matlab.ui.container.Menu
        csvMenu                         matlab.ui.container.Menu
        ExportprojectMenu               matlab.ui.container.Menu
        SettingsMenu                    matlab.ui.container.Menu
        DisconnectMenu                  matlab.ui.container.Menu
        ExpertmodeMenu                  matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        ControlOptionsTab               matlab.ui.container.Tab
        ControlOptionsGridLayout        matlab.ui.container.GridLayout
        FeedControlPanel                matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        OpenLoopFeedControlActiveLabel  matlab.ui.control.Label
        F_RwlhEditField                 matlab.ui.control.NumericEditField
        F_RwlhEditFieldLabel            matlab.ui.control.Label
        c_S1Lwgl1EditField              matlab.ui.control.NumericEditField
        c_S1Lwgl1EditFieldLabel         matlab.ui.control.Label
        F_R1maxlh1EditField             matlab.ui.control.NumericEditField
        F_R1maxlh1EditFieldLabel        matlab.ui.control.Label
        Feeding_ModeDropDown            matlab.ui.control.DropDown
        Feeding_ModeDropDownLabel       matlab.ui.control.Label
        FeedControlSwitch               matlab.ui.control.RockerSwitch
        FeedControlLamp                 matlab.ui.control.Lamp
        ReservoirDropDown               matlab.ui.control.DropDown
        ReservoirDropDownLabel          matlab.ui.control.Label
        FeedingParametersButton         matlab.ui.control.Button
        LevelControlPanel               matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        AntifoamLamp                    matlab.ui.control.Lamp
        AntifoamadditionSwitch          matlab.ui.control.RockerSwitch
        LiquidWeightPanel               matlab.ui.container.Panel
        LiquidWeightGridLayout          matlab.ui.container.GridLayout
        F_HwEditField                   matlab.ui.control.NumericEditField
        fontsize10fontnameHelveticaF_HwEditFieldLabel  matlab.ui.control.Label
        ModeDropDown_4                  matlab.ui.control.DropDown
        ModeLabel                       matlab.ui.control.Label
        m_LkgEditField                  matlab.ui.control.NumericEditField
        fontsize10fontnameHelveticam_LkgEditFieldLabel  matlab.ui.control.Label
        m_L_wkgEditField                matlab.ui.control.NumericEditField
        fontsize10fontnameHelveticam_L_wkgEditFieldLabel  matlab.ui.control.Label
        HarvestSwitch                   matlab.ui.control.RockerSwitch
        HarvestSwitchLabel              matlab.ui.control.Label
        LiquidWeightLamp                matlab.ui.control.Lamp
        LWParametersButton              matlab.ui.control.Button
        pO2ControlPanel                 matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        F_nGwlminEditField              matlab.ui.control.NumericEditField
        fontsize10fontnameHelveticaF_nGwlmin1Label  matlab.ui.control.Label
        ModeDropDown_3                  matlab.ui.control.DropDown
        ModeLabel_2                     matlab.ui.control.Label
        F_nO2wlminEditField             matlab.ui.control.NumericEditField
        fontsize10fontnameHelveticaF_nO2wlminLabel  matlab.ui.control.Label
        F_nAIRwlminEditField            matlab.ui.control.NumericEditField
        fontsize10fontnameHelveticaF_nAIRwlmin1Label  matlab.ui.control.Label
        N_StwrpmEditField               matlab.ui.control.NumericEditField
        fontsize10fontnameHelveticaN_StwrpmEditFieldLabel  matlab.ui.control.Label
        pO_2EditField                   matlab.ui.control.NumericEditField
        fontsize10fontnameHelveticapO_2EditFieldLabel  matlab.ui.control.Label
        pO_2_wEditField                 matlab.ui.control.NumericEditField
        fontsize10fontnameHelveticapO_2_wEditFieldLabel  matlab.ui.control.Label
        pO2Lamp                         matlab.ui.control.Lamp
        pO2ParametersButton             matlab.ui.control.Button
        TemperatureControlPanel         matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        ModeDropDown_2                  matlab.ui.control.DropDown
        ModeLabel_3                     matlab.ui.control.Label
        theta_LCEditField               matlab.ui.control.NumericEditField
        fontsize10fontnameHelveticavartheta_LCEditField_2Label  matlab.ui.control.Label
        theta_wCEditField               matlab.ui.control.NumericEditField
        fontsize10fontnameHelveticavartheta_LwCLabel  matlab.ui.control.Label
        HeatingSwitch                   matlab.ui.control.RockerSwitch
        HeatingSwitchLabel              matlab.ui.control.Label
        CoolingSwitch                   matlab.ui.control.RockerSwitch
        CoolingSwitchLabel              matlab.ui.control.Label
        TemperatureLamp                 matlab.ui.control.Lamp
        tempParametersButton            matlab.ui.control.Button
        pHControlPanel                  matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        ModeDropDown                    matlab.ui.control.DropDown
        ModeLabel_4                     matlab.ui.control.Label
        pH_LEditField                   matlab.ui.control.NumericEditField
        fontsize10fontnameHelveticapH_LEditFieldLabel  matlab.ui.control.Label
        fontsize10fontnameHelveticapH_wEditFieldLabel  matlab.ui.control.Label
        pH_wEditField                   matlab.ui.control.NumericEditField
        AcidSwitch                      matlab.ui.control.RockerSwitch
        AcidSwitchLabel                 matlab.ui.control.Label
        AlkaliSwitch                    matlab.ui.control.RockerSwitch
        AlkaliSwitchLabel               matlab.ui.control.Label
        pHLamp                          matlab.ui.control.Lamp
        pHParametersButton              matlab.ui.control.Button
        VariablePoolTab                 matlab.ui.container.Tab
        PumpRatesPanel                  matlab.ui.container.Panel
        FR3EditField                    matlab.ui.control.NumericEditField
        fontsize10fontnameHelveticaF_R3lh1EditFieldLabel  matlab.ui.control.Label
        FR2EditField                    matlab.ui.control.NumericEditField
        fontsize10fontnameHelveticaF_R2lh1EditFieldLabel  matlab.ui.control.Label
        FR1EditField                    matlab.ui.control.NumericEditField
        fontsize10fontnameHelveticaF_R1lh1EditFieldLabel  matlab.ui.control.Label
        FHEditField                     matlab.ui.control.NumericEditField
        fontsize10fontnameHelveticaF_Hlh1EditFieldLabel  matlab.ui.control.Label
        fontsize10fontnameHelveticaF_T2baselh1EditFieldLabel  matlab.ui.control.Label
        FT2EditField                    matlab.ui.control.NumericEditField
        fontsize10fontnameHelveticaF_T1acidlh1Label  matlab.ui.control.Label
        FT1EditField                    matlab.ui.control.NumericEditField
        LiquidVolumePanel               matlab.ui.container.Panel
        addedfeedR3lEditField           matlab.ui.control.NumericEditField
        addedfeedR3lEditFieldLabel      matlab.ui.control.Label
        addedfeedR2lEditField           matlab.ui.control.NumericEditField
        addedfeedR2lEditFieldLabel      matlab.ui.control.Label
        addedfeedR1lEditField           matlab.ui.control.NumericEditField
        addedfeedR1lEditFieldLabel      matlab.ui.control.Label
        addedAFlEditField               matlab.ui.control.NumericEditField
        addedAFlEditFieldLabel          matlab.ui.control.Label
        addedacidlEditField             matlab.ui.control.NumericEditField
        addedacidlEditFieldLabel        matlab.ui.control.Label
        addedbaselEditField             matlab.ui.control.NumericEditField
        addedbaselEditFieldLabel        matlab.ui.control.Label
        VLlEditField                    matlab.ui.control.NumericEditField
        VLlEditFieldLabel               matlab.ui.control.Label
        TrendTree                       matlab.ui.container.CheckBoxTree
        DisplayableVariablesNode        matlab.ui.container.TreeNode
        TrendUITable                    matlab.ui.control.Table
        ProcessManagerTab               matlab.ui.container.Tab
        ProcessManagerGridLayout        matlab.ui.container.GridLayout
        AddPhaseButton                  matlab.ui.control.Button
        LogTab                          matlab.ui.container.Tab
        LoggerLabel                     matlab.ui.control.Label
        UILogTable                      matlab.ui.control.Table
        IncludeparameterupdatesCheckBox  matlab.ui.control.CheckBox
        InformationTab                  matlab.ui.container.Tab
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        Label                           matlab.ui.control.Label
        StaticPanel                     matlab.ui.container.Panel
        ReconnectButton                 matlab.ui.control.Button
        EditStartingVariablesButton     matlab.ui.control.Button
        SpeedfactorxSpinner             matlab.ui.control.Spinner
        SpeedfactorxSpinnerLabel        matlab.ui.control.Label
        InoculateButton                 matlab.ui.control.StateButton
        InoculatedLamp                  matlab.ui.control.Lamp
        InoculatedLampLabel             matlab.ui.control.Label
        OpenPlotButton                  matlab.ui.control.Button
        DeltatsEditField                matlab.ui.control.NumericEditField
        fontsize10fontnameHelveticaDeltatsLabel  matlab.ui.control.Label
        StartSimulationButton           matlab.ui.control.Button
        ProcessRunningLamp              matlab.ui.control.Lamp
        ProcessRunningLampLabel         matlab.ui.control.Label
        ProcessTimehEditField           matlab.ui.control.NumericEditField
        fontsize10fontnameHelveticaProcessTimehEditFieldLabel  matlab.ui.control.Label
        ExitButton                      matlab.ui.control.Button
        DynamicPanel                    matlab.ui.container.Panel
        ContextMenu                     matlab.ui.container.ContextMenu
        TemplateMenu1                   matlab.ui.container.Menu
        TemplateMenu2                   matlab.ui.container.Menu
        TemplateMenu3                   matlab.ui.container.Menu
        TemplateMenu4                   matlab.ui.container.Menu
        TemplateMenu5                   matlab.ui.container.Menu
    end

    % This work is licensed under the Creative Commons Attribution 4.0 International License. To view a copy of this license, visit http://creativecommons.org/licenses/by/4.0/ or send a letter to Creative Commons, PO Box 1866, Mountain View, CA 94042, USA.
    
    % Version 2.1 is optimized to run on a single system. For this reason, interfaces are not taken into account and, apart from the cyclically uploaded process data, do not receive any current parameter data. 
    properties (Access = public)
        DialogApp  % Starting Variables Dialog box app
        DialogApp2 % pH Controller Parameter Dialog box app
        DialogApp3 % Temperture Controller Parameter Dialog box app
        DialogApp4 % pO2 Controller Parameter Dialog box app
        DialogApp5 % Liquid Weight Controller Parameter Dialog box app
        DialogApp6 % Feed Control
        SimulationApp  % Simulation Window
        StartingApp % Starting screen
        Datatable
        Figureapps
        Phases        struct    % struct array of all phase data in memory
        p_meta        table     % full parameter metadata (tex, unit, type, ...)
        p_meta_cyclic table     % cyclic-only subset (used by PhaseParameterEditor)
        p_modes       table     % parameter_controlmodesTab
        nextProcessID = 1       % local ID counter for new phases
    end

    properties (Access = public)
        t = 0 % process time
        last_parameters % variables of last cycle

        p = struct() % Parameter struct
        v = struct() % Variable struct
        v_prop = struct() % Variable struct with properties such as the unit

        % properties for preallocation method
        cntr = 0 % counter for preallocation
        nxtidx % Next index to be filled

        % Databank related properties
        dbpath
        additionalFunctionsFolder = "Additional_functions"

        Refresher % refresh timer
        refreshrate = 2 % sec
        projectInfo
        projectName
        projectID
        projectDescription
        author
        createdon
        recentuse
        organismID
        organismname
        bioreactorID
        bioreactorname
        reservoirs
        texinterpreter = "\fontsize{12}\fontname{Helvetica}";

        processrunning = false % if process is running or not
        logText
        logchanges

        % Process Manager Properties
        numPhases = 0 % Number of phases added
        phaseList = {} % List of active phases
        arrowList = {} % List of arrow buttons
        rightArrowFig = fullfile('figures', 'rightarrow.png') % Arrow figure

        % Databank tables for process automation
        process_status
        process_type
        process_variable
        process_operator
        start_conditiontype
        end_conditiontype

        startupFinished = false % flag that allows parameter uploads after startupFcn has ended

        variableObjectCollection cell % contains all objects and their variables

    end

        properties (Access = public) % additional properties from calculationApp. May be deleted later for unused props
        Startingscreen % Define main app


        Timer
        % List of variables and separated into when to upload
        cyclic_variables table
        end_variables table


        a = struct() % auxiliary parameter struct

        deltat % delta t in h
        real_t string % Real time stamp
        process_running = false

        init_file
        func_file % .m file with organism specific calculation functions
        initialization_file
        calculation_func % function of the func_file
        initialization_func % function for initialization

        process_conditiontype

        % Phase switches
        switch_pulse = false
        switch_exp = false

        currentPhase
        currentReservoir
        numReservoir

        logTable
    end

    methods (Access = public)

        function logMessage(app, event_type, message)
            % Initialize table on first call
            if isempty(app.logTable)
                app.logTable = table( ...
                    'Size', [0, 5], ...
                    'VariableTypes', {'string','string','string','double','string'}, ...
                    'VariableNames', {'datetime','event_type','message','projectID','process_time'});
            end

            newRow = table( ...
                string(datetime("now", "Format", "dd.MM.uuuu HH:mm:ss.SSS")), ...
                string(event_type), ...
                string(message), ...
                app.projectID, ...
                app.v.t(app.nxtidx), ...
                'VariableNames', {'datetime','event_type','message','projectID','process_time'});

            app.logTable = [app.logTable; newRow];
        end

        function ParameterChangedFcn(app, parameter, old_value, new_value)
            % This function documents all parameter changes

            % Documentation in log table
            msg = sprintf("Parameter %s has been changed from %d to %d", parameter, old_value, new_value);
            logMessage(app, "Parameter Value Change", msg)
        end

        function updateLogFcn(app)
            % Function to update the log display
            % It lists all entires from the logTable
            if isempty(app.logTable) || ~istable(app.logTable) || height(app.logTable) == 0
                app.UILogTable.Data = [];
                return;
            end
            if app.IncludeparameterupdatesCheckBox.Value
                app.UILogTable.Data = [app.logTable.datetime, app.logTable.event_type, app.logTable.message];
            else
                mask = app.logTable.event_type ~= "Parameter Value Change";
                app.UILogTable.Data = [app.logTable.datetime(mask, :), app.logTable.event_type(mask, :), app.logTable.message(mask, :)];
            end
        end

        function updateControlOptionsTabFcn(app)
            % Function to update all control option elements
            % pH-Control
            app.ModeDropDown.Value = app.p.Mode_pH;
            app.pH_LEditField.Value = app.v.pHL(app.nxtidx);
            app.AlkaliSwitch.Value = app.p.f_alkali;
            app.AcidSwitch.Value = app.p.f_acid;
            if app.ModeDropDown.Value == 0
                app.pHLamp.Color = 'red';
            else
                app.pHLamp.Color = 'green';
            end

            % Temperature-Control
            app.ModeDropDown_2.Value = app.p.Mode_temp;
            app.theta_wCEditField.Value = app.p.thetaLw;
            app.theta_LCEditField.Value = app.v.thetaL(app.nxtidx);
            app.CoolingSwitch.Value = app.p.f_cooling;
            app.HeatingSwitch.Value = app.p.f_heating;
            if app.ModeDropDown_2.Value == 0
                app.TemperatureLamp.Color = 'red';
            else
                app.TemperatureLamp.Color = 'green';
            end

            % pO2-Control
            app.ModeDropDown_3.Value = app.p.Mode_pO2;
            app.pO_2_wEditField.Value = app.p.pO2w;
            app.pO_2EditField.Value = app.v.pO2(app.nxtidx);
            app.N_StwrpmEditField.Value = app.p.NStw;
            app.F_nGwlminEditField.Value = app.p.FnGw;
            app.F_nAIRwlminEditField.Value = app.p.FnAIRw;
            app.F_nO2wlminEditField.Value = app.p.FnO2w;
            if app.ModeDropDown_3.Value == 0
                app.pO2Lamp.Color = 'red';
            else
                app.pO2Lamp.Color = 'green';
            end

            % Liquid Weight
            app.ModeDropDown_4.Value = app.p.Mode_harvest;
            app.m_L_wkgEditField.Value = app.p.LWw;
            app.m_LkgEditField.Value = app.v.VL(app.nxtidx) * app.p.rhoL;
            app.HarvestSwitch.Value = app.p.f_harvest;
            app.F_HwEditField.Value = app.p.FHrelw;
            if app.ModeDropDown_4.Value == 0
                app.LiquidWeightLamp.Color = 'red';
            else
                app.LiquidWeightLamp.Color = 'green';
            end

            % Level-Control
            app.AntifoamadditionSwitch.Value = app.p.f_antifoam;
            if app.AntifoamadditionSwitch.Value == 0
                app.AntifoamLamp.Color = 'red';
            else
                app.AntifoamLamp.Color = 'green';
            end

            % Feed Control
            app.Feeding_ModeDropDown.Value = app.p.f_feed;

            if app.FeedControlSwitch.Value == 0
                app.FeedControlLamp.Color = 'red';
            else
                app.FeedControlLamp.Color = 'green';
            end

            if app.switch_exp
                app.FeedControlPanel.Enable = "off";
                app.OpenLoopFeedControlActiveLabel.Text = "Open loop feed control active";
            else 
                app.FeedControlPanel.Enable = "on";
                app.OpenLoopFeedControlActiveLabel.Text = "";
            end
        end

        function updateTrendFcn(app)
            % Fill Tree with nodes
            if isempty(app.DisplayableVariablesNode.Children)
                variableNames = fieldnames(app.v_prop);
                for i = 1:numel(variableNames)
                    variable = variableNames{i};
                    variable_unit = sprintf('%s [%s]', variable, app.v_prop.(variable).unit);
                    uitreenode('Text', variable_unit,'Parent',app.DisplayableVariablesNode, 'NodeData',{app.v_prop.(variable).variableID,variable});
                end
                expand(app.DisplayableVariablesNode);
                % Check first 20 nodes by default
                allNodes    = app.DisplayableVariablesNode.Children;
                numToCheck  = min(10, numel(allNodes));
                app.TrendTree.CheckedNodes = allNodes(1:numToCheck);
            end
            checkedNodes = app.TrendTree.CheckedNodes;

            % Check if variables exist
            if app.nxtidx > 1
            if ~isempty(checkedNodes) % Check if nodes are checked
                % Check if Displayable Variables is also checked
                firstNode = checkedNodes(1);
                if firstNode.Text == "Displayable Variables"
                    checkedNodes = checkedNodes(2:end);
                end

                numVariables = length(checkedNodes);
                checkedVariableID = zeros(numVariables,1);
                checkedVariableName = strings(numVariables,1);
                % Determine variableID of checked variables
                for i = 1:numVariables
                    node = checkedNodes(i);
                    datacell = node.NodeData;
                    checkedVariableID(i) = datacell{1};
                    checkedVariableName(i) = datacell{2};
                end

                data_tab = table('Size', [numVariables, 4], 'VariableTypes', {'string', 'double', 'string', 'string'}, 'VariableNames',{'Variable Name','Current Value', 'Unit', 'Trend'});

                time = app.v.t(1:app.nxtidx); % Define time column
                for i = 1:numVariables
                    name = checkedVariableName{i};
                    data = app.v.(name)(1:app.nxtidx);
                    if ~isempty(data)
                        % Volume exception
                        if name == "VL" % Convert volume into mass
                            data = data .* app.p.rhoL;
                        end
                        if length(data) < 10
                            pp = spline(time,data);
                        else
                            pp = spline(time(end-9:end),data(end-9:end)); % Cap to 10 latest values
                        end
                        % Extract the details from the spline, a piece-wise polynomial, by breaking it apart
                        [breaks,coefs,l,k,d] = unmkpp(pp);
                        % Create a new piece-wise polynomial
                        % corresponding to the derivative of the spline
                        pp2 = mkpp(breaks,repmat(k-1:-1:1,d*l,1).*coefs(:,1:k-1),d);
                        val = ppval(pp2,time(end-(length(pp2)-1):end));
                        % If derivative at last entry is not zero, indicate increase or decrease with an arrow
                        if val > 0
                            ind = "⬈";
                        elseif val < 0
                            ind = "⬊";
                        else
                            ind = "➞";
                        end
                        % Convert value to cell type and write the
                        % value and indication with the variable name
                        % to the table
                        data_tab.('Variable Name')(i) = name;
                        data_tab.('Current Value')(i) = data(end);
                        data_tab.('Unit')(i) = app.v_prop.(name).unit;
                        data_tab.('Trend')(i) = ind;

                        app.TrendUITable.Data = data_tab;
                    end
                end
            else
                % Delete data in table if no nodes are selected
                app.TrendUITable.Data = [];

            end
            end
            % Update Liquid Volume Panel
            app.VLlEditField.Value = app.v.VL(app.nxtidx);
            app.addedbaselEditField.Value = app.v.Vbase(app.nxtidx);
            app.addedacidlEditField.Value = app.v.Vacid(app.nxtidx);
            app.addedAFlEditField.Value = app.v.AAF(app.nxtidx);
            if isfield(app.v, "VR1in")
                app.addedfeedR1lEditField.Value = app.v.VR1in(app.nxtidx);
            end
            if isfield(app.v, "VR2in")
                app.addedfeedR2lEditField.Value = app.v.VR2in(app.nxtidx);
            end
            if isfield(app.v, "VR3in")
                app.addedfeedR3lEditField.Value = app.v.VR3in(app.nxtidx);
            end
            % Update Pump Rates Panel
            if isfield(app.v, 'FR1')
                app.FR1EditField.Value = app.v.FR1(app.nxtidx);
            end
            if isfield(app.v, 'FR2')
                app.FR2EditField.Value = app.v.FR2(app.nxtidx);
            end
            if isfield(app.v, 'FR3')
                app.FR3EditField.Value = app.v.FR3(app.nxtidx);
            end
            if isfield(app.v, 'FH')
                app.FHEditField.Value = app.v.FH(app.nxtidx);
            end
            if isfield(app.v, 'FT1')
                app.FT1EditField.Value = app.v.FT2(app.nxtidx);
            end
            if isfield(app.v, 'FT2')
                app.FT2EditField.Value = app.v.FT1(app.nxtidx);
            end
        end
    end

    methods (Access = private)

        function loadProject(app)
            loadProjectInformation(app);
            [parentPath, ~, ~] = fileparts(app.dbpath);
            addpath(fullfile(parentPath, app.func_file));
            addpath(fullfile(parentPath, app.additionalFunctionsFolder));
            app.initialization_func = str2func(app.init_file);
            app.calculation_func    = str2func(app.func_file);
            downloadProjectData(app);
        end


        function loadProjectInformation(app)
            % Load selected project based on project ID
            sqlquery = sprintf(['SELECT p.name, p.description, p.author, p.created_on, p.recent_use, o.name AS Oname, o.organismID, b.name AS Bname, b.bioreactorID, o.initialization_file, o.function_file, o.reservoirs ' ...
                'FROM projectTab p ' ...
                'JOIN organismTab o ON o.organismID = p.organismID ' ...
                'JOIN bioreactorTab b ON b.bioreactorID = p.bioreactorID ' ...
                'WHERE p.projectID = %i'], app.projectID);
            conn = sqlite(app.dbpath, "readonly");
            app.projectInfo = fetch(conn,sqlquery);
            close(conn)
            app.projectName = app.projectInfo.name(1);
            app.projectDescription = app.projectInfo.description(1);
            app.author = app.projectInfo.author(1);
            app.createdon = app.projectInfo.created_on(1);
            app.recentuse = app.projectInfo.recent_use(1);
            app.organismID = app.projectInfo.organismID(1);
            app.organismname = app.projectInfo.Oname(1);
            app.bioreactorID = app.projectInfo.bioreactorID(1);
            app.bioreactorname = app.projectInfo.Bname(1);
            app.init_file = app.projectInfo.initialization_file(1);
            app.func_file = app.projectInfo.function_file(1);
            app.numReservoir = app.projectInfo.reservoirs(1);
        end

        function loadProjectVariables(app, projectID)
            % Retrieve variable list for this organism
            sqlquery = sprintf([ ...
                'SELECT vh.variableID, v.name, v.unit ' ...
                'FROM variableTab v ' ...
                'JOIN variable_handlingTab vh ON vh.variableID = v.variableID ' ...
                'WHERE vh.visible = 1 AND vh.organismID = ' ...
                '(SELECT organismID FROM organismTab WHERE name = ''%s'')'], ...
                app.organismname);
            conn = sqlite(app.dbpath, "readonly");
            T    = fetch(conn, sqlquery);
            close(conn);

            variableID   = T.variableID;
            variableName = T.name;

            % Load all data in long format
            sqlquery = sprintf([ ...
                'SELECT t.process_time, d.variableID, d.value ' ...
                'FROM dataTab d ' ...
                'JOIN timeTab t ON d.timeID = t.timeID ' ...
                'WHERE t.projectID = %g ' ...
                'ORDER BY t.process_time, d.variableID'], projectID);

            conn  = sqlite(app.dbpath, "readonly");
            data  = fetch(conn, sqlquery);
            close(conn);

            if isempty(data)
                app.nxtidx = 1;
                app.v      = [];
                return;
            end

            % Pivot in MATLAB: one column per variable
            timeVec = unique(data.process_time, 'stable');
            nT      = numel(timeVec);
            nV      = numel(variableID);

            % Build lookup: time index
            [~, tIdx] = ismember(data.process_time, timeVec);

            % Build lookup: variable index
            [~, vIdx] = ismember(data.variableID, variableID);

            % Fill value matrix — NaN for missing entries
            valMatrix = NaN(nT, nV);
            for k = 1:height(data)
                if tIdx(k) > 0 && vIdx(k) > 0
                    valMatrix(tIdx(k), vIdx(k)) = data.value(k);
                end
            end

            % Build app.v struct
            temp_v   = struct();
            temp_v.t = timeVec';
            for i = 1:nV
                temp_v.(string(variableName(i))) = valMatrix(:, i)';
            end

            % Ensure all fields are row vectors
            fields = fieldnames(temp_v);
            for k = 1:numel(fields)
                if ~isrow(temp_v.(fields{k}))
                    temp_v.(fields{k}) = temp_v.(fields{k})';
                end
            end

            % Load real timestamps
            conn2 = sqlite(app.dbpath, "readonly");
            tTab  = fetch(conn2, sprintf([ ...
                'SELECT datetime FROM timeTab ' ...
                'WHERE projectID = %g ' ...
                'ORDER BY process_time'], projectID));
            close(conn2);
            if ~isempty(tTab)
                app.real_t = string(tTab.datetime)';
            end

            app.nxtidx = nT;
            app.v      = temp_v;
        end

        function preallocationFcn(app)
            % Preallocation is used to fill the vectors blockwise, since
            % cyclic appending leads to performance issues.
            % the counter will be extended by 1 for every period until 1000 is
            % reached. Then a new block of 1000 will be added.

            extendBy   = 20;

            if app.cntr == 0 || app.cntr >= extendBy
                app.cntr   = 1;
                fieldNames = fieldnames(app.v);

                for i = 1:numel(fieldNames)
                    field       = fieldNames{i};
                    currentData = app.v.(field);

                    % Ensure row vector before appending NaN block
                    if ~isrow(currentData)
                        currentData = currentData';
                    end

                    app.v.(field) = [currentData, NaN(1, extendBy)];
                end
            else
                app.cntr = app.cntr + 1;
            end
        end

        function downloadProjectData(app)
            % Load variable data (app.v)
            loadProjectVariables(app, app.projectID);

            % Check if project was previously run
            if ~isempty(app.v) && app.nxtidx > 1
                app.StartSimulationButton.Text = "Run";
                if app.p.f_InocStart == 1
                    app.InoculateButton.Value  = 0;
                    app.InoculateButton.Enable = "off";
                end
                disp("Project loaded.")
            else
                % First start — initialize variable struct with zeros
                app.v.t = 0;
                sqlquery = sprintf([ ...
                    'SELECT vh.variableID, v.name ' ...
                    'FROM variableTab v ' ...
                    'JOIN variable_handlingTab vh ON vh.variableID = v.variableID ' ...
                    'WHERE vh.visible = 1 AND vh.organismID = ' ...
                    '(SELECT organismID FROM organismTab WHERE name = ''%s'')'], ...
                    app.organismname);
                conn = sqlite(app.dbpath, "readonly");
                T    = fetch(conn, sqlquery);
                close(conn);
                variables = table2array(T(:, 2));
                for i = 1:length(variables)
                    app.v.(variables(i)) = 0;
                end
            end

            % Populate v_prop — needed by updateTrendFcn for Tree nodes and units
            sqlquery = sprintf(['SELECT v.variableID, v.name, v.shorttex, v.longtex, v.unit, v.tex_unit ' ...
                'FROM variable_handlingTab vh ' ...
                'JOIN variableTab v ON v.variableID = vh.variableID ' ...
                'WHERE vh.visible = 1 AND vh.organismID = (SELECT organismID FROM organismTab WHERE name = ''%s'')'], ...
                app.organismname);
            conn = sqlite(app.dbpath, "readonly");
            T = fetch(conn, sqlquery);
            close(conn);
            headers = T.Properties.VariableNames;
            for i = 1:size(T,1)
                for n = 1:length(headers)
                    header = headers{n};
                    variableName = T.name(i);
                    app.v_prop.(variableName).(header) = T.(header)(i);
                end
            end

            % Run organism-specific initialization (needs app.p — loaded by loadPhases)
            app = app.initialization_func(app);

            % Convert delta t from seconds to hours
            app.a.deltat = app.p.deltatsec / 3600;
            preallocationFcn(app);

            % Fill ControlOptionsTab setpoint fields and UI elements
            fillObjects(app);
        end

        function fillObjects(app)
            % Define ItemsData here as double scalars, since defining the
            % ItemsData in the Appdesigner UI results in them becoming
            % character vectors
            app.AntifoamadditionSwitch.ItemsData = [0,1];
            app.AlkaliSwitch.ItemsData   = [0,1];
            app.AcidSwitch.ItemsData     = [0,1];
            app.CoolingSwitch.ItemsData  = [0,1];
            app.HeatingSwitch.ItemsData  = [0,1];

            % Initialize lamp colors for active and unactive controls
            app.AntifoamLamp.Color     = "green";
            app.pHLamp.Color           = "green";
            app.TemperatureLamp.Color  = "green";
            app.LiquidWeightLamp.Color = "red";
            app.pO2Lamp.Color          = "red";

            % Modes
            app.ModeDropDown.Value      = app.p.Mode_pH;
            %ModeDropDownValueChanged(app)

            app.ModeDropDown_2.Value    = app.p.Mode_temp;
            %ModeDropDown_2ValueChanged(app)

            app.ModeDropDown_3.Value     = app.p.Mode_pO2;
            %app.ModeDropDown_3ValueChanged(app)

            app.ModeDropDown_4.Value = app.p.Mode_harvest;
            %app.ModeDropDown_4ValueChanged(app)

            app.Feeding_ModeDropDown.Value = app.p.Mode_feed;
            %app.Feeding_ModeDropDownValueChanged(app)

            n = num2str(app.p.R_feed);

            % Display initial values and setpoints upon control app startup
            app.N_StwrpmEditField.Value    = app.p.NStw;
            app.F_RwlhEditField.Value      = app.p.(['FR' n 'w']);
            app.F_R1maxlh1EditField.Value = app.p.(['FR' n 'max']);
            app.c_S1Lwgl1EditField.Value = app.p.(['cS' n 'Lw']);
            app.F_HwEditField.Value        = app.p.FHrelw;
            app.F_nAIRwlminEditField.Value = app.p.FnAIRw;
            app.F_nO2wlminEditField.Value  = app.p.FnO2w;
            app.theta_wCEditField.Value   = app.p.thetaLw;
            app.pH_wEditField.Value        = app.p.pHw;
            app.pO_2_wEditField.Value       = app.p.pO2w;
            app.m_L_wkgEditField.Value      = app.p.LWw;
            app.F_nGwlminEditField.Value   = app.p.FnGw;
            app.DeltatsEditField.Value    = app.p.deltatsec;

            % Show trend reservoir volume
            if app.reservoirs >= 2
                app.addedfeedR2lEditField.Visible = "on";
                app.addedfeedR2lEditFieldLabel.Visible = "on";
                app.FR2EditField.Visible = "on";
                app.fontsize10fontnameHelveticaF_R2lh1EditFieldLabel.Visible = "on";
            end
            if app.reservoirs >= 3
                app.addedfeedR3lEditField.Visible = "on";
                app.addedfeedR3lEditFieldLabel.Visible = "on";
                app.FR3EditField.Visible = "on";
                app.fontsize10fontnameHelveticaF_R3lh1EditFieldLabel.Visible = "on";
            end

            % Set up column descriptions upon startup
            app.TrendUITable.ColumnName = {'Variable Name','Current Value','Unit' ,'Trend'};
        end

        function timerFcn(app,varargin)
            try
                % Refresh tabs only if they are opened
                if app.TabGroup.SelectedTab == app.VariablePoolTab
                    updateTrendFcn(app) % Trend
                elseif app.TabGroup.SelectedTab == app.LogTab
                    updateLogFcn(app) % Log
                elseif app.TabGroup.SelectedTab == app.ControlOptionsTab
                    updateControlOptionsTabFcn(app) % Control options
                end
                % Add here cyclic execution of calculation fcn
                if app.processrunning
                    calculationFcn(app)
                    % Show current process time
                    app.ProcessTimehEditField.Value = app.v.t(app.nxtidx);
                    app.DeltatsEditField.Value = app.p.deltatsec;
                    app.SpeedfactorxSpinner.Value = app.p.speedfactor;
                end
                % Refresh all figure windows
                figureRefresh(app)
                % Refresh all data table windows
                DatatableRefresh(app)
            catch ME
                stop(app.Refresher)
                app.DisconnectMenu.Text = "Reconnect";
                app.ReconnectButton.Visible = "on";
                disp(getReport(ME));
            end
        end
    
        function figureRefresh(app)
            for i = 1:length(app.Figureapps)
                figureapp = app.Figureapps{1,i};
                if isvalid(figureapp)
                    figureapp.AutoUpdateFcn;
                end
            end

        end

        function DatatableRefresh(app)
            if ~isempty(app.Datatable)
                for i = 1:length(app.Datatable)
                    Datatableapp = app.Datatable{i};
                    if isvalid(Datatableapp)
                        if Datatableapp.autoupdate
                            Datatableapp.fillTable;
                        end
                    end
                end
            end

        end


        function addPhase(app, ~)
            app.numPhases = app.numPhases + 1;

            % Build new phase struct
            ph = struct();
            ph.processID      = app.nextProcessID;
            app.nextProcessID = app.nextProcessID + 1;
            ph.projectID      = app.projectID;
            ph.statusID       = 1;   % Upcoming
            ph.typeID         = 1;   % Blank
            ph.name           = "Empty Phase";
            ph.reservoirID    = 1;

            ph.start.typeID     = 1;   % Variable condition
            ph.start.variableID = 1;
            ph.start.operatorID = 1;
            ph.start.value      = 0;
            ph.start.time       = NaN;

            ph.end.typeID     = 5;   % Next phase condition
            ph.end.variableID = NaN;
            ph.end.operatorID = NaN;
            ph.end.value      = 0;
            ph.end.time       = NaN;

            ph.parameters = struct();

            if isempty(app.Phases) || ~isstruct(app.Phases) || numel(app.Phases) == 0
                app.Phases = ph;
            else
                app.Phases(end+1) = ph;
            end

            % Rebuild columns first so new panel/arrow have valid columns to land in
            rebuildGridColumns(app);

            % Create arrow between previous and new phase
            if app.numPhases > 1
                createArrowButton(app);
            end

            % Create the new phase panel
            createPhasePanel(app);

            % Rebuild columns again now that panel and arrow exist
            rebuildGridColumns(app);

            updatePhasePanels(app);
        end


        function deletePhase(app, ~, phaseToDelete)
            colToDelete = phaseToDelete.Layout.Column;
            phaseNum    = (colToDelete + 1) / 2;

            % Delete the arrow associated with this phase:
            % - Phase 1: delete arrow to the right (arrowList{1})
            % - Phase i>1: delete arrow to the left (arrowList{phaseNum-1})
            if phaseNum == 1 && numel(app.arrowList) >= 1 && ~isempty(app.arrowList{1})
                delete(app.arrowList{1});
                app.arrowList(1) = [];
            elseif phaseNum > 1 && phaseNum-1 <= numel(app.arrowList) && ~isempty(app.arrowList{phaseNum-1})
                delete(app.arrowList{phaseNum-1});
                app.arrowList(phaseNum-1) = [];
            end

            % Delete panel and update tracking lists
            delete(phaseToDelete);
            app.phaseList(phaseNum) = [];
            app.Phases(phaseNum)    = [];
            app.numPhases           = app.numPhases - 1;

            % Update panel titles and UserData
            for i = 1:app.numPhases
                app.phaseList{i}.Title    = sprintf('Phase %d', i);
                app.phaseList{i}.UserData = i;
            end

            % Update arrow UserData
            for i = 1:numel(app.arrowList)
                if ~isempty(app.arrowList{i}) && isvalid(app.arrowList{i})
                    app.arrowList{i}.UserData = i + 1;
                end
            end

            % Rebuild columns to match new phase count
            rebuildGridColumns(app);

            if app.numPhases > 0
                updatePhasePanels(app);
            end
        end

        function rebuildGridColumns(app)
            n = app.numPhases;
            if n == 0
                % Only AddPhaseButton remains in column 1
                app.ProcessManagerGridLayout.ColumnWidth = {100};
                app.AddPhaseButton.Layout.Column = 1;
                return;
            end

            % Build column width array: panel, arrow, panel, arrow, ..., AddButton
            totalCols = n * 2;  % n panels + (n-1) arrows + 1 AddButton slot = 2n cols
            widths = cell(1, totalCols);
            for i = 1:n
                widths{i*2 - 1} = 210;   % panel column
                if i < n
                    widths{i*2} = 75;    % arrow column
                else
                    widths{i*2} = 100;   % AddPhaseButton column (last)
                end
            end
            app.ProcessManagerGridLayout.ColumnWidth = widths;

            % Reposition all panels to correct columns
            for i = 1:n
                if i <= numel(app.phaseList) && ~isempty(app.phaseList{i})
                    app.phaseList{i}.Layout.Column = i*2 - 1;
                end
            end

            % Reposition all arrows to correct columns
            for i = 1:numel(app.arrowList)
                if ~isempty(app.arrowList{i}) && isvalid(app.arrowList{i})
                    app.arrowList{i}.Layout.Column = i*2;  % between panel i and i+1
                end
            end

            % Reposition AddPhaseButton to last column
            app.AddPhaseButton.Layout.Column = n*2;
        end

        function createPhasePanel(app)
            col = app.numPhases * 2 - 1;  % correct column for this phase

            panel = uipanel(app.ProcessManagerGridLayout);
            panel.Layout.Row    = [2, 4];
            panel.Layout.Column = col;
            panel.Title         = sprintf('Phase %d', app.numPhases);
            panel.TitlePosition = 'centertop';
            panel.FontSize      = 14;
            panel.FontWeight    = 'bold';
            panel.UserData      = app.numPhases;
            app.phaseList{app.numPhases} = panel;

            layout = uigridlayout(panel);
            layout.ColumnWidth     = {"1x","1x",25,"1x","1x"};
            layout.RowHeight       = {30,30,"1x",25,25,25,"1x",15,25};
            layout.ColumnSpacing   = 10;
            layout.RowSpacing      = 10;
            layout.BackgroundColor = [1,1,1];
            layout.Padding         = [10,10,10,10];

            % Row 1 — name label
            namelabel = uilabel(layout);
            namelabel.Tag                 = 'namelabel';
            namelabel.Text                = "Empty Phase";
            namelabel.Layout.Column       = [1,5];
            namelabel.Layout.Row          = 1;
            namelabel.FontWeight          = 'bold';
            namelabel.FontSize            = 16;
            namelabel.HorizontalAlignment = 'center';

            % Row 2-3 — type label
            typelabel = uilabel(layout);
            typelabel.Tag                 = 'typelabel';
            typelabel.Text                = "Blank";
            typelabel.Layout.Column       = [1,5];
            typelabel.Layout.Row          = [2,3];
            typelabel.HorizontalAlignment = 'center';

            % Row 4 — start
            startlabel = uilabel(layout);
            startlabel.Layout.Column = 1;
            startlabel.Layout.Row    = 4;
            startlabel.Text          = "Start:";

            startcondlabel = uilabel(layout);
            startcondlabel.Tag           = 'startcond';
            startcondlabel.Layout.Column = [2,5];
            startcondlabel.Layout.Row    = 4;
            startcondlabel.Text          = "-";

            % Row 5 — end
            endlabel = uilabel(layout);
            endlabel.Layout.Column = 1;
            endlabel.Layout.Row    = 5;
            endlabel.Text          = "End:";

            endcondlabel = uilabel(layout);
            endcondlabel.Tag           = 'endcond';
            endcondlabel.Layout.Column = [2,5];
            endcondlabel.Layout.Row    = 5;
            endcondlabel.Text          = "-";

            % Row 7 — edit button
            editbutton = uibutton(layout);
            editbutton.Layout.Column   = [2,4];
            editbutton.Layout.Row      = 7;
            editbutton.Text            = "Edit";
            editbutton.ButtonPushedFcn = @(src,event) app.EditPhase(src,event);

            % Row 8 — status lamp
            statuslamp = uilamp(layout);
            statuslamp.Tag           = 'statuslamp';
            statuslamp.Layout.Column = [2,4];
            statuslamp.Layout.Row    = 8;
            statuslamp.Color         = [0.5,0.5,0.5];
            if ~isempty(app.process_status)
                statuslamp.Tooltip = app.process_status.status(1);
            end

            % Row 9 — delete button
            deletebutton = uibutton(layout, 'push', 'Text', 'x');
            deletebutton.ButtonPushedFcn = @(btn,event) app.deletePhase(btn, panel);
            deletebutton.Layout.Row      = numel(layout.RowHeight);
            deletebutton.Layout.Column   = 3;
        end

        function EditPhase(app, src, ~)
            layout   = src.Parent;
            panel    = layout.Parent;
            phaseNum = panel.UserData;
            editor   = PhasePanelEditor(app, phaseNum);
            waitfor(editor);
            % Set first phase to pending if all are still upcoming
            if ~isempty(app.Phases) && all([app.Phases.statusID] == 1)
                app.Phases(1).statusID = 2;
            end
            updatePhasePanels(app);
        end

        function updatePhasePanels(app)
            if isempty(app.Phases) || ~isstruct(app.Phases); return; end

            for i = 1:numel(app.Phases)
                if i > numel(app.phaseList) || isempty(app.phaseList{i}); continue; end

                ph     = app.Phases(i);
                panel  = app.phaseList{i};
                layout = panel.Children;
                obj    = layout.Children;

                % Access by Tag when available, fall back to index
                namelabel      = findobj(layout, 'Tag', 'namelabel');
                typelabel      = findobj(layout, 'Tag', 'typelabel');
                startcondlabel = findobj(layout, 'Tag', 'startcond');
                endcondlabel   = findobj(layout, 'Tag', 'endcond');
                statuslamp     = findobj(layout, 'Tag', 'statuslamp');

                if isempty(namelabel);      namelabel      = obj(1); end
                if isempty(typelabel);      typelabel      = obj(2); end
                if isempty(startcondlabel); startcondlabel = obj(4); end
                if isempty(endcondlabel);   endcondlabel   = obj(6); end
                if isempty(statuslamp);     statuslamp     = obj(8); end

                % Name and type
                namelabel.Text = ph.name;
                typelabel.Text = app.process_type.type(ph.typeID);

                % Start condition text
                switch ph.start.typeID
                    case 1
                        v_idx = find(ph.start.variableID == app.process_variable.variableID, 1);
                        o_idx = find(ph.start.operatorID == app.process_operator.process_operatorID, 1);
                        if ~isempty(v_idx) && ~isempty(o_idx)
                            start_text = sprintf('%s %s %.2f %s', ...
                                app.process_variable.shorttex(v_idx), ...
                                app.process_operator.condition(o_idx), ...
                                ph.start.value, ...
                                app.process_variable.tex_unit(v_idx));
                        else
                            start_text = "-";
                        end
                    case 2; start_text = app.start_conditiontype.conditiontype(2);
                    case 3; start_text = app.start_conditiontype.conditiontype(3);
                    otherwise; start_text = "-";
                end
                startcondlabel.Text = append(app.texinterpreter, "\fontsize{9}", start_text);
                startcondlabel.Interpreter = 'tex';

                % End condition text
                switch ph.end.typeID
                    case 5
                        end_text = app.end_conditiontype.conditiontype(1);
                    case 6
                        v_idx = find(ph.end.variableID == app.process_variable.variableID, 1);
                        o_idx = find(ph.end.operatorID == app.process_operator.process_operatorID, 1);
                        if ~isempty(v_idx) && ~isempty(o_idx)
                            end_text = sprintf('%s %s %.2f %s', ...
                                app.process_variable.shorttex(v_idx), ...
                                app.process_operator.condition(o_idx), ...
                                ph.end.value, ...
                                app.process_variable.tex_unit(v_idx));
                        else
                            end_text = "-";
                        end
                    case 7
                        if ismember(ph.statusID, [3,4]) && ~isnan(ph.end.time)
                            end_text = sprintf("t = %.2f h (%.2f h Timer)", ph.end.time, ph.end.value);
                        else
                            end_text = sprintf("Timer (%.2f h)", ph.end.value);
                        end
                    otherwise; end_text = "-";
                end
                endcondlabel.Text = append(app.texinterpreter, "\fontsize{9}", end_text);
                endcondlabel.Interpreter = 'tex';

                % Status lamp
                colors = {[0.5,0.5,0.5], [1,0.7,0.1], 'green', 'red'};
                if ph.statusID >= 1 && ph.statusID <= 4
                    statuslamp.Color   = colors{ph.statusID};
                    statuslamp.Tooltip = app.process_status.status(ph.statusID);
                end

                % Panel tooltip with timestamp
                if ph.statusID == 2 && ~isnan(ph.start.time)
                    panel.Tooltip = sprintf("Start: t = %.3f h", ph.start.time);
                elseif ph.statusID == 3 && ~isnan(ph.end.time)
                    panel.Tooltip = sprintf("End: t = %.3f h", ph.end.time);
                else
                    panel.Tooltip = "";
                end
            end
        end

        function arrowButtonPushed(app, src, ~)
                i = src.UserData;
                app.Phases(i).statusID   = 2;
                app.Phases(i-1).statusID = 4;
                src.Enable = 'off';
                src.Tooltip = "";
                % Activate next arrow
                if i ~= numel(app.arrowList)
                    nextArrow = app.arrowList{i+1};
                    nextArrow.Enable = 'on';
                    nextArrow.Tooltip = "Force start next phase";
                    nextArrow.ButtonPushedFcn = @(src, event) arrowButtonPushed(app, src, event);
                end
                updatePhasePanels(app)
            end

            function createArrowButton(app)
                arrowButton = uibutton(app.ProcessManagerGridLayout);
                arrowButton.Text           = "";
                arrowButton.Tooltip        = "";
                arrowButton.Layout.Row     = 3;
                arrowButton.Layout.Column  = (app.numPhases - 1) * 2;  % between phase n-1 and n
                arrowButton.Icon           = app.rightArrowFig;
                arrowButton.IconAlignment  = 'center';
                arrowButton.Enable         = 'off';
                arrowButton.UserData       = app.numPhases;  % points to phase it activates
                app.arrowList{app.numPhases - 1} = arrowButton;
                % Note: arrowList index i = arrow between phase i and phase i+1
            end

            function disablePhaseObjects(app, mode)
                if ~isempty(app.phaseList)
                    for i = 1:numel(app.phaseList)
                        panel  = app.phaseList{i};
                        layout = panel.Children;

                        editbutton   = findobj(layout, 'Text', 'Edit');
                        deletebutton = findobj(layout, 'Text', 'x');

                        % Keep locked for active or ended phases
                        if ismember(app.Phases(i).statusID, [3,4])
                            editbutton.Enable   = 'off';
                            deletebutton.Enable = 'off';
                        else
                            editbutton.Enable   = mode;
                            deletebutton.Enable = mode;
                        end

                        % Arrow i sits between phase i and i+1 — only exists if i < numPhases
                        if i < numel(app.phaseList) && i <= numel(app.arrowList) ...
                                && ~isempty(app.arrowList{i}) && isvalid(app.arrowList{i})
                            arrow = app.arrowList{i};
                            if mode == "on"
                                prevStatus = app.Phases(i).statusID;
                                nextStatus = app.Phases(i+1).statusID;
                                if ismember(prevStatus, [2,3]) && nextStatus == 1
                                    arrow.Enable          = 'on';
                                    arrow.Tooltip         = "Force start next phase";
                                    arrow.ButtonPushedFcn = @(src,event) arrowButtonPushed(app,src,event);
                                else
                                    arrow.Enable          = 'off';
                                    arrow.Tooltip         = "";
                                    arrow.ButtonPushedFcn = [];
                                end
                            else
                                arrow.Enable = 'off';
                            end
                        end
                    end
                end

                % Enable/disable AddPhaseButton
                phasebutton        = app.ProcessManagerGridLayout.Children(1);
                phasebutton.Enable = mode;
            end
            
    end

    methods (Access = public)

        function loadPhases(app)
            % Single DB load at session start.
            % Loads processTab + process_parameterTab + all parameter metadata
            % and builds app.Phases(i) struct array in memory.


            % Helper: safely convert SQLite values (strings, cells) to double / NaN
            function val = safeNum(raw)
                if iscell(raw);    raw = raw{1}; end
                if isnumeric(raw); val = double(raw); return; end
                if isstring(raw) || ischar(raw)
                    val = str2double(raw);  % returns NaN for empty/invalid
                else
                    val = NaN;
                end
            end

            conn = sqlite(app.dbpath, "readonly");

            % -- Lookup tables -------------------------------------------
            app.process_status   = fetch(conn, "SELECT * FROM process_statusTab");
            app.process_type     = fetch(conn, "SELECT * FROM process_typeTab");
            app.process_variable = fetch(conn, [ ...
                'SELECT pv.process_variableID, pv.variableID, v.name, v.shorttex, v.tex_unit ' ...
                'FROM process_variableTab pv ' ...
                'JOIN variableTab v ON v.variableID = pv.variableID']);
            app.process_operator    = fetch(conn, "SELECT * FROM process_operatorTab");
            app.start_conditiontype = fetch(conn, ...
                "SELECT * FROM process_conditiontypeTab WHERE start_end = 1");
            app.end_conditiontype   = fetch(conn, ...
                "SELECT * FROM process_conditiontypeTab WHERE start_end = 2");

            % -- Project parameters: values (app.p) + metadata (app.p_meta) --
            sqlquery = sprintf([ ...
                'SELECT prop.parameterID, prop.name AS parametername, prop.tex, ' ...
                'ppara.value, prop.type, prop.unit, ppara.description, ' ...
                'c.name AS categoryname, c.section AS categorysection, ' ...
                'c.reading_rate ' ...
                'FROM project_parameterTab ppara ' ...
                'JOIN parameterTab prop ON ppara.parameterID = prop.parameterID ' ...
                'JOIN categoryTab c ON prop.categoryID = c.categoryID ' ...
                'WHERE ppara.projectID = %i ' ...
                'ORDER BY c.section, c.name, prop.internal_order'], app.projectID);

            paramAll = fetch(conn, sqlquery);

            if ~isempty(paramAll)
                % Values into app.p
                temp_p = struct();
                for i = 1:height(paramAll)
                    temp_p.(string(paramAll.parametername(i))) = paramAll.value(i);
                end
                app.p = temp_p;

                % Full metadata into app.p_meta
                app.p_meta = paramAll;

                % Cyclic parameters only — used by PhaseParameterEditor as app.T
                isCyclic          = string(paramAll.reading_rate) == "cyclic";
                app.p_meta_cyclic = paramAll(isCyclic, :);
            else
                app.p             = [];
                app.p_meta        = table();
                app.p_meta_cyclic = table();
            end

            % -- Control mode table (for dropdowns in PhaseParameterEditor) --
            app.p_modes = fetch(conn, [ ...
                'SELECT p.name AS parametername, cm.value, cm.mode ' ...
                'FROM parameter_controlmodesTab cm ' ...
                'JOIN parameterTab p ON p.parameterID = cm.parameterID']);

            % -- Load phase data ------------------------------------------
            sqlquery = sprintf([ ...
                'SELECT processID, projectID, process_statusID, process_typeID, name, ' ...
                'start_typeID, start_variableID, start_operatorID, start_value, start_time, ' ...
                'end_typeID, end_variableID, end_operatorID, end_value, end_time, reservoirID ' ...
                'FROM processTab ' ...
                'WHERE projectID = %i ' ...
                'ORDER BY processID'], app.projectID);

            T = fetch(conn, sqlquery);

            % -- Load phase-specific parameters (all at once) -------------
            sqlquery = sprintf([ ...
                'SELECT pp.processID, pp.parameterID, p.name, pp.value ' ...
                'FROM process_parameterTab pp ' ...
                'JOIN parameterTab p ON p.parameterID = pp.parameterID ' ...
                'WHERE pp.processID IN ' ...
                '(SELECT processID FROM processTab WHERE projectID = %i)'], ...
                app.projectID);

            paramTab = fetch(conn, sqlquery);
            close(conn);  % Single close — connection released

            % -- Build struct array ---------------------------------------
            app.Phases = struct([]);

            if isempty(T)
                app.nextProcessID = 1;
                return;
            end

            for i = 1:height(T)
                ph = struct();

                ph.processID   = T.processID(i);
                ph.projectID   = T.projectID(i);
                ph.statusID    = T.process_statusID(i);
                ph.typeID      = T.process_typeID(i);
                ph.name        = string(T.name(i));
                ph.reservoirID = safeNum(T.reservoirID(i));

                ph.start.typeID     = T.start_typeID(i);
                ph.start.variableID = safeNum(T.start_variableID(i));
                ph.start.operatorID = safeNum(T.start_operatorID(i));
                ph.start.value      = safeNum(T.start_value(i));
                ph.start.time       = safeNum(T.start_time(i));   % NaN if not yet started

                ph.end.typeID     = T.end_typeID(i);
                ph.end.variableID = safeNum(T.end_variableID(i));
                ph.end.operatorID = safeNum(T.end_operatorID(i));
                ph.end.value      = safeNum(T.end_value(i));
                ph.end.time       = safeNum(T.end_time(i));       % NaN if not yet ended

                % Phase-specific parameters from process_parameterTab
                ph.parameters = struct();
                if ~isempty(paramTab)
                    mask = paramTab.processID == ph.processID;
                    if any(mask)
                        names  = string(paramTab.name(mask));
                        values = paramTab.value(mask);
                        for k = 1:numel(names)
                            ph.parameters.(names(k)) = values(k);
                        end
                    end
                end

                app.Phases(i) = ph;
            end

            % Local ID counter for new phases — no DB access needed
            app.nextProcessID = max([app.Phases.processID]) + 1;
        end


        function savePhases(app)
            % Single DB write at session end.
            % Writes all of app.Phases into processTab + process_parameterTab.

            % SQL null/value helpers
            function s = nullStr(val)
                if isnan(val); s = 'NULL'; else; s = sprintf('%g', val); end
            end
            function s = nullOrInt(val)
                if isnan(val) || val == 0; s = 'NULL'; else; s = sprintf('%i', round(val)); end
            end
            function s = nanOrZero(val)
                if isnan(val); s = '0'; else; s = sprintf('%g', val); end
            end

            if isempty(app.Phases) || ~isstruct(app.Phases)
                return;
            end

            conn = sqlite(app.dbpath, "connect");
            exec(conn, "PRAGMA foreign_keys = ON");
            exec(conn, "BEGIN TRANSACTION");

            try
                % Remove old phase data (CASCADE deletes process_parameterTab)
                exec(conn, sprintf("DELETE FROM processTab WHERE projectID = %i", app.projectID));

                for i = 1:numel(app.Phases)
                    ph = app.Phases(i);

                    % Insert processTab row
                    sqlquery = sprintf([ ...
                        'INSERT INTO processTab ' ...
                        '(processID, projectID, process_statusID, process_typeID, name, ' ...
                        'start_typeID, start_variableID, start_operatorID, start_value, start_time, ' ...
                        'end_typeID, end_variableID, end_operatorID, end_value, end_time, reservoirID) ' ...
                        'VALUES (%i, %i, %i, %i, ''%s'', ' ...
                        '%i, %s, %s, %g, %s, ' ...
                        '%i, %s, %s, %g, %s, %s)'], ...
                        ph.processID, ph.projectID, ph.statusID, ph.typeID, ph.name, ...
                        ph.start.typeID, ...
                        nullOrInt(ph.start.variableID), ...
                        nullOrInt(ph.start.operatorID), ...
                        nanOrZero(ph.start.value), ...
                        nullStr(ph.start.time), ...
                        ph.end.typeID, ...
                        nullOrInt(ph.end.variableID), ...
                        nullOrInt(ph.end.operatorID), ...
                        nanOrZero(ph.end.value), ...
                        nullStr(ph.end.time), ...
                        nullOrInt(ph.reservoirID));

                    exec(conn, sqlquery);

                    % Insert process_parameterTab rows
                    if ~isempty(fieldnames(ph.parameters))
                        paramNames = fieldnames(ph.parameters);
                        for k = 1:numel(paramNames)
                            pname = paramNames{k};
                            pval  = ph.parameters.(pname);
                            sqlquery = sprintf([ ...
                                'INSERT INTO process_parameterTab (processID, parameterID, value) ' ...
                                'VALUES (%i, ' ...
                                '(SELECT parameterID FROM parameterTab WHERE name = ''%s''), ' ...
                                '%g)'], ph.processID, pname, pval);
                            exec(conn, sqlquery);
                        end
                    end
                end

                exec(conn, "COMMIT");

            catch ME
                exec(conn, "ROLLBACK");
                fprintf('[savePhases] Error: %s\n', ME.message);
            end

            close(conn);
        end


        %% Data Export Section

        function exportData(app, filetype)
            % Create table from variable data
            T = table();
            T.("time in h") = (app.v.t(1:app.nxtidx))';
            selection = fieldnames(app.v);
            for i = 2:numel(selection)
                variableName = selection{i};
                if any(strcmp(fieldnames(app.v_prop), variableName))
                    headerName = append(variableName, " in ", app.v_prop.(variableName).unit);
                    data = round2sig(app,app.v.(variableName)(1:app.nxtidx),app.StartingApp.sigfig); % 6 significant positions
                    T.(headerName) = data';
                end
            end
            % Default file name
            defaultFileName = append(app.projectName, filetype);
            switch filetype
                case '.xlsx'
                    input = {'*.xlsx', 'Excel Files (*.xlsx)'; ...
                        '*.txt', 'Text Files (*.txt)'; ...
                        '*.csv', 'CSV Files (*.csv)'; ...
                        '*.*', 'All Files (*.*)'};
                case '.csv'
                    input = {'*.csv', 'CSV Files (*.csv)'; ...
                        '*.xlsx', 'Excel Files (*.xlsx)'; ...
                        '*.txt', 'Text Files (*.txt)'; ...
                        '*.*', 'All Files (*.*)'};
                case '.txt'
                    input = {'*.txt', 'Text Files (*.txt)'; ...
                        '*.xlsx', 'Excel Files (*.xlsx)'; ...
                        '*.csv', 'CSV Files (*.csv)'; ...
                        '*.*', 'All Files (*.*)'};
                otherwise
                    return
            end
            [file, path] = uiputfile(input,'Save as', defaultFileName);

            % Check if user selected a file
            if isequal(file, 0)
                disp('User selected Cancel');
            else
                fullFileName = fullfile(path, file);
                disp(['User selected ', fullFileName]);

                % Dateierweiterung extrahieren
                [~, ~, ext] = fileparts(fullFileName);
                try
                    % Tabelle basierend auf der Dateierweiterung speichern
                    switch ext
                        case '.txt'
                            % Speichern als Textdatei
                            writetable(T, fullFileName, 'FileType', 'text');
                        case '.csv'
                            % Speichern als CSV-Datei
                            writetable(T, fullFileName);
                        case '.xlsx'
                            % Speichern als Excel-Datei
                            writetable(T, fullFileName, 'FileType', 'spreadsheet');
                        otherwise
                            msgbox('Unsupported file type',"Error","error");
                    end
                    disp('File saved.');
                catch ME
                    msgbox(ME.message,"Error","error");
                end
            end
        end

        function y = round2sig(~, x, n)
            % Vectorized — works on arrays of any size
            y           = x;
            valid       = x ~= 0 & isfinite(x);
            magnitude   = floor(log10(abs(x(valid))));
            y(valid)    = round(x(valid), n - magnitude - 1);
        end

        function saveProject(app)
            % Saves parameters, variable data and phases to the database.
            % Called once at session end.

            conn = sqlite(app.dbpath, "connect");

            try
                % ── 1. Parameter update ──────────────────────────────────
                old_p = fetch(conn, sprintf([ ...
                    'SELECT p.parameterID, p.name, pp.value ' ...
                    'FROM project_parameterTab pp ' ...
                    'JOIN parameterTab p ON p.parameterID = pp.parameterID ' ...
                    'WHERE pp.projectID = %i'], app.projectID));

                for i = 1:height(old_p)
                    pname = string(old_p.name(i));
                    if isfield(app.p, pname) && app.p.(pname) ~= old_p.value(i)
                        exec(conn, sprintf([ ...
                            'UPDATE project_parameterTab SET value = %g ' ...
                            'WHERE parameterID = %i AND projectID = %i'], ...
                            app.p.(pname), old_p.parameterID(i), app.projectID));
                    end
                end

                % ── 2. Variable data ─────────────────────────────────────
                if ~isempty(app.v) && isfield(app.v, 't') && app.nxtidx > 1

                    % COUNT(*) is always a number — never NULL unlike MAX()
                    res    = fetch(conn, sprintf( ...
                        'SELECT COUNT(*) AS n FROM timeTab WHERE projectID = %i', ...
                        app.projectID));
                    nSaved = double(res.n);

                    % First index not yet saved
                    if nSaved == 0
                        timeIdx = 1;
                    else
                        timeIdx = nSaved + 1;
                    end

                    if timeIdx <= app.nxtidx

                        % Remove NaN preallocation slots
                        tRange    = timeIdx:app.nxtidx;
                        procTimes = app.v.t(tRange);
                        realTimes = app.real_t(tRange);
                        validMask = ~isnan(procTimes);
                        tRange    = tRange(validMask);
                        procTimes = procTimes(validMask);
                        realTimes = realTimes(validMask);

                        % Replace missing timestamps with current time
                        emptyMask = ismissing(realTimes) | realTimes == "";
                        if any(emptyMask)
                            fallback              = string(datetime("now", ...
                                'Format', 'dd.MM.yyyy HH:mm:ss.SSS'));
                            realTimes(emptyMask)  = fallback;
                        end

                        if ~isempty(procTimes)

                            conn.AutoCommit = "off";
                            exec(conn, "BEGIN TRANSACTION");

                            % Write time entries
                            timeTable = table( ...
                                repmat(app.projectID, numel(tRange), 1), ...
                                realTimes(:), ...
                                procTimes(:), ...
                                'VariableNames', {'projectID','datetime','process_time'});
                            sqlwrite(conn, 'timeTab', timeTable);

                            % Get inserted timeIDs
                            lastIDres = fetch(conn, 'SELECT last_insert_rowid()');
                            lastID    = double(lastIDres{1,1});
                            nTimes    = numel(tRange);
                            timeIDs   = (lastID - nTimes + 1 : lastID)';

                            % Get variable list for this project
                            uploadVars = fetch(conn, sprintf([ ...
                                'SELECT v.variableID, v.name ' ...
                                'FROM variableTab v ' ...
                                'JOIN variable_handlingTab vh ' ...
                                '    ON v.variableID = vh.variableID ' ...
                                'JOIN projectTab p ' ...
                                '    ON p.organismID = vh.organismID ' ...
                                'WHERE p.projectID = %i'], app.projectID));

                            % Pre-allocate data table arrays
                            nV   = height(uploadVars);
                            vIDs = repmat(uploadVars.variableID, nTimes, 1);
                            tIDs = repelem(timeIDs, nV);
                            vals = zeros(nTimes * nV, 1);

                            % Fill values — NaN prealloc slots become 0
                            for i = 1:nTimes
                                tIdx = tRange(i);
                                for j = 1:nV
                                    vname = string(uploadVars.name(j));
                                    if isfield(app.v, vname)
                                        v = app.v.(vname)(tIdx);
                                        if ~isnan(v)
                                            vals((i-1)*nV + j) = v;
                                        end
                                    end
                                end
                            end

                            % Write variable data
                            dataTable = table(vIDs, tIDs, vals, ...
                                'VariableNames', {'variableID','timeID','value'});
                            sqlwrite(conn, 'dataTab', dataTable);

                            exec(conn, "COMMIT");
                            conn.AutoCommit = "on";
                        end
                    end
                end

                close(conn);

            catch ME
                try
                    exec(conn, "ROLLBACK");
                    close(conn);
                catch
                end
                error('[saveProject] %s', ME.message);
            end

            % ── 3. Phases (own connection inside savePhases) ─────────────
            savePhases(app);

            % ── 4. Log data ───────────────────────────────────────────────────
            if ~isempty(app.logTable) && height(app.logTable) > 0

                res    = fetch(conn, sprintf( ...
                    'SELECT COUNT(*) AS n FROM logTab WHERE projectID = %i', ...
                    app.projectID));
                nSaved = double(res.n);

                newEntries = app.logTable(nSaved+1:end, :);

                if height(newEntries) > 0
                    for i = 1:height(newEntries)
                        % Combine event_type and message into one string
                        fullMessage = sprintf('[%s] %s', ...
                            newEntries.event_type(i), ...
                            newEntries.message(i));

                        % Escape single quotes for SQL
                        fullMessage = replace(fullMessage, "'", "''");

                        exec(conn, sprintf([ ...
                            'INSERT INTO logTab (projectID, datetime, message) ' ...
                            'VALUES (%i, ''%s'', ''%s'')'], ...
                            app.projectID, ...
                            newEntries.datetime(i), ...
                            fullMessage));
                    end
                end
            end
            fprintf('[saveProject] Done.\n');
        end

    end

    %% FROM CALCULATION APP
    methods (Access = private) 

        % function calculationFcn(app) % private
        %     % Single period operation time measurement start
        %     operationT = tic;
        % 
        %     % Check process phase start
        %     checkStartCondition(app)
        % 
        %     % Performance optimization by predefining 
        %     % Simulation calculation
        %     calculation_time = zeros(app.p.speedfactor,1); % Calc. time initialization
        %     for i = 1:app.p.speedfactor % repeat x speed factor
        %         calculationT = tic; % Start measuring calculation time
        %         try
        %             app.real_t(app.nxtidx) = string(datetime("now",'Format','dd.MM.yyyy HH:mm:ss.SSS')); % Timestamp of calculation
        %             app = app.calculation_func(app); % Execute calculations
        %         catch ME
        %             disp(ME.message)
        %         end
        %         calculation_time(i) = toc(calculationT); % Measure over
        %         preallocationFcn(app) % check preallocation status
        %     end
        % 
        %     %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %     operation_time = toc(operationT);
        %     fprintf('Mean calc. t[sec]: %1.4f. Total t[sec]: %2.4f.\n', mean(calculation_time), operation_time);
        %     %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % 
        %     % Check process phase ending
        %     checkEndCondition(app)
        % end
function calculationFcn(app)
    operationT = tic;

    % Check phase start before calculation
    checkStartCondition(app);

    calculation_time = zeros(app.p.speedfactor, 1);

    for i = 1:app.p.speedfactor
        calculationT = tic;

        try
            % Execute calculation first — this writes to nxtidx
            app = app.calculation_func(app);

            % Timestamp AFTER calculation, at the same nxtidx
            app.real_t(app.nxtidx) = string(datetime("now", ...
                'Format', 'dd.MM.yyyy HH:mm:ss.SSS'));

        catch ME
            fprintf('[calculationFcn] %s\n', ME.message);
        end

        calculation_time(i) = toc(calculationT);

        % Advance nxtidx after both calculation and timestamp are set
        preallocationFcn(app);
    end

    fprintf('Mean calc. t[sec]: %1.4f. Total t[sec]: %2.4f.\n', ...
        mean(calculation_time), toc(operationT));

    % Check phase end after all speedfactor steps
    checkEndCondition(app);
end
        function flag = conditionCheck(app, startOrEnd, phaseNum)
            flag = false;
            ph   = app.Phases(phaseNum);

            if strcmp(startOrEnd, 'start')
                switch ph.start.typeID
                    case 1  % Variable condition
                        flag = evaluateCondition(app, ...
                            ph.start.variableID, ph.start.operatorID, ph.start.value);
                    case 2  % Previous phase ended
                        flag = true;
                    case 3  % Batch end detection (pO2 slope)
                        flag = BatchEndDetection_pO2Slope(app.v, app.p.deltatsec, app.v.t,app.nxtidx);
                    otherwise
                        fprintf('[conditionCheck] Unknown start_typeID: %i\n', ph.start.typeID);
                end
            else  % 'end'
                switch ph.end.typeID
                    case 5  % Next phase start condition
                        if phaseNum < numel(app.Phases)
                            flag = conditionCheck(app, 'start', phaseNum + 1);
                        end
                    case 6  % Variable condition
                        flag = evaluateCondition(app, ...
                            ph.end.variableID, ph.end.operatorID, ph.end.value);
                    case 7  % Timer
                        flag = app.v.t(app.nxtidx) >= ph.end.time;
                    otherwise
                        fprintf('[conditionCheck] Unknown end_typeID: %i\n', ph.end.typeID);
                end
            end
        end

        function flag = evaluateCondition(app, variableID, operatorID, threshold)
            % Direct comparison without eval()
            flag = false;

            v_idx = find(app.process_variable.variableID == variableID, 1);
            if isempty(v_idx)
                fprintf('[evaluateCondition] variableID %i not found.\n', variableID);
                return;
            end
            varName = string(app.process_variable.name(v_idx));

            % Read current value from simulation state
            if varName == "t"
                currentVal = app.v.t(app.nxtidx);
            elseif isfield(app.v, varName)
                currentVal = app.v.(varName)(app.nxtidx);
            else
                fprintf('[evaluateCondition] Variable "%s" not in app.v.\n', varName);
                return;
            end

            o_idx = find(app.process_operator.process_operatorID == operatorID, 1);
            if isempty(o_idx)
                fprintf('[evaluateCondition] operatorID %i not found.\n', operatorID);
                return;
            end
            op = string(app.process_operator.operator(o_idx));

            switch op
                case ">";  flag = currentVal >  threshold;
                case ">="; flag = currentVal >= threshold;
                case "<";  flag = currentVal <  threshold;
                case "<="; flag = currentVal <= threshold;
                case "=="; flag = currentVal == threshold;
                case "~="; flag = currentVal ~= threshold;
                otherwise
                    fprintf('[evaluateCondition] Unknown operator: %s\n', op);
            end
        end

        function checkStartCondition(app)
            if ~isempty(app.currentPhase)
                return;  % A phase is already active
            end
            if isempty(app.Phases) || ~isstruct(app.Phases)
                return;
            end

            allStatus = [app.Phases.statusID];

            % All phases finished?
            if all(allStatus == 4)
                return;
            end

            % Find first pending phase (statusID == 2)
            i = find(allStatus == 2, 1, 'first');

            % No pending phase — take the next upcoming after the last ended
            if isempty(i)
                lastEnded = find(allStatus == 4, 1, 'last');
                if ~isempty(lastEnded)
                    if lastEnded == numel(app.Phases)
                        return;  % Last phase already ended
                    end
                    i = lastEnded + 1;
                else
                    i = 1;  % Start from first phase
                end
                app.Phases(i).statusID = 2;  % Mark as pending
            end

            % Check start condition
            if ~conditionCheck(app, 'start', i)
                return;
            end

            % Start the phase
            app.Phases(i).statusID   = 3;  % Active
            app.Phases(i).start.time = app.v.t(app.nxtidx);

            % Calculate end time for timer-based phases
            if app.Phases(i).end.typeID == 7
                app.Phases(i).end.time = app.Phases(i).start.time + app.Phases(i).end.value;
            end

            logMessage(app, "Phase Event", sprintf("%s [Phase %i] started at t = %.3f h.", ...
                app.Phases(i).name, i, app.v.t(app.nxtidx)));

            processPhaseActions(app, i);

            app.currentPhase = i;
            updatePhasePanels(app);
        end


        function checkEndCondition(app)
            if isempty(app.currentPhase)
                return;
            end

            i = app.currentPhase;

            % Check end condition or manually forced end (statusID == 4)
            if ~conditionCheck(app, 'end', i) && app.Phases(i).statusID ~= 4
                return;
            end

            app.Phases(i).statusID = 4;  % Ended
            app.Phases(i).end.time = app.v.t(app.nxtidx);

            logMessage(app, "Phase Event", sprintf("%s [Phase %i] ended at t = %.3f h.", ...
                app.Phases(i).name, i, app.v.t(app.nxtidx)));

            % Set next phase to pending
            if i < numel(app.Phases)
                app.Phases(i+1).statusID = 2;
            else
                fprintf('[Phase] Last phase ended.\n');
            end

            app.currentPhase = [];
            app.switch_exp   = false;
            app.switch_pulse = false;

            updatePhasePanels(app);
        end

        function processPhaseActions(app, idx)
    switch app.Phases(idx).typeID
        case 1  % Blank — do nothing
            return;
        case 2  % Stop process
            StartSimulationButtonPushed(app);
        case 3  % Parameter update
            applyPhaseParameters(app, idx);
        case 4  % Pulse feed
            handlePulseFeed(app, idx);
        case 5  % Exponential feed
            handleExponentialFeed(app, idx);
        otherwise
            fprintf('[processPhaseActions] Unknown process_typeID: %i\n', ...
                app.Phases(idx).typeID);
    end
        end

        function applyPhaseParameters(app, idx)
            % Apply phase-specific parameters from memory to app.p
            params = app.Phases(idx).parameters;
            if isempty(fieldnames(params)); return; end
            names = fieldnames(params);
            for k = 1:numel(names)
                pname = names{k};
                if isfield(app.p, pname)
                    old = app.p.(pname);
                    app.p.(pname) = params.(pname);
                    ParameterChangedFcn(app, pname, old, params.(pname));
                end
            end
        end

        function handlePulseFeed(app, idx)
            ph  = app.Phases(idx);
            old = app.p.R_feed;

            applyPhaseParameters(app, idx);

            app.switch_pulse = true;
            app.p.R_feed     = min(ph.reservoirID, app.numReservoir);
            n                = num2str(app.p.R_feed);

            ParameterChangedFcn(app, 'f_feed', 1, 0);
            ParameterChangedFcn(app, "R_feed", old, app.p.R_feed);

            logMessage(app, "Phase Information", sprintf( ...
                "Pulse feed R%s: FR = %.3f l/h", n, ...
                app.p.(['kR' n]) * app.p.(['FR' n 'max'])));
        end

        function handleExponentialFeed(app, idx)
            ph  = app.Phases(idx);
            old = app.p.R_feed;

            applyPhaseParameters(app, idx);

            app.switch_exp = true;
            app.p.R_feed   = min(ph.reservoirID, app.numReservoir);
            n              = num2str(app.p.R_feed);

            % Store initial conditions at phase start time
            app.p.(['t'   n 'j']) = app.v.t(app.nxtidx);
            app.p.(['cXL' n 'j']) = app.v.cXL(app.nxtidx);

            % Calculate initial feed rate
            qXpXw  = app.p.(['qXpX' n 'w']);
            qSpXm  = app.p.(['qS'   n 'pXm']);
            yXpSgr = app.p.(['yXpS' n 'gr']);
            cSR    = app.p.(['cS'   n 'R'  n]);
            FRj    = ((qXpXw + qSpXm * yXpSgr) * app.v.VL(app.nxtidx) * ...
                app.v.cXL(app.nxtidx)) / (yXpSgr * cSR);
            app.p.(['FR' n 'j']) = FRj;

            % Persist calculated values in phase struct for savePhases
            app.Phases(idx).parameters.(['t'   n 'j']) = app.p.(['t'   n 'j']);
            app.Phases(idx).parameters.(['cXL' n 'j']) = app.p.(['cXL' n 'j']);
            app.Phases(idx).parameters.(['FR'  n 'j']) = FRj;

            ParameterChangedFcn(app, 'f_feed', 1, 0);
            ParameterChangedFcn(app, "R_feed", old, app.p.R_feed);

            logMessage(app, "Phase Information", sprintf( ...
                "\n\t===================================\n" + ...
                "\tOpen loop feed R%s:\n"                  + ...
                "\t qX/X%sw  = %.3f 1/h\n"                + ...
                "\t qS%s/Xm  = %.3f 1/h\n"                + ...
                "\t yX/S%sgr = %.3f\n"                    + ...
                "\t cS%sR%s  = %.1f g/l\n"                + ...
                "\t cXLj     = %.3g g/l\n"                + ...
                "\t t%sj     = %.3f h\n"                  + ...
                "\t FR%sj    = %.3f l/h\n"                + ...
                "\t===================================",   ...
                n, n, qXpXw, n, qSpXm, n, yXpSgr, n, n, cSR, ...
                app.p.(['cXL' n 'j']), n, app.p.(['t' n 'j']), n, FRj));
        end
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, CallingApp, projectID)
            app.StartingApp = CallingApp;
            app.projectID   = projectID;
            app.dbpath      = app.StartingApp.dbpath;
            centerWindow(app.UIFigure);

            % Load parameters, metadata, lookup tables and phases (single DB call)
            % Must run before loadProject so app.p is available for initialization_func
            loadPhases(app);

            % Load project info and variable data, run initialization
            loadProject(app);

            % Create timer object
            app.Refresher = timer( ...
                'ExecutionMode', 'fixedRate', ...
                'Period',        app.refreshrate, ...
                'BusyMode',      'queue', ...
                'TimerFcn',      @app.timerFcn);
            start(app.Refresher);

            % Build phase panels from struct array populated by loadPhases
            for i = 1:numel(app.Phases)
                app.numPhases = i;
                if i > 1
                    createArrowButton(app);
                end
                createPhasePanel(app);
            end
            if numel(app.Phases) > 0
                updatePhasePanels(app);
            end

            % Set window name and button callbacks
            app.UIFigure.Name = sprintf("Control App - %s - %s - %s", ...
                app.projectName, app.organismname, app.bioreactorname);
            app.AddPhaseButton.ButtonPushedFcn = @(btn,event) app.addPhase(event);

            % Set first timestamp
            app.real_t(1) = datetime("now", 'Format', 'dd.MM.yyyy HH:mm:ss.SSS');

            % Apply settings
            if app.StartingApp.studentmode
                app.DeltatsEditField.Visible = "off";
                app.SpeedfactorxSpinner.Visible = "off";
            end
            app.SpeedfactorxSpinner.Limits = [0 app.StartingApp.maxspeedfactor];
        end

        % Callback function: ExitButton, UIFigure
        function UIFigureCloseRequest(app, event)
            if isvalid(app.Refresher) && ~isempty(app.Refresher) 
                if app.Refresher.Running == "on"
                    stop(app.Refresher) % Turn timer off
                end
            end
            if app.StartSimulationButton.Text == "Pause"
                StartSimulationButtonPushed(app)
            end
            closingapp = ClosingScreen(app, app.StartingApp);
            waitfor(closingapp)
            delete(app)
        end

        % Button pushed function: StartSimulationButton
        function StartSimulationButtonPushed(app, event)
            if app.processrunning % Pause process
                app.StartSimulationButton.Text = "Run";
                app.ProcessRunningLamp.Color = "red";
                app.processrunning = false;
                disablePhaseObjects(app, 'on')
            else %  Run process
                if app.StartSimulationButton.Text == "Start Simulation" % If it is the first start
                   app.EditStartingVariablesButton.Visible = 'off';
                   app.StartSimulationButton.Tooltip = "";
                end
                app.StartSimulationButton.Text = "Pause";
                app.ProcessRunningLamp.Color = "green";
                app.processrunning = true;
                disablePhaseObjects(app, 'off')
            end
        end

        % Button pushed function: EditStartingVariablesButton
        function EditStartingVariablesButtonPushed(app, event)
            % Disable the Edit Starting Variables button while dialog box is open
            app.EditStartingVariablesButton.Enable = 'off';

            % Open the options dialog and pass inputs
            app.DialogApp = DialogBox1(app, app.StartingApp);
        end

        % Value changed function: N_StwrpmEditField
        function N_StwrpmEditFieldValueChanged(app, event)
            % Change stirrer speed setpoint when edit field value changes
            % and turn on the motor flag if the value is larger than zero
            app.p.NStw = app.N_StwrpmEditField.Value;
            ParameterChangedFcn(app, "NStw", event.PreviousValue, event.Value)
        end

        % Value changed function: F_nAIRwlminEditField
        function F_nAIRwlminEditFieldValueChanged(app, event)
            % Change air aeration rate setpoint when edit field value changes
            % and turn on the aeration flag if the value is larger than
            % zero
            app.p.FnAIRw = app.F_nAIRwlminEditField.Value;
            ParameterChangedFcn(app, "FnAIRw", event.PreviousValue, event.Value)
        end

        % Value changed function: F_nO2wlminEditField
        function F_nO2wlminEditFieldValueChanged(app, event)
            % Change O2 aeration rate setpoint when edit field value changes
            % and turn on the aeration flag if the value is larger than
            % zero
            app.p.FnO2w = app.F_nO2wlminEditField.Value;
            ParameterChangedFcn(app, "FnO2w", event.PreviousValue, event.Value)           
        end

        % Value changed function: F_RwlhEditField
        function F_RwlhEditFieldValueChanged(app, event)
            % Change feed rate setpoint when edit field value changes
            n = num2str(app.p.R_feed);
            app.p.(['FR' n 'w']) = app.F_RwlhEditField.Value;
            ParameterChangedFcn(app, ['FR' n 'w'], event.PreviousValue, event.Value)
        end

        % Value changed function: F_HwEditField
        function F_HwEditFieldValueChanged(app, event)
            % Change harvest rate setpoint when edit field value changes
            % and turn on the harvest flag if the value is larger than zero
            app.p.FHrelw = app.F_HwEditField.Value;
            ParameterChangedFcn(app, "FHrelw", event.PreviousValue, event.Value)
        end

        % Value changed function: AntifoamadditionSwitch
        function AntifoamadditionSwitchValueChanged(app, event)
            % Set antifoam flag and lamp indication to on if the antifoam
            % control is activated
            app.p.f_antifoam = app.AntifoamadditionSwitch.Value;
            ParameterChangedFcn(app, "f_antifoam", event.PreviousValue, event.Value)
        end

        % Value changed function: AlkaliSwitch
        function AlkaliSwitchValueChanged(app, event)
            app.p.f_alkali = app.AlkaliSwitch.Value; % Set flag for base addition if alkali switch is turned
            ParameterChangedFcn(app, "f_alkali", event.PreviousValue, event.Value)
        end

        % Value changed function: AcidSwitch
        function AcidSwitchValueChanged(app, event)
            app.p.f_acid = app.AcidSwitch.Value; % Set flag for acid addition if acid switch is turned
            ParameterChangedFcn(app, "f_acid", event.PreviousValue, event.Value)
        end

        % Value changed function: CoolingSwitch
        function CoolingSwitchValueChanged(app, event)
            app.p.f_cooling = app.CoolingSwitch.Value; % Set flag for cooling if cooling switch is turned
            ParameterChangedFcn(app, "f_cooling", event.PreviousValue, event.Value)
        end

        % Value changed function: HeatingSwitch
        function HeatingSwitchValueChanged(app, event)
            app.p.f_heating = app.HeatingSwitch.Value; % Set flag for heating if heating switch is turned
            ParameterChangedFcn(app, "f_heating", event.PreviousValue, event.Value)
        end

        % Value changed function: pH_wEditField
        function pH_wEditFieldValueChanged(app, event)
            app.p.pHw = app.pH_wEditField.Value;
            ParameterChangedFcn(app, "pHw", event.PreviousValue, event.Value)
        end

        % Value changed function: F_nGwlminEditField
        function F_nGwlminEditFieldValueChanged(app, event)
            app.p.FnGw = app.F_nGwlminEditField.Value; % Change aeration rate setpoint if edit field value changes
            ParameterChangedFcn(app, "FnGw", event.PreviousValue, event.Value)
        end

        % Value changed function: pO_2_wEditField
        function pO_2_wEditFieldValueChanged(app, event)
            app.p.pO2w = app.pO_2_wEditField.Value; % Change pO2 setpoint if the edit field value is changed
            ParameterChangedFcn(app, "pO2w", event.PreviousValue, event.Value)
        end

        % Value changed function: theta_wCEditField
        function theta_wCEditFieldValueChanged(app, event)
            app.p.thetaLw = app.theta_wCEditField.Value; % Change temperature setpoint if the edit field value is changed
            ParameterChangedFcn(app, "thetaLw", event.PreviousValue, event.Value)
        end

        % Value changed function: m_L_wkgEditField
        function m_L_wkgEditFieldValueChanged(app, event)
            app.p.LWw = app.m_L_wkgEditField.Value; % Change liquid weight setpoint if the edit field value is changed
            ParameterChangedFcn(app, "LWw", event.PreviousValue, event.Value)
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            % Select pH control mode and enable/disable manual controls
            % accordingly
            app.p.Mode_pH = app.ModeDropDown.Value;
            ParameterChangedFcn(app, "Mode_pH", event.PreviousValue, event.Value)
        end

        % Value changed function: ModeDropDown_2
        function ModeDropDown_2ValueChanged(app, event)
            % Select temperature control mode and enable/disable manual controls
            % accordingly
            app.p.Mode_temp = app.ModeDropDown_2.Value;
            ParameterChangedFcn(app, "Mode_temp", event.PreviousValue, event.Value)
        end

        % Value changed function: ModeDropDown_3
        function ModeDropDown_3ValueChanged(app, event)
            % Select pO2 control mode, set flags and enable/disable manual controls
            % accordingly
            app.p.Mode_pO2 = app.ModeDropDown_3.Value;
            ParameterChangedFcn(app, "Mode_pO2", event.PreviousValue, event.Value)
        end

        % Value changed function: ModeDropDown_4
        function ModeDropDown_4ValueChanged(app, event)
            % Select liquid weight control mode and enable/disable manual controls
            % accordingly
            app.p.Mode_harvest = app.ModeDropDown_4.Value;
            ParameterChangedFcn(app, "Mode_harvest", event.PreviousValue, event.Value)
        end

        % Value changed function: DeltatsEditField
        function DeltatsEditFieldValueChanged(app, event)
            app.p.deltatsec = app.DeltatsEditField.Value; % Change delta t if the edit field value changes
            ParameterChangedFcn(app, "deltatsec", event.PreviousValue, event.Value)
        end

        % Button pushed function: pHParametersButton
        function pHParametersButtonPushed(app, event)
            % Disable the Edit Starting Variables button while dialog box is open
            app.pHParametersButton.Enable = 'off';

            % Open the options dialog and pass inputs
            app.DialogApp2 = DialogBox2(app);
            centerWindow(app.DialogApp2.UIFigure)
        end

        % Button pushed function: tempParametersButton
        function tempParametersButtonPushed(app, event)
            % Disable the Edit Starting Variables button while dialog box is open
            app.tempParametersButton.Enable = 'off';

            % Open the options dialog and pass inputs
            app.DialogApp3 = DialogBox3(app);
            centerWindow(app.DialogApp3.UIFigure)
        end

        % Button pushed function: pO2ParametersButton
        function pO2ParametersButtonPushed(app, event)
            % Disable the Edit Starting Variables button while dialog box is open
            app.pO2ParametersButton.Enable = 'off';

            % Open the options dialog and pass inputs
            app.DialogApp4 = DialogBox4(app);
            centerWindow(app.DialogApp4.UIFigure)
        end

        % Button pushed function: LWParametersButton
        function LWParametersButtonPushed(app, event)
            % Disable the Edit Starting Variables button while dialog box is open
            app.LWParametersButton.Enable = 'off';

            % Open the options dialog and pass inputs
            app.DialogApp5 = DialogBox5(app);
            centerWindow(app.DialogApp5.UIFigure)
        end

        % Menu selected function: OpendatatableMenu
        function OpendatatableMenuSelected(app, event)
            app.Datatable{end+1} = DataTable(app, app.projectID);
        end

        % Button pushed function: OpenPlotButton
        function OpenPlotButtonPushed(app, event)
            app.Figureapps{end+1} = FigureApp(app, app.projectID, 1); % Open default template
        end

        % Menu selected function: DisconnectMenu
        function DisconnectMenuSelected(app, event)
            if app.Refresher.Running == "on"
                stop(app.Refresher)
                app.DisconnectMenu.Text = "Reconnect";
            else
                start(app.Refresher)
                app.DisconnectMenu.Text = "Disconnect";
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if selectedTab == app.LogTab
                updateLogFcn(app) % update log text
                app.LogTab.Title = "Log";
                app.logchanges = 0;
            elseif selectedTab == app.VariablePoolTab
                updateTrendFcn(app) % Update trend table once trend view is opened
            end
        end

        % Value changed function: IncludeparameterupdatesCheckBox
        function IncludeparameterupdatesCheckBoxValueChanged(app, event)
            text = "";
            for n = 1:numel(app.logText)
                if app.IncludeparameterupdatesCheckBox.Value == 1
                    if text == ""
                        text = app.logText{n};
                    else
                        text = sprintf('%s\n%s',text,app.logText{n});
                    end
                elseif ~contains(app.logText(n),"Parameter")
                    if text == ""
                        text = app.logText{n};
                    else
                        text = sprintf('%s\n%s',text,app.logText{n});
                    end
                end
            end
        end

        % Value changed function: HarvestSwitch
        function HarvestSwitchValueChanged(app, event)
            app.p.f_harvest = app.HarvestSwitch.Value;
            ParameterChangedFcn(app, "f_harvest", event.PreviousValue, event.Value)
        end

        % Value changed function: InoculateButton
        function InoculateButtonValueChanged(app, event)
            if event.Value
                if app.v.t(app.nxtidx) ~= 0
                    app.p.f_Inoc = 1;
                    ParameterChangedFcn(app, "f_Inoc", 0, 1) % hier nochmal checken
                    app.InoculateButton.Enable = "off";
                    app.InoculatedLamp.Color = "green";
                    ParameterChangedFcn(app, "f_InocStart", 0, 1)
                    app.p.f_InocStart = 1;
                else
                    app.p.f_InocStart = app.InoculateButton.Value;
                    ParameterChangedFcn(app, "f_InocStart", 0, 1)
                end
            end
        end

        % Value changed function: SpeedfactorxSpinner
        function SpeedfactorxSpinnerValueChanged(app, event)
            app.p.speedfactor = app.SpeedfactorxSpinner.Value;
            ParameterChangedFcn(app, "speedfactor", event.PreviousValue, event.Value)
        end

        % Button pushed function: AddPhaseButton
        function AddPhaseButtonPushed(app, event)
            createPhasePanel(app)
            
        end

        % Value changed function: Feeding_ModeDropDown
        function Feeding_ModeDropDownValueChanged(app, event)
            app.p.Mode_feed = app.Feeding_ModeDropDown.Value;
            ParameterChangedFcn(app, "Mode_feed", event.PreviousValue, event.Value)
        end

        % Value changed function: ReservoirDropDown
        function ReservoirDropDownValueChanged(app, event)
            app.p.R_feed = app.ReservoirDropDown.Value;
            ParameterChangedFcn(app, "R_feed", event.PreviousValue, event.Value)
        end

        % Value changed function: FeedControlSwitch
        function FeedControlSwitchValueChanged(app, event)
            app.p.f_feed = app.FeedControlSwitch.Value;
            ParameterChangedFcn(app, "f_feed", event.PreviousValue, event.Value)
        end

        % Value changed function: c_S1Lwgl1EditField
        function c_S1Lwgl1EditFieldValueChanged(app, event)
            n = num2str(app.p.R_feed);
            app.p.(['cS' n 'Lw']) = app.c_S1Lwgl1EditField.Value;
            ParameterChangedFcn(app, ['cS' n 'Lw'], event.PreviousValue, event.Value)
        end

        % Button pushed function: FeedingParametersButton
        function FeedingParametersButtonPushed(app, event)
            app.FeedingParametersButton.Enable = "off";
            app.DialogApp6 = DialogBox6(app, app.p.R_feed);
        end

        % Context menu opening function: ContextMenu
        function ContextMenuOpening(app, event)
            conn = sqlite(app.dbpath, "readonly");
            templates = fetch(conn, "SELECT templateID, name, description FROM plot_templateTab");
            close(conn)
            children = app.ContextMenu.Children;
            delete(children) % Delete previous menus
            for i = 1:size(templates, 1)
                % Create a new menu item
                menu = uimenu(app.ContextMenu);
                menu.Text = templates.name{i};  % Access cell for strings in table
                menu.Tooltip = templates.description{i};  % Access cell for strings in table
                menu.UserData = templates.templateID(i);  % Access numeric values directly
                menu.MenuSelectedFcn = @(src, event) FigureMenuSelected(app, src, event);  % Callback function
            end

        end

        % Menu selected function: xlsxMenu
        function xlsxMenuSelected(app, event)
            exportData(app, '.xlsx')
        end

        % Menu selected function: txtMenu
        function txtMenuSelected(app, event)
            exportData(app, '.txt')
        end

        % Menu selected function: csvMenu
        function csvMenuSelected(app, event)
            exportData(app, '.csv')
        end

        % Menu selected function: ExportprojectMenu
        function ExportprojectMenuSelected(app, event)
            exportproject = ExportProject(app);
            waitfor(exportproject)
        end

        % Menu selected function: SaveandexitMenu
        function SaveandexitMenuSelected(app, event)
            saveProject(app)
            UIFigureCloseRequest(app)
        end

        % Button pushed function: ReconnectButton
        function ReconnectButtonPushed(app, event)
            app.ReconnectButton.Visible = "off";
            app.DisconnectMenuSelected
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ProjectMenu = uimenu(app.UIFigure);
            app.ProjectMenu.Text = 'Project';
            app.StartnewprojectMenu = uimenu(app.ProjectMenu);
            app.StartnewprojectMenu.Separator = 'on';
            app.StartnewprojectMenu.Text = 'Start new project';
            app.QuickstartMenu = uimenu(app.StartnewprojectMenu);
            app.QuickstartMenu.Text = 'Quick start';
            app.StandardMenu = uimenu(app.StartnewprojectMenu);
            app.StandardMenu.Text = 'Standard';
            app.OpenprojectMenu = uimenu(app.ProjectMenu);
            app.OpenprojectMenu.Text = 'Open project';
            app.SaveandexitMenu = uimenu(app.ProjectMenu);
            app.SaveandexitMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveandexitMenuSelected, true);
            app.SaveandexitMenu.Separator = 'on';
            app.SaveandexitMenu.Text = 'Save and exit';
            app.DeleteprojectMenu = uimenu(app.ProjectMenu);
            app.DeleteprojectMenu.Separator = 'on';
            app.DeleteprojectMenu.Text = 'Delete project';
            app.ExportMenu = uimenu(app.UIFigure);
            app.ExportMenu.Text = 'Export';
            app.OpendatatableMenu = uimenu(app.ExportMenu);
            app.OpendatatableMenu.MenuSelectedFcn = createCallbackFcn(app, @OpendatatableMenuSelected, true);
            app.OpendatatableMenu.Tooltip = {'Access a variable data table'};
            app.OpendatatableMenu.Text = 'Open data table';
            app.ExportdataasMenu = uimenu(app.ExportMenu);
            app.ExportdataasMenu.Text = 'Export data as...';
            app.xlsxMenu = uimenu(app.ExportdataasMenu);
            app.xlsxMenu.MenuSelectedFcn = createCallbackFcn(app, @xlsxMenuSelected, true);
            app.xlsxMenu.Text = '*.xlsx';
            app.txtMenu = uimenu(app.ExportdataasMenu);
            app.txtMenu.MenuSelectedFcn = createCallbackFcn(app, @txtMenuSelected, true);
            app.txtMenu.Text = '*.txt';
            app.csvMenu = uimenu(app.ExportdataasMenu);
            app.csvMenu.MenuSelectedFcn = createCallbackFcn(app, @csvMenuSelected, true);
            app.csvMenu.Text = '*.csv';
            app.ExportprojectMenu = uimenu(app.ExportMenu);
            app.ExportprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportprojectMenuSelected, true);
            app.ExportprojectMenu.Text = 'Export project';
            app.SettingsMenu = uimenu(app.UIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.DisconnectMenu = uimenu(app.SettingsMenu);
            app.DisconnectMenu.MenuSelectedFcn = createCallbackFcn(app, @DisconnectMenuSelected, true);
            app.DisconnectMenu.Tooltip = {'Client refresher'};
            app.DisconnectMenu.Text = 'Disconnect';
            app.ExpertmodeMenu = uimenu(app.SettingsMenu);
            app.ExpertmodeMenu.Tooltip = {'Access additional features'};
            app.ExpertmodeMenu.Visible = 'off';
            app.ExpertmodeMenu.Text = 'Expert mode';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.DynamicPanel = uipanel(app.GridLayout);
            app.StaticPanel = uipanel(app.GridLayout);
            app.ExitButton = uibutton(app.StaticPanel, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExitButton.Text = 'Exit';
            app.fontsize10fontnameHelveticaProcessTimehEditFieldLabel = uilabel(app.StaticPanel);
            app.fontsize10fontnameHelveticaProcessTimehEditFieldLabel.Interpreter = 'tex';
            app.fontsize10fontnameHelveticaProcessTimehEditFieldLabel.Text = '\fontsize{10}\fontname{Helvetica}Process Time [h]';
            app.ProcessTimehEditField = uieditfield(app.StaticPanel, 'numeric');
            app.ProcessTimehEditField.ValueDisplayFormat = '%.3f';
            app.ProcessTimehEditField.Editable = 'off';
            app.ProcessRunningLampLabel = uilabel(app.StaticPanel);
            app.ProcessRunningLampLabel.Text = 'Process Running';
            app.ProcessRunningLamp = uilamp(app.StaticPanel);
            app.StartSimulationButton = uibutton(app.StaticPanel, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationButtonPushed, true);
            app.StartSimulationButton.Tooltip = {'Start a new simulation with the current starting variables'};
            app.StartSimulationButton.Text = 'Start Simulation';
            app.fontsize10fontnameHelveticaDeltatsLabel = uilabel(app.StaticPanel);
            app.fontsize10fontnameHelveticaDeltatsLabel.Interpreter = 'tex';
            app.fontsize10fontnameHelveticaDeltatsLabel.Text = '\fontsize{10}\fontname{Helvetica}\Deltat [s]';
            app.DeltatsEditField = uieditfield(app.StaticPanel, 'numeric');
            app.DeltatsEditField.Limits = [0.1 10];
            app.DeltatsEditField.ValueChangedFcn = createCallbackFcn(app, @DeltatsEditFieldValueChanged, true);
            app.DeltatsEditField.Value = 0.1;
            app.OpenPlotButton = uibutton(app.StaticPanel, 'push');
            app.OpenPlotButton.ButtonPushedFcn = createCallbackFcn(app, @OpenPlotButtonPushed, true);
            app.OpenPlotButton.Text = 'Open Plot';
            app.InoculatedLampLabel = uilabel(app.StaticPanel);
            app.InoculatedLampLabel.Text = 'Inoculated';
            app.InoculatedLamp = uilamp(app.StaticPanel);
            app.InoculateButton = uibutton(app.StaticPanel, 'state');
            app.InoculateButton.ValueChangedFcn = createCallbackFcn(app, @InoculateButtonValueChanged, true);
            app.InoculateButton.Text = 'Inoculate';
            app.SpeedfactorxSpinnerLabel = uilabel(app.StaticPanel);
            app.SpeedfactorxSpinnerLabel.Text = 'Speed factor [x]';
            app.SpeedfactorxSpinner = uispinner(app.StaticPanel);
            app.SpeedfactorxSpinner.Limits = [1 100];
            app.SpeedfactorxSpinner.ValueChangedFcn = createCallbackFcn(app, @SpeedfactorxSpinnerValueChanged, true);
            app.SpeedfactorxSpinner.Value = 1;
            app.EditStartingVariablesButton = uibutton(app.StaticPanel, 'push');
            app.EditStartingVariablesButton.ButtonPushedFcn = createCallbackFcn(app, @EditStartingVariablesButtonPushed, true);
            app.EditStartingVariablesButton.Visible = 'off';
            app.EditStartingVariablesButton.Tooltip = {'Edit or display the currently loaded starting variables'};
            app.EditStartingVariablesButton.Text = 'Edit Starting Variables';
            app.ReconnectButton = uibutton(app.StaticPanel, 'push');
            app.ReconnectButton.ButtonPushedFcn = createCallbackFcn(app, @ReconnectButtonPushed, true);
            app.ReconnectButton.Visible = 'off';
            app.ReconnectButton.Text = 'Reconnect';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ControlOptionsTab = uitab(app.TabGroup);
            app.ControlOptionsTab.Title = 'Control Options';
            app.ControlOptionsGridLayout = uigridlayout(app.ControlOptionsTab);
            app.pHControlPanel = uipanel(app.ControlOptionsGridLayout);
            app.pHControlPanel.Title = 'pH-Control';
            app.GridLayout4 = uigridlayout(app.pHControlPanel);
            app.pHParametersButton = uibutton(app.GridLayout4, 'push');
            app.pHParametersButton.ButtonPushedFcn = createCallbackFcn(app, @pHParametersButtonPushed, true);
            app.pHParametersButton.Tooltip = {'Adjust the gains of the pH controller.'};
            app.pHParametersButton.Text = 'Parameters';
            app.pHLamp = uilamp(app.GridLayout4);
            app.AlkaliSwitchLabel = uilabel(app.GridLayout4);
            app.AlkaliSwitchLabel.Text = 'Alkali';
            app.AlkaliSwitch = uiswitch(app.GridLayout4, 'rocker');
            app.AlkaliSwitch.ItemsData = [0 1];
            app.AlkaliSwitch.ValueChangedFcn = createCallbackFcn(app, @AlkaliSwitchValueChanged, true);
            app.AlkaliSwitch.Tooltip = {'Turn on alkali pump at maximum pump rate.'};
            app.AlkaliSwitch.Value = 0;
            app.AcidSwitchLabel = uilabel(app.GridLayout4);
            app.AcidSwitchLabel.Text = 'Acid';
            app.AcidSwitch = uiswitch(app.GridLayout4, 'rocker');
            app.AcidSwitch.ItemsData = [0 1];
            app.AcidSwitch.ValueChangedFcn = createCallbackFcn(app, @AcidSwitchValueChanged, true);
            app.AcidSwitch.Tooltip = {'Turn on acid pump at maximum pump rate.'};
            app.AcidSwitch.Value = 0;
            app.pH_wEditField = uieditfield(app.GridLayout4, 'numeric');
            app.pH_wEditField.ValueDisplayFormat = '%2.2f';
            app.pH_wEditField.ValueChangedFcn = createCallbackFcn(app, @pH_wEditFieldValueChanged, true);
            app.pH_wEditField.Tooltip = {'Enter a setpoint for the pH value.'};
            app.pH_wEditField.Value = 7;
            app.fontsize10fontnameHelveticapH_wEditFieldLabel = uilabel(app.GridLayout4);
            app.fontsize10fontnameHelveticapH_wEditFieldLabel.Interpreter = 'tex';
            app.fontsize10fontnameHelveticapH_wEditFieldLabel.Text = '\fontsize{10}\fontname{Helvetica}pH_{w}';
            app.fontsize10fontnameHelveticapH_LEditFieldLabel = uilabel(app.GridLayout4);
            app.fontsize10fontnameHelveticapH_LEditFieldLabel.Interpreter = 'tex';
            app.fontsize10fontnameHelveticapH_LEditFieldLabel.Text = '\fontsize{10}\fontname{Helvetica}pH_{L}';
            app.pH_LEditField = uieditfield(app.GridLayout4, 'numeric');
            app.pH_LEditField.ValueDisplayFormat = '%2.2f';
            app.pH_LEditField.Editable = 'off';
            app.pH_LEditField.Tooltip = {'Current pH value'};
            app.ModeLabel_4 = uilabel(app.GridLayout4);
            app.ModeLabel_4.Text = 'Mode:';
            app.ModeDropDown = uidropdown(app.GridLayout4);
            app.ModeDropDown.Items = {'Manual', 'Auto'};
            app.ModeDropDown.ItemsData = [0 1];
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Tooltip = {'Turn on/off pH control (on per default).'};
            app.ModeDropDown.Value = 1;
            app.TemperatureControlPanel = uipanel(app.ControlOptionsGridLayout);
            app.TemperatureControlPanel.Title = 'Temperature-Control';
            app.GridLayout3 = uigridlayout(app.TemperatureControlPanel);
            app.tempParametersButton = uibutton(app.GridLayout3, 'push');
            app.tempParametersButton.ButtonPushedFcn = createCallbackFcn(app, @tempParametersButtonPushed, true);
            app.tempParametersButton.Tooltip = {'Adjust the gains of the temperature controller.'};
            app.tempParametersButton.Text = 'Parameters';
            app.TemperatureLamp = uilamp(app.GridLayout3);
            app.CoolingSwitchLabel = uilabel(app.GridLayout3);
            app.CoolingSwitchLabel.Text = 'Cooling';
            app.CoolingSwitch = uiswitch(app.GridLayout3, 'rocker');
            app.CoolingSwitch.ItemsData = [0 1];
            app.CoolingSwitch.ValueChangedFcn = createCallbackFcn(app, @CoolingSwitchValueChanged, true);
            app.CoolingSwitch.Tooltip = {'Turn on cooling water flow at maximum flow rate.'};
            app.CoolingSwitch.Value = 0;
            app.HeatingSwitchLabel = uilabel(app.GridLayout3);
            app.HeatingSwitchLabel.Text = 'Heating';
            app.HeatingSwitch = uiswitch(app.GridLayout3, 'rocker');
            app.HeatingSwitch.ItemsData = [0 1];
            app.HeatingSwitch.ValueChangedFcn = createCallbackFcn(app, @HeatingSwitchValueChanged, true);
            app.HeatingSwitch.Tooltip = {'Turn on heating at maximum heating power.'};
            app.HeatingSwitch.Value = 0;
            app.fontsize10fontnameHelveticavartheta_LwCLabel = uilabel(app.GridLayout3);
            app.fontsize10fontnameHelveticavartheta_LwCLabel.Interpreter = 'tex';
            app.fontsize10fontnameHelveticavartheta_LwCLabel.Text = '\fontsize{10}\fontname{Helvetica}\vartheta_{Lw} [°C]';
            app.theta_wCEditField = uieditfield(app.GridLayout3, 'numeric');
            app.theta_wCEditField.ValueDisplayFormat = '%2.2f';
            app.theta_wCEditField.ValueChangedFcn = createCallbackFcn(app, @theta_wCEditFieldValueChanged, true);
            app.theta_wCEditField.Tooltip = {'Enter a setpoint for the temperature value.'};
            app.fontsize10fontnameHelveticavartheta_LCEditField_2Label = uilabel(app.GridLayout3);
            app.fontsize10fontnameHelveticavartheta_LCEditField_2Label.Interpreter = 'tex';
            app.fontsize10fontnameHelveticavartheta_LCEditField_2Label.Text = '\fontsize{10}\fontname{Helvetica}\vartheta_{L} [°C]';
            app.theta_LCEditField = uieditfield(app.GridLayout3, 'numeric');
            app.theta_LCEditField.ValueDisplayFormat = '%3.2f';
            app.theta_LCEditField.Editable = 'off';
            app.theta_LCEditField.Tooltip = {'Current liquid phase temperature'};
            app.ModeLabel_3 = uilabel(app.GridLayout3);
            app.ModeLabel_3.Text = 'Mode:';
            app.ModeDropDown_2 = uidropdown(app.GridLayout3);
            app.ModeDropDown_2.Items = {'Manual', 'Auto'};
            app.ModeDropDown_2.ItemsData = [0 1];
            app.ModeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ModeDropDown_2ValueChanged, true);
            app.ModeDropDown_2.Tooltip = {'Turn on/off temperature control (on per default).'};
            app.ModeDropDown_2.Value = 0;
            app.pO2ControlPanel = uipanel(app.ControlOptionsGridLayout);
            app.pO2ControlPanel.Title = 'pO2-Control';
            app.GridLayout2 = uigridlayout(app.pO2ControlPanel);
            app.pO2ParametersButton = uibutton(app.GridLayout2, 'push');
            app.pO2ParametersButton.ButtonPushedFcn = createCallbackFcn(app, @pO2ParametersButtonPushed, true);
            app.pO2ParametersButton.Tooltip = {'Adjust the gains of the pO2 controllers.'};
            app.pO2ParametersButton.Text = 'Parameters';
            app.pO2Lamp = uilamp(app.GridLayout2);
            app.fontsize10fontnameHelveticapO_2_wEditFieldLabel = uilabel(app.GridLayout2);
            app.fontsize10fontnameHelveticapO_2_wEditFieldLabel.Interpreter = 'tex';
            app.fontsize10fontnameHelveticapO_2_wEditFieldLabel.Text = '\fontsize{10}\fontname{Helvetica}pO_2_{w} [%]';
            app.pO_2_wEditField = uieditfield(app.GridLayout2, 'numeric');
            app.pO_2_wEditField.ValueDisplayFormat = '%3.1f';
            app.pO_2_wEditField.ValueChangedFcn = createCallbackFcn(app, @pO_2_wEditFieldValueChanged, true);
            app.pO_2_wEditField.Tooltip = {'Enter a setpoint for the pO2 value.'};
            app.fontsize10fontnameHelveticapO_2EditFieldLabel = uilabel(app.GridLayout2);
            app.fontsize10fontnameHelveticapO_2EditFieldLabel.Interpreter = 'tex';
            app.fontsize10fontnameHelveticapO_2EditFieldLabel.Text = '\fontsize{10}\fontname{Helvetica}pO_2 [%]';
            app.pO_2EditField = uieditfield(app.GridLayout2, 'numeric');
            app.pO_2EditField.ValueDisplayFormat = '%3.1f';
            app.pO_2EditField.Editable = 'off';
            app.pO_2EditField.Tooltip = {'Current partical oxygen pressure'};
            app.fontsize10fontnameHelveticaN_StwrpmEditFieldLabel = uilabel(app.GridLayout2);
            app.fontsize10fontnameHelveticaN_StwrpmEditFieldLabel.Interpreter = 'tex';
            app.fontsize10fontnameHelveticaN_StwrpmEditFieldLabel.Text = '\fontsize{10}\fontname{Helvetica}N_{Stw} [rpm]';
            app.N_StwrpmEditField = uieditfield(app.GridLayout2, 'numeric');
            app.N_StwrpmEditField.Limits = [0 1500];
            app.N_StwrpmEditField.ValueDisplayFormat = '%.1f';
            app.N_StwrpmEditField.ValueChangedFcn = createCallbackFcn(app, @N_StwrpmEditFieldValueChanged, true);
            app.N_StwrpmEditField.Tooltip = {'Enter a setpoint for the stirrer speed.'};
            app.fontsize10fontnameHelveticaF_nAIRwlmin1Label = uilabel(app.GridLayout2);
            app.fontsize10fontnameHelveticaF_nAIRwlmin1Label.Interpreter = 'tex';
            app.fontsize10fontnameHelveticaF_nAIRwlmin1Label.Text = '\fontsize{10}\fontname{Helvetica}F_{nAIRw} [lmin^{-1}]';
            app.F_nAIRwlminEditField = uieditfield(app.GridLayout2, 'numeric');
            app.F_nAIRwlminEditField.Limits = [0 15];
            app.F_nAIRwlminEditField.ValueDisplayFormat = '%.1f';
            app.F_nAIRwlminEditField.ValueChangedFcn = createCallbackFcn(app, @F_nAIRwlminEditFieldValueChanged, true);
            app.F_nAIRwlminEditField.Tooltip = {'Enter a setpoint for the air aeration rate.'};
            app.fontsize10fontnameHelveticaF_nO2wlminLabel = uilabel(app.GridLayout2);
            app.fontsize10fontnameHelveticaF_nO2wlminLabel.Interpreter = 'tex';
            app.fontsize10fontnameHelveticaF_nO2wlminLabel.Text = '\fontsize{10}\fontname{Helvetica}F_{nO_2w} [lmin^{-1}]';
            app.F_nO2wlminEditField = uieditfield(app.GridLayout2, 'numeric');
            app.F_nO2wlminEditField.Limits = [0 10];
            app.F_nO2wlminEditField.ValueDisplayFormat = '%.1f';
            app.F_nO2wlminEditField.ValueChangedFcn = createCallbackFcn(app, @F_nO2wlminEditFieldValueChanged, true);
            app.F_nO2wlminEditField.Tooltip = {'Enter a setpoint for the O2 aeration rate.'};
            app.ModeLabel_2 = uilabel(app.GridLayout2);
            app.ModeLabel_2.Text = 'Mode:';
            app.ModeDropDown_3 = uidropdown(app.GridLayout2);
            app.ModeDropDown_3.Items = {'Manual', 'pO2-agitation', 'pO2-aeration', 'pO2-gasmix', 'pO2-feed'};
            app.ModeDropDown_3.ItemsData = [0 1 2 3 4];
            app.ModeDropDown_3.ValueChangedFcn = createCallbackFcn(app, @ModeDropDown_3ValueChanged, true);
            app.ModeDropDown_3.Tooltip = {'Turn on/off pO2 control and select between different control modes (off per default).'};
            app.ModeDropDown_3.Value = 0;
            app.fontsize10fontnameHelveticaF_nGwlmin1Label = uilabel(app.GridLayout2);
            app.fontsize10fontnameHelveticaF_nGwlmin1Label.Interpreter = 'tex';
            app.fontsize10fontnameHelveticaF_nGwlmin1Label.Text = '\fontsize{10}\fontname{Helvetica}F_{nGw} [lmin^{-1}]';
            app.F_nGwlminEditField = uieditfield(app.GridLayout2, 'numeric');
            app.F_nGwlminEditField.Limits = [0 25];
            app.F_nGwlminEditField.ValueDisplayFormat = '%.1f';
            app.F_nGwlminEditField.ValueChangedFcn = createCallbackFcn(app, @F_nGwlminEditFieldValueChanged, true);
            app.F_nGwlminEditField.Tooltip = {'Enter a setpoint for the overall aeration rate.'};
            app.LiquidWeightPanel = uipanel(app.ControlOptionsGridLayout);
            app.LiquidWeightPanel.Title = 'Liquid Weight';
            app.LiquidWeightGridLayout = uigridlayout(app.LiquidWeightPanel);
            app.LWParametersButton = uibutton(app.LiquidWeightGridLayout, 'push');
            app.LWParametersButton.ButtonPushedFcn = createCallbackFcn(app, @LWParametersButtonPushed, true);
            app.LWParametersButton.Tooltip = {'Adjust the gains of the liquid weight controller.'};
            app.LWParametersButton.Text = 'Parameters';
            app.LiquidWeightLamp = uilamp(app.LiquidWeightGridLayout);
            app.HarvestSwitchLabel = uilabel(app.LiquidWeightGridLayout);
            app.HarvestSwitchLabel.Text = 'Harvest';
            app.HarvestSwitch = uiswitch(app.LiquidWeightGridLayout, 'rocker');
            app.HarvestSwitch.ItemsData = [0 1];
            app.HarvestSwitch.ValueChangedFcn = createCallbackFcn(app, @HarvestSwitchValueChanged, true);
            app.HarvestSwitch.Tooltip = {'Turn on harvest at maximum harvest rate.'};
            app.HarvestSwitch.Value = 0;
            app.fontsize10fontnameHelveticam_L_wkgEditFieldLabel = uilabel(app.LiquidWeightGridLayout);
            app.fontsize10fontnameHelveticam_L_wkgEditFieldLabel.Interpreter = 'tex';
            app.fontsize10fontnameHelveticam_L_wkgEditFieldLabel.Text = '\fontsize{10}\fontname{Helvetica}m_L_{w} [kg]';
            app.m_L_wkgEditField = uieditfield(app.LiquidWeightGridLayout, 'numeric');
            app.m_L_wkgEditField.ValueDisplayFormat = '%4.2f';
            app.m_L_wkgEditField.ValueChangedFcn = createCallbackFcn(app, @m_L_wkgEditFieldValueChanged, true);
            app.m_L_wkgEditField.Tooltip = {'Enter a setpoint for the liquid weight value.'};
            app.fontsize10fontnameHelveticam_LkgEditFieldLabel = uilabel(app.LiquidWeightGridLayout);
            app.fontsize10fontnameHelveticam_LkgEditFieldLabel.Interpreter = 'tex';
            app.fontsize10fontnameHelveticam_LkgEditFieldLabel.Text = '\fontsize{10}\fontname{Helvetica}m_{L} [kg]';
            app.m_LkgEditField = uieditfield(app.LiquidWeightGridLayout, 'numeric');
            app.m_LkgEditField.ValueDisplayFormat = '%4.2f';
            app.m_LkgEditField.Editable = 'off';
            app.m_LkgEditField.Tooltip = {'Current liquid volume mass'};
            app.ModeLabel = uilabel(app.LiquidWeightGridLayout);
            app.ModeLabel.Text = 'Mode:';
            app.ModeDropDown_4 = uidropdown(app.LiquidWeightGridLayout);
            app.ModeDropDown_4.Items = {'Manual', 'Harvest'};
            app.ModeDropDown_4.ItemsData = [0 1];
            app.ModeDropDown_4.ValueChangedFcn = createCallbackFcn(app, @ModeDropDown_4ValueChanged, true);
            app.ModeDropDown_4.Tooltip = {'Turn on/off liquid weight control (off per default).'};
            app.ModeDropDown_4.Value = 0;
            app.fontsize10fontnameHelveticaF_HwEditFieldLabel = uilabel(app.LiquidWeightGridLayout);
            app.fontsize10fontnameHelveticaF_HwEditFieldLabel.Interpreter = 'tex';
            app.fontsize10fontnameHelveticaF_HwEditFieldLabel.Text = '\fontsize{10}\fontname{Helvetica}F_{Hw} [%]';
            app.F_HwEditField = uieditfield(app.LiquidWeightGridLayout, 'numeric');
            app.F_HwEditField.Limits = [0 100];
            app.F_HwEditField.ValueDisplayFormat = '%.1f';
            app.F_HwEditField.ValueChangedFcn = createCallbackFcn(app, @F_HwEditFieldValueChanged, true);
            app.F_HwEditField.Tooltip = {'Enter a setpoint for the harvest rate.'};
            app.LevelControlPanel = uipanel(app.ControlOptionsGridLayout);
            app.LevelControlPanel.Title = 'Level-Control';
            app.GridLayout5 = uigridlayout(app.LevelControlPanel);
            app.AntifoamadditionSwitch = uiswitch(app.GridLayout5, 'rocker');
            app.AntifoamadditionSwitch.ItemsData = [0 1];
            app.AntifoamadditionSwitch.ValueChangedFcn = createCallbackFcn(app, @AntifoamadditionSwitchValueChanged, true);
            app.AntifoamadditionSwitch.Tooltip = {'Turn on/off antifoam control (on per default).'};
            app.AntifoamadditionSwitch.Value = 1;
            app.AntifoamLamp = uilamp(app.GridLayout5);
            app.FeedControlPanel = uipanel(app.ControlOptionsGridLayout);
            app.FeedControlPanel.Title = 'Feed Control';
            app.GridLayout6 = uigridlayout(app.FeedControlPanel);
            app.FeedingParametersButton = uibutton(app.GridLayout6, 'push');
            app.FeedingParametersButton.ButtonPushedFcn = createCallbackFcn(app, @FeedingParametersButtonPushed, true);
            app.FeedingParametersButton.Text = 'Parameters';
            app.ReservoirDropDownLabel = uilabel(app.GridLayout6);
            app.ReservoirDropDownLabel.Text = 'Reservoir:';
            app.ReservoirDropDown = uidropdown(app.GridLayout6);
            app.ReservoirDropDown.Items = {'R1', 'R2', 'R3'};
            app.ReservoirDropDown.ItemsData = [1 2 3];
            app.ReservoirDropDown.ValueChangedFcn = createCallbackFcn(app, @ReservoirDropDownValueChanged, true);
            app.ReservoirDropDown.Value = 1;
            app.FeedControlLamp = uilamp(app.GridLayout6);
            app.FeedControlSwitch = uiswitch(app.GridLayout6, 'rocker');
            app.FeedControlSwitch.ItemsData = [0 1];
            app.FeedControlSwitch.ValueChangedFcn = createCallbackFcn(app, @FeedControlSwitchValueChanged, true);
            app.FeedControlSwitch.Value = 0;
            app.Feeding_ModeDropDownLabel = uilabel(app.GridLayout6);
            app.Feeding_ModeDropDownLabel.Text = 'Mode:';
            app.Feeding_ModeDropDown = uidropdown(app.GridLayout6);
            app.Feeding_ModeDropDown.Items = {'Manual', 'Feed Control'};
            app.Feeding_ModeDropDown.ItemsData = [0 1];
            app.Feeding_ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @Feeding_ModeDropDownValueChanged, true);
            app.Feeding_ModeDropDown.Value = 0;
            app.F_R1maxlh1EditFieldLabel = uilabel(app.GridLayout6);
            app.F_R1maxlh1EditFieldLabel.Interpreter = 'tex';
            app.F_R1maxlh1EditFieldLabel.Text = '\fontsize{10}\fontname{Helvetica}F_{R1max} [lh^{-1}]';
            app.F_R1maxlh1EditField = uieditfield(app.GridLayout6, 'numeric');
            app.F_R1maxlh1EditField.Editable = 'off';
            app.c_S1Lwgl1EditFieldLabel = uilabel(app.GridLayout6);
            app.c_S1Lwgl1EditFieldLabel.Interpreter = 'tex';
            app.c_S1Lwgl1EditFieldLabel.Text = '\fontsize{10}\fontname{Helvetica}c_{S1Lw} [gl^{-1}]';
            app.c_S1Lwgl1EditField = uieditfield(app.GridLayout6, 'numeric');
            app.c_S1Lwgl1EditField.Limits = [0 1000];
            app.c_S1Lwgl1EditField.ValueDisplayFormat = '%1.4g';
            app.c_S1Lwgl1EditField.ValueChangedFcn = createCallbackFcn(app, @c_S1Lwgl1EditFieldValueChanged, true);
            app.F_RwlhEditFieldLabel = uilabel(app.GridLayout6);
            app.F_RwlhEditFieldLabel.Interpreter = 'tex';
            app.F_RwlhEditFieldLabel.Text = '\fontsize{10}\fontname{Helvetica}F_{R1w} [lh^{-1}]';
            app.F_RwlhEditField = uieditfield(app.GridLayout6, 'numeric');
            app.F_RwlhEditField.Limits = [0 0.5];
            app.F_RwlhEditField.ValueDisplayFormat = '%.4f';
            app.F_RwlhEditField.ValueChangedFcn = createCallbackFcn(app, @F_RwlhEditFieldValueChanged, true);
            app.F_RwlhEditField.Tooltip = {'Enter a setpoint for the feed rate.'};
            app.OpenLoopFeedControlActiveLabel = uilabel(app.GridLayout6);
            app.OpenLoopFeedControlActiveLabel.Text = 'Open Loop Feed Control Active';
            app.VariablePoolTab = uitab(app.TabGroup);
            app.VariablePoolTab.Title = 'Variable Pool';
            app.TrendUITable = uitable(app.VariablePoolTab);
            app.TrendUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.TrendUITable.RowName = {};
            app.TrendUITable.Tooltip = {'Trend table populated by selecting variables from the variable list to the left.'};
            app.TrendTree = uitree(app.VariablePoolTab, 'checkbox');
            app.TrendTree.Tooltip = {'Select variables to generate a trend table entry for.'};
            app.DisplayableVariablesNode = uitreenode(app.TrendTree);
            app.DisplayableVariablesNode.Text = 'Displayable Variables';
            app.LiquidVolumePanel = uipanel(app.VariablePoolTab);
            app.LiquidVolumePanel.Title = 'Liquid Volume';
            app.VLlEditFieldLabel = uilabel(app.LiquidVolumePanel);
            app.VLlEditFieldLabel.Text = 'VL [l]';
            app.VLlEditField = uieditfield(app.LiquidVolumePanel, 'numeric');
            app.VLlEditField.ValueDisplayFormat = '%.3f';
            app.VLlEditField.Editable = 'off';
            app.addedbaselEditFieldLabel = uilabel(app.LiquidVolumePanel);
            app.addedbaselEditFieldLabel.Text = 'added base [l]';
            app.addedbaselEditField = uieditfield(app.LiquidVolumePanel, 'numeric');
            app.addedbaselEditField.ValueDisplayFormat = '%.3f';
            app.addedbaselEditField.Editable = 'off';
            app.addedacidlEditFieldLabel = uilabel(app.LiquidVolumePanel);
            app.addedacidlEditFieldLabel.Text = 'added acid [l]';
            app.addedacidlEditField = uieditfield(app.LiquidVolumePanel, 'numeric');
            app.addedacidlEditField.ValueDisplayFormat = '%.3f';
            app.addedacidlEditField.Editable = 'off';
            app.addedAFlEditFieldLabel = uilabel(app.LiquidVolumePanel);
            app.addedAFlEditFieldLabel.Text = 'added AF [l]';
            app.addedAFlEditField = uieditfield(app.LiquidVolumePanel, 'numeric');
            app.addedAFlEditField.ValueDisplayFormat = '%.3f';
            app.addedAFlEditField.Editable = 'off';
            app.addedfeedR1lEditFieldLabel = uilabel(app.LiquidVolumePanel);
            app.addedfeedR1lEditFieldLabel.Text = 'added feed R1 [l]';
            app.addedfeedR1lEditField = uieditfield(app.LiquidVolumePanel, 'numeric');
            app.addedfeedR1lEditField.ValueDisplayFormat = '%.3f';
            app.addedfeedR1lEditField.Editable = 'off';
            app.addedfeedR2lEditFieldLabel = uilabel(app.LiquidVolumePanel);
            app.addedfeedR2lEditFieldLabel.Visible = 'off';
            app.addedfeedR2lEditFieldLabel.Text = 'added feed R2 [l]';
            app.addedfeedR2lEditField = uieditfield(app.LiquidVolumePanel, 'numeric');
            app.addedfeedR2lEditField.ValueDisplayFormat = '%.3f';
            app.addedfeedR2lEditField.Editable = 'off';
            app.addedfeedR2lEditField.Visible = 'off';
            app.addedfeedR3lEditFieldLabel = uilabel(app.LiquidVolumePanel);
            app.addedfeedR3lEditFieldLabel.Visible = 'off';
            app.addedfeedR3lEditFieldLabel.Text = 'added feed R3 [l]';
            app.addedfeedR3lEditField = uieditfield(app.LiquidVolumePanel, 'numeric');
            app.addedfeedR3lEditField.ValueDisplayFormat = '%.3f';
            app.addedfeedR3lEditField.Editable = 'off';
            app.addedfeedR3lEditField.Visible = 'off';
            app.PumpRatesPanel = uipanel(app.VariablePoolTab);
            app.PumpRatesPanel.Title = 'Pump Rates';
            app.FT1EditField = uieditfield(app.PumpRatesPanel, 'numeric');
            app.FT1EditField.ValueDisplayFormat = '%.3f';
            app.FT1EditField.Editable = 'off';
            app.fontsize10fontnameHelveticaF_T1acidlh1Label = uilabel(app.PumpRatesPanel);
            app.fontsize10fontnameHelveticaF_T1acidlh1Label.Interpreter = 'tex';
            app.fontsize10fontnameHelveticaF_T1acidlh1Label.Text = '\fontsize{10}\fontname{Helvetica}F_{T1} (acid) [lh^{-1}]';
            app.FT2EditField = uieditfield(app.PumpRatesPanel, 'numeric');
            app.FT2EditField.ValueDisplayFormat = '%.3f';
            app.FT2EditField.Editable = 'off';
            app.fontsize10fontnameHelveticaF_T2baselh1EditFieldLabel = uilabel(app.PumpRatesPanel);
            app.fontsize10fontnameHelveticaF_T2baselh1EditFieldLabel.Interpreter = 'tex';
            app.fontsize10fontnameHelveticaF_T2baselh1EditFieldLabel.Text = '\fontsize{10}\fontname{Helvetica}F_{T2} (base) [lh^{-1}]';
            app.fontsize10fontnameHelveticaF_Hlh1EditFieldLabel = uilabel(app.PumpRatesPanel);
            app.fontsize10fontnameHelveticaF_Hlh1EditFieldLabel.Interpreter = 'tex';
            app.fontsize10fontnameHelveticaF_Hlh1EditFieldLabel.Text = '\fontsize{10}\fontname{Helvetica}F_{H} [lh^{-1}]';
            app.FHEditField = uieditfield(app.PumpRatesPanel, 'numeric');
            app.FHEditField.Limits = [0 100];
            app.FHEditField.ValueDisplayFormat = '%.3f';
            app.FHEditField.Editable = 'off';
            app.fontsize10fontnameHelveticaF_R1lh1EditFieldLabel = uilabel(app.PumpRatesPanel);
            app.fontsize10fontnameHelveticaF_R1lh1EditFieldLabel.Interpreter = 'tex';
            app.fontsize10fontnameHelveticaF_R1lh1EditFieldLabel.Text = '\fontsize{10}\fontname{Helvetica}F_{R1} [lh^{-1}]';
            app.FR1EditField = uieditfield(app.PumpRatesPanel, 'numeric');
            app.FR1EditField.ValueDisplayFormat = '%.3f';
            app.FR1EditField.Editable = 'off';
            app.fontsize10fontnameHelveticaF_R2lh1EditFieldLabel = uilabel(app.PumpRatesPanel);
            app.fontsize10fontnameHelveticaF_R2lh1EditFieldLabel.Visible = 'off';
            app.fontsize10fontnameHelveticaF_R2lh1EditFieldLabel.Interpreter = 'tex';
            app.fontsize10fontnameHelveticaF_R2lh1EditFieldLabel.Text = '\fontsize{10}\fontname{Helvetica}F_{R2} [lh^{-1}]';
            app.FR2EditField = uieditfield(app.PumpRatesPanel, 'numeric');
            app.FR2EditField.ValueDisplayFormat = '%.3f';
            app.FR2EditField.Editable = 'off';
            app.FR2EditField.Visible = 'off';
            app.fontsize10fontnameHelveticaF_R3lh1EditFieldLabel = uilabel(app.PumpRatesPanel);
            app.fontsize10fontnameHelveticaF_R3lh1EditFieldLabel.Visible = 'off';
            app.fontsize10fontnameHelveticaF_R3lh1EditFieldLabel.Interpreter = 'tex';
            app.fontsize10fontnameHelveticaF_R3lh1EditFieldLabel.Text = '\fontsize{10}\fontname{Helvetica}F_{R3} [lh^{-1}]';
            app.FR3EditField = uieditfield(app.PumpRatesPanel, 'numeric');
            app.FR3EditField.ValueDisplayFormat = '%.3f';
            app.FR3EditField.Editable = 'off';
            app.FR3EditField.Visible = 'off';
            app.ProcessManagerTab = uitab(app.TabGroup);
            app.ProcessManagerTab.Title = 'Process Manager';
            app.ProcessManagerGridLayout = uigridlayout(app.ProcessManagerTab);
            app.AddPhaseButton = uibutton(app.ProcessManagerGridLayout, 'push');
            app.AddPhaseButton.ButtonPushedFcn = createCallbackFcn(app, @AddPhaseButtonPushed, true);
            app.AddPhaseButton.Text = 'Add Phase';
            app.LogTab = uitab(app.TabGroup);
            app.LogTab.Title = 'Log';
            app.IncludeparameterupdatesCheckBox = uicheckbox(app.LogTab);
            app.IncludeparameterupdatesCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludeparameterupdatesCheckBoxValueChanged, true);
            app.IncludeparameterupdatesCheckBox.Text = 'Include parameter updates';
            app.IncludeparameterupdatesCheckBox.Value = true;
            app.UILogTable = uitable(app.LogTab);
            app.UILogTable.ColumnName = {'Time stamp'; 'Event Type'; 'Message'};
            app.UILogTable.RowName = {};
            app.LoggerLabel = uilabel(app.LogTab);
            app.LoggerLabel.Text = 'Logger:';
            app.InformationTab = uitab(app.TabGroup);
            app.InformationTab.Title = 'Information';
            app.Label = uilabel(app.InformationTab);
            app.Label.Text = {'=========================================='; ' Title:     Biofermentation Simulation App Version 2.1'; ' Author: Philipp Yuma Iff'; ' Date:    18.07.2025'; '=========================================='; 'This work is licensed under the Creative Commons Attribution 4.0 International License. To view a copy of this license, visit http://creativecommons.org/licenses/by/4.0/. '; ''; 'This application is based on Version 1.3 of the Biofermentation Simulation App by Lena Sophia Kaletsch (01.03.2024) and the BIOSIM program conceived by Prof. Dr.-Ing. R. Luttmann. It was developed for the laboratory of Bioprocess Automation at the University of Applied Sciences Hamburg.'};
            app.Image = uiimage(app.InformationTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'figures', 'HAW_logo.jpg');
            app.Image2 = uiimage(app.InformationTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'figures', 'BPA_logo.jpg');
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ContextMenu.ContextMenuOpeningFcn = createCallbackFcn(app, @ContextMenuOpening, true);
            app.TemplateMenu1 = uimenu(app.ContextMenu);
            app.TemplateMenu1.Text = 'Menu';
            app.TemplateMenu2 = uimenu(app.ContextMenu);
            app.TemplateMenu2.Text = 'Menu2';
            app.TemplateMenu3 = uimenu(app.ContextMenu);
            app.TemplateMenu3.Text = 'Menu3';
            app.TemplateMenu4 = uimenu(app.ContextMenu);
            app.TemplateMenu4.Text = 'Menu4';
            app.TemplateMenu5 = uimenu(app.ContextMenu);
            app.TemplateMenu5.Text = 'Menu5';
            % Assign app.ContextMenu
            app.OpenPlotButton.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ControlApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end