classdef ControlApp_ODE_Luttmann_pHTEMP_new_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ProjectMenu                    matlab.ui.container.Menu
        SaveinMenu                     matlab.ui.container.Menu
        SaveProjectasMenu              matlab.ui.container.Menu
        StartNewProjectMenu            matlab.ui.container.Menu
        OpenProjectMenu                matlab.ui.container.Menu
        Menu3                          matlab.ui.container.Menu
        DataTableMenu                  matlab.ui.container.Menu
        OpenDataTableMenu              matlab.ui.container.Menu
        ExportdataasMenu               matlab.ui.container.Menu
        xlsxMenu                       matlab.ui.container.Menu
        txtMenu                        matlab.ui.container.Menu
        csvMenu                        matlab.ui.container.Menu
        EditMenu                       matlab.ui.container.Menu
        ProjectMenu_2                  matlab.ui.container.Menu
        ModelMenu_2                    matlab.ui.container.Menu
        Menu_2                         matlab.ui.container.Menu
        SettingsMenu                   matlab.ui.container.Menu
        DisconnectMenu                 matlab.ui.container.Menu
        GridLayout                     matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        ControlOptionsTab              matlab.ui.container.Tab
        ControlOptionsGridLayout       matlab.ui.container.GridLayout
        TabGroup2                      matlab.ui.container.TabGroup
        S1Tab                          matlab.ui.container.Tab
        GridLayout6                    matlab.ui.container.GridLayout
        StartFeedCheckBox              matlab.ui.control.CheckBox
        Switch                         matlab.ui.control.RockerSwitch
        c_XL1jglEditField              matlab.ui.control.NumericEditField
        cXL1jLabel                     matlab.ui.control.Label
        V_RinendlEditField             matlab.ui.control.NumericEditField
        VRinendLabel                   matlab.ui.control.Label
        V_RinlEditField                matlab.ui.control.NumericEditField
        VRinlLabel                     matlab.ui.control.Label
        V_L1jlEditField                matlab.ui.control.NumericEditField
        VL1jlLabel                     matlab.ui.control.Label
        FR1jlhEditField                matlab.ui.control.NumericEditField
        FR1jlhLabel                    matlab.ui.control.Label
        Lamp                           matlab.ui.control.Lamp
        t_1jhEditField                 matlab.ui.control.NumericEditField
        t1jhLabel                      matlab.ui.control.Label
        F_R1tlhEditField               matlab.ui.control.NumericEditField
        fontsize10fontnameHelveticaF_R1tlhLabel  matlab.ui.control.Label
        ModeDropDownS1                 matlab.ui.control.DropDown
        ModeDropDown_5Label            matlab.ui.control.Label
        OpenLoopFeedS1Label            matlab.ui.control.Label
        feedParametersButton           matlab.ui.control.Button
        S2Tab                          matlab.ui.container.Tab
        GridLayout6_2                  matlab.ui.container.GridLayout
        c_XL1jglEditField_2            matlab.ui.control.NumericEditField
        cXL1jLabel_2                   matlab.ui.control.Label
        V_RinendlEditField_2           matlab.ui.control.NumericEditField
        VRinendLabel_2                 matlab.ui.control.Label
        V_RinlEditField_2              matlab.ui.control.NumericEditField
        VRinlLabel_2                   matlab.ui.control.Label
        V_L1jlEditField_2              matlab.ui.control.NumericEditField
        VL1jlLabel_2                   matlab.ui.control.Label
        FR1jlhEditField_2              matlab.ui.control.NumericEditField
        FR1jlhLabel_2                  matlab.ui.control.Label
        t_1jhEditField_2               matlab.ui.control.NumericEditField
        t1jhLabel_2                    matlab.ui.control.Label
        F_R1tlhEditField_2             matlab.ui.control.NumericEditField
        fontsize10fontnameHelveticaF_R1tlhLabel_2  matlab.ui.control.Label
        ModeDropDownS1_2               matlab.ui.control.DropDown
        ModeDropDown_5Label_2          matlab.ui.control.Label
        StartFeedCheckBox_2            matlab.ui.control.CheckBox
        Switch_2                       matlab.ui.control.RockerSwitch
        Lamp_2                         matlab.ui.control.Lamp
        OpenLoopFeedS2Label            matlab.ui.control.Label
        feedParametersButton_2         matlab.ui.control.Button
        S3Tab                          matlab.ui.container.Tab
        GridLayout6_3                  matlab.ui.container.GridLayout
        c_XL1jglEditField_3            matlab.ui.control.NumericEditField
        cXL1jLabel_3                   matlab.ui.control.Label
        V_RinendlEditField_3           matlab.ui.control.NumericEditField
        VRinendLabel_3                 matlab.ui.control.Label
        V_RinlEditField_3              matlab.ui.control.NumericEditField
        VRinlLabel_3                   matlab.ui.control.Label
        V_L1jlEditField_3              matlab.ui.control.NumericEditField
        VL1jlLabel_3                   matlab.ui.control.Label
        FR1jlhEditField_3              matlab.ui.control.NumericEditField
        FR1jlhLabel_3                  matlab.ui.control.Label
        t_1jhEditField_3               matlab.ui.control.NumericEditField
        t1jhLabel_3                    matlab.ui.control.Label
        F_R1tlhEditField_3             matlab.ui.control.NumericEditField
        fontsize10fontnameHelveticaF_R1tlhLabel_3  matlab.ui.control.Label
        ModeDropDownS1_3               matlab.ui.control.DropDown
        ModeDropDown_5Label_3          matlab.ui.control.Label
        StartFeedCheckBox_3            matlab.ui.control.CheckBox
        Switch_3                       matlab.ui.control.RockerSwitch
        Lamp_3                         matlab.ui.control.Lamp
        OpenLoopFeedS3Label            matlab.ui.control.Label
        feedParametersButton_3         matlab.ui.control.Button
        LevelControlPanel              matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        AntifoamLamp                   matlab.ui.control.Lamp
        AntifoamadditionSwitch         matlab.ui.control.RockerSwitch
        LiquidWeightPanel              matlab.ui.container.Panel
        LiquidWeightGridLayout         matlab.ui.container.GridLayout
        F_HwEditField                  matlab.ui.control.NumericEditField
        fontsize10fontnameHelveticaF_HwEditFieldLabel  matlab.ui.control.Label
        ModeDropDown_4                 matlab.ui.control.DropDown
        ModeLabel                      matlab.ui.control.Label
        m_LkgEditField                 matlab.ui.control.NumericEditField
        fontsize10fontnameHelveticam_LkgEditFieldLabel  matlab.ui.control.Label
        m_L_wkgEditField               matlab.ui.control.NumericEditField
        fontsize10fontnameHelveticam_L_wkgEditFieldLabel  matlab.ui.control.Label
        HarvestSwitch                  matlab.ui.control.RockerSwitch
        HarvestSwitchLabel             matlab.ui.control.Label
        mLtrendLabel                   matlab.ui.control.Label
        LiquidWeightLamp               matlab.ui.control.Lamp
        LWParametersButton             matlab.ui.control.Button
        pO2ControlPanel                matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        x_OGinwEditField               matlab.ui.control.NumericEditField
        fontsize10fontnameHelveticax_OGinwEditFieldLabel  matlab.ui.control.Label
        F_nGwlminEditField             matlab.ui.control.NumericEditField
        fontsize10fontnameHelveticaF_nGwlminEditFieldLabel  matlab.ui.control.Label
        ModeDropDown_3                 matlab.ui.control.DropDown
        ModeLabel_2                    matlab.ui.control.Label
        F_nAIRwlminEditField_2         matlab.ui.control.NumericEditField
        fontsize10fontnameHelveticaF_nO2wlminLabel  matlab.ui.control.Label
        F_nAIRwlminEditField           matlab.ui.control.NumericEditField
        fontsize10fontnameHelveticaF_nAIRwlminEditFieldLabel  matlab.ui.control.Label
        F_RwlhEditField                matlab.ui.control.NumericEditField
        fontsize10fontnameHelveticaF_RwlhEditFieldLabel  matlab.ui.control.Label
        N_StwrpmEditField              matlab.ui.control.NumericEditField
        fontsize10fontnameHelveticaN_StwrpmEditFieldLabel  matlab.ui.control.Label
        pO_2EditField                  matlab.ui.control.NumericEditField
        fontsize10fontnameHelveticapO_2EditFieldLabel  matlab.ui.control.Label
        pO_2_wEditField                matlab.ui.control.NumericEditField
        fontsize10fontnameHelveticapO_2_wEditFieldLabel  matlab.ui.control.Label
        pO2trendLabel                  matlab.ui.control.Label
        pO2Lamp                        matlab.ui.control.Lamp
        pO2ParametersButton            matlab.ui.control.Button
        TemperatureControlPanel        matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        ModeDropDown_2                 matlab.ui.control.DropDown
        ModeLabel_3                    matlab.ui.control.Label
        theta_LCEditField              matlab.ui.control.NumericEditField
        fontsize10fontnameHelveticavartheta_LCEditField_2Label  matlab.ui.control.Label
        theta_wCEditField              matlab.ui.control.NumericEditField
        fontsize10fontnameHelveticavartheta_wCEditFieldLabel  matlab.ui.control.Label
        HeatingSwitch                  matlab.ui.control.RockerSwitch
        HeatingSwitchLabel             matlab.ui.control.Label
        CoolingSwitch                  matlab.ui.control.RockerSwitch
        CoolingSwitchLabel             matlab.ui.control.Label
        tempParametersButton           matlab.ui.control.Button
        temptrendLabel                 matlab.ui.control.Label
        TemperatureLamp                matlab.ui.control.Lamp
        pHControlPanel                 matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        ModeDropDown                   matlab.ui.control.DropDown
        ModeLabel_4                    matlab.ui.control.Label
        pH_LEditField                  matlab.ui.control.NumericEditField
        fontsize10fontnameHelveticapH_LEditFieldLabel  matlab.ui.control.Label
        fontsize10fontnameHelveticapH_wEditFieldLabel  matlab.ui.control.Label
        pH_wEditField                  matlab.ui.control.NumericEditField
        AcidSwitch                     matlab.ui.control.RockerSwitch
        AcidSwitchLabel                matlab.ui.control.Label
        AlkaliSwitch                   matlab.ui.control.RockerSwitch
        AlkaliSwitchLabel              matlab.ui.control.Label
        pHParametersButton             matlab.ui.control.Button
        pHtrendLabel                   matlab.ui.control.Label
        pHLamp                         matlab.ui.control.Lamp
        VariablePoolTab                matlab.ui.container.Tab
        FHEditField                    matlab.ui.control.NumericEditField
        FHEditFieldLabel               matlab.ui.control.Label
        xOGinEditField                 matlab.ui.control.NumericEditField
        xOGinEditFieldLabel            matlab.ui.control.Label
        FnGlminEditField               matlab.ui.control.NumericEditField
        FnGlminEditFieldLabel          matlab.ui.control.Label
        NStrpmEditField                matlab.ui.control.NumericEditField
        NStrpmEditFieldLabel           matlab.ui.control.Label
        FRlhEditField                  matlab.ui.control.NumericEditField
        FRlhEditFieldLabel             matlab.ui.control.Label
        FT2baselhEditFieldLabel        matlab.ui.control.Label
        FT2baselhEditField             matlab.ui.control.NumericEditField
        FT1acidlhEditFieldLabel        matlab.ui.control.Label
        FT1acidlhEditField             matlab.ui.control.NumericEditField
        LiquidVolumePanel              matlab.ui.container.Panel
        addedfeedlEditField            matlab.ui.control.NumericEditField
        addedfeedlEditFieldLabel       matlab.ui.control.Label
        addedAFlEditField              matlab.ui.control.NumericEditField
        addedAFlEditFieldLabel         matlab.ui.control.Label
        addedacidlEditField            matlab.ui.control.NumericEditField
        addedacidlEditFieldLabel       matlab.ui.control.Label
        addedbaselEditField            matlab.ui.control.NumericEditField
        addedbaselEditFieldLabel       matlab.ui.control.Label
        VLlEditField                   matlab.ui.control.NumericEditField
        VLlEditFieldLabel              matlab.ui.control.Label
        Tree                           matlab.ui.container.CheckBoxTree
        DisplayableVariablesNode       matlab.ui.container.TreeNode
        UITable                        matlab.ui.control.Table
        ProcessManagerTab              matlab.ui.container.Tab
        ProcessManagerGridLayout       matlab.ui.container.GridLayout
        AddPhaseButton                 matlab.ui.control.Button
        AppOperationTab                matlab.ui.container.Tab
        ExpertmodeButton               matlab.ui.control.Button
        DataStorageOptionsLabel        matlab.ui.control.Label
        PreviouslyRecordedDataLabel    matlab.ui.control.Label
        StartingVariableOptionsLabel   matlab.ui.control.Label
        DataStorageatStartCheckBox     matlab.ui.control.CheckBox
        PlotPreviousDataButton         matlab.ui.control.Button
        LoadProcessButton              matlab.ui.control.Button
        SelectedFileEditField          matlab.ui.control.EditField
        SelectedFileEditFieldLabel     matlab.ui.control.Label
        DataStorageStartButton         matlab.ui.control.Button
        SaveStartingVariablesButton    matlab.ui.control.Button
        EditStartingVariablesButton    matlab.ui.control.Button
        ReadInStartingVariablesButton  matlab.ui.control.Button
        LogTab                         matlab.ui.container.Tab
        LoggerTextArea                 matlab.ui.control.TextArea
        LoggerTextAreaLabel            matlab.ui.control.Label
        IncludeparameterupdatesCheckBox  matlab.ui.control.CheckBox
        InformationTab                 matlab.ui.container.Tab
        Label                          matlab.ui.control.Label
        StaticPanel                    matlab.ui.container.Panel
        SpeedfactorxSpinner            matlab.ui.control.Spinner
        SpeedfactorxSpinnerLabel       matlab.ui.control.Label
        ReconnectButton                matlab.ui.control.Button
        InoculateButton                matlab.ui.control.StateButton
        InoculatedLamp                 matlab.ui.control.Lamp
        InoculatedLampLabel            matlab.ui.control.Label
        OpenPlotButton                 matlab.ui.control.Button
        DeltatsEditField               matlab.ui.control.NumericEditField
        fontsize10fontnameHelveticaDeltatsLabel  matlab.ui.control.Label
        StartSimulationButton          matlab.ui.control.Button
        ConnectedLamp                  matlab.ui.control.Lamp
        ConnectedLampLabel             matlab.ui.control.Label
        ProcessRunningLamp             matlab.ui.control.Lamp
        ProcessRunningLampLabel        matlab.ui.control.Label
        ProcessTimehEditField          matlab.ui.control.NumericEditField
        fontsize10fontnameHelveticaProcessTimehEditFieldLabel  matlab.ui.control.Label
        ExitButton                     matlab.ui.control.Button
        DynamicPanel                   matlab.ui.container.Panel
    end

    % This work is licensed under the Creative Commons Attribution 4.0 International License. To view a copy of this license, visit http://creativecommons.org/licenses/by/4.0/ or send a letter to Creative Commons, PO Box 1866, Mountain View, CA 94042, USA.
    properties (Access = public)
        DialogApp  % Starting Variables Dialog box app
        DialogApp2 % pH Controller Parameter Dialog box app
        DialogApp3 % Temperture Controller Parameter Dialog box app
        DialogApp4 % pO2 Controller Parameter Dialog box app
        DialogApp5 % Liquid Weight Controller Parameter Dialog box app
        SimulationApp  % Simulation Window
        CallingApp % Starting screen
        Engine % Simulation engine
        Datatable
        Figureapp
    end
    
    properties (Access = public)
        % Once parameters for calculation of 

        % Cycic parameters
        deltapGw    % Pressure setpoint [bar]
        LWw
        FHrelw % Harvest rate [% maximum pump rate]

        NStw        % Setpoint for agitation speed [1/min]
        FnAIRw      % Aeration rate AIR extern [l/min]
        FnN2w       % Aeration rate N2 extern [l/min]
        FnO2w       % Aeration rate O2 extern [l/min]
        FnCO2w      % Aeration rate CO2 extern [l/min]
        FnGw        % Value of chosen aeration rate [l/min]
        FRw         % Value of chosen constant feed rate [l/h]
        pO2w        % Value of pO2 setpoint [%]
        pHw         % Value of pH Setpoint [-]
        thetaLw     % Value of temperature Setpoint [°C]

        qxpx1w
        qxpx2w
        qxpx3w
        kR1
        kR2
        kR3

        ypH_SET     % Setpoint for difference between measured pH and wanted pH [-]

        KP_pH       % Gain of P part of pH master controller [-]
        KP_pH2a     % Gain of P part of acid pH slave controller [-]
        KP_pH2b     % Gain of P part of base pH slave controller [-]

        KI_temp1    % Gain of I part of temperature master controller [-]
        KP_temp1    % Gain of P part of temperature master controller [-]
        KP_temp2h   % Gain of P part of temperature heating slave controller [-]
        KP_temp2c   % Gain of P part of temperature heating slave controller [-]

        KI_agi      % Gain of I part of agitation controller [-]
        KP_agi      % Gain of P part of agitation controller [-]
        KD_agi      % Gain of D part of agitation controller [-]

        KI_feed     % Gain of I part of feed controller [-]
        KP_feed     % Gain of P part of feed controller [-]
        KD_feed     % Gain of D part of feed controller [-]

        KI_aeration % Gain of I part of aeration controller [-]
        KP_aeration % Gain of P part of aeration controller [-]
        KD_aeration % Gain of D part of aeration controller [-]

        KI_gasmix   % Gain of I part of gasmix controller [-]
        KP_gasmix   % Gain of P part of gasmix controller [-]
        KD_gasmix   % Gain of D part of gasmix controller [-]

        KI_LW       % Gain of I part of liquid weight controller [-]
        KP_LW       % Gain of P part of liquid weight controller [-]
        KD_LW       % Gain of D part of liquid weight controller [-]


        % Flags for different control options (turned off = 0 by default
        % when starting the control app)
        f_motor        = 1; % Signal for stirrer to be turned on/off
        f_aeration     = 1; % Signal for aeration to be turned on/off
        f_air          = 0; % Signal for aeration with AIR
        f_N2           = 0; % Signal for aeration with N2
        f_O2           = 0; % Signal for aeration with O2
        f_CO2          = 0; % Signal for aeration with CO2
        f_feed         = 0; % Signal for feed to be turned on/off
        f_harvest      = 0; % Signal for harvest to be turned on/off
        f_antifoam     = 0; % Signal for antifoam addition
        f_alkali       = 0; % Signal for alkali addition
        f_acid         = 0; % Signal for acid addition
        f_cooling      = 0; % Signal for cooling
        f_heating      = 0; % Signal for heating
        f_pH_mode      = 0; % Signal for automatic or manual pH control
        f_temp_mode    = 0; % Signal for automatic or manual temperature control
        f_pO2_agi      = 0; % Signal for pO2 agitation control
        f_pO2_feed     = 0; % Signal for pO2 feed control
        f_pO2_aeration = 0; % Signal for pO2 aeration control
        f_pO2_gasmix   = 0; % Signal for pO2 gasmix control
        f_LW_harvest   = 0; % Signal for liquid weight harvest control
        f_FR1           = 0;
        f_FR2           = 0;
        f_FR3           = 0;

        f_InocStart    = 0; % Signal for inoculation at start of simulation
        % f_Inoculated

        % Options
        f_mode_pH = 'Auto';
        f_mode_temp = 'Auto';
        f_mode_pO2 = 'Auto';
        f_mode_harvest = 'Manual';
        DataStorageatStart;
        InoculationStorageatStart;

        % Variables
        cXL = 0
        cS1L = 0
        cS2L = 0
        cS3L = 0
        FR = 0
        FH = 0
        CAlLtot = 0
        xOG = 0
        xOL = 0
        qXpX = 0
        cOL = 0
        NSt = 0
        FnG = 0
        FnAIR = 0
        FnO2 = 0
        FnN2 = 0
        FnCO2 = 0
        pHL = 0
        thetaL = 0
        pO2 = 0
        Vacid = 0
        Vbase = 0
        AAF = 0
        Vfeed = 0
        VL = 0
        xOGin = 0
        xCGin = 0
        xCG = 0
        RQ = 0
        QO2max = 0
        QCO2max = 0
        OTR = 0
        OUR = 0
        kLa = 0
        CTR = 0
        FT1 = 0
        FT2 = 0
        CAcLtot = 0
        pG = 0
        CPLtot = 0
        pHLm = 0
        thetaLm = 0
        pO2m = 0
        OURm = 0
        hF = 0
        CCLtot = 0
        CB1Ltot = 0
        CB2Ltot = 0
        pH = 0
        thetaD = 0
        VR10 = 0
        VR20 = 0
        VR30 = 0
        VT10 = 0
        VT20 = 0
        FR1 = 0
        FR2 = 0
        FR3 = 0
        FR1j = 0
        FR2j = 0
        FR3j = 0
        VR1 = 0
        VR2 = 0
        VR3 = 0
        t1j = 0
        t2j = 0
        t3j = 0
        cXL1j = 0
        cXL2j = 0
        cXL3j = 0


        % Time related parameters
        tmax % Maximum process time
        speedfactor % Speed factor
        deltatsec % Time increments of simulation [h]
        f_Inoc = 0 % Becomes 1 post inoculation

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Parameters needed for internal simulation
        dfn       = "Data"; % Data File Name
        pfn       = "Parameter"; % Parameter File Name
        dfnx % Data File Name plus timestamp
        pfnx % Parameter File Name plus timestamp
        filter    = {'*.xlsx';'*.txt'}; % Define loadable files
        erasestr  = {'.xlsx','.xls','.txt'}; % Define file endings that should be erased for display
        
        mds          = 0; % Data Saving Flag
        SelectedFile = "none"; % File selected for variable storage
        FileName     = "none"; % File Name ofloaded initial values
        DataLoaded   = 0; % Flag for data loading event
        
        ExpertMode   = 1; % Flag to set whether or not expert mode is accessible
        Password     = "BPA1" % Password for expert mode
        X            = "none" % Password message box
        SnapShot     = 0; % Flag for process start from snapshot of previous simulation
        PrevPlot     = 0; % Flag for plotting previous data
        t = 0 % process time
        time_past % new time entries since last sync


        % Databank related properties
        dbpath = 'SimulationAppDB.db'
        parametertable  % Table of all parameters including value, unit and description
        prevparametertable % Previous table of all parameters
        variableID
        variables       % All variables available in model
        trendvariables  % Variables to be shown in trend view
        
        Refresher % refresh timer
        refreshrate = 2 % sec
        projectName
        projectID
        projectdescription
        author
        createdon
        recentuse
        organismname
        bioreactorname
        message
        prevlogID % previous logID
        prevVariables % Table for comparison for updated variable values
        prevProcessTime % Table for comparison for updated time values
        

        shorttex % variable tex interpreter
        longtex % longer version of some variables
        units % variable unit list
        texinterpreter = "\fontsize{12}\fontname{Helvetica}";

        processrunning = false % if process is running or not
        logText
        logchanges

        % Process Manager Properties
        numPhases = 0 % Number of phases added
        phaseID = 0 % unique ID of generated phase panel 
        phaseList = {} % List of active phases
        arrowList = {} % List of arrow buttons
        rightArrowFig = fullfile('figures', 'rightarrow.png') % Arrow figure
        checkFig = fullfile('figures', 'checked.png') % Check figure

        % Databank tables for process automation
        processID
        process_status
        process_type
        process_variable
        process_operator
        start_conditiontype
        end_conditiontype
        
        varibleTab
        parameterTab

        PhaseTable

    end
    
    methods (Access = public)

        function ParameterChangedFcn(app, parameter, value)
            % Change value in parameter table
            sqlquery = sprintf(['UPDATE project_parameterTab ' ...
                'SET value = %g ' ...
                'WHERE projectID = %g ' ...
                'AND propertyID IN ' ...
                '(SELECT propertyID ' ...
                'FROM propertyTab ' ...
                'WHERE name = ''%s'')'], value, app.projectID, parameter);
                execpy(app.dbpath, sqlquery);
        end
            
        function UpdateLogFcn(app)
            % Check the number of log entries
            
            sqlquery = sprintf(['SELECT l.logID, l.datetime, l.message ' ...
                'FROM logTab l ' ...
                'JOIN projectTab p ON p.projectID = l.projectID ' ...
                'WHERE l.projectID = %g'], app.projectID);
            table = fetchpy(app.dbpath,sqlquery);
            wasnumber = numel(app.logText);
            isnumber = size(table,1);
            if isnumber > wasnumber
                spec_table = table((wasnumber+1):isnumber,:);
                logID = table2array(spec_table(:,1));
                for i = 1:(isnumber-wasnumber)
                    if table2array(spec_table(i,3)) ~= "Parameter update"
                        % Event log
                        app.logText{end+1} = sprintf('[%s, t = %2.3f h] Event: %s',table2array(spec_table(i,2)), app.t(end), table2array(spec_table(i,3)));
                        % Count number of changes for tab title
                        app.logchanges = app.logchanges + 1;
                        % Write number of changes in tab title
                        app.LogTab.Title = sprintf("Log (%g)",app.logchanges);
                    else
                        % Parameter change log
                        sqlquery = sprintf(['SELECT pp.name, l.old_value, l.new_value, pp.unit ' ...
                            'FROM logTab l ' ...
                            'JOIN propertyTab pp ON pp.propertyID = l.propertyID ' ...
                            'WHERE l.projectID = %g AND l.logID = %g'], ...
                            app.projectID, logID(i));
                        T = fetchpy(app.dbpath, sqlquery);
                        
                        unit = table2array(T(1,4));
                        if ismissing(unit) || isempty(unit) || unit == "-"
                            unit = [];
                        end
                        parameter = table2array(T(1,1));
                        old_value = table2array(T(1,2));
                        new_value = table2array(T(1,3));
                        if old_value == 0 && new_value == 1
                            app.logText{end+1} = sprintf('[%s, t = %2.3f h] %s: %s was switched on',table2array(spec_table(i,2)), app.t(end), table2array(spec_table(i,3)), parameter);
                        elseif old_value == 1 && new_value == 0
                            app.logText{end+1} = sprintf('[%s, t = %2.3f h] %s: %s was switched off',table2array(spec_table(i,2)), app.t(end), table2array(spec_table(i,3)), parameter);
                        else
                            app.logText{end+1} = sprintf('[%s, t = %2.3f h] %s: %s was changed from %g to %g %s',table2array(spec_table(i,2)), app.t(end), table2array(spec_table(i,3)), parameter, old_value, new_value, unit);
                        end
                    end
                end
                app.LoggerTextArea.Value = "";
                text = "";
                for n = 1:numel(app.logText)
                    if app.IncludeparameterupdatesCheckBox.Value == 1
                        if text == ""
                            text = app.logText{n};
                        else
                            text = sprintf('%s\n%s',text,app.logText{n});
                        end
                    elseif ~contains(app.logText(n),"Parameter")
                        if text == ""
                            text = app.logText{n};
                        else
                            text = sprintf('%s\n%s',text,app.logText{n});
                        end
                    end
                end
                app.LoggerTextArea.Value = text;
                
            end
        end
    end
    
    methods (Access = private)
        
        function loadProject(app)
            
            sqlquery = sprintf(['SELECT p.projectID, p.description, p.author, p.created_on, p.recent_use, o.name, b.name ' ...
                'FROM projectTab p ' ...
                'JOIN organismTab o ON o.organismID = p.organismID ' ...
                'JOIN bioreactorTab b ON b.bioreactorID = p.bioreactorID ' ...
                'WHERE p.name = ''%s'''], app.projectName);
            projectinfo = table2array(fetchpy(app.dbpath,sqlquery));
            app.projectID = projectinfo(1);
            app.projectdescription = projectinfo(2);
            app.author = projectinfo(3);
            app.createdon = projectinfo(4);
            app.recentuse = projectinfo(5);
            app.organismname = projectinfo(6);
            app.bioreactorname = projectinfo(7);
        end

        function readInitalParameters(app)
            % Read property values
            sqlquery = sprintf(['SELECT prop.name, ppara.value, ppara.description ' ...
                'FROM project_parameterTab ppara ' ...
                'JOIN projectTab proj ON proj.projectID = ppara.projectID ' ...
                'JOIN propertyTab prop ON ppara.propertyID = prop.propertyID ' ...
                'JOIN categoryTab c ON prop.categoryID = c.categoryID ' ...
                'WHERE proj.name = ''%s'' AND (c.reading_rate = ''cyclic'' OR c.reading_rate = ''invisible'')'], app.projectName);
            table = fetchpy(app.dbpath, sqlquery);
            properties = table2array(table(:,1));
            values = double(table2array(table(:,2)));
            for i = 1:length(properties)
                app.(properties(i)) = values(i);
            end
            
            % Load all variables to be saved
            sqlquery = sprintf(['SELECT vh.variableID, v.name ' ...
                'FROM variableTab v ' ...
                'JOIN variable_handlingTab vh ON vh.variableID = v.variableID ' ...
                'WHERE vh.visible = 1 AND organismID = (SELECT organismID FROM organismTab WHERE name = ''%s'')'], app.organismname);
            T = fetchpy(app.dbpath, sqlquery);
            app.variableID = table2array(T(:,1));
            app.variables = table2array(T(:,2));

            % Check if project starts at 0 or if project was loaded
            selectClause = 'SELECT t.process_time as time, ';
            for j = 1:length(app.variables)
                selectClause = append(selectClause, ...
                    'MAX(CASE WHEN d.variableID = ', num2str(app.variableID(j)), ' THEN d.value END) AS "', app.variables(j),'"');
                if j ~= length(app.variables)
                    selectClause = append(selectClause, ', '); % add comma
                end
            end
            fromClause = sprintf(' FROM dataTab d JOIN timeTab t ON d.timeID = t.timeID WHERE t.projectID = %g ', app.projectID);
            groupByClause = 'GROUP BY t.process_time ORDER BY t.process_time LIMIT 10;';
            query = append(selectClause, fromClause, groupByClause);
            T = fetchpy(app.dbpath, query);
            if ~isempty(T)
                app.t = double(table2array(T));
                % Assign variable columns
                vars = T.Properties.VariableNames;
                for j = 2:length(vars)
                    app.(vars{j}) = table2array(T(:,j));
                end
                % Change start button to run
                app.StartSimulationButton.Text = "Run";
                % Disable inoculation checkbox if already inoculated
                if app.f_Inoc == 1
                    app.InoculateButton.Value = 0;
                    app.InoculateButton.Enable = "off";
                end
            end
        end

        function cyclicDownload(app)
            try
                % Determine the time column and read variable information from databank
                selectClause = 'SELECT t.process_time as time, ';
                for i = 1:length(app.variables)
                    selectClause = append(selectClause, ...
                        'MAX(CASE WHEN d.variableID = ', num2str(app.variableID(i)), ' THEN d.value END) AS "', app.variables(i),'"');
                    if i ~= length(app.variables)
                        selectClause = append(selectClause, ', '); % add comma
                    end
                end
                fromClause = sprintf(' FROM dataTab d JOIN timeTab t ON d.timeID = t.timeID WHERE t.projectID = %g ', app.projectID);
                groupByClause = 'GROUP BY t.process_time ORDER BY t.process_time DESC LIMIT 10;';
                query = append(selectClause, fromClause, groupByClause);
                T = flipud(fetchpy(app.dbpath, query)); % Flip, because order was flipped before
                if ~isempty(T)
                    % Assign time column
                    app.t = table2array(T(:,1));
                    % Assign variable columns
                    vars = T.Properties.VariableNames;
                    for i = 2:length(vars)
                        app.(vars{i}) = table2array(T(:,i));
                    end
                end 
            
            catch ME
                % If an error occurs, display an error message and close the connection if it is open
                if exist('conn', 'var')
                    
                end
                disp('An error occurred:');
                disp(ME.message);
            end

            % Display setpoints, which may have changed by a third app
            app.N_StwrpmEditField.Value    = app.NStw;
            app.F_RwlhEditField.Value      = app.FRw;
            app.F_HwEditField.Value        = app.FHrelw;
            app.F_nAIRwlminEditField.Value = app.FnAIRw;
            app.F_nAIRwlminEditField_2.Value  = app.FnO2w;
            app.theta_wCEditField.Value   = app.thetaLw;
            app.pH_wEditField.Value        = app.pHw;
            app.pO_2_wEditField.Value       = app.pO2w;
            app.NStrpmEditField.Value     = app.NStw;
            app.m_L_wkgEditField.Value      = app.LWw;
            app.F_nGwlminEditField.Value   = app.FnGw;
            app.DeltatsEditField.Value    = app.deltatsec;
            if any(app.f_InocStart == 1) && any(app.t == 0)
                %app.InoculationatStartCheckBox.Value = true;
            elseif app.f_InocStart == 1
                app.f_Inoc = 1;
                app.InoculateButton.Enable = "off";
            else
               
            end
        end

        function displayVariables(app)
            %% Control Display
            % Time
            app.ProcessTimehEditField.Value = app.t(end);
            % Delta t in sec
            app.DeltatsEditField.Value = app.deltatsec;
            % Speed
            app.SpeedfactorxSpinner.Value = app.speedfactor;      
            % pH
            app.pH_LEditField.Value = app.pHL(end);
            % Temperature
            app.theta_LCEditField.Value = app.thetaL(end);
            % pO2
            app.pO_2EditField.Value = app.pO2(end);
            % Liquid Weigth
            app.m_LkgEditField.Value = app.VL(end); % With rho 1 kg/l

            %% Variable pool
            app.VLlEditField.Value = app.VL(end);
            app.addedbaselEditField.Value = app.Vbase(end);
            app.addedacidlEditField.Value = app.Vacid(end);
            app.addedAFlEditField.Value = app.AAF(end);
            app.addedfeedlEditField.Value = app.Vfeed(end);

            app.FnGlminEditField.Value = app.FnG(end);
            app.xOGinEditField.Value = app.xOGin(end);
            app.NStrpmEditField.Value = app.NSt(end);

            app.FT1acidlhEditField.Value = app.FT1(end);
            app.FT2baselhEditField.Value = app.FT2(end);
        end

        function RefreshFcn(app,varargin)
            try
                % Read parameters
                cyclicDownload(app)

                % Display cyclic parameters
                displayVariables(app)

                % Log
                UpdateLogFcn(app)

                % Check for changes in process manager
                checkProcessStatus(app)
           
            catch ME
                %RunProcessFcn(app.CallingApp) % Stop process
                stop(app.Refresher)
                app.ReconnectButton.Visible = 'on';
                app.ConnectedLamp.Color = "red";
                disp(getReport(ME));
            end
        end

        function initialFcn(app)
            % Create timer object
            app.Refresher = timer(...
                'ExecutionMode', 'fixedRate', ...                           % Run timer repeatedly
                'Period', app.refreshrate, ...                                % Period is dependent on delta t
                'BusyMode', 'queue',...                                     % Queue timer callbacks when busy
                'TimerFcn', @app.RefreshFcn);                                 % Specify callback function

            start(app.Refresher)

            app.ConnectedLampLabel.Text = "Connected";
            app.ConnectedLamp.Color = "green";
        end


        function trendDisplay(app)
            if app.processrunning
                % Load variables of interest into the Matrix M and create a
                % cell array containing the respective name at the same
                % index
                M      = [(app.SimulationApp.VL)' (app.SimulationApp.cXL)' (app.SimulationApp.cS1L)' (app.SimulationApp.cS2L)' (app.SimulationApp.cS3L)' (app.SimulationApp.thetaL)' (app.SimulationApp.pO2)' (app.SimulationApp.pHL)' (app.SimulationApp.xOG)' (app.SimulationApp.xCG)' (app.SimulationApp.RQ)' (app.SimulationApp.QO2max)' (app.SimulationApp.QCO2max)' (app.SimulationApp.OTR)' (app.SimulationApp.CTR)' (app.SimulationApp.NSt)' (app.SimulationApp.FnG)' (app.SimulationApp.xOGin)' (app.SimulationApp.FR)' (app.SimulationApp.CAlLtot)'];
                tabstr = {'VL [l]' 'cXL [g/l]' 'cS1L [g/l]' 'cS2L [g/l]' 'cS3L [g/l]' 'thetaL [°C]' 'pO2 [%]' 'pHL [-]' 'xOG [-]' 'xCG [-]' 'RQ [-]' 'QO2max [g/(l*h)]' 'QCO2max [g/(l*h)]' 'OTR [g/(l*h)]' 'CTR [g/(l*h)]' 'NSt [rpm]' 'FnG [l/min]' 'xOGin [-]' 'FR [l/h]' 'CAlLtot [mol/l]'};
                sqlquery = sprintf(['SELECT v.name v.unit ' ...
                    'FROM variable_handlingTab vh ' ...
                    'JOIN variableTab v ON v.variableID = vb.varibaleID ' ...
                    'WHERE trend_displayed = 1']);
                T = fetchpy(app.dbpath,sqlquery);

                a    = [8 6 7 1];
                b    = ["pHtrendLabel" "temptrendLabel" "pO2trendLabel" "mLtrendLabel"];

                checkedNodes = app.Tree.CheckedNodes;
                if ~isempty(checkedNodes)
                    % The nodes contain numeric NodeData from 1 to 20,
                    % corresponding to the column index of the same variables in the
                    % previously defined M, which is now loaded
                    data = [checkedNodes.NodeData];
                    n    = 1;

                    % For every variable that can theoretically be selected
                    % (15 in total), cycle through the loop below
                    for i = 1:20
                        if exist("data","var") == 1 && any(data == i)
                            % Interpolate the last 11 entires as a slpine
                            % or if not enough data has been generated yet
                            % interpolate all existing entries of the
                            % variable
                            if length(app.SimulationApp.t) < 12
                                pp = spline(app.SimulationApp.t,M(:,i));
                            else
                                pp = spline(app.SimulationApp.t((end-10):end),M((end-10):end,i));
                            end
                            % Extract the details from the spline, a piece-wise polynomial, by breaking it apart
                            [breaks,coefs,l,k,d] = unmkpp(pp);
                            % Create a new piece-wise polynomial
                            % corresponding to the derivative of the spline
                            pp2 = mkpp(breaks,repmat(k-1:-1:1,d*l,1).*coefs(:,1:k-1),d);
                            val = ppval(pp2,app.SimulationApp.t(end));
                            % If derivative at last entry is not zero, indicate increase or decrease with an arrow
                            if val > 0
                                ind = "⬈";
                            elseif val < 0
                                ind = "⬊";
                            else
                                ind = "➞";
                            end
                            % Convert value to cell type and write the
                            % value and indication with the variable name
                            % to the table
                            help          = M(end,i);
                            help          = num2cell(help);
                            data_tab(1,n) = help;
                            data_tab(2,n) = cellstr(ind);
                            headers(1,n)  = tabstr(i);
                            n = n+1;

                            for c = 1:length(a)
                                prop = b{c};
                                if i == a(c)
                                    app.(prop).Text = cellstr(ind);
                                end
                            end
                        end
                    end
        
                    if exist("headers","var") == 1
                        % If entries were generated, display them in the UI
                        % table
                        app.UITable.Data = ([headers;data_tab])';
                    end
    
                else
                    % Delete data in table if no nodes are selected
                    app.UITable.Data = [];
                end

                for c = 1:length(a)
                    prop = b{c};
                    if ~any(data == a(c))
                        app.(prop).Text = "";
                    end
                end
            end
        end

        function changeFeedMode(app, SubstrateNumber)
            num = num2str(SubstrateNumber);
            % 1: Manual
            % 2: Substrate
            % 3: pO2
            % 4: Off gas
            switch app.(['ModeDropDownS1' num]).Value
                case 1
                    
                case 2

                case 3

                case 4

            end
        end

        function addPhase(app, event)
            app.numPhases = app.numPhases + 1;
            
            if app.numPhases ~= 1
                createArrowButton(app)
            end
            createPhasePanel(app)

        end


        % Callback for deleting a panel and shifting others to the left
        function deletePhase(app, src, phaseToDelete)
            local_panelID = src.UserData;
            if app.numPhases > 1
            colToDelete = phaseToDelete.Layout.Column;
            layout = phaseToDelete.Parent;
            phaseNum = (colToDelete + 1) / 2;
            arrowToDelete = app.arrowList{phaseNum};
            %del = questdlg(sprintf("Are you sure you want to delete Phase %d", phaseNum), "Delete Phase", "Cancel", "Delete", "Delete");
            del = "Delete";
            switch del
                case "Delete"
                    % Delete the panel and update the phase list
                    delete(phaseToDelete);
                    app.phaseList(phaseNum) = [];

                    % Delete the corresponding arrow if it exists and update the arrow list
                    if phaseNum > 1
                        delete(arrowToDelete);
                        app.arrowList(phaseNum) = [];
                    end

                    % Update the number of phases
                    app.numPhases = app.numPhases - 1;

                    % Shift all panels and arrows to the left
                    for i = phaseNum:app.numPhases
                        % Move the panel to the left by 2 columns
                        app.phaseList{i}.Layout.Column = app.phaseList{i}.Layout.Column - 2;

                        % Move the arrow to the left by 2 columns, if it exists
                        if i > 2
                            app.arrowList{i}.Layout.Column = app.arrowList{i}.Layout.Column - 2;
                        end

                        % Update the title of the panel to reflect the new phase number
                        app.phaseList{i}.Title = sprintf('Phase %d', i);
                        app.phaseList{i}.UserData = i;
                    end

                    % Update the position of the AddPhaseButton
                    if app.numPhases > 1
                        % Position the AddPhaseButton in the next available column
                        app.AddPhaseButton.Layout.Column = app.numPhases * 2;
                        layout.ColumnWidth(colToDelete-1) = {100};
                    else
                        % If no phases remain, position the AddPhaseButton in column 1
                        app.AddPhaseButton.Layout.Column = 2;
                        
                    end

                    % Delete row from phasetable
                    %row = find(app.PhaseTable.phaseID == local_panelID);
                    app.PhaseTable(phaseNum, :) = [];
                case "Cancel"
                    return;
            end
            else
                msgbox("At least one active phase is required.", "Phase can not be deleted")
            end
        end

        function createPhasePanel(app)
            app.phaseID = app.phaseID + 1;
            col = app.numPhases * 2 - 1;
            % Create the new panel spanning all 3 rows
            panel = uipanel(app.ProcessManagerGridLayout);
            panel.Layout.Row = [2, 4];
            panel.Layout.Column = col;
            panel.Title = sprintf('Phase %d', app.numPhases);
            panel.TitlePosition = 'centertop';
            panel.FontSize = 14;
            panel.FontWeight = 'bold';
            panel.UserData = app.numPhases;

            % Store the panel in the list
            app.phaseList{app.numPhases} = panel;

            % Create layout on panel
            layout = uigridlayout(panel);
            layout.ColumnWidth = {"1x","1x",25,"1x","1x"};
            layout.RowHeight = {30,30,"1x",25,25,25,"1x",15, 25};
            layout.ColumnSpacing = 10;
            layout.RowSpacing = 10;
            layout.BackgroundColor = [1, 1, 1];
            layout.Padding = [10, 10, 10, 10];
            layout.UserData = app.phaseID;

            % Phase Name: 1
            namelabel = uilabel(layout);
            namelabel.Text = sprintf("Empty Phase %d", app.numPhases);
            namelabel.Layout.Column = [1, 5];
            namelabel.Layout.Row = 1;
            namelabel.FontWeight = 'bold';
            namelabel.FontSize = 16;
            namelabel.HorizontalAlignment = 'center';

            % Create Type Label: 2
            typelabel = uilabel(layout);
            typelabel.Text = "Blank";
            typelabel.Layout.Column = [1, 5];
            typelabel.Layout.Row = [2 3];
            typelabel.HorizontalAlignment = 'center';

            % Create Start Label: 3
            startlabel = uilabel(layout);
            startlabel.Layout.Column = 1;
            startlabel.Layout.Row = 4;
            startlabel.Text = "Start:";
            % Create Start Condition Label: 4
            startconditionlabel = uilabel(layout);
            startconditionlabel.Layout.Column = [2, 5];
            startconditionlabel.Layout.Row = 4;
            startconditionlabel.Text = "-";
            %startconditionlabel.WordWrap = 'on';
            % Create End Label: 5
            endlabel = uilabel(layout);
            endlabel.Layout.Column = 1;
            endlabel.Layout.Row = 5;
            endlabel.Text = "End:";
            % Create End Condition Label: 6
            endconditionlabel = uilabel(layout);
            endconditionlabel.Layout.Column = [2, 5];
            endconditionlabel.Layout.Row = 5;
            endconditionlabel.Text = "-";
            %endconditionlabel.WordWrap = 'on';

            % Create Edit Button: 7
            editbutton = uibutton(layout);
            editbutton.Layout.Column = [2, 4];
            editbutton.Layout.Row = 7;
            editbutton.Text = "Edit";
            editbutton.ButtonPushedFcn = @(src, event) app.EditPhase(src, event);

            % Create Status Label: 8
            % statuslabel = uilabel(layout);
            % statuslabel.Layout.Column = [2, 4];
            % statuslabel.Layout.Row = 8;
            % statuslabel.Text = "Empty";
            % statuslabel.HorizontalAlignment = 'center';
            statuslamp = uilamp(layout);
            statuslamp.Layout.Column = [2, 4];
            statuslamp.Layout.Row = 8;
            %statuslamp.Color = [0.5, 0.5, 0.5]; % grey
            statuslamp.Color = [1, 0.70, 0.1]; % orange
            statuslamp.Tooltip = app.process_status.status(3);

            % Create delete button on panel layout: 9
            deletebutton = uibutton(layout, 'push', 'Text', 'x');
            deletebutton.ButtonPushedFcn = @(btn,event)app.deletePhase(btn, panel);
            deletebutton.Layout.Row = numel(layout.RowHeight);
            deletebutton.Layout.Column = 3;
            deletebutton.UserData = app.phaseID;
            
            % Add the new "Add Phase" button in the next column
            app.AddPhaseButton.Layout.Column = col + 1;
            if app.numPhases == 1
                app.ProcessManagerGridLayout.ColumnWidth(col:col+1) = {210, 100};
            else
                app.ProcessManagerGridLayout.ColumnWidth(col-1:col+1) = {75, 210, 100};
            end

            name = namelabel.Text;
            statusID = 1; % Upcoming
            typeID = 1; % Standard
            % Insert information in phasetable
            if isempty(app.PhaseTable)
                app.PhaseTable = table('Size', [1 16], 'VariableNames', {'processID', 'projectID', 'process_statusID', 'process_typeID', 'name', ...
                    'start_typeID', 'start_variableID', 'start_operatorID', 'start_value', 'start_time' ...
                    'end_typeID', 'end_variableID', 'end_operatorID', 'end_value', 'end_time', 'reservoirID'}, ...
                    'VariableTypes', {'double', 'double','double','double','string', 'double', 'double', 'double', 'double', 'double', 'double', 'double', 'double', 'double', 'double', 'double'});
                app.PhaseTable(1, :) = {app.processID, app.projectID, statusID, typeID, name, NaN, NaN, NaN, NaN, NaN, NaN, NaN, NaN, NaN, NaN, NaN};
            else
                app.processID = app.processID + 1;
                app.PhaseTable(end+1, :) = {app.processID, app.projectID, statusID, typeID, name, NaN, NaN, NaN, NaN, NaN, NaN, NaN, NaN, NaN, NaN, NaN};
                % 'phaseID', 'projectID', 'process_statusID', 'process_typeID', 'name', 'start_typeID', 'start_variableID', 'start_operatorID', 'start_value', 'end_typeID', 'end_variableID', 'end_operatorID', 'end_value', 'reservoirID'
            end

        end

        function EditPhase(app, src, event)
            layout = src.Parent;
            panel = layout.Parent;
            phaseNum = panel.UserData;
            editor = PhasePanelEditor(app, phaseNum);
            waitfor(editor);
            
            updatePhasePanels(app)

            % Upload all changes made
            UploadProcessInformation(app)
        end

        function updatePhasePanels(app)
            table = app.PhaseTable;
            % Insert phase information on all panels
            for i = 1:size(table,1)
                panel = app.phaseList{i};
                layout = panel.Children;
                obj = layout.Children;
                namelabel = obj(1);
                typelabel = obj(2);
                startcondition = obj(4);
                endcondition = obj(6);
                statuslamp = obj(8);

                % Name
                name = table.name(i);
                namelabel.Text = name;
                % Type
                typeID = table.process_typeID(i);
                typelabel.Text = app.process_type.type(typeID);

                start_time = table.start_time(i);

                % Conditions: Start
                switch table.start_typeID(i)
                    case 1
                        % Create a string containing variable condition
                        v_idx = find(table.start_variableID(i) == app.process_variable.variableID);
                        variable = app.process_variable.shorttex(v_idx);
                        o_idx = find(table.start_operatorID(i) == app.process_operator.process_operatorID);
                        operator = app.process_operator.condition(o_idx);
                        value = table.start_value(i);
                        unit = app.process_variable.tex_unit(v_idx);
                        start_condition_text = sprintf('%s %s %1.2f %s', variable, operator, value, unit);
                    case 2
                        % Show condition text on label
                        start_condition_text = sprintf('%s%s',app.start_conditiontype.conditiontype(2), start_time);
                    case 3
                        % Show condition text on label
                        start_condition_text = sprintf('%s%s',app.start_conditiontype.conditiontype(3), start_time);
                    case 4
                        % Show condition text on label
                        start_condition_text = sprintf('%s%s',app.start_conditiontype.conditiontype(4), start_time);
                    otherwise
                        start_condition_text = "-";
                end
                startcondition.Text = append(app.texinterpreter,"\fontsize{9}", start_condition_text);
                startcondition.Interpreter = 'tex';

                end_time = table.end_time(i);

                % Conditions: End
                switch table.end_typeID(i)
                    case 5
                        % Show condition text on label
                        end_condition_text = app.end_conditiontype.conditiontype(1);
                    case 6
                        % Create a string containing variable condition
                        v_idx = find(table.end_variableID(i) == app.process_variable.variableID);
                        variable = app.process_variable.shorttex(v_idx);
                        o_idx = find(table.end_operatorID(i) == app.process_operator.process_operatorID);
                        operator = app.process_operator.condition(o_idx);
                        value = table.end_value(i);
                        unit = app.process_variable.tex_unit(v_idx);
                        end_condition_text = sprintf('%s %s %1.2f %s', variable, operator, value, unit);
                    case 7
                        if table.process_statusID(i) == 3
                            end_condition_text = sprintf("t = %1.2f h (%1.2f h Timer)",end_time, table.end_value(i)); % Fix here later with time left or end process time
                        else
                            end_condition_text = sprintf("Timer (%1.2f h)",table.end_value(i));
                        end
                    otherwise
                        end_condition_text = "-";
                end
                endcondition.Text = append(app.texinterpreter,"\fontsize{9}", end_condition_text);
                endcondition.Interpreter = 'tex';

                % Status
                statusID = table.process_statusID(i);
                colors = {[0.5, 0.5, 0.5], [1, 0.7, 0.1], 'green', [0.25, 0.25, 0.25]};
                if statusID ~= 0
                    statuslamp.Color = colors{statusID};
                    statuslamp.Tooltip = app.process_status.status(statusID);
                end
            end
        end
        
        function createArrowButton(app)
            col = app.numPhases * 2 - 2;
            % Create arrow botton
            arrowButton = uibutton(app.ProcessManagerGridLayout);
            arrowButton.Text = "";
            arrowButton.Tooltip = "Force start next phase";
            arrowButton.Layout.Row = 3;
            arrowButton.Layout.Column = col;
            arrowButton.Icon = app.rightArrowFig;
            arrowButton.IconAlignment = 'center';
            app.arrowList{app.numPhases} = arrowButton;
        end

        function disablePhaseObjects(app, mode)
            % Enable/Disable Panel Buttons
            if ~isempty(app.phaseList)
                for i = 1:numel(app.phaseList)
                    panel = app.phaseList{i};
                    layout = panel.Children;
                    obj = layout.Children;
                    editbutton = obj(7);
                    deletebutton = obj(9);
                    editbutton.Enable = mode;
                    deletebutton.Enable = mode;
                    if mode == "on"
                        panel.Tooltip = "";
                    else
                        panel.Tooltip = "Pause process to edit phase panels";
                    end
                end
            end
            % Enable/Disable Arrow Buttons
            if ~isempty(app.arrowList)
                for j = 1:numel(app.arrowList)
                    arrow = app.arrowList{j};
                    arrow.Enable = mode;
                end
            end
            % Enable/Disable Add Phase Button
            phasebutton = app.ProcessManagerGridLayout.Children(1);
            phasebutton.Enable = mode;
        end
    end

methods (Access = private)
        function UploadProcessInformation(app)
            % This function uploads data, which is written in the process panels
            % Delete previous entry
            sqlquery = sprintf('DELETE FROM processTab WHERE projectID = %s', app.projectID);
            execpy(app.dbpath, sqlquery)

            % Upload new process table
            sqlwritepy(app.dbpath, "processTab", app.PhaseTable)
        end

        function DownloadProcessInformation(app)
            % This function downloads data, which is written in the process panels
            % Download process table
            sqlquery = sprintf('SELECT * FROM processTab WHERE projectID = %g', app.projectID);
            T = fetchpy(app.dbpath, sqlquery);
            % Convert all empty string "" entries to NaN
            imax = size(T,1);
            headers = T.Properties.VariableNames(6:end);
            for h = headers
                header = char(h);
                % Empty strings lead columns to turn to cell arrays
                if iscell(T.(header))
                    % Eliminate cell arrays and keep numeric entries
                    new_column = zeros(imax,1);
                    for i = 1:imax
                        value = T.(header){i};
                        if isnumeric(value)
                            new_column(i) = value;
                        else
                            new_column(i) = NaN;
                        end
                    end
                    T.(header) = new_column;
                elseif ~isnumeric(T.(header)) && any(T.(header) == "")
                    % Turn empty columns to NaN
                    new_column = nan(imax, 1);
                    T.(header) = new_column;
                end
            end
            app.PhaseTable = T;
        end

        function checkProcessStatus(app)
            sqlquery = sprintf('SELECT process_statusID FROM processTab WHERE projectID = %g',app.projectID);
            status = table2array(fetchpy(app.dbpath, sqlquery));
            if ~isempty(app.PhaseTable)
                if isnumeric(status)
                    if ~isequal(status, app.PhaseTable.process_statusID)
                        DownloadProcessInformation(app)
                        updatePhasePanels(app)
                    end
                end
            end
        end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, CallingApp, Engine, projectName)
            app.CallingApp = CallingApp;
            app.Engine = Engine;
            app.projectName = projectName;
            app.dbpath = app.CallingApp.dbpath;
            % Define window name
            app.UIFigure.Name = sprintf("Control App - %s",app.projectName);
            centerWindow(app.UIFigure)

            % Assign callback to add phase button
            app.AddPhaseButton.ButtonPushedFcn = @(btn,event)app.addPhase(event);
            
            % Functions for parameter and variable definition
            loadProject(app);
            readInitalParameters(app);
            initialFcn(app);
            
            %% Commented for development
            % app.ExpertMode = 0;
            % app.pHParametersButton.Visible   = "off";
            % app.tempParametersButton.Visible = "off";
            % app.pO2ParametersButton.Visible  = "off";
            % app.LWParametersButton.Visible   = "off";

            % % Disable non-needed switches and edit fields upon startup
            % app.CoolingSwitch.Enable = "Off";
            % app.HeatingSwitch.Enable = "Off";
            % 
             % app.AcidSwitch.Enable   = "Off";
            % app.AlkaliSwitch.Enable = "Off";
            % 
            % app.F_nGwlminEditField.Visible      = "off";
            % app.fontsize10fontnameHelveticaF_nGwlminEditFieldLabel.Visible = "off";
            % 
            % app.x_OGinwEditField.Visible      = "off";
            % app.fontsize10fontnameHelveticax_OGinwEditFieldLabel.Visible = "off";

            % Make trend labels invisible upon app launch
            app.pHtrendLabel.Text   = "";
            app.temptrendLabel.Text = "";
            app.pO2trendLabel.Text  = "";
            app.mLtrendLabel.Text   = "";

            % Define ItemsData here as double scalars, since defining the
            % ItemsData in the Appdesigner UI results in them becoming
            % character vectors
            app.AntifoamadditionSwitch.ItemsData = [0,1];
            app.AlkaliSwitch.ItemsData   = [0,1];
            app.AcidSwitch.ItemsData     = [0,1];
            app.CoolingSwitch.ItemsData  = [0,1];
            app.HeatingSwitch.ItemsData  = [0,1];

            % Initialize lamp colors for active and unactive controls
            app.AntifoamLamp.Color     = "green";
            app.pHLamp.Color           = "green";
            app.TemperatureLamp.Color  = "green";
            app.LiquidWeightLamp.Color = "red";
            app.pO2Lamp.Color          = "red";

            % % % Modes
            % app.ModeDropDown.Value      = app.Mode_pH;
            % app.ModeDropDownValueChanged(app)
            % 
            % app.ModeDropDown_2.Value    = app.Mode_temp;
            % app.ModeDropDown_2ValueChanged(app)
            % 
            % app.ModeDropDown_3.Value     = app.Mode_pO2;
            % app.ModeDropDown_3ValueChanged(app)
            % 
            % app.ModeDropDown_4.Value = app.Mode_harvest;
            % app.ModeDropDown_4ValueChanged(app)
            % app.DataStorageatStartCheckBox.Value = app.DataStorageatStart;
            % app.DataStorageatStartCheckBoxValueChanged(app)
            % 
            % app.InoculationatStartCheckBox.Value = app.InoculationStorageatStart;
            % app.InoculationatStartCheckBoxValueChanged(app)

            % Display initial values and setpoints upon control app startup
            app.N_StwrpmEditField.Value    = app.NStw;
            app.F_RwlhEditField.Value      = app.FRw;
            app.F_HwEditField.Value        = app.FHrelw;
            app.F_nAIRwlminEditField.Value = app.FnAIRw;
            app.F_nAIRwlminEditField_2.Value  = app.FnO2w;
            app.theta_wCEditField.Value   = app.thetaLw;
            app.pH_wEditField.Value        = app.pHw;
            app.pO_2_wEditField.Value       = app.pO2w;
            app.NStrpmEditField.Value     = app.NStw;
            app.m_L_wkgEditField.Value      = app.LWw;
            app.F_nGwlminEditField.Value   = app.FnGw;
            app.DeltatsEditField.Value    = app.deltatsec;

            % Set up column descriptions upon startup
            app.UITable.ColumnName = {'Variable name','Current Value','Trend'};

            % Download process automation data
            sqlquery = "SELECT * FROM process_statusTab";
            app.process_status = fetchpy(app.dbpath, sqlquery);

            sqlquery = "SELECT * FROM process_typeTab";
            app.process_type = fetchpy(app.dbpath, sqlquery);

            sqlquery = "SELECT pv.process_variableID, pv.variableID, v.name, v.shorttex, v.tex_unit FROM process_variableTab pv JOIN variableTab v ON v.variableID = pv.variableID";
            app.process_variable = fetchpy(app.dbpath, sqlquery);            

            sqlquery = "SELECT * FROM process_operatorTab";
            app.process_operator = fetchpy(app.dbpath, sqlquery);

            sqlquery = "SELECT * FROM process_conditiontypeTab WHERE start_end = 1";
            app.start_conditiontype = fetchpy(app.dbpath, sqlquery);

            sqlquery = "SELECT * FROM process_conditiontypeTab WHERE start_end = 2";
            app.end_conditiontype = fetchpy(app.dbpath, sqlquery);

            % Load process
            sqlquery = sprintf('SELECT * FROM processTab WHERE projectID = %g ', app.projectID);
            processTab = table2array(fetchpy(app.dbpath, sqlquery));
            if ~isempty(processTab)
                app.PhaseTable = processTab;
            else
                sqlquery = sprintf('SELECT processID FROM processTab WHERE %g ORDER BY processID DESC LIMIT 1', app.projectID);
                app.processID = table2array(fetchpy(app.dbpath, sqlquery));
                if isempty(app.processID)
                    app.processID = 1;
                else
                   app.processID = app.processID + 1; 
                end
            end
        end

        % Callback function: ExitButton, UIFigure
        function UIFigureCloseRequest(app, event)
            % % % Create window that asks the user to confirm closing the
            % % % simulation
            % % YN = uiconfirm(app.UIFigure,'Do you want to exit the program?', 'Close request');
            % % if strcmpi(YN,'OK')
            % %     % Check if Figure App or Dialog App are open and delete them if
            % %     % they are
            % %     e = evalin('base','who'); % Get all the variable names present in the workspace
            % % 
            % %     if ismember('app.SimulationApp',e) % Check if FigureApp is one of them
            % %         delete(app.SimulationApp)
            % %     end
            % % 
            % %     if ismember('app.DialogApp',e) % Check if Dialog Box is one of them
            % %         delete(app.DialogApp)
            % %     end
            % % 
            % % 
            % %     delete(app)
            % % end  
            delete(app)
        end

        % Button pushed function: StartSimulationButton
        function StartSimulationButtonPushed(app, event)
            if app.StartSimulationButton.Text == "Start Simulation"
                % Send command to engine
                RunProcessFcn(app.Engine)
                app.StartSimulationButton.Text = "Pause";
                app.ProcessRunningLamp.Color = "green";
                app.processrunning = true;
                disablePhaseObjects(app, 'off')
            elseif app.StartSimulationButton.Text == "Pause"
                RunProcessFcn(app.Engine)
                app.StartSimulationButton.Text = "Run";
                app.ProcessRunningLamp.Color = "red";
                app.processrunning = true;
                disablePhaseObjects(app, 'on')
            else
                RunProcessFcn(app.Engine)
                app.StartSimulationButton.Text = "Pause";
                app.ProcessRunningLamp.Color = "green";
                app.processrunning = false;
                disablePhaseObjects(app, 'off')
            end
        end

        % Button pushed function: ReadInStartingVariablesButton
        function ReadInStartingVariablesButtonPushed(app, event)
            % Read in variables from selected file
            app.SelectedFile = uigetfile(app.filter);
            if app.SelectedFile ~= "none"
                app.DataLoaded   = 1;
                app.FileName     = erase(convertCharsToStrings(app.SelectedFile),app.erasestr);
                strings          = ["cXL0","cS1L0","FRw","NStw","FnGw","VLmax","cS1R","VR0","tmax"];
                ReadData         = readcell(app.SelectedFile);
    
                app.SelectedFileEditField.Value = app.SelectedFile;
    
                % Initialize a stop execution variable that contains zeros with
                % as many entries as we have starting variables
                StopExecution(1:length(strings)) = 0;
                
                % Separate Header from Data
                Header = ReadData(1,:);
                Data   = ReadData(2,:);
                        
                % Convert data type to string and then to numbers
                Data = string(Data);
                Data = str2double(Data);
    
                % Preallocate empty vector with as many zeros as we have
                % starting variables
                A = zeros(1:length(strings));
    
                % Sort Variables by Header into the order cXL0, cS1L0, FRw, NStw, FnGw, VLmax, cS1R, VR0 and tmax and store the values in A
                for i = 1:length(strings)
                    idx = strcmp(Header,strings(i));
                    % Error message if string was not found in file
                    if sum(idx) == 0
                        errordlg(sprintf('Check Spelling of %s',strings(i)),'Variable Name Spelling Error');
                        StopExecution(i) = 1;
                    else
                        A(i) = Data(idx);
    
                        % Test variables for plausibility and give error
                        % message for values that are not a number (NaN)
                        if sum(isnan(A)) == 1
                            errordlg('Variables cannot be a character','Variable Type Error');
                            StopExecution(i) = 1;
                        end
                    end
                end
    
                % If no problem was identified, set the initial values and setpoints to the
                % ones found in the loaded file
                if StopExecution(1) == 0
                    app.cXL0  = A(1);
                end
                if StopExecution(2) == 0
                    app.cS1L0 = A(2);
                end
                if StopExecution(3) == 0
                    app.FRw   = A(3);
                end
                if StopExecution(4) == 0
                    app.NStw  = A(4);
                end
                if StopExecution(5) == 0
                    app.FnGw  = A(5);
                end
                if StopExecution(6) == 0
                    app.VLmax = A(6);
                end
                if StopExecution(7) == 0
                    app.cS1R  = A(7);
                end
                if StopExecution(8) == 0
                    app.VR0   = A(8);
                end
                if StopExecution(9) == 0
                    app.tmax  = A(9);
                end
            end

            app.DialogApp = DialogBox1(app);
        end

        % Button pushed function: SaveStartingVariablesButton
        function SaveStartingVariablesButtonPushed(app, event)
            % Choose a file to store variables in
            app.SelectedFile = uigetfile({'*.xlsx';'*.txt'},'File Selection','Select a File to save your parameters in');
            app.FileName     = erase(convertCharsToStrings(app.SelectedFile),app.erasestr);

            if app.SelectedFile ~= 0
                app.SelectedFileEditField.Value = app.SelectedFile;
    
                % Establish headers and current values
                strings = cellstr(["cXL0","cS1L0","FRw","NStw","FnGw","VLmax","cS1R","VR0","tmax"]);
                values = num2cell([app.cXL0 app.cS1L0 app.FRw app.NStw app.FnGw app.VLmax app.cS1R app.VR0 app.tmax]);
                
                % Read in raw data
                if app.SelectedFile ~= "none"
                    ReadData = readcell(app.SelectedFile);
        
                    % If file is not empty, update values
                    if isempty(ReadData) == 0
                        
                        % Find each strings entry in raw data and change the data to
                        % the updated value
                        for i = 1:length(strings)
                            p = strcmp(ReadData(1,:),strings(i));
                            [x,y] = find(p == 1);
                            ReadData(x+1,y) = values(i);
                        end

                        % Save the updated values
                        writecell(ReadData,app.SelectedFile)
                    else
                        ReadData = [strings; values];
                        writecell(ReadData,app.SelectedFile)
                    end
        

                end
            end
        end

        % Button pushed function: EditStartingVariablesButton
        function EditStartingVariablesButtonPushed(app, event)
            % Disable the Edit Starting Variables button while dialog box is open
            app.EditStartingVariablesButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.DialogApp = DialogBox1(app, app.CallingApp);
        end

        % Button pushed function: DataStorageStartButton
        function DataStorageStartButtonPushed(app, event)
            if app.DataStorageStartButton.Text == "Data Storage Start"
           
               if app.StartSimulationButton.Text == "Stop Simulation"
                   % Set the data saving flag to on
                   app.mds = 1;
                   app.ConnectedLamp.Color = "green";
    
                   % Define file names including the date and time of creation
                   app.dfnx= strrep(datestr(datetime),':','-'); % Add timestamp to name of data file
                   app.pfnx= strrep(datestr(datetime),':','-'); % Add timestamp to name of parameter file
    
                   % Write the chosen parameters to the parameter file
                   writeParameterFile(app.SimulationApp,sprintf("%s",app.Version));
                   writeParameterFile(app.SimulationApp," ");
                   writeParameterFile(app.SimulationApp,"Chosen Parameters");
                   writeParameterFile(app.SimulationApp,sprintf("cXL0          : %3.2f g",app.cXL0));
                   writeParameterFile(app.SimulationApp,sprintf("cS1L0          : %3.2f g",app.cS1L0));
                   writeParameterFile(app.SimulationApp,sprintf("FRw          : %3.2f 1/h",app.FRw));
                   writeParameterFile(app.SimulationApp,sprintf("NStw          : %3.2f g/g",app.NStw));
                   writeParameterFile(app.SimulationApp,sprintf("FnGw          : %3.2f 1/h",app.FnGw));
                   writeParameterFile(app.SimulationApp,sprintf("VLmax          : %3.2f 1/h",app.VLmax));
                   writeParameterFile(app.SimulationApp,sprintf("cS1R          : %3.2f 1/h",app.cS1R));
                   writeParameterFile(app.SimulationApp,sprintf("VR0          : %3.2f 1/h",app.VR0));
                   writeParameterFile(app.SimulationApp,sprintf("tmax          : %3.2f 1/h",app.tmax));
    
                   % Define headers for the file columns
                   writeDataFile(app.SimulationApp,"Time                ;           t [s];        cXL [g/l];        cS1L [g/l];        cS2L [g/l];        cS3L [g/l];        pO2 [%];        thetaL [°C];        pHL [-];        xOG [-];        xCG [-];        VL [l];        NSt [rpm];        FnG [l/min];        FR [l/h];        deltapG [bar];        my [1/h];        CAltot [mol/l]");
    
                   % Change text on push button
                   app.DataStorageStartButton.Text = "Data Storage Stop";
               end
            else
               % If button is pressed again, data saving is stopped and the
               % data flag set to off
               app.DataStorageatStartCheckBox.Value = 0;
               app.mds = 0;
               app.ConnectedLamp.Color = "red";
               app.DataStorageStartButton.Text = "Data Storage Start";
           end
        end

        % Value changed function: N_StwrpmEditField
        function N_StwrpmEditFieldValueChanged(app, event)
            % Change stirrer speed setpoint when edit field value changes
            % and turn on the motor flag if the value is larger than zero
            app.NStw = app.N_StwrpmEditField.Value;
            parameter = "NStw";
            value = app.(parameter);
            ParameterChangedFcn(app, parameter, value)
            if app.NStw > 0
                if app.f_motor == 0
                    app.f_motor = 1;
                end
            else
                if app.f_motor == 1
                    app.f_motor = 0;
                end
            end
            parameter = "f_motor";
            value = app.(parameter);
            ParameterChangedFcn(app, parameter, value)
        end

        % Value changed function: F_nAIRwlminEditField
        function F_nAIRwlminEditFieldValueChanged(app, event)
            % Change air aeration rate setpoint when edit field value changes
            % and turn on the aeration flag if the value is larger than
            % zero
            app.FnAIRw = app.F_nAIRwlminEditField.Value;
            parameter = "FnAIRw";
            value = app.FnAIRw;
            ParameterChangedFcn(app, parameter, value)
            if app.FnAIRw > 0
                app.f_air      = 1;
                app.f_aeration = 1;
            else
                app.f_air = 0;
                if app.f_O2 == 0
                    app.f_aeration = 0;
                else
                    app.f_aeration = 1;
                end
            end
            parameter = "f_aeration";
            value = app.(parameter);
            ParameterChangedFcn(app, parameter, value)
        end

        % Value changed function: F_nAIRwlminEditField_2
        function F_nAIRwlminEditField_2ValueChanged(app, event)
            % Change O2 aeration rate setpoint when edit field value changes
            % and turn on the aeration flag if the value is larger than
            % zero
            app.FnO2w = app.F_nAIRwlminEditField_2.Value;
            parameter = "FnO2w";
            value = app.(parameter);
            ParameterChangedFcn(app, parameter, value)
            if app.FnO2w > 0
                app.f_O2       = 1;
                app.f_aeration = 1;
            else
                app.f_O2 = 0;
                if app.f_air == 0
                    app.f_aeration = 0;
                else
                    app.f_aeration = 1;
                end
            end
            parameter = "f_aeration";
            value = app.(parameter);
            ParameterChangedFcn(app, parameter, value)
        end

        % Value changed function: F_RwlhEditField
        function F_RwlhEditFieldValueChanged(app, event)
            % Change feed rate setpoint when edit field value changes
            % and turn on the feed flag if the value is larger than zero
            app.FRw = app.F_RwlhEditField.Value;
            parameter = "FRw";
            value = app.(parameter);
            ParameterChangedFcn(app, parameter, value)
            if app.FRw > 0
                app.f_feed = 1;
            else
                app.f_feed = 0;
            end
            parameter = "f_feed";
            value = app.(parameter);
            ParameterChangedFcn(app, parameter, value)
        end

        % Value changed function: F_HwEditField
        function F_HwEditFieldValueChanged(app, event)
            % Change harvest rate setpoint when edit field value changes
            % and turn on the harvest flag if the value is larger than zero
            app.FHrelw = app.F_HwEditField.Value;
            parameter = "FHrelw";
            value = app.(parameter);
            ParameterChangedFcn(app, parameter, value)
            if app.FHrelw > 0
                app.f_harvest = 1;
            else
                app.f_harvest = 0;
            end
            parameter = "f_harvest";
            value = app.(parameter);
            ParameterChangedFcn(app, parameter, value)
        end

        % Value changed function: AntifoamadditionSwitch
        function AntifoamadditionSwitchValueChanged(app, event)
            % Set antifoam flag and lamp indication to on if the antifoam
            % control is activated
            app.f_antifoam = app.AntifoamadditionSwitch.Value;
            parameter = "f_antifoam";
            value = app.(parameter);
            ParameterChangedFcn(app, parameter, value)
            if app.f_antifoam == 1
                app.AntifoamLamp.Color = "green";
            else
                app.AntifoamLamp.Color = "red";
            end
        end

        % Value changed function: AlkaliSwitch
        function AlkaliSwitchValueChanged(app, event)
            app.f_alkali = app.AlkaliSwitch.Value; % Set flag for base addition if alkali switch is turned
            parameter = "f_alkali";
            value = app.(parameter);
            ParameterChangedFcn(app, parameter, value)
        end

        % Value changed function: AcidSwitch
        function AcidSwitchValueChanged(app, event)
            app.f_acid = app.AcidSwitch.Value; % Set flag for acid addition if acid switch is turned
            parameter = "f_acid";
            value = app.(parameter);
            ParameterChangedFcn(app, parameter, value)
        end

        % Value changed function: CoolingSwitch
        function CoolingSwitchValueChanged(app, event)
            app.f_cooling = app.CoolingSwitch.Value; % Set flag for cooling if cooling switch is turned
            parameter = "f_cooling";
            value = app.(parameter);
            ParameterChangedFcn(app, parameter, value)
        end

        % Value changed function: HeatingSwitch
        function HeatingSwitchValueChanged(app, event)
            app.f_heating = app.HeatingSwitch.Value; % Set flag for heating if heating switch is turned
            parameter = "f_heating";
            value = app.(parameter);
            ParameterChangedFcn(app, parameter, value)
        end

        % Value changed function: pH_wEditField
        function pH_wEditFieldValueChanged(app, event)
            app.pHw = app.pH_wEditField.Value;
            parameter = "pHw";
            value = app.(parameter);
            ParameterChangedFcn(app, parameter, value)
        end

        % Value changed function: F_nGwlminEditField
        function F_nGwlminEditFieldValueChanged(app, event)
            app.FnGw = app.F_nGwlminEditField.Value; % Change aeration rate setpoint if edit field value changes
            parameter = "FnGw";
            value = app.(parameter);
            ParameterChangedFcn(app, parameter, value)
        end

        % Value changed function: pO_2_wEditField
        function pO_2_wEditFieldValueChanged(app, event)
            app.pO2w = app.pO_2_wEditField.Value; % Change pO2 setpoint if the edit field value is changed
            parameter = "pO2w";
            value = app.(parameter);
            ParameterChangedFcn(app, parameter, value)
        end

        % Value changed function: theta_wCEditField
        function theta_wCEditFieldValueChanged(app, event)
            app.thetaLw = app.theta_wCEditField.Value; % Change temperature setpoint if the edit field value is changed
            parameter = "thetaLw";
            value = app.(parameter);
            ParameterChangedFcn(app, parameter, value)
        end

        % Value changed function: m_L_wkgEditField
        function m_L_wkgEditFieldValueChanged(app, event)
            app.LWw = app.m_L_wkgEditField.Value; % Change liquid weight setpoint if the edit field value is changed
            parameter = "LWw";
            value = app.(parameter);
            ParameterChangedFcn(app, parameter, value)
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            if app.StartSimulationButton.Text == "Stop Simulation"
                % Load variables of interest into the Matrix M and create a
                % cell array containing the respective name at the same
                % index
                M      = [(app.SimulationApp.VL)' (app.SimulationApp.cXL)' (app.SimulationApp.cS1L)' (app.SimulationApp.cS2L)' (app.SimulationApp.cS3L)' (app.SimulationApp.thetaL)' (app.SimulationApp.pO2)' (app.SimulationApp.pHL)' (app.SimulationApp.xOG)' (app.SimulationApp.xCG)' (app.SimulationApp.RQ)' (app.SimulationApp.QO2max)' (app.SimulationApp.QCO2max)' (app.SimulationApp.OTR)' (app.SimulationApp.CTR)' (app.SimulationApp.NSt)' (app.SimulationApp.FnG)' (app.SimulationApp.xOGin)' (app.SimulationApp.FR)' (app.SimulationApp.CAlLtot)'];
                tabstr = {'VL [l]' 'cXL [g/l]' 'cS1L [g/l]' 'cS2L [g/l]' 'cS3L [g/l]' 'thetaL [°C]' 'pO2 [%]' 'pHL [-]' 'xOG [-]' 'xCG [-]' 'RQ [-]' 'QO2max [g/(l*h)]' 'QCO2max [g/(l*h)]' 'OTR [g/(l*h)]' 'CTR [g/(l*h)]' 'NSt [rpm]' 'FnG [l/min]' 'xOGin [-]' 'FR [l/h]' 'CAlLtot [mol/l]'};

                a    = [8 6 7 1];
                b    = ["pHtrendLabel" "temptrendLabel" "pO2trendLabel" "mLtrendLabel"];

                checkedNodes = app.Tree.CheckedNodes;
                if ~isempty(checkedNodes)
                    % The nodes contain numeric NodeData from 1 to 20,
                    % corresponding to the column index of the same variables in the
                    % previously defined M, which is now loaded
                    data = [checkedNodes.NodeData];
                    n    = 1;

                    % For every variable that can theoretically be selected
                    % (15 in total), cycle through the loop below
                    for i = 1:20
                        if exist("data","var") == 1 && any(data == i)
                            % Interpolate the last 11 entires as a slpine
                            % or if not enough data has been generated yet
                            % interpolate all existing entries of the
                            % variable
                            if length(app.SimulationApp.t) < 12
                                pp = spline(app.SimulationApp.t,M(:,i));
                            else
                                pp = spline(app.SimulationApp.t((end-10):end),M((end-10):end,i));
                            end
                            % Extract the details from the spline, a piece-wise polynomial, by breaking it apart
                            [breaks,coefs,l,k,d] = unmkpp(pp);
                            % Create a new piece-wise polynomial
                            % corresponding to the derivative of the spline
                            pp2 = mkpp(breaks,repmat(k-1:-1:1,d*l,1).*coefs(:,1:k-1),d);
                            val = ppval(pp2,app.SimulationApp.t(end));
                            % If derivative at last entry is not zero, indicate increase or decrease with an arrow
                            if val > 0
                                ind = "⬈";
                            elseif val < 0
                                ind = "⬊";
                            else
                                ind = "➞";
                            end
                            % Convert value to cell type and write the
                            % value and indication with the variable name
                            % to the table
                            help          = M(end,i);
                            help          = num2cell(help);
                            data_tab(1,n) = help;
                            data_tab(2,n) = cellstr(ind);
                            headers(1,n)  = tabstr(i);
                            n = n+1;

                            for c = 1:length(a)
                                prop = b{c};
                                if i == a(c)
                                    app.(prop).Text = cellstr(ind);
                                end
                            end
                        end
                    end
        
                    if exist("headers","var") == 1
                        % If entries were generated, display them in the UI
                        % table
                        app.UITable.Data = ([headers;data_tab])';
                    end
    
                else
                    % Delete data in table if no nodes are selected
                    app.UITable.Data = [];
                end

                for c = 1:length(a)
                    prop = b{c};
                    if ~any(data == a(c))
                        app.(prop).Text = "";
                    end
                end
            end
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            % Select pH control mode and enable/disable manual controls
            % accordingly
            pHmode = app.ModeDropDown.Value;
            if pHmode == 0
                app.f_pH_mode             = 0;
                app.AcidSwitch.Enable   = "On";
                app.AlkaliSwitch.Enable = "On";
                app.pHLamp.Color        = "red";
            else
                app.f_pH_mode             = 1;
                app.AcidSwitch.Enable   = "Off";
                app.AlkaliSwitch.Enable = "Off";
                app.pHLamp.Color        = "green";
            end
            parameter = "f_pH_mode";
            value = app.(parameter);
            ParameterChangedFcn(app, parameter, value)
        end

        % Value changed function: ModeDropDown_2
        function ModeDropDown_2ValueChanged(app, event)
            % Select temperature control mode and enable/disable manual controls
            % accordingly
            tempmode = app.ModeDropDown_2.Value;
            if tempmode == 0
                app.f_temp_mode             = 0;
                app.HeatingSwitch.Enable  = "On";
                app.CoolingSwitch.Enable  = "On";
                app.TemperatureLamp.Color = "red";
            else
                app.f_temp_mode             = 1;
                app.HeatingSwitch.Enable  = "Off";
                app.CoolingSwitch.Enable  = "Off";
                app.TemperatureLamp.Color = "green";
            end
            parameter = "f_temp_mode";
            value = app.(parameter);
            ParameterChangedFcn(app, parameter, value)
        end

        % Value changed function: ModeDropDown_3
        function ModeDropDown_3ValueChanged(app, event)
            % Select pO2 control mode, set flags and enable/disable manual controls
            % accordingly
            pO2mode = app.ModeDropDown_3.Value;
            aerationflags = ["f_pO2_agi" "f_pO2_feed" "f_pO2_aeration" "f_pO2_gasmix"];
            % upload changes in a loop
            for i = 1:length(aerationflags)
                parameter = aerationflags(i);
                value = app.(parameter);
                if value == 1
                    value = 0;
                    ParameterChangedFcn(app, parameter, value)
                end
            end

            switch pO2mode
                case 0 % "Manual"
                app.f_pO2_agi                            = 0;
                app.f_pO2_feed                           = 0;
                app.f_pO2_aeration                       = 0;
                app.f_pO2_gasmix                         = 0;
                app.F_RwlhEditField.Enable              = "on";
                app.N_StwrpmEditField.Enable            = "on";
                app.F_HwEditField.Enable                = "on";
                app.pO2Lamp.Color                      = "red";
                app.fontsize10fontnameHelveticaN_StwrpmEditFieldLabel.Enable      = "on";
                app.N_StwrpmEditField.Enable           = "on";
                app.fontsize10fontnameHelveticaF_RwlhEditFieldLabel.Enable       = "on";
                app.F_RwlhEditField.Enable             = "on";
                app.F_nGwlminEditField.Enable          = "off";
                app.fontsize10fontnameHelveticaF_nGwlminEditFieldLabel.Enable    = "off";
                app.fontsize10fontnameHelveticaF_nAIRwlminEditFieldLabel.Enable   = "on";
                app.F_nAIRwlminEditField.Enable        = "on";
                app.fontsize10fontnameHelveticaF_nO2wlminLabel.Enable    = "on";
                app.F_nAIRwlminEditField_2.Enable         = "on";
                app.x_OGinwEditField.Enable            = "off";
                app.fontsize10fontnameHelveticax_OGinwEditFieldLabel.Enable    = "off";

                case 1 % "pO2-agitation"
                app.f_pO2_agi                            = 1;
                app.f_pO2_feed                           = 0;
                app.f_pO2_aeration                       = 0;
                app.f_pO2_gasmix                         = 0;
                % upload changes
                parameter = "f_pO2_agi";
                value = app.(parameter);
                ParameterChangedFcn(app, parameter, value)
                app.F_RwlhEditField.Enable              = "on";
                app.N_StwrpmEditField.Enable            = "off";
                app.F_HwEditField.Enable                = "on";
                app.pO2Lamp.Color                      = "green";
                app.fontsize10fontnameHelveticaN_StwrpmEditFieldLabel.Enable     = "off";
                app.N_StwrpmEditField.Enable           = "off";
                app.fontsize10fontnameHelveticaF_RwlhEditFieldLabel.Enable      = "on";
                app.F_RwlhEditField.Enable             = "on";
                app.F_nGwlminEditField.Enable          = "off";
                app.fontsize10fontnameHelveticaF_nGwlminEditFieldLabel.Enable    = "off";
                app.fontsize10fontnameHelveticaF_nAIRwlminEditFieldLabel.Enable  = "on";
                app.F_nAIRwlminEditField.Enable        = "on";
                app.fontsize10fontnameHelveticaF_nO2wlminLabel.Enable    = "on";
                app.F_nAIRwlminEditField_2.Enable         = "on";
                app.x_OGinwEditField.Enable            = "off";
                app.fontsize10fontnameHelveticax_OGinwEditFieldLabel.Enable       = "off";

                case 2 % "pO2-aeration"
                app.f_pO2_agi                            = 0;
                app.f_pO2_feed                           = 0;
                app.f_pO2_aeration                       = 1;
                app.f_pO2_gasmix                         = 0;
                % upload changes
                parameter = "f_pO2_aeration";
                value = app.(parameter);
                ParameterChangedFcn(app, parameter, value)
                app.F_RwlhEditField.Enable              = "on";
                app.N_StwrpmEditField.Enable            = "on";
                app.F_HwEditField.Enable                = "on";
                app.pO2Lamp.Color                      = "green";
                app.fontsize10fontnameHelveticaN_StwrpmEditFieldLabel.Enable      = "on";
                app.N_StwrpmEditField.Enable           = "on";
                app.fontsize10fontnameHelveticaF_RwlhEditFieldLabel.Enable        = "on";
                app.F_RwlhEditField.Enable             = "on";
                app.F_nGwlminEditField.Enable          = "off";
                app.fontsize10fontnameHelveticaF_nGwlminEditFieldLabel.Enable     = "off";
                app.fontsize10fontnameHelveticaF_nAIRwlminEditFieldLabel.Enable   = "off";
                app.F_nAIRwlminEditField.Enable        = "off";
                app.fontsize10fontnameHelveticaF_nO2wlminLabel.Enable    = "off";
                app.F_nAIRwlminEditField_2.Enable         = "off";
                app.x_OGinwEditField.Enable            = "off";
                app.fontsize10fontnameHelveticax_OGinwEditFieldLabel.Enable       = "off";
                
                case 3 % "pO2-gasmix"
                app.f_pO2_agi                            = 0;
                app.f_pO2_feed                           = 0;
                app.f_pO2_aeration                       = 0;
                app.f_pO2_gasmix                         = 1;
                % upload changes
                parameter = "f_pO2_gasmix";
                value = app.(parameter);
                ParameterChangedFcn(app, parameter, value)
                app.F_RwlhEditField.Enable              = "on";
                app.N_StwrpmEditField.Enable            = "on";
                app.F_HwEditField.Enable                = "on";
                app.pO2Lamp.Color                      = "green";
                app.fontsize10fontnameHelveticaN_StwrpmEditFieldLabel.Enable      = "on";
                app.N_StwrpmEditField.Enable           = "on";
                app.fontsize10fontnameHelveticaF_RwlhEditFieldLabel.Enable        = "on";
                app.F_RwlhEditField.Enable             = "on";
                app.F_nGwlminEditField.Enable          = "on";
                app.fontsize10fontnameHelveticaF_nGwlminEditFieldLabel.Enable     = "on";
                app.fontsize10fontnameHelveticaF_nAIRwlminEditFieldLabel.Enable   = "off";
                app.F_nAIRwlminEditField.Enable        = "off";
                app.fontsize10fontnameHelveticaF_nO2wlminLabel.Enable    = "off";
                app.F_nAIRwlminEditField_2.Enable         = "off";
                app.x_OGinwEditField.Enable            = "on";
                app.fontsize10fontnameHelveticax_OGinwEditFieldLabel.Enable       = "on";

                case 4 % "pO2-feed"
                app.f_pO2_agi                            = 0;
                app.f_pO2_feed                           = 1;
                app.f_pO2_aeration                       = 0;
                app.f_pO2_gasmix                         = 0;
                % upload changes
                parameter = "f_pO2_feed";
                value = app.(parameter);
                ParameterChangedFcn(app, parameter, value)
                app.F_RwlhEditField.Enable              = "off";
                app.N_StwrpmEditField.Enable            = "on";
                app.F_HwEditField.Enable                = "on";
                app.pO2Lamp.Color                      = "green";
                app.fontsize10fontnameHelveticaN_StwrpmEditFieldLabel.Enable      = "on";
                app.N_StwrpmEditField.Enable           = "on";
                app.fontsize10fontnameHelveticaF_RwlhEditFieldLabel.Enable        = "off";
                app.F_RwlhEditField.Enable             = "off";
                app.F_nGwlminEditField.Enable          = "off";
                app.fontsize10fontnameHelveticaF_nGwlminEditFieldLabel.Enable     = "off";
                app.fontsize10fontnameHelveticaF_nAIRwlminEditFieldLabel.Enable   = "on";
                app.F_nAIRwlminEditField.Enable        = "on";
                app.fontsize10fontnameHelveticaF_nO2wlminLabel.Enable    = "on";
                app.F_nAIRwlminEditField_2.Enable         = "on";
                app.x_OGinwEditField.Enable            = "off";
                app.fontsize10fontnameHelveticax_OGinwEditFieldLabel.Enable       = "off";
            end
        end

        % Value changed function: ModeDropDown_4
        function ModeDropDown_4ValueChanged(app, event)
            % Select liquid weight control mode and enable/disable manual controls
            % accordingly
            LWmode = app.ModeDropDown_4.Value;
            if LWmode == 1
                app.f_LW_harvest                     = 1;
                app.LiquidWeightLamp.Color         = "green";
                app.F_HwEditField.Enable            = "off";
                app.HarvestSwitch.Enable           = "off";
            else
                app.f_LW_harvest                     = 0;
                app.LiquidWeightLamp.Color         = "red";
                app.F_HwEditField.Enable            = "on";
                app.HarvestSwitch.Enable           = "on";
            end
            parameter = "f_LW_harvest";
            value = app.(parameter);
            ParameterChangedFcn(app, parameter, value)
        end

        % Value changed function: DeltatsEditField
        function DeltatsEditFieldValueChanged(app, event)
            app.deltatsec = app.DeltatsEditField.Value; % Change delta t if the edit field value changes
            parameter = "deltatsec";
            value = app.(parameter);
            ParameterChangedFcn(app, parameter, value)
        end

        % Button pushed function: LoadProcessButton
        function LoadProcessButtonPushed(app, event)
            app.SnapShot = 1;

            % Change button text
            app.StartSimulationButton.Text = "Stop Simulation";

            % Change indication light to green
            app.ProcessRunningLamp.Color = "green";

            % Display starting variables in edit fields
            app.N_StwrpmEditField.Value        = app.NStw;
            app.F_nAIRwlminEditField.Value     = app.FnAIRw;
            app.F_nAIRwlminEditField_2.Value      = app.FnO2w;
            app.F_RwlhEditField.Value          = app.FRw;
            app.F_HwEditField.Value            = app.FHrelw;

            % Open Simulation Window
            app.SimulationApp = FigureApp_ODESystem_Luttmann_pHTEMP(app);

            % Call figure app's public function
            cycle_func(app.SimulationApp);

            % Disable starting value edit button
            app.EditStartingVariablesButton.Enable = "off";
        end

        % Button pushed function: PlotPreviousDataButton
        function PlotPreviousDataButtonPushed(app, event)
            % Open Simulation Window
            app.SimulationApp = FigureApp_ODESystem_Luttmann_pHTEMP(app);

            app.PrevPlot = 1;
            
            prev_plot(app.SimulationApp);
        end

        % Button pushed function: pHParametersButton
        function pHParametersButtonPushed(app, event)
            % Disable the Edit Starting Variables button while dialog box is open
            app.pHParametersButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.DialogApp2 = DialogBox2(app);
            adjustWindow(app.CallingApp, app.DialogApp2.UIFigure)
        end

        % Button pushed function: tempParametersButton
        function tempParametersButtonPushed(app, event)
            % Disable the Edit Starting Variables button while dialog box is open
            app.tempParametersButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.DialogApp3 = DialogBox3(app);
            adjustWindow(app.CallingApp, app.DialogApp3.UIFigure)
        end

        % Button pushed function: pO2ParametersButton
        function pO2ParametersButtonPushed(app, event)
            % Disable the Edit Starting Variables button while dialog box is open
            app.pO2ParametersButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.DialogApp4 = DialogBox4(app);
            adjustWindow(app.CallingApp, app.DialogApp4.UIFigure)
        end

        % Button pushed function: LWParametersButton
        function LWParametersButtonPushed(app, event)
           % Disable the Edit Starting Variables button while dialog box is open
            app.LWParametersButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.DialogApp5 = DialogBox5(app);
            adjustWindow(app.CallingApp, app.DialogApp5.UIFigure)
        end

        % Button pushed function: ExpertmodeButton
        function ExpertmodeButtonPushed(app, event)
            if app.ExpertMode == 0
                app.X = inputdlg('Please enter the password to access expert mode:');
                if app.X == app.Password
                    app.ExpertMode = 1;
                    app.pHParametersButton.Visible   = "on";
                    app.tempParametersButton.Visible = "on";
                    app.pO2ParametersButton.Visible  = "on";
                    app.LWParametersButton.Visible   = "on";
                    uiwait(msgbox('Expert mode is now accessible.'));
                else
                    uiwait(msgbox('Incorrect password. Expert mode is not accessible.'));
                end
            end
        end

        % Menu selected function: OpenDataTableMenu
        function OpenDataTableMenuSelected(app, event)
            app.Datatable = DataTable(app, app.projectID);
            
            ChangeProject(app.Datatable, app.projectName)
        end

        % Button pushed function: OpenPlotButton
        function OpenPlotButtonPushed(app, event)
            app.Figureapp = FigureApp4(app, app.projectID);
        end

        % Menu selected function: DisconnectMenu
        function DisconnectMenuSelected(app, event)
            if app.Refresher.Running == "on"
                stop(app.Refresher)
                app.DisconnectMenu.Text = "Reconnect";
            else
                start(app.Refresher)
                app.DisconnectMenu.Text = "Disconnect";
            end
        end

        % Callback function
        function SpeedfactorxSliderValueChanged(app, event)
            stop(app.Refresher)
            app.speedfactor = round(app.SpeedfactorxSlider.Value,1);
            parameter = "speedfactor";
            value = app.(parameter);
            ParameterChangedFcn(app, parameter, value)
            start(app.Refresher)
        end

        % Button pushed function: ReconnectButton
        function ReconnectButtonPushed(app, event)
            start(app.Refresher)
            app.ReconnectButton.Visible = "off";
            app.ConnectedLamp.Color = "green";
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if selectedTab == app.LogTab
                app.LogTab.Title = "Log";
                app.logchanges = 0;
            end
        end

        % Value changed function: IncludeparameterupdatesCheckBox
        function IncludeparameterupdatesCheckBoxValueChanged(app, event)
                app.LoggerTextArea.Value = "";
                text = "";
                for n = 1:numel(app.logText)
                    if app.IncludeparameterupdatesCheckBox.Value == 1
                        if text == ""
                            text = app.logText{n};
                        else
                            text = sprintf('%s\n%s',text,app.logText{n});
                        end
                    elseif ~contains(app.logText(n),"Parameter")
                        if text == ""
                            text = app.logText{n};
                        else
                            text = sprintf('%s\n%s',text,app.logText{n});
                        end
                    end
                end
                app.LoggerTextArea.Value = text;
        end

        % Value changed function: HarvestSwitch
        function HarvestSwitchValueChanged(app, event)
            value = app.HarvestSwitch.Value;
            
        end

        % Value changed function: InoculateButton
        function InoculateButtonValueChanged(app, event)
            if app.t(end) ~= 0
                app.f_Inoc = 1;
                parameter = "f_Inoc";
                value = app.(parameter);
                ParameterChangedFcn(app, parameter, value)
                app.InoculateButton.Enable = "off";
                app.InoculatedLamp.Color = "green";
            else
                app.f_InocStart = app.InoculateButton.Value;
                parameter = "f_InocStart";
                value = app.(parameter);
                ParameterChangedFcn(app, parameter, value)
            end
        end

        % Value changed function: SpeedfactorxSpinner
        function SpeedfactorxSpinnerValueChanged(app, event)
            app.speedfactor = app.SpeedfactorxSpinner.Value;
            stop(app.Refresher)
            parameter = "speedfactor";
            value = app.(parameter);
            ParameterChangedFcn(app, parameter, value)
            start(app.Refresher)
        end

        % Button pushed function: AddPhaseButton
        function AddPhaseButtonPushed(app, event)
           createPhasePanel(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ProjectMenu = uimenu(app.UIFigure);
            app.ProjectMenu.Text = 'Project';
            app.SaveinMenu = uimenu(app.ProjectMenu);
            app.SaveinMenu.Text = 'Save in ...';
            app.SaveProjectasMenu = uimenu(app.ProjectMenu);
            app.SaveProjectasMenu.Text = 'Save Project as...';
            app.StartNewProjectMenu = uimenu(app.ProjectMenu);
            app.StartNewProjectMenu.Separator = 'on';
            app.StartNewProjectMenu.Text = 'Start New Project';
            app.OpenProjectMenu = uimenu(app.ProjectMenu);
            app.OpenProjectMenu.Separator = 'on';
            app.OpenProjectMenu.Text = 'Open Project';
            app.Menu3 = uimenu(app.ProjectMenu);
            app.Menu3.Text = 'Menu3';
            app.DataTableMenu = uimenu(app.UIFigure);
            app.DataTableMenu.Text = 'Data Table';
            app.OpenDataTableMenu = uimenu(app.DataTableMenu);
            app.OpenDataTableMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenDataTableMenuSelected, true);
            app.OpenDataTableMenu.Text = 'Open Data Table';
            app.ExportdataasMenu = uimenu(app.DataTableMenu);
            app.ExportdataasMenu.Text = 'Export data as...';
            app.xlsxMenu = uimenu(app.ExportdataasMenu);
            app.xlsxMenu.Text = '*.xlsx';
            app.txtMenu = uimenu(app.ExportdataasMenu);
            app.txtMenu.Text = '*.txt';
            app.csvMenu = uimenu(app.ExportdataasMenu);
            app.csvMenu.Text = '*.csv';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.ProjectMenu_2 = uimenu(app.EditMenu);
            app.ProjectMenu_2.Text = 'Project';
            app.ModelMenu_2 = uimenu(app.EditMenu);
            app.ModelMenu_2.Text = 'Model';
            app.Menu_2 = uimenu(app.EditMenu);
            app.SettingsMenu = uimenu(app.UIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.DisconnectMenu = uimenu(app.SettingsMenu);
            app.DisconnectMenu.MenuSelectedFcn = createCallbackFcn(app, @DisconnectMenuSelected, true);
            app.DisconnectMenu.Text = 'Disconnect';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.DynamicPanel = uipanel(app.GridLayout);
            app.StaticPanel = uipanel(app.GridLayout);
            app.ExitButton = uibutton(app.StaticPanel, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExitButton.Text = 'Exit';
            app.fontsize10fontnameHelveticaProcessTimehEditFieldLabel = uilabel(app.StaticPanel);
            app.fontsize10fontnameHelveticaProcessTimehEditFieldLabel.Interpreter = 'tex';
            app.fontsize10fontnameHelveticaProcessTimehEditFieldLabel.Text = '\fontsize{10}\fontname{Helvetica}Process Time [h]';
            app.ProcessTimehEditField = uieditfield(app.StaticPanel, 'numeric');
            app.ProcessTimehEditField.ValueDisplayFormat = '%.3f';
            app.ProcessTimehEditField.Editable = 'off';
            app.ProcessRunningLampLabel = uilabel(app.StaticPanel);
            app.ProcessRunningLampLabel.Text = 'Process Running';
            app.ProcessRunningLamp = uilamp(app.StaticPanel);
            app.ConnectedLampLabel = uilabel(app.StaticPanel);
            app.ConnectedLampLabel.Text = 'Connected';
            app.ConnectedLamp = uilamp(app.StaticPanel);
            app.StartSimulationButton = uibutton(app.StaticPanel, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationButtonPushed, true);
            app.StartSimulationButton.Tooltip = {'Start a new simulation with the current starting variables'};
            app.StartSimulationButton.Text = 'Start Simulation';
            app.fontsize10fontnameHelveticaDeltatsLabel = uilabel(app.StaticPanel);
            app.fontsize10fontnameHelveticaDeltatsLabel.Interpreter = 'tex';
            app.fontsize10fontnameHelveticaDeltatsLabel.Text = '\fontsize{10}\fontname{Helvetica}\Deltat [s]';
            app.DeltatsEditField = uieditfield(app.StaticPanel, 'numeric');
            app.DeltatsEditField.Limits = [0.1 10];
            app.DeltatsEditField.ValueChangedFcn = createCallbackFcn(app, @DeltatsEditFieldValueChanged, true);
            app.DeltatsEditField.Value = 0.1;
            app.OpenPlotButton = uibutton(app.StaticPanel, 'push');
            app.OpenPlotButton.ButtonPushedFcn = createCallbackFcn(app, @OpenPlotButtonPushed, true);
            app.OpenPlotButton.Text = 'Open Plot';
            app.InoculatedLampLabel = uilabel(app.StaticPanel);
            app.InoculatedLampLabel.Text = 'Inoculated';
            app.InoculatedLamp = uilamp(app.StaticPanel);
            app.InoculateButton = uibutton(app.StaticPanel, 'state');
            app.InoculateButton.ValueChangedFcn = createCallbackFcn(app, @InoculateButtonValueChanged, true);
            app.InoculateButton.Text = 'Inoculate';
            app.ReconnectButton = uibutton(app.StaticPanel, 'push');
            app.ReconnectButton.ButtonPushedFcn = createCallbackFcn(app, @ReconnectButtonPushed, true);
            app.ReconnectButton.Visible = 'off';
            app.ReconnectButton.Text = 'Reconnect';
            app.SpeedfactorxSpinnerLabel = uilabel(app.StaticPanel);
            app.SpeedfactorxSpinnerLabel.Text = 'Speed factor [x]';
            app.SpeedfactorxSpinner = uispinner(app.StaticPanel);
            app.SpeedfactorxSpinner.Limits = [1 100];
            app.SpeedfactorxSpinner.ValueChangedFcn = createCallbackFcn(app, @SpeedfactorxSpinnerValueChanged, true);
            app.SpeedfactorxSpinner.Value = 1;
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ControlOptionsTab = uitab(app.TabGroup);
            app.ControlOptionsTab.Title = 'Control Options';
            app.ControlOptionsGridLayout = uigridlayout(app.ControlOptionsTab);
            app.pHControlPanel = uipanel(app.ControlOptionsGridLayout);
            app.pHControlPanel.Title = 'pH-Control';
            app.GridLayout4 = uigridlayout(app.pHControlPanel);
            app.pHLamp = uilamp(app.GridLayout4);
            app.pHtrendLabel = uilabel(app.GridLayout4);
            app.pHtrendLabel.Text = 'pHtrend';
            app.pHParametersButton = uibutton(app.GridLayout4, 'push');
            app.pHParametersButton.ButtonPushedFcn = createCallbackFcn(app, @pHParametersButtonPushed, true);
            app.pHParametersButton.Tooltip = {'Adjust the gains of the pH controller.'};
            app.pHParametersButton.Text = 'Parameters';
            app.AlkaliSwitchLabel = uilabel(app.GridLayout4);
            app.AlkaliSwitchLabel.Text = 'Alkali';
            app.AlkaliSwitch = uiswitch(app.GridLayout4, 'rocker');
            app.AlkaliSwitch.ItemsData = [0 1];
            app.AlkaliSwitch.ValueChangedFcn = createCallbackFcn(app, @AlkaliSwitchValueChanged, true);
            app.AlkaliSwitch.Tooltip = {'Turn on alkali pump at maximum pump rate.'};
            app.AlkaliSwitch.Value = 0;
            app.AcidSwitchLabel = uilabel(app.GridLayout4);
            app.AcidSwitchLabel.Text = 'Acid';
            app.AcidSwitch = uiswitch(app.GridLayout4, 'rocker');
            app.AcidSwitch.ItemsData = [0 1];
            app.AcidSwitch.ValueChangedFcn = createCallbackFcn(app, @AcidSwitchValueChanged, true);
            app.AcidSwitch.Tooltip = {'Turn on acid pump at maximum pump rate.'};
            app.AcidSwitch.Value = 0;
            app.pH_wEditField = uieditfield(app.GridLayout4, 'numeric');
            app.pH_wEditField.ValueDisplayFormat = '%2.2f';
            app.pH_wEditField.ValueChangedFcn = createCallbackFcn(app, @pH_wEditFieldValueChanged, true);
            app.pH_wEditField.Tooltip = {'Enter a setpoint for the pH value.'};
            app.pH_wEditField.Value = 7;
            app.fontsize10fontnameHelveticapH_wEditFieldLabel = uilabel(app.GridLayout4);
            app.fontsize10fontnameHelveticapH_wEditFieldLabel.Interpreter = 'tex';
            app.fontsize10fontnameHelveticapH_wEditFieldLabel.Text = '\fontsize{10}\fontname{Helvetica}pH_{w}';
            app.fontsize10fontnameHelveticapH_LEditFieldLabel = uilabel(app.GridLayout4);
            app.fontsize10fontnameHelveticapH_LEditFieldLabel.Interpreter = 'tex';
            app.fontsize10fontnameHelveticapH_LEditFieldLabel.Text = '\fontsize{10}\fontname{Helvetica}pH_{L}';
            app.pH_LEditField = uieditfield(app.GridLayout4, 'numeric');
            app.pH_LEditField.ValueDisplayFormat = '%2.2f';
            app.pH_LEditField.Editable = 'off';
            app.pH_LEditField.Tooltip = {'Current pH value'};
            app.ModeLabel_4 = uilabel(app.GridLayout4);
            app.ModeLabel_4.Text = 'Mode:';
            app.ModeDropDown = uidropdown(app.GridLayout4);
            app.ModeDropDown.Items = {'Auto', 'Manual'};
            app.ModeDropDown.ItemsData = [1 0];
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Tooltip = {'Turn on/off pH control (on per default).'};
            app.ModeDropDown.Value = 1;
            app.TemperatureControlPanel = uipanel(app.ControlOptionsGridLayout);
            app.TemperatureControlPanel.Title = 'Temperature-Control';
            app.GridLayout3 = uigridlayout(app.TemperatureControlPanel);
            app.TemperatureLamp = uilamp(app.GridLayout3);
            app.temptrendLabel = uilabel(app.GridLayout3);
            app.temptrendLabel.Text = 'temptrend';
            app.tempParametersButton = uibutton(app.GridLayout3, 'push');
            app.tempParametersButton.ButtonPushedFcn = createCallbackFcn(app, @tempParametersButtonPushed, true);
            app.tempParametersButton.Tooltip = {'Adjust the gains of the temperature controller.'};
            app.tempParametersButton.Text = 'Parameters';
            app.CoolingSwitchLabel = uilabel(app.GridLayout3);
            app.CoolingSwitchLabel.Text = 'Cooling';
            app.CoolingSwitch = uiswitch(app.GridLayout3, 'rocker');
            app.CoolingSwitch.ItemsData = [0 1];
            app.CoolingSwitch.ValueChangedFcn = createCallbackFcn(app, @CoolingSwitchValueChanged, true);
            app.CoolingSwitch.Tooltip = {'Turn on cooling water flow at maximum flow rate.'};
            app.CoolingSwitch.Value = 0;
            app.HeatingSwitchLabel = uilabel(app.GridLayout3);
            app.HeatingSwitchLabel.Text = 'Heating';
            app.HeatingSwitch = uiswitch(app.GridLayout3, 'rocker');
            app.HeatingSwitch.ItemsData = [0 1];
            app.HeatingSwitch.ValueChangedFcn = createCallbackFcn(app, @HeatingSwitchValueChanged, true);
            app.HeatingSwitch.Tooltip = {'Turn on heating at maximum heating power.'};
            app.HeatingSwitch.Value = 0;
            app.fontsize10fontnameHelveticavartheta_wCEditFieldLabel = uilabel(app.GridLayout3);
            app.fontsize10fontnameHelveticavartheta_wCEditFieldLabel.Interpreter = 'tex';
            app.fontsize10fontnameHelveticavartheta_wCEditFieldLabel.Text = '\fontsize{10}\fontname{Helvetica}\vartheta_{w} [°C]';
            app.theta_wCEditField = uieditfield(app.GridLayout3, 'numeric');
            app.theta_wCEditField.ValueDisplayFormat = '%2.2f';
            app.theta_wCEditField.ValueChangedFcn = createCallbackFcn(app, @theta_wCEditFieldValueChanged, true);
            app.theta_wCEditField.Tooltip = {'Enter a setpoint for the temperature value.'};
            app.fontsize10fontnameHelveticavartheta_LCEditField_2Label = uilabel(app.GridLayout3);
            app.fontsize10fontnameHelveticavartheta_LCEditField_2Label.Interpreter = 'tex';
            app.fontsize10fontnameHelveticavartheta_LCEditField_2Label.Text = '\fontsize{10}\fontname{Helvetica}\vartheta_{L} [°C]';
            app.theta_LCEditField = uieditfield(app.GridLayout3, 'numeric');
            app.theta_LCEditField.ValueDisplayFormat = '%3.2f';
            app.theta_LCEditField.Editable = 'off';
            app.theta_LCEditField.Tooltip = {'Current liquid phase temperature'};
            app.ModeLabel_3 = uilabel(app.GridLayout3);
            app.ModeLabel_3.Text = 'Mode:';
            app.ModeDropDown_2 = uidropdown(app.GridLayout3);
            app.ModeDropDown_2.Items = {'Manual', 'Auto'};
            app.ModeDropDown_2.ItemsData = {'0', '1'};
            app.ModeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ModeDropDown_2ValueChanged, true);
            app.ModeDropDown_2.Tooltip = {'Turn on/off temperature control (on per default).'};
            app.ModeDropDown_2.Value = '0';
            app.pO2ControlPanel = uipanel(app.ControlOptionsGridLayout);
            app.pO2ControlPanel.Title = 'pO2-Control';
            app.GridLayout2 = uigridlayout(app.pO2ControlPanel);
            app.pO2ParametersButton = uibutton(app.GridLayout2, 'push');
            app.pO2ParametersButton.ButtonPushedFcn = createCallbackFcn(app, @pO2ParametersButtonPushed, true);
            app.pO2ParametersButton.Tooltip = {'Adjust the gains of the pO2 controllers.'};
            app.pO2ParametersButton.Text = 'Parameters';
            app.pO2Lamp = uilamp(app.GridLayout2);
            app.pO2trendLabel = uilabel(app.GridLayout2);
            app.pO2trendLabel.Text = 'pO2trend';
            app.fontsize10fontnameHelveticapO_2_wEditFieldLabel = uilabel(app.GridLayout2);
            app.fontsize10fontnameHelveticapO_2_wEditFieldLabel.Interpreter = 'tex';
            app.fontsize10fontnameHelveticapO_2_wEditFieldLabel.Text = '\fontsize{10}\fontname{Helvetica}pO_2_{w} [%]';
            app.pO_2_wEditField = uieditfield(app.GridLayout2, 'numeric');
            app.pO_2_wEditField.ValueDisplayFormat = '%3.1f';
            app.pO_2_wEditField.ValueChangedFcn = createCallbackFcn(app, @pO_2_wEditFieldValueChanged, true);
            app.pO_2_wEditField.Tooltip = {'Enter a setpoint for the pO2 value.'};
            app.fontsize10fontnameHelveticapO_2EditFieldLabel = uilabel(app.GridLayout2);
            app.fontsize10fontnameHelveticapO_2EditFieldLabel.Interpreter = 'tex';
            app.fontsize10fontnameHelveticapO_2EditFieldLabel.Text = '\fontsize{10}\fontname{Helvetica}pO_2 [%]';
            app.pO_2EditField = uieditfield(app.GridLayout2, 'numeric');
            app.pO_2EditField.ValueDisplayFormat = '%3.1f';
            app.pO_2EditField.Editable = 'off';
            app.pO_2EditField.Tooltip = {'Current partical oxygen pressure'};
            app.fontsize10fontnameHelveticaN_StwrpmEditFieldLabel = uilabel(app.GridLayout2);
            app.fontsize10fontnameHelveticaN_StwrpmEditFieldLabel.Interpreter = 'tex';
            app.fontsize10fontnameHelveticaN_StwrpmEditFieldLabel.Text = '\fontsize{10}\fontname{Helvetica}N_{Stw} [rpm]';
            app.N_StwrpmEditField = uieditfield(app.GridLayout2, 'numeric');
            app.N_StwrpmEditField.Limits = [0 1500];
            app.N_StwrpmEditField.ValueDisplayFormat = '%.1f';
            app.N_StwrpmEditField.ValueChangedFcn = createCallbackFcn(app, @N_StwrpmEditFieldValueChanged, true);
            app.N_StwrpmEditField.Tooltip = {'Enter a setpoint for the stirrer speed.'};
            app.fontsize10fontnameHelveticaF_RwlhEditFieldLabel = uilabel(app.GridLayout2);
            app.fontsize10fontnameHelveticaF_RwlhEditFieldLabel.Interpreter = 'tex';
            app.fontsize10fontnameHelveticaF_RwlhEditFieldLabel.Text = '\fontsize{10}\fontname{Helvetica}F_{Rw} [l/h]';
            app.F_RwlhEditField = uieditfield(app.GridLayout2, 'numeric');
            app.F_RwlhEditField.Limits = [0 0.5];
            app.F_RwlhEditField.ValueDisplayFormat = '%.4f';
            app.F_RwlhEditField.ValueChangedFcn = createCallbackFcn(app, @F_RwlhEditFieldValueChanged, true);
            app.F_RwlhEditField.Tooltip = {'Enter a setpoint for the feed rate.'};
            app.fontsize10fontnameHelveticaF_nAIRwlminEditFieldLabel = uilabel(app.GridLayout2);
            app.fontsize10fontnameHelveticaF_nAIRwlminEditFieldLabel.Interpreter = 'tex';
            app.fontsize10fontnameHelveticaF_nAIRwlminEditFieldLabel.Text = '\fontsize{10}\fontname{Helvetica}F_{nAIRw} [l/min]';
            app.F_nAIRwlminEditField = uieditfield(app.GridLayout2, 'numeric');
            app.F_nAIRwlminEditField.Limits = [0 15];
            app.F_nAIRwlminEditField.ValueDisplayFormat = '%.1f';
            app.F_nAIRwlminEditField.ValueChangedFcn = createCallbackFcn(app, @F_nAIRwlminEditFieldValueChanged, true);
            app.F_nAIRwlminEditField.Tooltip = {'Enter a setpoint for the air aeration rate.'};
            app.fontsize10fontnameHelveticaF_nO2wlminLabel = uilabel(app.GridLayout2);
            app.fontsize10fontnameHelveticaF_nO2wlminLabel.Interpreter = 'tex';
            app.fontsize10fontnameHelveticaF_nO2wlminLabel.Text = '\fontsize{10}\fontname{Helvetica}F_{nO_2w} [l/min]';
            app.F_nAIRwlminEditField_2 = uieditfield(app.GridLayout2, 'numeric');
            app.F_nAIRwlminEditField_2.Limits = [0 10];
            app.F_nAIRwlminEditField_2.ValueDisplayFormat = '%.1f';
            app.F_nAIRwlminEditField_2.ValueChangedFcn = createCallbackFcn(app, @F_nAIRwlminEditField_2ValueChanged, true);
            app.F_nAIRwlminEditField_2.Tooltip = {'Enter a setpoint for the O2 aeration rate.'};
            app.ModeLabel_2 = uilabel(app.GridLayout2);
            app.ModeLabel_2.Text = 'Mode:';
            app.ModeDropDown_3 = uidropdown(app.GridLayout2);
            app.ModeDropDown_3.Items = {'Manual', 'pO2-agitation', 'pO2-aeration', 'pO2-gasmix', 'pO2-feed'};
            app.ModeDropDown_3.ItemsData = [0 1 2 3 4];
            app.ModeDropDown_3.ValueChangedFcn = createCallbackFcn(app, @ModeDropDown_3ValueChanged, true);
            app.ModeDropDown_3.Tooltip = {'Turn on/off pO2 control and select between different control modes (off per default).'};
            app.ModeDropDown_3.Value = 0;
            app.fontsize10fontnameHelveticaF_nGwlminEditFieldLabel = uilabel(app.GridLayout2);
            app.fontsize10fontnameHelveticaF_nGwlminEditFieldLabel.Interpreter = 'tex';
            app.fontsize10fontnameHelveticaF_nGwlminEditFieldLabel.Text = '\fontsize{10}\fontname{Helvetica}F_{nGw} [l/min]';
            app.F_nGwlminEditField = uieditfield(app.GridLayout2, 'numeric');
            app.F_nGwlminEditField.Limits = [0 25];
            app.F_nGwlminEditField.ValueDisplayFormat = '%.1f';
            app.F_nGwlminEditField.ValueChangedFcn = createCallbackFcn(app, @F_nGwlminEditFieldValueChanged, true);
            app.F_nGwlminEditField.Tooltip = {'Enter a setpoint for the overall aeration rate.'};
            app.fontsize10fontnameHelveticax_OGinwEditFieldLabel = uilabel(app.GridLayout2);
            app.fontsize10fontnameHelveticax_OGinwEditFieldLabel.Interpreter = 'tex';
            app.fontsize10fontnameHelveticax_OGinwEditFieldLabel.Text = '\fontsize{10}\fontname{Helvetica}x_{OGinw} [-]';
            app.x_OGinwEditField = uieditfield(app.GridLayout2, 'numeric');
            app.x_OGinwEditField.Limits = [0 1];
            app.x_OGinwEditField.ValueDisplayFormat = '%.4f';
            app.x_OGinwEditField.Editable = 'off';
            app.x_OGinwEditField.Tooltip = {'Calculated setpoint for the molar fraction of O2 in the gas phase at the reactor inlet.'};
            app.LiquidWeightPanel = uipanel(app.ControlOptionsGridLayout);
            app.LiquidWeightPanel.Title = 'Liquid Weight';
            app.LiquidWeightGridLayout = uigridlayout(app.LiquidWeightPanel);
            app.LWParametersButton = uibutton(app.LiquidWeightGridLayout, 'push');
            app.LWParametersButton.ButtonPushedFcn = createCallbackFcn(app, @LWParametersButtonPushed, true);
            app.LWParametersButton.Tooltip = {'Adjust the gains of the liquid weight controller.'};
            app.LWParametersButton.Text = 'Parameters';
            app.LiquidWeightLamp = uilamp(app.LiquidWeightGridLayout);
            app.mLtrendLabel = uilabel(app.LiquidWeightGridLayout);
            app.mLtrendLabel.Text = 'mLtrend';
            app.HarvestSwitchLabel = uilabel(app.LiquidWeightGridLayout);
            app.HarvestSwitchLabel.Text = 'Harvest';
            app.HarvestSwitch = uiswitch(app.LiquidWeightGridLayout, 'rocker');
            app.HarvestSwitch.ItemsData = [0 1];
            app.HarvestSwitch.ValueChangedFcn = createCallbackFcn(app, @HarvestSwitchValueChanged, true);
            app.HarvestSwitch.Tooltip = {'Turn on harvest at maximum harvest rate.'};
            app.HarvestSwitch.Value = 0;
            app.fontsize10fontnameHelveticam_L_wkgEditFieldLabel = uilabel(app.LiquidWeightGridLayout);
            app.fontsize10fontnameHelveticam_L_wkgEditFieldLabel.Interpreter = 'tex';
            app.fontsize10fontnameHelveticam_L_wkgEditFieldLabel.Text = '\fontsize{10}\fontname{Helvetica}m_L_{w} [kg]';
            app.m_L_wkgEditField = uieditfield(app.LiquidWeightGridLayout, 'numeric');
            app.m_L_wkgEditField.ValueDisplayFormat = '%4.2f';
            app.m_L_wkgEditField.ValueChangedFcn = createCallbackFcn(app, @m_L_wkgEditFieldValueChanged, true);
            app.m_L_wkgEditField.Tooltip = {'Enter a setpoint for the liquid weight value.'};
            app.fontsize10fontnameHelveticam_LkgEditFieldLabel = uilabel(app.LiquidWeightGridLayout);
            app.fontsize10fontnameHelveticam_LkgEditFieldLabel.Interpreter = 'tex';
            app.fontsize10fontnameHelveticam_LkgEditFieldLabel.Text = '\fontsize{10}\fontname{Helvetica}m_{L} [kg]';
            app.m_LkgEditField = uieditfield(app.LiquidWeightGridLayout, 'numeric');
            app.m_LkgEditField.ValueDisplayFormat = '%4.2f';
            app.m_LkgEditField.Editable = 'off';
            app.m_LkgEditField.Tooltip = {'Current liquid volume mass'};
            app.ModeLabel = uilabel(app.LiquidWeightGridLayout);
            app.ModeLabel.Text = 'Mode:';
            app.ModeDropDown_4 = uidropdown(app.LiquidWeightGridLayout);
            app.ModeDropDown_4.Items = {'Manual', 'Harvest'};
            app.ModeDropDown_4.ItemsData = [0 1];
            app.ModeDropDown_4.ValueChangedFcn = createCallbackFcn(app, @ModeDropDown_4ValueChanged, true);
            app.ModeDropDown_4.Tooltip = {'Turn on/off liquid weight control (off per default).'};
            app.ModeDropDown_4.Value = 0;
            app.fontsize10fontnameHelveticaF_HwEditFieldLabel = uilabel(app.LiquidWeightGridLayout);
            app.fontsize10fontnameHelveticaF_HwEditFieldLabel.Interpreter = 'tex';
            app.fontsize10fontnameHelveticaF_HwEditFieldLabel.Text = '\fontsize{10}\fontname{Helvetica}F_{Hw} [%]';
            app.F_HwEditField = uieditfield(app.LiquidWeightGridLayout, 'numeric');
            app.F_HwEditField.Limits = [0 100];
            app.F_HwEditField.ValueDisplayFormat = '%.1f';
            app.F_HwEditField.ValueChangedFcn = createCallbackFcn(app, @F_HwEditFieldValueChanged, true);
            app.F_HwEditField.Tooltip = {'Enter a setpoint for the harvest rate.'};
            app.LevelControlPanel = uipanel(app.ControlOptionsGridLayout);
            app.LevelControlPanel.Title = 'Level-Control';
            app.GridLayout5 = uigridlayout(app.LevelControlPanel);
            app.AntifoamadditionSwitch = uiswitch(app.GridLayout5, 'rocker');
            app.AntifoamadditionSwitch.ItemsData = [0 1];
            app.AntifoamadditionSwitch.ValueChangedFcn = createCallbackFcn(app, @AntifoamadditionSwitchValueChanged, true);
            app.AntifoamadditionSwitch.Tooltip = {'Turn on/off antifoam control (on per default).'};
            app.AntifoamadditionSwitch.Value = 1;
            app.AntifoamLamp = uilamp(app.GridLayout5);
            app.TabGroup2 = uitabgroup(app.ControlOptionsGridLayout);
            app.S1Tab = uitab(app.TabGroup2);
            app.S1Tab.Title = 'S1';
            app.GridLayout6 = uigridlayout(app.S1Tab);
            app.feedParametersButton = uibutton(app.GridLayout6, 'push');
            app.feedParametersButton.Text = 'Parameters';
            app.OpenLoopFeedS1Label = uilabel(app.GridLayout6);
            app.OpenLoopFeedS1Label.Text = 'Open-Loop Feed S1';
            app.ModeDropDown_5Label = uilabel(app.GridLayout6);
            app.ModeDropDown_5Label.Text = 'Mode:';
            app.ModeDropDownS1 = uidropdown(app.GridLayout6);
            app.ModeDropDownS1.Items = {'Manual', 'Substrate', 'pO2', 'Off gas'};
            app.ModeDropDownS1.ItemsData = {'1', '2', '3', '4'};
            app.ModeDropDownS1.Value = '1';
            app.fontsize10fontnameHelveticaF_R1tlhLabel = uilabel(app.GridLayout6);
            app.fontsize10fontnameHelveticaF_R1tlhLabel.Interpreter = 'tex';
            app.fontsize10fontnameHelveticaF_R1tlhLabel.Text = '\fontsize{10}\fontname{Helvetica}F_{R1}(t) [l/h]';
            app.F_R1tlhEditField = uieditfield(app.GridLayout6, 'numeric');
            app.F_R1tlhEditField.Editable = 'off';
            app.t1jhLabel = uilabel(app.GridLayout6);
            app.t1jhLabel.Visible = 'off';
            app.t1jhLabel.Interpreter = 'tex';
            app.t1jhLabel.Text = '\fontsize{10}\fontname{Helvetica}t_{1j} [h]';
            app.t_1jhEditField = uieditfield(app.GridLayout6, 'numeric');
            app.t_1jhEditField.Editable = 'off';
            app.t_1jhEditField.Visible = 'off';
            app.Lamp = uilamp(app.GridLayout6);
            app.FR1jlhLabel = uilabel(app.GridLayout6);
            app.FR1jlhLabel.Visible = 'off';
            app.FR1jlhLabel.Interpreter = 'tex';
            app.FR1jlhLabel.Text = '\fontsize{10}\fontname{Helvetica}F_{R1j} [l/h]';
            app.FR1jlhEditField = uieditfield(app.GridLayout6, 'numeric');
            app.FR1jlhEditField.Editable = 'off';
            app.FR1jlhEditField.Visible = 'off';
            app.VL1jlLabel = uilabel(app.GridLayout6);
            app.VL1jlLabel.Visible = 'off';
            app.VL1jlLabel.Interpreter = 'tex';
            app.VL1jlLabel.Text = '\fontsize{10}\fontname{Helvetica}V_{L1j} [l]';
            app.V_L1jlEditField = uieditfield(app.GridLayout6, 'numeric');
            app.V_L1jlEditField.Editable = 'off';
            app.V_L1jlEditField.Visible = 'off';
            app.VRinlLabel = uilabel(app.GridLayout6);
            app.VRinlLabel.Visible = 'off';
            app.VRinlLabel.Interpreter = 'tex';
            app.VRinlLabel.Text = '\fontsize{10}\fontname{Helvetica}V_{Rin} [l]';
            app.V_RinlEditField = uieditfield(app.GridLayout6, 'numeric');
            app.V_RinlEditField.Editable = 'off';
            app.V_RinlEditField.Visible = 'off';
            app.VRinendLabel = uilabel(app.GridLayout6);
            app.VRinendLabel.Visible = 'off';
            app.VRinendLabel.Interpreter = 'tex';
            app.VRinendLabel.Text = '\fontsize{10}\fontname{Helvetica}V_{Rin,end} [l]:';
            app.V_RinendlEditField = uieditfield(app.GridLayout6, 'numeric');
            app.V_RinendlEditField.Editable = 'off';
            app.V_RinendlEditField.Visible = 'off';
            app.cXL1jLabel = uilabel(app.GridLayout6);
            app.cXL1jLabel.Visible = 'off';
            app.cXL1jLabel.Interpreter = 'tex';
            app.cXL1jLabel.Text = '\fontsize{10}\fontname{Helvetica}c_{XL1j} [g/l]';
            app.c_XL1jglEditField = uieditfield(app.GridLayout6, 'numeric');
            app.c_XL1jglEditField.Editable = 'off';
            app.c_XL1jglEditField.Visible = 'off';
            app.Switch = uiswitch(app.GridLayout6, 'rocker');
            app.StartFeedCheckBox = uicheckbox(app.GridLayout6);
            app.StartFeedCheckBox.Text = 'Start Feed';
            app.S2Tab = uitab(app.TabGroup2);
            app.S2Tab.Title = 'S2';
            app.GridLayout6_2 = uigridlayout(app.S2Tab);
            app.feedParametersButton_2 = uibutton(app.GridLayout6_2, 'push');
            app.feedParametersButton_2.Text = 'Parameters';
            app.OpenLoopFeedS2Label = uilabel(app.GridLayout6_2);
            app.OpenLoopFeedS2Label.Text = 'Open-Loop Feed S2';
            app.Lamp_2 = uilamp(app.GridLayout6_2);
            app.Switch_2 = uiswitch(app.GridLayout6_2, 'rocker');
            app.StartFeedCheckBox_2 = uicheckbox(app.GridLayout6_2);
            app.StartFeedCheckBox_2.Text = 'Start Feed';
            app.ModeDropDown_5Label_2 = uilabel(app.GridLayout6_2);
            app.ModeDropDown_5Label_2.Text = 'Mode:';
            app.ModeDropDownS1_2 = uidropdown(app.GridLayout6_2);
            app.ModeDropDownS1_2.Items = {'Manual', 'Substrate', 'pO2', 'Off gas'};
            app.ModeDropDownS1_2.ItemsData = {'1', '2', '3', '4'};
            app.ModeDropDownS1_2.Value = '1';
            app.fontsize10fontnameHelveticaF_R1tlhLabel_2 = uilabel(app.GridLayout6_2);
            app.fontsize10fontnameHelveticaF_R1tlhLabel_2.Interpreter = 'tex';
            app.fontsize10fontnameHelveticaF_R1tlhLabel_2.Text = '\fontsize{10}\fontname{Helvetica}F_{R1}(t) [l/h]';
            app.F_R1tlhEditField_2 = uieditfield(app.GridLayout6_2, 'numeric');
            app.F_R1tlhEditField_2.Editable = 'off';
            app.t1jhLabel_2 = uilabel(app.GridLayout6_2);
            app.t1jhLabel_2.Visible = 'off';
            app.t1jhLabel_2.Interpreter = 'tex';
            app.t1jhLabel_2.Text = '\fontsize{10}\fontname{Helvetica}t_{1j} [h]';
            app.t_1jhEditField_2 = uieditfield(app.GridLayout6_2, 'numeric');
            app.t_1jhEditField_2.Editable = 'off';
            app.t_1jhEditField_2.Visible = 'off';
            app.FR1jlhLabel_2 = uilabel(app.GridLayout6_2);
            app.FR1jlhLabel_2.Visible = 'off';
            app.FR1jlhLabel_2.Interpreter = 'tex';
            app.FR1jlhLabel_2.Text = '\fontsize{10}\fontname{Helvetica}F_{R1j} [l/h]';
            app.FR1jlhEditField_2 = uieditfield(app.GridLayout6_2, 'numeric');
            app.FR1jlhEditField_2.Editable = 'off';
            app.FR1jlhEditField_2.Visible = 'off';
            app.VL1jlLabel_2 = uilabel(app.GridLayout6_2);
            app.VL1jlLabel_2.Visible = 'off';
            app.VL1jlLabel_2.Interpreter = 'tex';
            app.VL1jlLabel_2.Text = '\fontsize{10}\fontname{Helvetica}V_{L1j} [l]';
            app.V_L1jlEditField_2 = uieditfield(app.GridLayout6_2, 'numeric');
            app.V_L1jlEditField_2.Editable = 'off';
            app.V_L1jlEditField_2.Visible = 'off';
            app.VRinlLabel_2 = uilabel(app.GridLayout6_2);
            app.VRinlLabel_2.Visible = 'off';
            app.VRinlLabel_2.Interpreter = 'tex';
            app.VRinlLabel_2.Text = '\fontsize{10}\fontname{Helvetica}V_{Rin} [l]';
            app.V_RinlEditField_2 = uieditfield(app.GridLayout6_2, 'numeric');
            app.V_RinlEditField_2.Editable = 'off';
            app.V_RinlEditField_2.Visible = 'off';
            app.VRinendLabel_2 = uilabel(app.GridLayout6_2);
            app.VRinendLabel_2.Visible = 'off';
            app.VRinendLabel_2.Interpreter = 'tex';
            app.VRinendLabel_2.Text = '\fontsize{10}\fontname{Helvetica}V_{Rin,end} [l]:';
            app.V_RinendlEditField_2 = uieditfield(app.GridLayout6_2, 'numeric');
            app.V_RinendlEditField_2.Editable = 'off';
            app.V_RinendlEditField_2.Visible = 'off';
            app.cXL1jLabel_2 = uilabel(app.GridLayout6_2);
            app.cXL1jLabel_2.Visible = 'off';
            app.cXL1jLabel_2.Interpreter = 'tex';
            app.cXL1jLabel_2.Text = '\fontsize{10}\fontname{Helvetica}c_{XL1j} [g/l]';
            app.c_XL1jglEditField_2 = uieditfield(app.GridLayout6_2, 'numeric');
            app.c_XL1jglEditField_2.Editable = 'off';
            app.c_XL1jglEditField_2.Visible = 'off';
            app.S3Tab = uitab(app.TabGroup2);
            app.S3Tab.Title = 'S3';
            app.GridLayout6_3 = uigridlayout(app.S3Tab);
            app.feedParametersButton_3 = uibutton(app.GridLayout6_3, 'push');
            app.feedParametersButton_3.Text = 'Parameters';
            app.OpenLoopFeedS3Label = uilabel(app.GridLayout6_3);
            app.OpenLoopFeedS3Label.Text = 'Open-Loop Feed S3';
            app.Lamp_3 = uilamp(app.GridLayout6_3);
            app.Switch_3 = uiswitch(app.GridLayout6_3, 'rocker');
            app.StartFeedCheckBox_3 = uicheckbox(app.GridLayout6_3);
            app.StartFeedCheckBox_3.Text = 'Start Feed';
            app.ModeDropDown_5Label_3 = uilabel(app.GridLayout6_3);
            app.ModeDropDown_5Label_3.Text = 'Mode:';
            app.ModeDropDownS1_3 = uidropdown(app.GridLayout6_3);
            app.ModeDropDownS1_3.Items = {'Manual', 'Substrate', 'pO2', 'Off gas'};
            app.ModeDropDownS1_3.ItemsData = {'1', '2', '3', '4'};
            app.ModeDropDownS1_3.Value = '1';
            app.fontsize10fontnameHelveticaF_R1tlhLabel_3 = uilabel(app.GridLayout6_3);
            app.fontsize10fontnameHelveticaF_R1tlhLabel_3.Interpreter = 'tex';
            app.fontsize10fontnameHelveticaF_R1tlhLabel_3.Text = '\fontsize{10}\fontname{Helvetica}F_{R3}(t) [l/h]';
            app.F_R1tlhEditField_3 = uieditfield(app.GridLayout6_3, 'numeric');
            app.F_R1tlhEditField_3.Editable = 'off';
            app.t1jhLabel_3 = uilabel(app.GridLayout6_3);
            app.t1jhLabel_3.Visible = 'off';
            app.t1jhLabel_3.Interpreter = 'tex';
            app.t1jhLabel_3.Text = '\fontsize{10}\fontname{Helvetica}t_{3j} [h]';
            app.t_1jhEditField_3 = uieditfield(app.GridLayout6_3, 'numeric');
            app.t_1jhEditField_3.Editable = 'off';
            app.t_1jhEditField_3.Visible = 'off';
            app.FR1jlhLabel_3 = uilabel(app.GridLayout6_3);
            app.FR1jlhLabel_3.Visible = 'off';
            app.FR1jlhLabel_3.Interpreter = 'tex';
            app.FR1jlhLabel_3.Text = '\fontsize{10}\fontname{Helvetica}F_{R1j} [l/h]';
            app.FR1jlhEditField_3 = uieditfield(app.GridLayout6_3, 'numeric');
            app.FR1jlhEditField_3.Editable = 'off';
            app.FR1jlhEditField_3.Visible = 'off';
            app.VL1jlLabel_3 = uilabel(app.GridLayout6_3);
            app.VL1jlLabel_3.Visible = 'off';
            app.VL1jlLabel_3.Interpreter = 'tex';
            app.VL1jlLabel_3.Text = '\fontsize{10}\fontname{Helvetica}V_{L1j} [l]';
            app.V_L1jlEditField_3 = uieditfield(app.GridLayout6_3, 'numeric');
            app.V_L1jlEditField_3.Editable = 'off';
            app.V_L1jlEditField_3.Visible = 'off';
            app.VRinlLabel_3 = uilabel(app.GridLayout6_3);
            app.VRinlLabel_3.Visible = 'off';
            app.VRinlLabel_3.Interpreter = 'tex';
            app.VRinlLabel_3.Text = '\fontsize{10}\fontname{Helvetica}V_{Rin} [l]';
            app.V_RinlEditField_3 = uieditfield(app.GridLayout6_3, 'numeric');
            app.V_RinlEditField_3.Editable = 'off';
            app.V_RinlEditField_3.Visible = 'off';
            app.VRinendLabel_3 = uilabel(app.GridLayout6_3);
            app.VRinendLabel_3.Visible = 'off';
            app.VRinendLabel_3.Interpreter = 'tex';
            app.VRinendLabel_3.Text = '\fontsize{10}\fontname{Helvetica}V_{Rin,end} [l]:';
            app.V_RinendlEditField_3 = uieditfield(app.GridLayout6_3, 'numeric');
            app.V_RinendlEditField_3.Editable = 'off';
            app.V_RinendlEditField_3.Visible = 'off';
            app.cXL1jLabel_3 = uilabel(app.GridLayout6_3);
            app.cXL1jLabel_3.Visible = 'off';
            app.cXL1jLabel_3.Interpreter = 'tex';
            app.cXL1jLabel_3.Text = '\fontsize{10}\fontname{Helvetica}c_{XL1j} [g/l]';
            app.c_XL1jglEditField_3 = uieditfield(app.GridLayout6_3, 'numeric');
            app.c_XL1jglEditField_3.Editable = 'off';
            app.c_XL1jglEditField_3.Visible = 'off';
            app.VariablePoolTab = uitab(app.TabGroup);
            app.VariablePoolTab.Title = 'Variable Pool';
            app.UITable = uitable(app.VariablePoolTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.Tooltip = {'Trend table populated by selecting variables from the variable list to the left.'};
            app.Tree = uitree(app.VariablePoolTab, 'checkbox');
            app.Tree.Tooltip = {'Select variables to generate a trend table entry for.'};
            app.DisplayableVariablesNode = uitreenode(app.Tree);
            app.DisplayableVariablesNode.Text = 'Displayable Variables';
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.LiquidVolumePanel = uipanel(app.VariablePoolTab);
            app.LiquidVolumePanel.Title = 'Liquid Volume';
            app.VLlEditFieldLabel = uilabel(app.LiquidVolumePanel);
            app.VLlEditFieldLabel.Text = 'VL [l]';
            app.VLlEditField = uieditfield(app.LiquidVolumePanel, 'numeric');
            app.VLlEditField.ValueDisplayFormat = '%.1f';
            app.VLlEditField.Editable = 'off';
            app.addedbaselEditFieldLabel = uilabel(app.LiquidVolumePanel);
            app.addedbaselEditFieldLabel.Text = 'added base [l]';
            app.addedbaselEditField = uieditfield(app.LiquidVolumePanel, 'numeric');
            app.addedbaselEditField.ValueDisplayFormat = '%.1f';
            app.addedbaselEditField.Editable = 'off';
            app.addedacidlEditFieldLabel = uilabel(app.LiquidVolumePanel);
            app.addedacidlEditFieldLabel.Text = 'added acid [l]';
            app.addedacidlEditField = uieditfield(app.LiquidVolumePanel, 'numeric');
            app.addedacidlEditField.ValueDisplayFormat = '%.1f';
            app.addedacidlEditField.Editable = 'off';
            app.addedAFlEditFieldLabel = uilabel(app.LiquidVolumePanel);
            app.addedAFlEditFieldLabel.Text = 'added AF [l]';
            app.addedAFlEditField = uieditfield(app.LiquidVolumePanel, 'numeric');
            app.addedAFlEditField.ValueDisplayFormat = '%.1f';
            app.addedAFlEditField.Editable = 'off';
            app.addedfeedlEditFieldLabel = uilabel(app.LiquidVolumePanel);
            app.addedfeedlEditFieldLabel.Text = 'added feed [l]';
            app.addedfeedlEditField = uieditfield(app.LiquidVolumePanel, 'numeric');
            app.addedfeedlEditField.ValueDisplayFormat = '%.1f';
            app.addedfeedlEditField.Editable = 'off';
            app.FT1acidlhEditField = uieditfield(app.VariablePoolTab, 'numeric');
            app.FT1acidlhEditField.ValueDisplayFormat = '%.3f';
            app.FT1acidlhEditField.Editable = 'off';
            app.FT1acidlhEditFieldLabel = uilabel(app.VariablePoolTab);
            app.FT1acidlhEditFieldLabel.Text = 'FT1 (acid) [l/h]';
            app.FT2baselhEditField = uieditfield(app.VariablePoolTab, 'numeric');
            app.FT2baselhEditField.ValueDisplayFormat = '%.3f';
            app.FT2baselhEditField.Editable = 'off';
            app.FT2baselhEditFieldLabel = uilabel(app.VariablePoolTab);
            app.FT2baselhEditFieldLabel.Text = 'FT2 (base) [l/h]';
            app.FRlhEditFieldLabel = uilabel(app.VariablePoolTab);
            app.FRlhEditFieldLabel.Text = 'FR [l/h]';
            app.FRlhEditField = uieditfield(app.VariablePoolTab, 'numeric');
            app.FRlhEditField.ValueDisplayFormat = '%.3f';
            app.FRlhEditField.Editable = 'off';
            app.NStrpmEditFieldLabel = uilabel(app.VariablePoolTab);
            app.NStrpmEditFieldLabel.Text = 'NSt [rpm]';
            app.NStrpmEditField = uieditfield(app.VariablePoolTab, 'numeric');
            app.NStrpmEditField.ValueDisplayFormat = '%.1f';
            app.NStrpmEditField.Editable = 'off';
            app.FnGlminEditFieldLabel = uilabel(app.VariablePoolTab);
            app.FnGlminEditFieldLabel.Text = 'FnG [l/min]';
            app.FnGlminEditField = uieditfield(app.VariablePoolTab, 'numeric');
            app.FnGlminEditField.ValueDisplayFormat = '%.1f';
            app.FnGlminEditField.Editable = 'off';
            app.xOGinEditFieldLabel = uilabel(app.VariablePoolTab);
            app.xOGinEditFieldLabel.Text = 'xOGin [-]';
            app.xOGinEditField = uieditfield(app.VariablePoolTab, 'numeric');
            app.xOGinEditField.ValueDisplayFormat = '%.4f';
            app.xOGinEditField.Editable = 'off';
            app.FHEditFieldLabel = uilabel(app.VariablePoolTab);
            app.FHEditFieldLabel.Text = 'FH [%] ';
            app.FHEditField = uieditfield(app.VariablePoolTab, 'numeric');
            app.FHEditField.Limits = [0 100];
            app.FHEditField.ValueDisplayFormat = '%.3f';
            app.FHEditField.Editable = 'off';
            app.ProcessManagerTab = uitab(app.TabGroup);
            app.ProcessManagerTab.Title = 'Process Manager';
            app.ProcessManagerGridLayout = uigridlayout(app.ProcessManagerTab);
            app.AddPhaseButton = uibutton(app.ProcessManagerGridLayout, 'push');
            app.AddPhaseButton.ButtonPushedFcn = createCallbackFcn(app, @AddPhaseButtonPushed, true);
            app.AddPhaseButton.Text = 'Add Phase';
            app.AppOperationTab = uitab(app.TabGroup);
            app.AppOperationTab.Title = 'App Operation';
            app.ReadInStartingVariablesButton = uibutton(app.AppOperationTab, 'push');
            app.ReadInStartingVariablesButton.ButtonPushedFcn = createCallbackFcn(app, @ReadInStartingVariablesButtonPushed, true);
            app.ReadInStartingVariablesButton.Enable = 'off';
            app.ReadInStartingVariablesButton.Tooltip = {'Read in new starting variables from an excel or text file'};
            app.ReadInStartingVariablesButton.Text = 'Read-In Starting Variables';
            app.EditStartingVariablesButton = uibutton(app.AppOperationTab, 'push');
            app.EditStartingVariablesButton.ButtonPushedFcn = createCallbackFcn(app, @EditStartingVariablesButtonPushed, true);
            app.EditStartingVariablesButton.Tooltip = {'Edit or display the currently loaded starting variables'};
            app.EditStartingVariablesButton.Text = 'Edit Starting Variables';
            app.SaveStartingVariablesButton = uibutton(app.AppOperationTab, 'push');
            app.SaveStartingVariablesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveStartingVariablesButtonPushed, true);
            app.SaveStartingVariablesButton.Enable = 'off';
            app.SaveStartingVariablesButton.Tooltip = {'Save currently selected starting variables to an excel or text file'};
            app.SaveStartingVariablesButton.Text = 'Save Starting Variables';
            app.DataStorageStartButton = uibutton(app.AppOperationTab, 'push');
            app.DataStorageStartButton.ButtonPushedFcn = createCallbackFcn(app, @DataStorageStartButtonPushed, true);
            app.DataStorageStartButton.Enable = 'off';
            app.DataStorageStartButton.Text = 'Data Storage Start';
            app.SelectedFileEditFieldLabel = uilabel(app.AppOperationTab);
            app.SelectedFileEditFieldLabel.Text = 'Selected File';
            app.SelectedFileEditField = uieditfield(app.AppOperationTab, 'text');
            app.SelectedFileEditField.Editable = 'off';
            app.SelectedFileEditField.Tooltip = {'The last loaded file is displayed here.'};
            app.LoadProcessButton = uibutton(app.AppOperationTab, 'push');
            app.LoadProcessButton.ButtonPushedFcn = createCallbackFcn(app, @LoadProcessButtonPushed, true);
            app.LoadProcessButton.Tooltip = {'Load a process from a MATLAB file'};
            app.LoadProcessButton.Text = 'Load Process';
            app.PlotPreviousDataButton = uibutton(app.AppOperationTab, 'push');
            app.PlotPreviousDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPreviousDataButtonPushed, true);
            app.PlotPreviousDataButton.Tooltip = {'Plot generated data of a previous simulation from an excel or text file'};
            app.PlotPreviousDataButton.Text = 'Plot Previous Data';
            app.DataStorageatStartCheckBox = uicheckbox(app.AppOperationTab);
            app.DataStorageatStartCheckBox.Enable = 'off';
            app.DataStorageatStartCheckBox.Tooltip = {'Check this box to start the data storage as soon as the simulation is launched. If this box is not checked, data storage can be started manually at any time by pressing the "Data Storage Start" button.'};
            app.DataStorageatStartCheckBox.Text = 'Data Storage at Start';
            app.DataStorageatStartCheckBox.Value = true;
            app.StartingVariableOptionsLabel = uilabel(app.AppOperationTab);
            app.StartingVariableOptionsLabel.Enable = 'off';
            app.StartingVariableOptionsLabel.Text = 'Starting Variable Options';
            app.PreviouslyRecordedDataLabel = uilabel(app.AppOperationTab);
            app.PreviouslyRecordedDataLabel.Text = 'Previously Recorded Data';
            app.DataStorageOptionsLabel = uilabel(app.AppOperationTab);
            app.DataStorageOptionsLabel.Enable = 'off';
            app.DataStorageOptionsLabel.Text = 'Data Storage Options';
            app.ExpertmodeButton = uibutton(app.AppOperationTab, 'push');
            app.ExpertmodeButton.ButtonPushedFcn = createCallbackFcn(app, @ExpertmodeButtonPushed, true);
            app.ExpertmodeButton.Text = 'Expert mode';
            app.LogTab = uitab(app.TabGroup);
            app.LogTab.Title = 'Log';
            app.IncludeparameterupdatesCheckBox = uicheckbox(app.LogTab);
            app.IncludeparameterupdatesCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludeparameterupdatesCheckBoxValueChanged, true);
            app.IncludeparameterupdatesCheckBox.Text = 'Include parameter updates';
            app.IncludeparameterupdatesCheckBox.Value = true;
            app.LoggerTextAreaLabel = uilabel(app.LogTab);
            app.LoggerTextAreaLabel.Text = 'Logger:';
            app.LoggerTextArea = uitextarea(app.LogTab);
            app.LoggerTextArea.Editable = 'off';
            app.InformationTab = uitab(app.TabGroup);
            app.InformationTab.Title = 'Information';
            app.Label = uilabel(app.InformationTab);
            app.Label.Text = {'=========================================='; ' Title:     Biofermentation Simulation App V 1.3'; ' Author: Lena Sophia Kaletsch'; ' Date:    01.03.2023'; '=========================================='; 'This work is licensed under the Creative Commons Attribution 4.0 International License. To view a copy of this license, visit http://creativecommons.org/licenses/by/4.0/. This application is based on the BIOSIM program conceived by Prof. Dr.-Ing. R. Luttmann and was developed for the laboratory of Bioprocess Automation at the University of Applied Sciences Hamburg.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ControlApp_ODE_Luttmann_pHTEMP_new_3(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end