classdef DialogBox3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        KP_slavecoolingEditField       matlab.ui.control.NumericEditField
        KP_slavecoolingEditFieldLabel  matlab.ui.control.Label
        KI_masterEditField             matlab.ui.control.NumericEditField
        KI_masterEditFieldLabel        matlab.ui.control.Label
        ConfirmButton                  matlab.ui.control.Button
        KP_slaveheatingEditField       matlab.ui.control.NumericEditField
        KP_slaveheatingLabel           matlab.ui.control.Label
        KP_masterEditField             matlab.ui.control.NumericEditField
        KP_masterEditFieldLabel        matlab.ui.control.Label
        SlaveControllerLabel           matlab.ui.control.Label
        MasterControllerLabel          matlab.ui.control.Label
        ParametersoftheTemperatureControllerLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        CallingApp   % Main app object
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            % Apply tex label
            font = "\fontname{Helvetica}\fontsize{10}";
            app.KP_masterEditFieldLabel.Text     = append(font,"K_P_{,Master}");
            app.KI_masterEditFieldLabel.Text = append(font,"K_I_{,Master}");
            app.KP_slaveheatingLabel.Text  = append(font,"K_P_{,Slave heating}");
            app.KP_slavecoolingEditFieldLabel.Text  = append(font,"K_P_{,Slave cooling}");

            % Update UI with input values
            app.KP_masterEditField.Value        = app.CallingApp.p.KP_temp1;
            app.KI_masterEditField.Value        = app.CallingApp.p.KI_temp1;
            app.KP_slaveheatingEditField.Value  = app.CallingApp.p.KP_temp2h;
            app.KP_slavecoolingEditField.Value  = app.CallingApp.p.KP_temp2c;

            % Define window name
            app.UIFigure.Name = "Temperature Controller Parameters";
            centerWindow(app.UIFigure)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Enable the Plot Opions button in main app
            app.CallingApp.tempParametersButton.Enable = 'on';
            
            % Delete the dialog box 
            delete(app)
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            % Upload parameter changes
            app.CallingApp.p.KP_temp1 = app.KP_masterEditField.Value;
            app.CallingApp.p.KI_temp1 = app.KI_masterEditField.Value;
            app.CallingApp.p.KP_temp2h = app.KP_slaveheatingEditField.Value;
            app.CallingApp.p.KP_temp2c = app.KP_slavecoolingEditField.Value;

            % Call close request function
            UIFigureCloseRequest(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ParametersoftheTemperatureControllerLabel = uilabel(app.UIFigure);
            app.ParametersoftheTemperatureControllerLabel.Text = {'Parameters of the'; 'Temperature Controller'};
            app.MasterControllerLabel = uilabel(app.UIFigure);
            app.MasterControllerLabel.Text = 'Master Controller';
            app.SlaveControllerLabel = uilabel(app.UIFigure);
            app.SlaveControllerLabel.Text = 'Slave Controller';
            app.KP_masterEditFieldLabel = uilabel(app.UIFigure);
            app.KP_masterEditFieldLabel.Interpreter = 'tex';
            app.KP_masterEditFieldLabel.Text = 'KP_master';
            app.KP_masterEditField = uieditfield(app.UIFigure, 'numeric');
            app.KP_masterEditField.ValueDisplayFormat = '%.4f';
            app.KP_slaveheatingLabel = uilabel(app.UIFigure);
            app.KP_slaveheatingLabel.Interpreter = 'tex';
            app.KP_slaveheatingLabel.Text = 'KP_slave (heating)';
            app.KP_slaveheatingEditField = uieditfield(app.UIFigure, 'numeric');
            app.KP_slaveheatingEditField.ValueDisplayFormat = '%.4f';
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.KI_masterEditFieldLabel = uilabel(app.UIFigure);
            app.KI_masterEditFieldLabel.Interpreter = 'tex';
            app.KI_masterEditFieldLabel.Text = 'KI_master';
            app.KI_masterEditField = uieditfield(app.UIFigure, 'numeric');
            app.KI_masterEditField.ValueDisplayFormat = '%.4f';
            app.KP_slavecoolingEditFieldLabel = uilabel(app.UIFigure);
            app.KP_slavecoolingEditFieldLabel.Interpreter = 'tex';
            app.KP_slavecoolingEditFieldLabel.Text = 'KP_slave (cooling)';
            app.KP_slavecoolingEditField = uieditfield(app.UIFigure, 'numeric');
            app.KP_slavecoolingEditField.ValueDisplayFormat = '%.4f';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogBox3(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end