classdef DialogBox4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        KDEditField_4       matlab.ui.control.NumericEditField
        KDEditField_4Label  matlab.ui.control.Label
        KIEditField_4       matlab.ui.control.NumericEditField
        KIEditField_4Label  matlab.ui.control.Label
        KPEditField_4       matlab.ui.control.NumericEditField
        KPEditField_4Label  matlab.ui.control.Label
        KDEditField_3       matlab.ui.control.NumericEditField
        KDEditField_3Label  matlab.ui.control.Label
        KIEditField_3       matlab.ui.control.NumericEditField
        KIEditField_3Label  matlab.ui.control.Label
        KPEditField_3       matlab.ui.control.NumericEditField
        KPEditField_3Label  matlab.ui.control.Label
        KDEditField_2       matlab.ui.control.NumericEditField
        KDEditField_2Label  matlab.ui.control.Label
        KIEditField_2       matlab.ui.control.NumericEditField
        KIEditField_2Label  matlab.ui.control.Label
        KPEditField_2       matlab.ui.control.NumericEditField
        KPEditField_2Label  matlab.ui.control.Label
        KDEditField         matlab.ui.control.NumericEditField
        KDEditFieldLabel    matlab.ui.control.Label
        FeedLabel           matlab.ui.control.Label
        GasmixLabel         matlab.ui.control.Label
        KIEditField         matlab.ui.control.NumericEditField
        KIEditFieldLabel    matlab.ui.control.Label
        ParametersofthepO2ControllerLabel  matlab.ui.control.Label
        KPEditField         matlab.ui.control.NumericEditField
        KPEditFieldLabel    matlab.ui.control.Label
        ConfirmButton       matlab.ui.control.Button
        AerationLabel       matlab.ui.control.Label
        AgitationLabel      matlab.ui.control.Label
    end

    
    properties (Access = public)
        CallingApp   % Main app object
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            % Update UI with input values
            app.KPEditField.Value = app.CallingApp.KP_agi;
            app.KIEditField.Value = app.CallingApp.KI_agi;
            app.KDEditField.Value = app.CallingApp.KD_agi;

            app.KPEditField_2.Value = app.CallingApp.KP_gasmix;
            app.KIEditField_2.Value = app.CallingApp.KI_gasmix;
            app.KDEditField_2.Value = app.CallingApp.KD_gasmix;

            app.KPEditField_3.Value = app.CallingApp.KP_aeration;
            app.KIEditField_3.Value = app.CallingApp.KI_aeration;
            app.KDEditField_3.Value = app.CallingApp.KD_aeration;

            app.KPEditField_4.Value = app.CallingApp.KP_feed;
            app.KIEditField_4.Value = app.CallingApp.KI_feed;
            app.KDEditField_4.Value = app.CallingApp.KD_feed;

            % Define window name
            app.UIFigure.Name = "pO2 Controller Parameters";
            centerWindow(app.UIFigure)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Enable the Plot Opions button in main app
            app.CallingApp.pO2ParametersButton.Enable = 'on';
            
            % Delete the dialog box 
            delete(app)
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            % Store inputs as properties of mainapp
            app.CallingApp.KP_agi = app.KPEditField.Value;
            app.CallingApp.KI_agi = app.KIEditField.Value;
            app.CallingApp.KD_agi = app.KDEditField.Value;

            app.CallingApp.KP_gasmix = app.KPEditField_2.Value;
            app.CallingApp.KI_gasmix = app.KIEditField_2.Value;
            app.CallingApp.KD_gasmix = app.KDEditField_2.Value;

            app.CallingApp.KP_aeration = app.KPEditField_3.Value;
            app.CallingApp.KI_aeration = app.KIEditField_3.Value;
            app.CallingApp.KD_aeration = app.KDEditField_3.Value;

            app.CallingApp.KP_feed = app.KPEditField_4.Value;
            app.CallingApp.KI_feed = app.KIEditField_4.Value;
            app.CallingApp.KD_feed = app.KDEditField_4.Value;

            % Upload changed to databank
            parameters = ["KP_agi", "KI_agi", "KD_agi", "KP_gasmix", "KI_gasmix", "KD_gasmix",...
                "KP_aeration", "KI_aeration", "KD_aeration", "KP_feed", "KI_feed", "KD_feed"];
            for i = 1:length(parameters)
                ParameterChangedFcn(app.CallingApp, parameters(i), app.CallingApp.(parameters(i)));
            end

            % Call close request function
            UIFigureCloseRequest(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AgitationLabel = uilabel(app.UIFigure);
            app.AgitationLabel.Text = 'Agitation';
            app.AerationLabel = uilabel(app.UIFigure);
            app.AerationLabel.Text = 'Aeration';
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.KPEditFieldLabel = uilabel(app.UIFigure);
            app.KPEditFieldLabel.Text = 'KP';
            app.KPEditField = uieditfield(app.UIFigure, 'numeric');
            app.KPEditField.ValueDisplayFormat = '%.4f';
            app.ParametersofthepO2ControllerLabel = uilabel(app.UIFigure);
            app.ParametersofthepO2ControllerLabel.Text = 'Parameters of the pO2 Controller';
            app.KIEditFieldLabel = uilabel(app.UIFigure);
            app.KIEditFieldLabel.Text = 'KI';
            app.KIEditField = uieditfield(app.UIFigure, 'numeric');
            app.KIEditField.ValueDisplayFormat = '%.4f';
            app.GasmixLabel = uilabel(app.UIFigure);
            app.GasmixLabel.Text = 'Gasmix';
            app.FeedLabel = uilabel(app.UIFigure);
            app.FeedLabel.Text = 'Feed';
            app.KDEditFieldLabel = uilabel(app.UIFigure);
            app.KDEditFieldLabel.Text = 'KD';
            app.KDEditField = uieditfield(app.UIFigure, 'numeric');
            app.KDEditField.ValueDisplayFormat = '%.4f';
            app.KPEditField_2Label = uilabel(app.UIFigure);
            app.KPEditField_2Label.Text = 'KP';
            app.KPEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.KPEditField_2.ValueDisplayFormat = '%.4f';
            app.KIEditField_2Label = uilabel(app.UIFigure);
            app.KIEditField_2Label.Text = 'KI';
            app.KIEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.KIEditField_2.ValueDisplayFormat = '%.4f';
            app.KDEditField_2Label = uilabel(app.UIFigure);
            app.KDEditField_2Label.Text = 'KD';
            app.KDEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.KDEditField_2.ValueDisplayFormat = '%.4f';
            app.KPEditField_3Label = uilabel(app.UIFigure);
            app.KPEditField_3Label.Text = 'KP';
            app.KPEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.KPEditField_3.ValueDisplayFormat = '%.4f';
            app.KIEditField_3Label = uilabel(app.UIFigure);
            app.KIEditField_3Label.Text = 'KI';
            app.KIEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.KIEditField_3.ValueDisplayFormat = '%.4f';
            app.KDEditField_3Label = uilabel(app.UIFigure);
            app.KDEditField_3Label.Text = 'KD';
            app.KDEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.KDEditField_3.ValueDisplayFormat = '%.4f';
            app.KPEditField_4Label = uilabel(app.UIFigure);
            app.KPEditField_4Label.Text = 'KP';
            app.KPEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.KPEditField_4.ValueDisplayFormat = '%.4f';
            app.KIEditField_4Label = uilabel(app.UIFigure);
            app.KIEditField_4Label.Text = 'KI';
            app.KIEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.KIEditField_4.ValueDisplayFormat = '%.4f';
            app.KDEditField_4Label = uilabel(app.UIFigure);
            app.KDEditField_4Label.Text = 'KD';
            app.KDEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.KDEditField_4.ValueDisplayFormat = '%.4f';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogBox4(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end