classdef ExportProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        IncludeparametersCheckBox  matlab.ui.control.CheckBox
        ExportButton               matlab.ui.control.Button
        IncludelogfileCheckBox     matlab.ui.control.CheckBox
        VariabledatafiletypeButtonGroup  matlab.ui.container.ButtonGroup
        txtButton                  matlab.ui.control.RadioButton
        csvButton                  matlab.ui.control.RadioButton
        xlsxButton                 matlab.ui.control.RadioButton
        VariablesindatatableLabel  matlab.ui.control.Label
        Tree                       matlab.ui.container.CheckBoxTree
        AllNode                    matlab.ui.container.TreeNode
    end

    
    properties (Access = private)
        variableTable % table containing variable information
        selectedVariables = 1:20 % variables selected in tree
        dataTable % table containing selected variable data
        CallingApp
        fileType = '.xlsx'
    end
    
    methods (Access = private)
        
        function readDatabase(app)
            % Read Project name
            sqlquery = sprintf('SELECT name FROM projectTab WHERE projectID = %i', app.CallingApp.projectID);
            conn = sqlite(app.CallingApp.dbpath, "readonly");
            app.CallingApp.projectName = table2array(fetch(conn,sqlquery));
            % Determine variable information
            sqlquery = sprintf(['SELECT v.variableID, v.name, v.unit ' ...
                'FROM variableTab v ' ...
                'JOIN variable_handlingTab vh ON vh.variableID = v.variableID ' ...
                'WHERE vh.upload_rate = "cyclic" and vh.organismID = %i'], app.CallingApp.organismID);
            app.variableTable = fetch(conn,sqlquery);
            close(conn)
        end

        function fillTree(app)
            delete(app.AllNode.Children);
            app.Tree.CheckedNodes = [];
            n = 1;
                % Add nodes to the second level
                for i = 1:size(app.variableTable,1)
                    childnode = uitreenode(app.AllNode, 'Text', app.variableTable.name(i), 'NodeData', app.variableTable.variableID(i));
                    if any(i == app.selectedVariables)
                        checkednodes(n) = childnode;
                        n = n + 1;
                    end
                end
                app.Tree.CheckedNodes = checkednodes;
                expand(app.AllNode);
        end

        function data = createDataTable(app)
            if ~isempty(app.selectedVariables)
                % Extract data from database
                selectClause = 'SELECT t.datetime as "Date/Time", t.process_time as "Process Time", ';
                for i = app.selectedVariables
                    idx = find(i == app.variableTable.variableID);
                    selectClause = append(selectClause, ...
                        'COALESCE(MAX(CASE WHEN d.variableID = ', num2str(i), ' THEN d.value END), "") AS "', app.variableTable.name(idx), " in ", app.variableTable.unit(idx),'"');
                    if i ~= app.selectedVariables(end)
                        selectClause = append(selectClause, ', ');
                    end
                end
                fromClause = sprintf(' FROM dataTab d JOIN timeTab t ON d.timeID = t.timeID WHERE t.projectID = %g ', app.CallingApp.projectID);
                groupByClause = 'GROUP BY t.process_time ORDER BY t.process_time;';
                sqlquery = append(selectClause, fromClause, groupByClause);
                conn = sqlite(app.CallingApp.dbpath, "readonly");
                data = fetch(conn,sqlquery);
                close(conn)
            else
                selectClause = 'SELECT t.datetime as "Date/Time", t.process_time as "Process Time" ';
                fromClause = sprintf(' FROM dataTab d JOIN timeTab t ON d.timeID = t.timeID WHERE t.projectID = %g ', app.CallingApp.projectID);
                groupByClause = 'GROUP BY t.process_time ORDER BY t.process_time;';
                sqlquery = append(selectClause, fromClause, groupByClause);
                conn = sqlite(app.CallingApp.dbpath, "readonly");
                data = fetch(conn,sqlquery);
                close(conn)
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, CallingApp)
            app.CallingApp = CallingApp;
            centerWindow(app.UIFigure)
            app.UIFigure.Name = "Export Project";
            readDatabase(app)
            fillTree(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            checkedNodes = app.Tree.CheckedNodes;
            app.selectedVariables = [];
            if ~isempty(checkedNodes)
                if checkedNodes(1).Text == "All"
                for i = 1:numel(checkedNodes)-1
                    app.selectedVariables(i) = checkedNodes(i+1).NodeData;
                end
                else
                for i = 1:numel(checkedNodes)
                    app.selectedVariables(i) = checkedNodes(i).NodeData;
                end
                end
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            %% Generate data table
            data = createDataTable(app);
            % Try to get the user's desktop path
            desktopPath = fullfile(getenv('USERPROFILE'), 'Desktop');

            % Check if the desktopPath is empty or doesn't exist
            if isempty(desktopPath) || ~exist(desktopPath, 'dir')
                disp('Desktop path not found. Using current working directory instead.');
                folderPath = fullfile(pwd, app.CallingApp.projectName);  % Use current directory if desktop is not available
            else
                % Create a default folder on the desktop
                folderBaseName = app.CallingApp.projectName;
                folderPath = fullfile(desktopPath, folderBaseName);

                % Check if the folder already exists, if so, append an ongoing number
                folderCounter = 1;
                while exist(folderPath, 'dir')
                    folderCounter = folderCounter + 1;
                    folderPath = fullfile(desktopPath, append(folderBaseName, "_", num2str(folderCounter)));
                end

                % Create the folder
                mkdir(folderPath);
            end

            [~, folderName, ~] = fileparts(folderPath);
            % The folder name as the default file name
            defaultDataFileName = append(folderName, "_data", app.fileType);

            fullFileName = fullfile(folderPath,defaultDataFileName);

                try
                    % Save the table based on the file extension
                    switch app.fileType
                        case '.txt'
                            % Save as text file
                            writetable(data, fullFileName, 'FileType', 'text');
                        case '.csv'
                            % Save as CSV file
                            writetable(data, fullFileName);
                        case '.xlsx'
                            % Save as Excel file
                            writetable(data, fullFileName, 'FileType', 'spreadsheet');
                        otherwise
                            msgbox('Unsupported file type',"Error","error");
                    end
                    disp('Data file saved successfully.');
                catch ME
                    msgbox(ME.message,"Error","error");
                end

                %% Create log file
                if app.IncludelogfileCheckBox.Value
                    % Create project information txt file
                    defaultLogFileName = append(folderName, "_log.txt");
                    fullFileName = fullfile(folderPath,defaultLogFileName);
                    % Open the file for writing
                    fileID = fopen(fullFileName, 'w');

                    % Ensure the file opened successfully
                    if fileID == -1
                        error('Could not open file for writing.');
                    end

                    % Loop through each row of the cell array
                    for row = 1:size(app.CallingApp.logText, 1)
                        % Loop through each column of the current row
                        for col = 1:size(app.CallingApp.logText, 2)
                            % Write the cell content to the file
                            fprintf(fileID, '%s\n', cell2mat(string(app.CallingApp.logText{row, col})));
                        end
                        % Newline after each row
                        fprintf(fileID, '\n');
                    end

                    % Close the file
                    fclose(fileID);
                end

                %% Create parameter file
                if app.IncludeparametersCheckBox.Value
                    % Read all property values
                    sqlquery = sprintf(['SELECT prop.name, ppara.value, ppara.description ' ...
                        'FROM project_parameterTab ppara ' ...
                        'JOIN projectTab proj ON proj.projectID = ppara.projectID ' ...
                        'JOIN parameterTab prop ON ppara.parameterID = prop.parameterID ' ...
                        'JOIN categoryTab c ON prop.categoryID = c.categoryID ' ...
                        'WHERE proj.projectID = %i'], app.CallingApp.projectID);
                    conn = sqlite(app.CallingApp.dbpath, "readonly");
                    T = fetch(conn,sqlquery);
                    close(conn)

                    defaultParameterFileName = append(folderName, "_parameters", app.fileType);

                    fullFileName = fullfile(folderPath,defaultParameterFileName);

                    try
                        % Save the table based on the file extension
                        switch app.fileType
                            case '.txt'
                                % Save as text file
                                writetable(T, fullFileName, 'FileType', 'text');
                            case '.csv'
                                % Save as CSV file
                                writetable(T, fullFileName);
                            case '.xlsx'
                                % Save as Excel file
                                writetable(T, fullFileName, 'FileType', 'spreadsheet');
                            otherwise
                                msgbox('Unsupported file type',"Error","error");
                        end
                        disp('Parameter file saved successfully.');
                    catch ME
                        msgbox(ME.message,"Error","error");
                    end

                    %% Create project information txt file

                    % First create a cell with project information
                    cellInfo(1,:) = {"Project name:", app.CallingApp.projectName};
                    cellInfo(2,:) = {"Description:", app.CallingApp.projectDescription};
                    cellInfo(3,:) = {"Author:", app.CallingApp.author};
                    cellInfo(4,:) = {"Created on:", app.CallingApp.createdon};
                    cellInfo(5,:) = {"Last opened:", app.CallingApp.recentuse};
                    cellInfo(6,:) = {"Organism:", app.CallingApp.organismname};
                    cellInfo(7,:) = {"Bioreactor:", app.CallingApp.bioreactorname};
                    defaultInfoFileName = "Project_Information.txt";
                    fullFileName = fullfile(folderPath,defaultInfoFileName);
                    % Open the file for writing
                    fileID = fopen(fullFileName, 'w');

                    % Ensure the file opened successfully
                    if fileID == -1
                        error('Could not open file for writing.');
                    end

                    % Loop through each row of the cell array
                    for row = 1:size(cellInfo, 1)
                        % Loop through each column of the current row
                        for col = 1:size(cellInfo, 2)
                            % Write the cell content to the file
                            fprintf(fileID, '%s\t', cell2mat(string(cellInfo{row, col})));
                        end
                        % Newline after each row
                        fprintf(fileID, '\n');
                    end

                    % Close the file
                    fclose(fileID);
                end
                delete(app)
        end

        % Selection changed function: VariabledatafiletypeButtonGroup
        function VariabledatafiletypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.VariabledatafiletypeButtonGroup.SelectedObject;
            if selectedButton == app.txtButton
                app.fileType = '.txt';
            elseif selectedButton == app.csvButton
                app.fileType = '.csv';
            elseif selectedButton == app.xlsxButton
                app.fileType = '.xlsx';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Tree = uitree(app.UIFigure, 'checkbox');
            app.AllNode = uitreenode(app.Tree);
            app.AllNode.Text = 'All';
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.VariablesindatatableLabel = uilabel(app.UIFigure);
            app.VariablesindatatableLabel.Text = 'Variables in data table:';
            app.VariabledatafiletypeButtonGroup = uibuttongroup(app.UIFigure);
            app.VariabledatafiletypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @VariabledatafiletypeButtonGroupSelectionChanged, true);
            app.VariabledatafiletypeButtonGroup.Title = 'Variable data file type';
            app.xlsxButton = uiradiobutton(app.VariabledatafiletypeButtonGroup);
            app.xlsxButton.Text = '*.xlsx';
            app.xlsxButton.Value = true;
            app.csvButton = uiradiobutton(app.VariabledatafiletypeButtonGroup);
            app.csvButton.Text = '*.csv';
            app.txtButton = uiradiobutton(app.VariabledatafiletypeButtonGroup);
            app.txtButton.Text = '*.txt';
            app.IncludelogfileCheckBox = uicheckbox(app.UIFigure);
            app.IncludelogfileCheckBox.Text = 'Include log file';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.IncludeparametersCheckBox = uicheckbox(app.UIFigure);
            app.IncludeparametersCheckBox.Tooltip = {'Include a list with all parameter values'};
            app.IncludeparametersCheckBox.Text = 'Include parameters';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExportProject(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end