classdef FigureApp4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ExportMenu                   matlab.ui.container.Menu
        ExportPlotMenu               matlab.ui.container.Menu
        TemplateMenu                 matlab.ui.container.Menu
        SaveChangesMenu              matlab.ui.container.Menu
        CreateTemplateMenu           matlab.ui.container.Menu
        LoadTemplateMenu             matlab.ui.container.Menu
        SelectMenu                   matlab.ui.container.Menu
        Menu2                        matlab.ui.container.Menu
        OptionsMenu                  matlab.ui.container.Menu
        AutoUpdatePlotMenu           matlab.ui.container.Menu
        FlagsMenu                    matlab.ui.container.Menu
        PlotTypeMenu                 matlab.ui.container.Menu
        ComplexPlotMenu              matlab.ui.container.Menu
        SimplePlotMenu               matlab.ui.container.Menu
        EditVariablesMenu            matlab.ui.container.Menu
        SettingsMenu                 matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        VariableLimitsPanel          matlab.ui.container.Panel
        VariableLimitsGridLayout     matlab.ui.container.GridLayout
        tlimEditField                matlab.ui.control.NumericEditField
        tlimminEditField             matlab.ui.control.NumericEditField
        MaxLabel                     matlab.ui.control.Label
        MinLabel                     matlab.ui.control.Label
        tlimEditFieldLabel           matlab.ui.control.Label
        TimeAxisLabel                matlab.ui.control.Label
        VariableAxisLabel            matlab.ui.control.Label
        AutoLabel                    matlab.ui.control.Label
        MaxLabel_2                   matlab.ui.control.Label
        MinLabel_2                   matlab.ui.control.Label
        VariableLabel                matlab.ui.control.Label
        LimitsOpener                 matlab.ui.control.StateButton
        ConfiOpener                  matlab.ui.control.StateButton
        PlotPanel                    matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        PlotConfigurationPanel       matlab.ui.container.Panel
        PlotConfigurationGridLayout  matlab.ui.container.GridLayout
        AutoUpdateButton             matlab.ui.control.StateButton
        VariablePanel                matlab.ui.container.Panel
        VariableGridLayout           matlab.ui.container.GridLayout
    end


    properties (Access = public)
        AutoUpdate % Timer object needed for the simulation
        plotmode = true % Simple or complex plot (true: complex)
        projectID
        % Variables
        variableID
        variables % Array with all visible variable names
        t_data
        y_data

        TexFontName = '\fontname{Helvetica}'
        FigureApps = "FigureApps";

        % Selected variable indices
        selection
        selectionsorted
        % Variables
        NameVar = strings(2,1); % Initialize string column vector
        SelectedVar
        AxisLabelVar = strings(2,1);
        FlagNameVar = strings(2,1);
        UnitVar = strings(2,1);
        LimitTypeVar
        yminVar;
        ymaxVar;
        FlagPointVar;
        FlagOriVar = strings(2,1);
        DecimalVar = strings(2,1);
        RVar;
        GVar;
        BVar;
        RGBVar;
        LineStyleVar = strings(2,1);
        TimeColumnVar;
        DataColumnVar;
        TimeUnitVar = strings(2,1);
        StartRowVar;

        % Process
        name;
        plottitle;
        profileID;
        description;
        last_changed;
        numAxes = 1;
        numphases;
        startTime;
        phaseName;
        phaseTime;
        titlebool;

        % Properties
        % Decimal
        decimalName;
        decimalSymbol;
        translateddecimal = string([]); % Has a list with corresponding decimals
        % Color
        colorName;
        RGB
        translatedcolor;
        % Line Style
        lineStyleSymbol;
        lineStyleNum;
        translatedlinestyle;
        % Flag Orientation
        flagOriCelestial;
        flagOriNum;
        translatedflagori;
        f_angle

        xaxisTitle

        % Settings
        % Graph
        graphlinewidth; % Line width
        graphvlinewidth; % Linewidth vertical lines
        graphfontsize; % Font size
        graphtitlefontsize % Title font size

        % Axes
        axisxlabel;
        axisxunit;
        axisytick; %Number of Ticks y-axis
        axisxtick; %Number of Ticks x-axis
        axisyoffset; % Offset Y−axis
        axisylabeloffsetabove; %Y label offset above
        axisylabeloffsetbelow; %Y label offset below
        axislabelfontsize; %Fontsize
        axislinewidth; % Linewidth Axes


        % Flags
        flaglength; % Length of Flag handle
        flagangle; % Angle of Flag handle in °
        flaglinewidth; % Width of Flag handle
        flagfontsize; %Fontsize

        refreshrate
        tstart
        tend

        Optionsapp;
        Phasesapp;
        Settingsapp

        % Plot Destination
        plotdest

        % Axes
        UIAxes1
        UIAxes2
        UIAxes3
        UIAxes4
        UIAxes5
        UIAxes6
        UIAxes7
        UIAxes8
        UIAxes9
        UIAxes10
        UIAxes11
        UIAxes12

        previous_selection = []
        dbpath = "SimulationAppDB.db";

        table
    end

    properties (Access = private)
        CallingApp
        longTexVariable
        shortTexVariable
        variableUnit

    end

    methods (Access = public)

        function readProfile(app, profileID)
            try
                % Read plot profile information and settings
                sqlquery = sprintf('SELECT * FROM plot_profileTab WHERE profileID = %g', profileID);
                T = fetchpy(app.dbpath,sqlquery);
                properties = T.Properties.VariableNames;
                for i = 1:length(properties)
                    app.(properties{i}) = table2array(T(1,i));
                end
                % Read all property information for variable plotting
                sqlquery = sprintf(['SELECT v.variableID as "variableID", v.name as "NameVar", p.selected_axis as "SelectedVar", ' ...
                    'v.shorttex as "FlagNameVar", v.longtex as "AxisLabelVar", ' ...
                    'v.tex_unit as "UnitVar", p.limit_type as "LimitTypeVar", p.ymin as "yminVar", ' ...
                    'p.ymax as "ymaxVar", ' ...
                    'f.flagori_num as "FlagOriVar", d.decimal_symbol as "DecimalVar", ' ...
                    'c.R as "RVar", c.G as "GVar", c.B as "BVar", l.linestyle_num as "LineStyleVar" ' ...
                    'FROM plot_variableTab p ' ...
                    'JOIN variableTab v ON v.variableID = p.variableID ' ...
                    'JOIN variable_handlingTab vh ON vh.variableID = v.variableID ' ...
                    'JOIN plot_flagoriTab f ON f.flagoriID = p.flagoriID ' ...
                    'JOIN plot_decimalTab d ON d.decimalID = p.decimalID ' ...
                    'JOIN plot_colorTab c ON c.colorID = p.colorID ' ...
                    'JOIN plot_linestyleTab l ON l.linestyleID = p.linestyleID ' ...
                    'WHERE p.profileID = %g  AND vh.visible = 1'], app.profileID);
                app.table = fetchpy(app.dbpath,sqlquery);
                %properties = app.table.Properties.VariableNames;
                
                % for i = 1:length(properties)
                %     app.(properties{i}) = table2array(app.table(:,i));
                % end

                % Define RGB
                app.RGBVar = [app.RVar, app.GVar, app.BVar];

                % Plot options
                % Color
                sqlquery = 'SELECT * FROM plot_colorTab';
                T = fetchpy(app.dbpath,sqlquery);
                app.colorName = table2array(T(:,2));
                app.RGB = table2array(T(:,3:5));
                % Decimal
                sqlquery = 'SELECT * FROM plot_decimalTab';
                T = fetchpy(app.dbpath,sqlquery);
                app.decimalName = table2array(T(:,2));
                app.decimalSymbol = table2array(T(:,3));
                % Flag orientation
                'SELECT * FROM plot_flagoriTab';
                T = fetchpy(app.dbpath,sqlquery);
                app.flagOriCelestial = table2array(T(:,2));
                app.flagOriNum = table2array(T(:,3));
                % Line Style
                sqlquery = 'SELECT * FROM plot_linestyleTab';
                T = fetchpy(app.dbpath,sqlquery);
                app.lineStyleSymbol = table2array(T(:,2));
                app.lineStyleNum = table2array(T(:,3));

                % t axis label
                app.xaxisTitle = {app.axisxlabel;append('[', app.axisxunit, ']')};

                % Flag angle
                app.f_angle = tand(double(app.flagangle))*app.flaglength; % Angle

            catch ME
                errordlg(['Error reading profile: ' ME.message], 'Profile Error', 'replace');
            end

        end

        function createDynamicGrid(app)
            app.tlimEditField.Value = app.tend;
            app.tlimminEditField.Value = app.tstart;
            delete(app.VariableLimitsGridLayout.Children(12:end))
            grid = app.VariableLimitsGridLayout;
            idx = find(app.table.SelectedVar == 1);
            for i = 1:length(idx)
                currentRow = i + 6;
                selectionIndex = idx(i);

                % Label in the first column
                lbl1 = uilabel(grid);
                lbl1.Layout.Row = currentRow;
                lbl1.Layout.Column = 1;
                lbl1.Text = sprintf('%g:', i);

                % Label in the second column
                lbl2 = uilabel(grid);
                lbl2.Layout.Row = currentRow;
                lbl2.Layout.Column = 2;
                LabelText = append(app.TexFontName, app.table.AxisLabelVar(selectionIndex));
                lbl2.Text = LabelText;
                lbl2.Interpreter = 'Tex';

                % Numeric Edit Field for Minimum in the third column
                minEdit = uieditfield(grid, 'numeric');
                minEdit.Layout.Row = currentRow;
                minEdit.Layout.Column = 3;
                minEdit.Value = app.table.yminVar(selectionIndex);
                minEdit.ValueChangedFcn = @(src,event) minEditCallback(app, src, event, i);

                % Numeric Edit Field for Maximum in the fourth column
                maxEdit = uieditfield(grid, 'numeric');
                maxEdit.Layout.Row = currentRow;
                maxEdit.Layout.Column = 4;
                maxEdit.Value = app.table.ymaxVar(selectionIndex);
                maxEdit.ValueChangedFcn = @(src,event) maxEditCallback(app, src, event, i);

                % Checkbox in the last column
                cb = uicheckbox(grid);
                cb.Layout.Row = currentRow;
                cb.Layout.Column = 6;
                cb.Text = '';
                cb.Value = isequal(app.table.LimitTypeVar(selectionIndex), 1);
                cb.ValueChangedFcn = @(src,event) LimitscheckboxCallback(app, src, event, i);
                cb.Tooltip = "Auto: Y-axis limits increase when data leaves plot area.";

                % Set enabled state based on LimitTypeVar
                if isequal(app.table.LimitTypeVar(selectionIndex), 1)
                    minEdit.Enable = 'off';
                    maxEdit.Enable = 'off';
                else
                    minEdit.Enable = 'on';
                    maxEdit.Enable = 'on';
                end
            end
        end

        function LimitscheckboxCallback(app, src, event, index)
            selectionIndex = app.selection(index);
            objectNum = 9+(5*(index));
            if src.Value
                app.LimitTypeVar(selectionIndex) = 1;
                src.Parent.Children(objectNum).Enable = 'off';  % Disable minEdit
                src.Parent.Children(objectNum+1).Enable = 'off';  % Disable maxEdit
                src.Parent.Children(objectNum).Value = app.yminVar(selectionIndex);   % Enable minEdit
                src.Parent.Children(objectNum+1).Value = app.ymaxVar(selectionIndex);   % Enable maxEdit
                updateDatabase(app, 'limit_type', selectionIndex, 1)
            else
                app.LimitTypeVar(selectionIndex) = 0;
                src.Parent.Children(objectNum).Enable = 'on';   % Enable minEdit
                src.Parent.Children(objectNum+1).Enable = 'on';   % Enable maxEdit
                updateDatabase(app, 'limit_type', selectionIndex, 0)
            end
            disp(['LimitType for axis ' num2str(index) ' changed to ' num2str(app.LimitTypeVar(selectionIndex))]);
        end

        function minEditCallback(app, src, event, index)
            selectionIndex = app.selection(index);
            newMin = src.Value;
            currentMax = app.table.ymaxVar(selectionIndex);

            if newMin < currentMax
                app.table.yminVar(selectionIndex) = newMin;
                updateDatabase(app, 'ymin', selectionIndex, newMin)
                disp(['Minimum for axis ' num2str(index) ' changed to ' num2str(newMin)]);
            else
                % If new min is greater than or equal to max, reset to previous value
                src.Value = event.PreviousValue;
                warndlg('Minimum value must be less than maximum value.', 'Invalid Input');
            end
        end

        function maxEditCallback(app, src, event, index)
            selectionIndex = app.selection(index);
            newMax = src.Value;
            currentMin = app.table.yminVar(selectionIndex);
            updateDatabase(app, 'ymax', selectionIndex, newMax)
            if newMax > currentMin
                app.table.ymaxVar(selectionIndex) = newMax;
                
                disp(['Maximum for axis ' num2str(index) ' changed to ' num2str(newMax)]);
            else
                % If new max is less than or equal to min, reset to previous value
                src.Value = event.PreviousValue;
                warndlg('Maximum value must be greater than minimum value.', 'Invalid Input');
            end
        end

        function createGridLayout(app)
            delete(app.VariableGridLayout.Children)
            variableNum = size(app.table,1);
            gridLayout = app.VariableGridLayout;
            % Set the grid layout to have two columns
            gridLayout.ColumnWidth = {'1x', '7x'};
            gridLayout.RowHeight = repmat({'fit'}, 1, variableNum);
            for n = 1:variableNum

            end
            % Find pre-selected variables
            app.selection = (find(app.table.SelectedVar == 1))';
            app.numAxes = length(app.selection);
            if app.numAxes > 6
                app.selection = app.selection(1:6);
            end
            % Loop over the number of rows
            for i = 1:variableNum
                % Create a checkbox in the first column of the ith row
                cb = uicheckbox(gridLayout, 'Text', '');
                cb.Layout.Row = i;
                cb.Layout.Column = 1;
                % Pre picked variables are checked
                if any(app.selection == i)
                    cb.Value = true;
                end
                % Assign a callback function to the checkbox
                cb.ValueChangedFcn = @(src, event)checkboxCallback(app, src, event, i);

                % Create a label in the second column of the ith row
                LabelText = append(app.TexFontName, app.table.AxisLabelVar(i), ' [', app.table.UnitVar(i),']');
                lbl = uilabel(gridLayout, 'Text', LabelText, Interpreter='Tex');
                lbl.Layout.Row = i;
                lbl.Layout.Column = 2;
            end


        end

        function checkboxCallback(app, src, event, index)
            
            if src.Value
                % Checkbox is checked
                if length(app.selection) < 6
                    app.table.SelectedVar(index) = 1;
                else
                    uialert(app.UIFigure, 'You can only plot up to 6 variable at a time.', 'Limit Exceeded');
                    src.Value = event.PreviousValue;
                end
            else
                % Checkbox is unchecked
                    app.table.SelectedVar(index) = 0;
            end
            app.selection = (find(app.table.SelectedVar == 1))';
            app.numAxes = length(app.selection);
            createDynamicGrid(app)
        end

        function updateDatabase(app, propertyName, selectionIndex, newValue)
            maxRetries = 5;
            retryDelay = 1; % seconds

            for attempt = 1:maxRetries
                try
                    sqlquery = sprintf('UPDATE plot_variableTab SET ''%s'' = %g WHERE profileID = %g AND variableID = %g', propertyName, newValue, app.profileID, selectionIndex);
                    execpy(app.dbpath,sqlquery)
                    
                    disp('Database update successful');
                    return; % Successful update, exit function
                catch ME
                    if attempt == maxRetries
                        warning('Database update failed after %d attempts: %s', maxRetries, ME.message);
                        % You could add additional error handling here
                    else
                        warning('Attempt %d failed, retrying in %d seconds...', attempt, retryDelay);
                        pause(retryDelay);
                    end
                end
            end
        end

        function updateOptions(app, propertyName, newValue)
            maxRetries = 5;
            retryDelay = 1; % seconds
            for attempt = 1:maxRetries
                try
                    sqlquery = sprintf('UPDATE plot_profileTab SET ''%s'' = %g WHERE profileID = %g', propertyName, newValue, app.profileID);
                    execpy(app.dbpath,sqlquery)
                    
                    disp('Database update successful');
                    return; % Successful update, exit function
                catch ME
                    if attempt == maxRetries
                        warning('Database update failed after %d attempts: %s', maxRetries, ME.message);
                        % You could add additional error handling here
                    else
                        warning('Attempt %d failed, retrying in %d seconds...', attempt, retryDelay);
                        pause(retryDelay);
                    end
                end
            end
        end

        function downloadData(app, variableIdx)
            try
                if isempty(variableIdx)
                    return
                end
                
                % Extract data from databank
                selectClause = 'SELECT t.process_time as time, ';
                for i = variableIdx
                    selectClause = append(selectClause, ...
                        'MAX(CASE WHEN d.variableID = ', num2str(app.table.variableID(i)), ' THEN d.value END) AS "', app.table.NameVar(i),'"');
                    if i ~= variableIdx(end)
                        selectClause = append(selectClause, ', '); % add comma
                    end
                end
                fromClause = sprintf(' FROM dataTab d JOIN timeTab t ON d.timeID = t.timeID WHERE t.projectID = %g ', app.projectID);
                groupByClause = 'GROUP BY t.process_time ORDER BY t.process_time;';
                query = append(selectClause, fromClause, groupByClause);
                T = fetchpy(app.dbpath, query);
                
                data = double(table2array(T));
                app.t_data = data(:,1);
                app.y_data = data(:,2:end);
            catch ME
                errordlg(['Error downloading data: ' ME.message], 'Data Error', 'replace');
                app.t_data = [];
                app.y_data = [];
            end
        end

        function plot_func(app)

            %% Simple Plot
            if app.plotmode
                % Advanced plot
                plotting(app);
                % plot_preparation(app)
            else
                a = 1;

                % Eliminate all plottable variables that are currently not
                % loaded in the workspace (avoids error if a node referring
                % to an empty variable is selected)
                for i = 1:length(app.tabstr)
                    if ~isempty(app.(app.Mstr{i}))
                        str{a} = app.tabstr{i};
                        M(a,:) = app.(app.Mstr{i});
                        a      = a+1;
                    end
                end

                h = 1;

                % Assess the checked nodes and compare them to the actually
                % loaded variables as filtered previously. Save the
                % positions of the variables that are to be plotted
                CN   = app.Tree.CheckedNodes;
                for j = 1:length(CN)
                    data = CN(j).Text;
                    p    = find(strcmp(data,str));
                    if isempty(p) ~=1
                        index(h) = p;
                        h = h+1;
                    end
                end

                % If variables were selected, split them into two
                % categories (one for each y axis) and generate
                % corresponding axis labels
                if exist("data","var")
                    n = 1;
                    m = 1;

                    for i = 1:length(app.primaryvars)
                        if i < length(app.primaryvars)/2+1
                            M1(n,:) = M(app.primaryvars(i),:);
                            h1(n)   = str(app.primaryvars(i));
                            n = n+1;
                        else
                            M2(m,:) = M(app.primaryvars(i),:);
                            h2(m)   = str(app.primaryvars(i));
                            m = m+1;
                        end
                        headers(n+m-2) = str(app.primaryvars(i));
                    end

                    % Plot selected data against t
                    if exist("M2","var")
                        yyaxis(app.UIAxes,'right')
                        app.Plot1 = plot(app.UIAxes,app.t,M2,'Color','red');
                        lim = app.UIAxes.YLim;
                        app.variablelimrightminEditField.Value = lim(1);
                        app.variablelimrightEditField.Value = lim(2);
                        ylabel(app.UIAxes,h2)
                    end

                    if exist("M1","var")
                        yyaxis(app.UIAxes,'left')
                        app.Plot2 = plot(app.UIAxes,app.t,M1,'Color','black');
                        lim = app.UIAxes.YLim;
                        app.variablelimleftminEditField.Value = lim(1);
                        app.VariableEditField.Value = lim(2);
                        ylabel(app.UIAxes,h1')
                        legend(app.UIAxes,headers,"Location","best")
                    end

                    lim = app.UIAxes.XLim;
                    app.tlimminEditField.Value = lim(1);
                    app.tlimEditField.Value = lim(2);
                end
            end
        end


    end

    methods (Access =public)


        function deleteAxes(app)
            % Entferne alle existierenden Achsen
            axesHandles = findobj(app.plotdest, 'Type', 'axes');
            delete(axesHandles);
        end

        function AutoUpdateFcn(app,varargin)
            try
                % plot_func(app)
                plotting(app)
            catch ME
                checkUpdater(app)
                app.AutoUpdatePlotMenu.Checked = "off";
                stop(app.AutoUpdate)
                errordlg(['Auto update error: ' ME.message], 'Update Error', 'replace');
            end
        end

            %% Plotting functions
        function plotting(app)
            downloadData(app, app.selection) % Download selected variable data from databank
            sortVariables(app); % Sort from lowest to highest y limit
            prepareVariables(app) % Extend y limits if needed

            %app.numAxes = length(app.selection);
                deleteAxes(app); % Delete axes
                
                app.numAxes = length(app.selection);
                for n = 1:app.numAxes % Loop for all axis
                    % Plot primary axes
                    kax = app.selection(n);
                    t = app.t_data;
                    d = app.y_data(:,n);
                    [Ypos, figurelength] = layout_plot(app,app.numAxes, app.axisyoffset);
                    % Loop to create axes
                    app.(['UIAxes' num2str(n)]) = uiaxes(app.plotdest);
                    axhN(n) = app.(['UIAxes' num2str(n)]);
                    axhN(n).Color = 'none';
                    axhN(n).Interactions = []; % Disable interactions
                    axhN(n).HitTest = 'off';
                    disableDefaultInteractivity(axhN(n));
                    if n == 1
                        set(app.UIAxes1,'InnerPosition',[0 0 figurelength Ypos(4)]);
                    else
                        set(axhN(n), 'InnerPosition',Ypos,'YAxisLocation','left');
                    end
                    YposN = [Ypos(1)-app.axisyoffset*(n-1) Ypos(2) Ypos(3) Ypos(4)];
                    ylab = [app.table.AxisLabelVar(kax);append("[",app.table.UnitVar(kax),"]")];
                    RGB = [app.table.RVar(kax), app.table.GVar(kax), app.table.BVar(kax)];
                    plotgen(app,t,d,axhN(n),RGB,app.graphlinewidth,YposN,app.table.yminVar(kax),app.table.ymaxVar(kax), app.tstart,app.tend,app.axisytick,app.graphfontsize,ylab,app.xaxisTitle,app.table.DecimalVar(kax),app.axislinewidth,app.table.LineStyleVar(kax),app.axisylabeloffsetabove,n);
                    if app.FlagsMenu.Checked == "on"
                        flagomat3(app,t,d,1,app.table.FlagOriVar(kax),app.table.ymaxVar(kax),app.table.yminVar(kax), app.flaglinewidth,RGB,app.flagfontsize,app.table.FlagNameVar(kax),axhN(n),app.f_angle,app.flaglength,app.tend, app.tstart);
                    end
                end
            if app.titlebool == 1
                title(app.(['UIAxes' num2str(1)]), app.plottitle,'fontsize',app.graphtitlefontsize,'fontweight','bold')
                set(get(app.(['UIAxes' num2str(1)]), 'title'),'Position',[app.tend/2 (app.table.ymaxVar(app.selection(app.numAxes))+0.05*(app.table.ymaxVar(app.selection(app.numAxes))-app.table.yminVar(app.selection(app.numAxes)))) 1])
            end
        end

        function deletePlot(app)
            for j = 1:app.numAxes
                num = num2str(j);
                delete(app.(['UIAxes' num]));
            end
        end

        function []=plotgen(app,Tdat,Ydat,axh,color,linewidth,Yposition,Ymin,Ymax,Tmin,Tend,tpaxis,fontsize,Y_label,X_label,Ytickform,linewidth_ax,linestyle,Ylaboff,Axn)
            if linestyle == 0
                plot(axh,Tdat,Ydat,'Color',color,'LineWidth',linewidth);
            end
            if linestyle == 1
                line1='--';
                plot(axh,Tdat,Ydat,line1,'Color',color,'LineWidth',linewidth);
            end
            if linestyle == 2
                line2='-.-';
                plot(axh,Tdat,Ydat,line2,'Color',color,'LineWidth',linewidth);
            end
            if Axn == 1
                %xtick=app.startTime:((app.phaseTime(app.numPhases)-app.startTime)/app.axisxtick):app.phaseTime(app.numPhases);
                xtick = app.tlimminEditField.Value:((app.tlimEditField.Value-app.tlimminEditField.Value)/app.axisxtick):app.tlimEditField.Value;
                set(axh,'TickDir','out','LineWidth',linewidth_ax);
                ytickformat(axh,Ytickform)
                tick=Ymin:((Ymax-Ymin)/tpaxis):Ymax;
                set(axh,'Ytick',tick,'Ylim',[Ymin Ymax],'FontSize',fontsize,'Ycolor','black','Visible','on');
                ylabel(axh,Y_label,'Position',[0 (Ymax-Ymin)*Ylaboff+Ymax -1],'Rotation',0,'HorizontalAlignment','center','Color',color);
                xlabel(axh,X_label);
                set(axh,'XGrid','on','YGrid','on');
                set(axh,'InnerPosition',Yposition, 'YAxisLocation','left','YLim',[Ymin Ymax], 'Xtick', xtick, 'XLim',[Tmin Tend],'Visible','on');
            elseif Axn == 2 || Axn == 3 || Axn == 4 ||Axn == 5 || Axn == 6
                set(axh,'YAxisLocation','left','YLim',[Ymin Ymax],'XLim',[Tmin Tend],'Visible','off');
                ytickformat(axh,Ytickform)
                num = num2str(Axn+5);
                app.(['UIAxes' num]) = uiaxes(app.plotdest);
                axh_off = app.(['UIAxes' num]);
                axh_off.Interactions = []; % Disable interactions
                set(axh_off,'InnerPosition',[Yposition(1) Yposition(2) 0.0001 Yposition(4)], 'YAxisLocation','left','YLim',[Ymin Ymax],'XTickLabel', [],'Visible','on');
                set(axh_off,'TickDir','out','LineWidth',linewidth_ax);
                tick=Ymin:((Ymax-Ymin)/tpaxis):Ymax;
                set(axh_off,'Ytick',tick,'Ylim',[Ymin Ymax],'FontSize',fontsize,'Ycolor','black');
                ylabel(axh_off,Y_label,'Position',[0 (Ymax-Ymin)*Ylaboff+Ymax -1 ],'Rotation',0,'HorizontalAlignment','center','Color',color);
                ytickformat(axh_off,Ytickform)
            end
        end

        function [Ypos, figurelength] = layout_plot(app,naxes,Yoffset)
            pos = app.plotdest.Position;
            Xoffset = pos(3)*0.08;
            length = pos(3)*0.975;
            width = pos(4)*0.75;
            figurelength0 = pos(3);
            switch naxes
                case 1
                    figurelength = figurelength0*0.75;
                    %Ypos = [Yoffset 0.1100 0.90 0.8150];
                    Ypos = [Yoffset*1 Xoffset length width];
                case 2
                    figurelength = figurelength0*0.8;
                    %Ypos = [Yoffset*2 0.1100 0.85 0.8150];
                    Ypos = [Yoffset*2 Xoffset abs(length-Yoffset*2) width];
                case 3
                    figurelength = figurelength0*0.85;
                    Ypos = [Yoffset*3 Xoffset abs(length-Yoffset*3) width];
                case 4
                    figurelength = figurelength0*0.90;
                    Ypos = [Yoffset*4 Xoffset abs(length-Yoffset*4) width];
                case 5
                    figurelength = figurelength0*0.95;
                    Ypos = [Yoffset*5 Xoffset abs(length-Yoffset*5) width];
                case 6
                    figurelength = figurelength0;
                    Ypos = [Yoffset*6 Xoffset abs(length-Yoffset*6) width];
            end
        end

        function [] = flagomat3(~,x,y,fpoint,fori,ymax,ymin,linewidth_flag,color,fontsize_flag,fname,ax,angle,flag_length,xmax,xmin)
            % Calculation of flag position
            %x_point=((fpoint)/100)*app.phaseTime(app.numPhases);
            x_point = x(end);
            v=x_point*ones(size(x));
            [~, position1]=min(abs(v-x));
            pos1 = position1(1);
            %if app.FlagsSwitch.Value == '1'
            hold(ax, "on")
            plot(ax,x(pos1,1),y(pos1,1),"Marker","o", "Color", color, 'MarkerSize',4);
            hold(ax, "off")
                switch fori
                    case 1
                        % Position upper right
                        text(x(pos1,1)+(xmax-xmin)*flag_length,y(pos1,1)+(ymax-ymin)*angle,fname,'color',color,'fontsize',fontsize_flag,'HorizontalAlignment','left','parent',ax) %Plotting of flag text
                        line([x(pos1,1) x(pos1,1)+(xmax-xmin)*flag_length],[y(pos1,1) y(pos1,1)+(ymax-ymin)*angle],'Linewidth',linewidth_flag,'color',color,'parent',ax) %Plotting of flag handle
                    case 2
                        % Position lower right
                        text(x(pos1,1)+(xmax-xmin)*flag_length,y(pos1,1)-(ymax-ymin)*angle,fname,'color',color,'fontsize',fontsize_flag,'HorizontalAlignment','left','parent',ax) %Plotting of flag text
                        line([x(pos1,1) x(pos1,1)+(xmax-xmin)*flag_length],[y(pos1,1) y(pos1,1)-(ymax-ymin)*angle],'Linewidth',linewidth_flag,'color',color,'parent',ax) %Plotting of flag handle
                    case 3
                        % Position lower left
                        text(x(pos1,1)-(xmax-xmin)*flag_length,y(pos1,1)-(ymax-ymin)*angle,fname,'color',color,'fontsize',fontsize_flag,'HorizontalAlignment','right','parent',ax) %Plotting of flag text
                        line([x(pos1,1) x(pos1,1)-(xmax-xmin)*flag_length],[y(pos1,1) y(pos1,1)-(ymax-ymin)*angle],'Linewidth',linewidth_flag,'color',color,'parent',ax) %Plotting of flag handle
                    case 4
                        % Position upper left
                        text(x(pos1,1)-(xmax-xmin)*flag_length,y(pos1,1)+(ymax-ymin)*angle,fname,'color',color,'fontsize',fontsize_flag,'HorizontalAlignment','right','parent',ax) %Plotting of flag text
                        line([x(pos1,1) x(pos1,1)-(xmax-xmin)*flag_length],[y(pos1,1) y(pos1,1)+(ymax-ymin)*angle],'Linewidth',linewidth_flag,'color',color,'parent',ax) %Plotting of flag handle
                end
            %end
        end


        function TopLim = findTopLimit(app, idx, data)
            for i = -6:6 % scan from 10^-6 to 10^6 for data range
                lim = 10^i;
                maximum = max(data); % find max value from set
                if maximum<=lim && maximum ~= 0 % checks if max value of dataset is smaller than 10^i, e.g. 463, so lim = 1000
                    fac = ceil(maximum/(lim*0.1)); % round deciaml number, e.g. 4.63 to 5
                    for j = [1 2 5 10] % desired steps
                        if fac <= j % e.g. 5
                            TopLim = j*lim*0.1; % define 5*1000*0.1 = 500 as the max Ylim instead of lim = 1000
                            break;
                        end
                    end
                    break;
                else
                    % Exception if maximum value equals zero
                    TopLim = app.table.ymaxVar(idx);
                end
            end
        end

        function sortVariables(app)
            if ~isempty(app.selection)
            variableIdx = app.selection;
            for i = 1:length(variableIdx)
                n = variableIdx(i);
                maxLimit = app.table.ymaxVar(n);
                max_values(i) = maxLimit;
            end
            [~, sorted] = sort(max_values);
            new_selection = variableIdx(sorted);
            if ~isequal(app.selection, new_selection)
                app.selection = new_selection;
                app.y_data = app.y_data(:,sorted);
                createDynamicGrid(app)
            end
            else
                warndlg("No variable selected. Select a variable.", "Warning");
                checkUpdater(app)
            end
        end

        function prepareVariables(app)
            variableIdx = app.selection;
            % Determine y-axis limit
            for i = 1:numel(variableIdx)
                idx = variableIdx(i);
                data = app.y_data(:,i);
                if app.table.LimitTypeVar(idx) == 1 && data(end)>=0.95*app.table.ymaxVar(idx) % Determine a new max limit value if last value exceeds 95% of max
                    app.table.ymaxVar(idx) = findTopLimit(app, idx, data);
                elseif app.table.LimitTypeVar(idx) == 1 && data(end)<=0.05*app.table.ymaxVar(idx)
                    app.table.ymaxVar(idx) = findTopLimit(app, idx, data);
                end
            end
        end

        function checkUpdater(app)
            status = get(app.AutoUpdate, 'Running');
            if status == "on"
                stop(app.AutoUpdate)
                app.AutoUpdateButton.Value = 0;
            else
                start(app.AutoUpdate)
                app.AutoUpdateButton.Value = 1;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, CallingApp, projectID)
            app.projectID = projectID;
            app.UIFigure.Name = "Figure";
            app.CallingApp = CallingApp;
            centerWindow(app.UIFigure)
            app.dbpath = app.CallingApp.dbpath;
            readProfile(app, 1)
            app.plotdest = app.PlotPanel;
            createGridLayout(app)
            createDynamicGrid(app)
            % Create timer object
            app.AutoUpdate = timer(...
                'ExecutionMode', 'fixedRate', ...                           % Run timer repeatedly
                'Period', app.refreshrate, ...                                % Period is dependent on delta t
                'BusyMode', 'drop',...                                     % Queue timer callbacks when busy
                'TimerFcn', @app.AutoUpdateFcn);                                 % Specify callback function
            start(app.AutoUpdate);
        end

        % Callback function
        function ExportPlotButtonPushed(app, event)
            filter = {'*.jpg';'*.png';'*.tif';'*.pdf'};
            if app.CallingApp.SelectedFile ~= "none"
                [filename,filepath] = uiputfile(filter,'Save plot of cultivation',strrep(datestr(datetime),':','-')+" "+app.CallingApp.FileName);
            else
                [filename,filepath] = uiputfile(filter);
            end
            if filename ~= 0
                exportgraphics(app.UIAxes,[filepath filename],"Resolution",300,'BackgroundColor','none','ContentType','vector');
                msgbox(sprintf('File was saved as %s',filename));
                app.ExportPlotButton.Text = "Export again";
            end
        end

        % Value changed function: ConfiOpener
        function ConfiOpenerValueChanged(app, event)
            value = app.ConfiOpener.Value;
            if value
                app.PlotConfigurationPanel.Visible = "on";
                app.GridLayout.ColumnWidth{5} = 250;
                app.ConfiOpener.Text = ">";
            else
                app.PlotConfigurationPanel.Visible = "off";
                app.GridLayout.ColumnWidth{5} = 0.001;
                app.ConfiOpener.Text = "<";
            end
        end

        % Callback function
        function EditPlotButtonPushed2(app, event)
            if strcmp(app.Ploteditor.EditPlotUIFigure.Visible, 'off')
                app.Ploteditor.EditPlotUIFigure.Visible = 'on';
            else
                app.Ploteditor.EditPlotUIFigure.Visible = 'off';
            end
            app.Ploteditor.EditPlotUIFigure.Visible = 'on';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % if isvalid(app.AutoUpdate)
            %     stop(app.AutoUpdate);
            % end
            % % % Check if timer is running and stop it if it is
            % % R = get(app.Timer, 'Running');
            % % if isequal(R ,'on')
            % %     stop(app.Timer);
            % % end
            % %
            % % app.PauseButton.Text = "Resume";
            % % YN = uiconfirm(app.UIFigure,'Do you want to stop the simulation?', 'Close request');
            % % if strcmpi(YN,'OK')
            % %     if app.CallingApp.StartSimulationButton.Text == "Stop Simulation"
            % %      app.CallingApp.EditStartingVariablesButton.Enable = "on";
            % %
            % %      app.CallingApp.ProcessTimehEditField.Value = 0.0;
            % %
            % %      app.CallingApp.ProcessRunningLamp.Color = "red";
            % %
            % %      app.CallingApp.StartSimulationButton.Text = "Start Simulation";
            % %
            % %      app.CallingApp.mds = 0;
            % %
            % %      app.CallingApp.SnapShot = 0;
            % %
            % %      app.CallingApp.PrevPlot = 0;
            % %
            % %      app.CallingApp.DataStorageRunningLamp.Color = "red";
            % %      app.CallingApp.DataStorageStartButton.Text = "Data Storage Start";
            % %     end
            % %     delete(app.Ploteditor);
            % %     delete(app);
            % % else
            % %     start(app.Timer);
            % %     app.PauseButton.Text = "Pause";
            % % end
            delete(app)
        end

        % Menu selected function: ComplexPlotMenu
        function ComplexPlotMenuSelected(app, event)
            if app.ComplexPlotMenu.Checked == "on"
                app.plotmode = true;
                app.SimplePlotMenu.Checked = "on";
                app.ComplexPlotMenu.Checked = "off";
            else
                app.plotmode = false;
                app.SimplePlotMenu.Checked = "off";
                app.ComplexPlotMenu.Checked = "on";
            end
        end

        % Menu selected function: SimplePlotMenu
        function SimplePlotMenuSelected(app, event)
            if app.SimplePlotMenu.Checked == "on"
                app.plotmode = true;
                app.SimplePlotMenu.Checked = "off";
                app.ComplexPlotMenu.Checked = "on";
            else
                app.plotmode = false;
                app.SimplePlotMenu.Checked = "on";
                app.ComplexPlotMenu.Checked = "off";
            end
        end

        % Menu selected function: FlagsMenu
        function FlagsMenuSelected(app, event)
            if app.FlagsMenu.Checked == "on"
                app.FlagsMenu.Checked = "off";
            else
                app.FlagsMenu.Checked = "on";
            end
        end

        % Menu selected function: AutoUpdatePlotMenu
        function AutoUpdatePlotMenuSelected(app, event)
            if app.AutoUpdatePlotMenu.Checked == "on"
                stop(app.AutoUpdate)
                app.AutoUpdatePlotMenu.Checked = "off";
            else
                start(app.AutoUpdate)
                app.AutoUpdatePlotMenu.Checked = "on";
            end
        end

        % Value changed function: AutoUpdateButton
        function AutoUpdateButtonValueChanged(app, event)
            checkUpdater(app)
        end

        % Value changed function: LimitsOpener
        function LimitsOpenerValueChanged(app, event)
            value = app.LimitsOpener.Value;
            if value
                app.VariableLimitsPanel.Visible = "on";
                app.GridLayout.ColumnWidth{1} = 250;
                app.LimitsOpener.Text = "<";
            else
                app.VariableLimitsPanel.Visible = "off";
                app.GridLayout.ColumnWidth{1} = 0.001;
                app.LimitsOpener.Text = ">";
            end
        end

        % Menu selected function: EditVariablesMenu
        function EditVariablesMenuSelected(app, event)
            
        end

        % Menu selected function: SettingsMenu
        function SettingsMenuSelected(app, event)
            status = get(app.AutoUpdate, 'Running');
            if status == "on"
                stop(app.AutoUpdate)
                app.AutoUpdateButton.Value = 0;
            end
            app.Settingsapp = FigureAppSettings(app, app.profileID);
            waitfor(app.Settingsapp)
            readProfile(app, app.profileID)
            checkUpdater(app)
        end

        % Value changed function: tlimEditField
        function tlimEditFieldValueChanged(app, event)
            value = app.tlimEditField.Value;
            if value > app.tstart
                app.tend = value;
                updateOptions(app,'tend',value)
            else
                app.tlimEditField.Value = app.tend;
                app.tlimEditField.Tooltip = "tmax must be larger than tmin.";
                pause(3)
                app.tlimEditField.Tooltip = "";
            end
        end

        % Value changed function: tlimminEditField
        function tlimminEditFieldValueChanged(app, event)
            value = app.tlimminEditField.Value;
            if value < app.tend
                app.tstart = value;
                updateOptions(app,'tstart',value)
            else
                app.tlimminEditField.Value = app.tstart;
                app.tlimminEditField.Tooltip = "tmin must be smaller than tmin.";
                pause(3)
                app.tlimminEditField.Tooltip = "";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExportMenu = uimenu(app.UIFigure);
            app.ExportMenu.Text = 'Export';
            app.ExportPlotMenu = uimenu(app.ExportMenu);
            app.ExportPlotMenu.Text = 'Export Plot';
            app.TemplateMenu = uimenu(app.UIFigure);
            app.TemplateMenu.Text = 'Template';
            app.SaveChangesMenu = uimenu(app.TemplateMenu);
            app.SaveChangesMenu.Text = 'Save Changes';
            app.CreateTemplateMenu = uimenu(app.TemplateMenu);
            app.CreateTemplateMenu.Text = 'Create Template';
            app.LoadTemplateMenu = uimenu(app.TemplateMenu);
            app.LoadTemplateMenu.Text = 'Load Template';
            app.SelectMenu = uimenu(app.LoadTemplateMenu);
            app.SelectMenu.Text = 'Select...';
            app.Menu2 = uimenu(app.LoadTemplateMenu);
            app.Menu2.Text = 'Menu2';
            app.OptionsMenu = uimenu(app.UIFigure);
            app.OptionsMenu.Text = 'Options';
            app.AutoUpdatePlotMenu = uimenu(app.OptionsMenu);
            app.AutoUpdatePlotMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoUpdatePlotMenuSelected, true);
            app.AutoUpdatePlotMenu.Checked = 'on';
            app.AutoUpdatePlotMenu.Text = 'Auto Update Plot';
            app.FlagsMenu = uimenu(app.OptionsMenu);
            app.FlagsMenu.MenuSelectedFcn = createCallbackFcn(app, @FlagsMenuSelected, true);
            app.FlagsMenu.Checked = 'on';
            app.FlagsMenu.Text = 'Flags';
            app.PlotTypeMenu = uimenu(app.OptionsMenu);
            app.PlotTypeMenu.Text = 'Plot Type';
            app.ComplexPlotMenu = uimenu(app.PlotTypeMenu);
            app.ComplexPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @ComplexPlotMenuSelected, true);
            app.ComplexPlotMenu.Checked = 'on';
            app.ComplexPlotMenu.Text = 'Complex Plot';
            app.SimplePlotMenu = uimenu(app.PlotTypeMenu);
            app.SimplePlotMenu.MenuSelectedFcn = createCallbackFcn(app, @SimplePlotMenuSelected, true);
            app.SimplePlotMenu.Text = 'Simple Plot';
            app.EditVariablesMenu = uimenu(app.OptionsMenu);
            app.EditVariablesMenu.MenuSelectedFcn = createCallbackFcn(app, @EditVariablesMenuSelected, true);
            app.EditVariablesMenu.Text = 'Edit Variables';
            app.SettingsMenu = uimenu(app.OptionsMenu);
            app.SettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @SettingsMenuSelected, true);
            app.SettingsMenu.Text = 'Settings';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.PlotConfigurationPanel = uipanel(app.GridLayout);
            app.PlotConfigurationPanel.Title = 'Plot Configuration';
            app.PlotConfigurationGridLayout = uigridlayout(app.PlotConfigurationPanel);
            app.VariablePanel = uipanel(app.PlotConfigurationGridLayout);
            app.VariableGridLayout = uigridlayout(app.VariablePanel);
            app.AutoUpdateButton = uibutton(app.PlotConfigurationGridLayout, 'state');
            app.AutoUpdateButton.ValueChangedFcn = createCallbackFcn(app, @AutoUpdateButtonValueChanged, true);
            app.AutoUpdateButton.Text = 'Auto Update';
            app.AutoUpdateButton.Value = true;
            app.PlotPanel = uipanel(app.GridLayout);
            app.PlotPanel.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotPanel);
            title(app.UIAxes, 'Cultivation Simulation')
            xlabel(app.UIAxes, 't [h]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.ConfiOpener = uibutton(app.GridLayout, 'state');
            app.ConfiOpener.ValueChangedFcn = createCallbackFcn(app, @ConfiOpenerValueChanged, true);
            app.ConfiOpener.Tooltip = {'Plot configuration'};
            app.ConfiOpener.Text = '>';
            app.ConfiOpener.Value = true;
            app.LimitsOpener = uibutton(app.GridLayout, 'state');
            app.LimitsOpener.ValueChangedFcn = createCallbackFcn(app, @LimitsOpenerValueChanged, true);
            app.LimitsOpener.Text = '<';
            app.LimitsOpener.Value = true;
            app.VariableLimitsPanel = uipanel(app.GridLayout);
            app.VariableLimitsPanel.Title = 'Variable Limits';
            app.VariableLimitsGridLayout = uigridlayout(app.VariableLimitsPanel);
            app.VariableLabel = uilabel(app.VariableLimitsGridLayout);
            app.VariableLabel.Text = 'Variable';
            app.MinLabel_2 = uilabel(app.VariableLimitsGridLayout);
            app.MinLabel_2.Text = 'Min';
            app.MaxLabel_2 = uilabel(app.VariableLimitsGridLayout);
            app.MaxLabel_2.Text = 'Max';
            app.AutoLabel = uilabel(app.VariableLimitsGridLayout);
            app.AutoLabel.Text = 'Auto';
            app.VariableAxisLabel = uilabel(app.VariableLimitsGridLayout);
            app.VariableAxisLabel.Text = 'Variable Axis';
            app.TimeAxisLabel = uilabel(app.VariableLimitsGridLayout);
            app.TimeAxisLabel.Text = ' Time Axis';
            app.tlimEditFieldLabel = uilabel(app.VariableLimitsGridLayout);
            app.tlimEditFieldLabel.Text = 't [h]:';
            app.MinLabel = uilabel(app.VariableLimitsGridLayout);
            app.MinLabel.Text = 'Min';
            app.MaxLabel = uilabel(app.VariableLimitsGridLayout);
            app.MaxLabel.Text = 'Max';
            app.tlimminEditField = uieditfield(app.VariableLimitsGridLayout, 'numeric');
            app.tlimminEditField.ValueChangedFcn = createCallbackFcn(app, @tlimminEditFieldValueChanged, true);
            app.tlimEditField = uieditfield(app.VariableLimitsGridLayout, 'numeric');
            app.tlimEditField.ValueChangedFcn = createCallbackFcn(app, @tlimEditFieldValueChanged, true);
            app.tlimEditField.Value = 5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FigureApp4(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end