classdef ModelConfigurator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ModelMenu                 matlab.ui.container.Menu
        SavechangesMenu           matlab.ui.container.Menu
        CreateMenu                matlab.ui.container.Menu
        ProcessMenu               matlab.ui.container.Menu
        BioreactorMenu            matlab.ui.container.Menu
        ModeMenu                  matlab.ui.container.Menu
        NormalMenu                matlab.ui.container.Menu
        AdvancedMenu              matlab.ui.container.Menu
        BasicGrid                 matlab.ui.container.GridLayout
        EditModelPanel            matlab.ui.container.Panel
        SaveButton                matlab.ui.control.Button
        ModelnameEditField        matlab.ui.control.EditField
        ModelnameLabel            matlab.ui.control.Label
        BioreactorEditField       matlab.ui.control.EditField
        BioreactorEditFieldLabel  matlab.ui.control.Label
        TabGroup                  matlab.ui.container.TabGroup
        ListView                  matlab.ui.container.Tab
        ParameterGrid             matlab.ui.container.GridLayout
        TableView                 matlab.ui.container.Tab
        UITable                   matlab.ui.control.Table
        CloseButton               matlab.ui.control.Button
        CategoriesLabel           matlab.ui.control.Label
        CategoryTree              matlab.ui.container.Tree
        DescriptionTextArea       matlab.ui.control.TextArea
        DescriptionTextAreaLabel  matlab.ui.control.Label
        OrganismEditField         matlab.ui.control.EditField
        OrganismEditFieldLabel    matlab.ui.control.Label
        CancelSavePanel           matlab.ui.container.Panel
        SelectaModelPanel         matlab.ui.container.Panel
        DeleteButton              matlab.ui.control.Button
        DownButton                matlab.ui.control.Button
        UpButton                  matlab.ui.control.Button
        CreateNewModelButton      matlab.ui.control.Button
        SelectButton              matlab.ui.control.Button
        ModelsListBox             matlab.ui.control.ListBox
    end


    properties (Access = public)
        CallingApp
        TexFontName = '\fontname{Helvetica}';
        CreateModelApp
        displayrank
        ValueEditField cell
        idx % filtered rows shown in views
        modelTable
        categories
        category

        sections

        changelog  cell
        parameterTable

        saveflag = false;
    end

    properties (Access = public)
        dbpath
        models
        currentmodel
        controlmodes % Table with control dropdown menu items
    end

    methods (Access = private)

        function readParameter(app)
            conn = sqlite(app.dbpath, "readonly");
            sqlquery = sprintf(['SELECT prop.name AS ParameterName, prop.tex AS TexName, mp.value AS Value, prop.tex_unit AS Unit, mp.description AS Description, c.name AS CategoryName, c.section AS CategorySection, prop.type AS Type ' ...
                'FROM model_parameterTab mp ' ...
                'JOIN parameterTab prop ON prop.parameterID = mp.parameterID ' ...
                'JOIN categoryTab c ON c.categoryID = prop.categoryID ' ...
                'JOIN modelTab m ON m.modelID = mp.modelID ' ...
                'WHERE m.name = ''%s'''],app.currentmodel);
            app.parameterTable = fetch(conn, sqlquery);
            close(conn)
            %app.parameterTable.Properties.VariableNames = {'ParameterName', 'TexName', 'Value', 'Unit', 'Description', 'CategoryName', 'CategorySection', 'Type'};
        end

        function fillListView(app)
            if ~isempty(app.currentmodel)
            delete(app.ParameterGrid.Children);
            % Filter categories for selected node
            if any(app.category == app.sections)
                app.idx = app.parameterTable.CategorySection == app.category;
            else
                app.idx = app.parameterTable.CategoryName == app.category;
            end
            filtered_parameterTable = app.parameterTable(app.idx, :);
            % Create the list through a loop
            clear app.ValueEditField;
            n = 1; % Row count
            str = [filtered_parameterTable.CategoryName];
            cats = unique(str, 'stable');
            for j = 1:length(cats)
                title_label = uilabel(app.ParameterGrid);
                title_label.Text = cats(j);
                title_label.Layout.Row = n;
                title_label.Layout.Column = [1 2];
                title_label.FontSize = 14;
                title_label.FontWeight = 'bold';
                n = n+1;
                index = find(cats(j) == str)';
                for i = index
                    label1 = uilabel(app.ParameterGrid);
                    label1.Text = append(app.TexFontName, filtered_parameterTable.TexName(i));
                    label1.Layout.Row = n;
                    label1.Layout.Column = 1;
                    label1.Interpreter = "tex";
                    label1.FontSize = 12;

                switch filtered_parameterTable.Type(i)
                    case "switch"
                        fswitch = uiswitch(app.ParameterGrid);
                        fswitch.Orientation = 'horizontal';
                        fswitch.Items = ["Off", "On"];
                        fswitch.ItemsData = [0, 1];
                        fswitch.Value = filtered_parameterTable.Value(i);
                        fswitch.Layout.Row = n;
                        fswitch.Layout.Column = 2;
                        fswitch.Tooltip = filtered_parameterTable.Description(i);
                        fswitch.ValueChangedFcn = @(src, event) updateTable(app,src, filtered_parameterTable.ParameterName(i));
                        app.ValueEditField(i) = {fswitch};
                    case "editfield"
                        editfield = uieditfield(app.ParameterGrid, 'numeric');
                        editfield.Value = filtered_parameterTable.Value(i);
                        editfield.Layout.Row = n;
                        editfield.Layout.Column = 2;
                        editfield.Tooltip = filtered_parameterTable.Description(i);
                        editfield.ValueChangedFcn = @(src, event) updateTable(app,src, filtered_parameterTable.ParameterName(i));
                        app.ValueEditField(i) = {editfield};
                    case "dropdown"
                        dropdown = uidropdown(app.ParameterGrid);
                        f = find(filtered_parameterTable.ParameterName(i) == app.controlmodes.name);
                        dropdown.Items = app.controlmodes.mode(f);
                        dropdown.ItemsData = app.controlmodes.value(f);
                        dropdown.Value = filtered_parameterTable.Value(i);
                        dropdown.Layout.Row = n;
                        dropdown.Layout.Column = 2;
                        dropdown.Tooltip = filtered_parameterTable.Description(i);
                        dropdown.ValueChangedFcn = @(src, event) updateTable(app,src,filtered_parameterTable.ParameterName(i));
                        app.ValueEditField(i) = {dropdown};
                end

                    if ~isempty(filtered_parameterTable.Unit(i))
                        label2 = uilabel(app.ParameterGrid);
                        label2.Text = append(app.TexFontName, filtered_parameterTable.Unit(i));
                        label2.Layout.Row = n;
                        label2.Layout.Column = 3;
                        label2.Interpreter = "tex";
                    end
                    n = n+1;
                end
            end
            app.ParameterGrid.RowHeight = repmat({25}, n, 1);
            app.ParameterGrid.ColumnWidth = {'1x'};%{'0.5x'};
            else
                delete(app.ParameterGrid.Children) % Delete all entries if no model is selected
            end
        end

        function updateTable(app,src, parameter)
            % Save changes in changelog
            app.changelog(end+1,1:2) = {parameter, src.Value};
            % Save changes in table view
            n = find(parameter == table2array(app.UITable.Data(:,1)));
            app.UITable.Data(n,2) = table(src.Value);
            % Makes sure that changes will be changed
            app.saveflag = true;
            app.SaveButton.Enable = "on";
        end

        function saveTable(app)
            if app.saveflag
                % Execute changes in a loop
                tic
                f = waitbar(0,"Uploading changed parameters...");
                len = size(app.changelog,1);
                for i = 1:len
                    sqlquery = sprintf([' UPDATE model_parameterTab ' ...
                        'SET value = %d ' ...
                        'WHERE parameterID IN (' ...
                        'SELECT pr.parameterID ' ...
                        'FROM parameterTab pr ' ...
                        'JOIN modelTab m ON pr.parameterID = model_parameterTab.parameterID ' ...
                        'AND model_parameterTab.modelID = m.modelID ' ...
                        'WHERE pr.name = ''%s'' AND m.name = ''%s'')'], app.changelog{i,2}, app.changelog{i,1}, app.currentmodel);
                    conn = sqlite(app.dbpath, "connect");
                    exec(conn,sqlquery);
                    close(conn)
                    waitbar(i/len,f,sprintf("Variable: %s", app.changelog{i,2}));
                end
                time = toc;
                waitbar(1,f,sprintf("Finishied after: %f", time));
                close(f);
                app.saveflag = false;
                app.changelog = {};
                app.SaveButton.Enable = "off";
            end
        end

        function fillTableView(app)
            if ~isempty(app.currentmodel)
                % Filter categories for selected node
                if any(app.category == app.sections)
                    app.idx = app.parameterTable.CategorySection == app.category;
                else
                    app.idx = app.parameterTable.CategoryName == app.category;
                end
                table = app.parameterTable(app.idx, :);
                % Check if only parameters without units are shown
                if any(app.category == ["Flags", "Controller gain"])
                    app.UITable.Data = table(:, [1 3 5]);
                    headers = ["Parameter" "Value" "Description"];
                else
                    app.UITable.Data = table(:, [1 3 4 5]);
                    headers = ["Parameter" "Value" "Unit" "Description"];
                end
                app.UITable.ColumnName = headers;

                % if app.ExpertModeMenu.Checked
                %     app.UITable.ColumnEditable = [true true true true];
                % else
                app.UITable.ColumnEditable = [false true false false];
                % end
            else
                app.UITable.Data = [];
            end
        end

        function fillOverview(app)
            if ~isempty(app.currentmodel)
                app.EditModelPanel.Enable = "off";
                f = waitbar(0,"Model loading...");
                % Fill title label with model name
                app.ModelnameEditField.Value = app.currentmodel;
                % Find position of current model in list
                i = find(app.currentmodel == app.modelTable.name);
                app.OrganismEditField.Value = app.modelTable.organism(i);
                app.BioreactorEditField.Value = app.modelTable.bioreactor(i);
                app.DescriptionTextArea.Value = app.modelTable.description(i);
                % Reset tree
                expand(app.CategoryTree)
                parameterCat = app.CategoryTree.Children(1);
                app.CategoryTree.SelectedNodes = parameterCat.Children(1);
                selectedNodes = app.CategoryTree.SelectedNodes;
                app.category = selectedNodes.Text;
                waitbar(0.33,f,"Read database...");
                % Read parameter data from database
                readParameter(app);
                waitbar(0.66,f,"List all parameters...");
                fillListView(app);
                fillTableView(app);
                app.EditModelPanel.Enable = "on";
                close(f);
            else
                % Delete all object values
                app.ModelnameEditField.Value = "";
                app.OrganismEditField.Value = "";
                app.BioreactorEditField.Value = "";
                app.DescriptionTextArea.Value = "";
                fillListView(app);
                fillTableView(app);
            end
        end

    end

    methods (Access = public)
        function updateModelList(app)
            conn = sqlite(app.dbpath, "readonly");
            sqlquery = ['SELECT m.name, m.description, o.name AS organism, b.name AS bioreactor, m.display_rank ' ...
                'FROM modelTab m ' ...
                'JOIN organismTab o ON o.organismID = m.organismID ' ...
                'JOIN bioreactorTab b ON b.bioreactorID = m.bioreactorID ' ...
                'ORDER BY m.display_rank'];
            app.modelTable = fetch(conn,sqlquery);
            close(conn)
            % Select a different value in list
            if ~isempty(app.modelTable)
                app.ModelsListBox.Items = app.modelTable.name(:);
            else
                app.ModelsListBox.Items = "No models found.";
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, CallingApp)
            app.CallingApp = CallingApp;
            app.dbpath = app.CallingApp.dbpath;
            app.UIFigure.Name = "Model Configurator";
            centerWindow(app.UIFigure)
            conn = sqlite(app.dbpath, "readonly");
            sqlquery = 'SELECT * FROM categoryTab';
            categoryTab = fetch(conn,sqlquery);
            app.categories = categoryTab.name(:);
            app.sections = categoryTab.section(:);
            % Fill tree
            delete(app.CategoryTree.Children);
            allsections = unique(app.sections,"stable");

            for j = 1:numel(allsections)
                section = allsections(j);
                sectionnode = uitreenode(app.CategoryTree, 'Text', section, 'NodeData', []);
                index = find(allsections(j) == app.sections); % Identify indices of categories
                for i = index'
                    categoryname = app.categories(i);
                    if categoryname ~= section
                        uitreenode(sectionnode, 'Text', categoryname, 'NodeData', []);
                    end
                end
            end
            expand(app.CategoryTree)
            % Select model information
            updateModelList(app)

            % Read dropdown data
            sqlquery = ['SELECT p.name, cm.value, cm.mode ' ...
                'FROM parameter_controlmodesTab cm ' ...
                'JOIN parameterTab p ON p.parameterID = cm.parameterID '];
            app.controlmodes = fetch(conn,sqlquery);
            close(conn)
            if ~isempty(app.modelTable)
                if isempty(app.currentmodel)
                    app.currentmodel = app.modelTable.name(1);
                else
                    app.ModelsListBox.Value = app.currentmodel;
                end
                fillOverview(app);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            delete(app)
        end

        % Button pushed function: CreateNewModelButton
        function CreateNewModelButtonPushed(app, event)
            prev = string(app.currentmodel);
            app.CreateModelApp = ModelCreator(app);
            waitfor(app.CreateModelApp);
            if isempty(prev)
                updateModelList(app)
                app.ModelsListBox.Value = app.currentmodel;
                fillOverview(app)
            elseif prev ~= string(app.currentmodel)
                updateModelList(app)
                app.ModelsListBox.Value = app.currentmodel;
                fillOverview(app)
            end
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            choice = questdlg(sprintf('Are you sure you want to delete the model "%s"?',app.ModelsListBox.Value), ...
                'Delete Confirmation', ...
                'Cancel', 'Delete', 'Cancel');
            
            switch choice
                case 'Delete'
                    value = app.ModelsListBox.Value;
                    pos = find(value == app.modelTable.name);
                    conn = sqlite(app.dbpath, "connect");
                    sqlquery = sprintf(['DELETE FROM model_parameterTab ' ...
                        'WHERE modelID = (SELECT modelID FROM modelTab WHERE name = "%s")'], value);
                    exec(conn,sqlquery);
                    sqlquery = sprintf('DELETE FROM modelTab WHERE name = "%s"', value);
                    exec(conn,sqlquery);
                    close(conn)

                    % Reload model list box
                    updateModelList(app)
                    if ~isempty(app.modelTable.name)
                        if length(app.modelTable.name) ~= pos-1
                            app.ModelsListBox.Value = app.modelTable.name(pos);
                        else
                            app.ModelsListBox.Value = app.modelTable.name(end);
                        end
                        if app.currentmodel == value
                            app.currentmodel = app.ModelsListBox.Value;
                            fillOverview(app)
                        end
                    else
                        app.currentmodel = [];
                            fillOverview(app)
                    end

                case 'Cancel'
                    return
            end
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed2(app, event)
            if app.saveflag
                choice = questdlg(sprintf('Do you want to keep the changes made in %s?', app.currentmodel), ...
                    'Save Changes?', ...
                    'Yes', 'No', 'Yes');
                switch choice
                    case 'Yes'
                        saveTable(app)
                    case 'no'
                        app.saveflag = false;
                        app.changelog = {};
                end
            end
            app.currentmodel = app.ModelsListBox.Value;
            fillOverview(app);
        end

        % Double-clicked callback: ModelsListBox
        function ModelsListBoxDoubleClicked(app, event)
            if app.ModelsListBox.Value ~= "No models found." % Dont allow to choose the filler model
                if app.saveflag
                    choice = questdlg(sprintf('Do you want to keep the changes made in %s?', app.currentmodel), ...
                        'Save Changes?', ...
                        'Yes', 'No', 'Yes');
                    switch choice
                        case 'Yes'
                            saveTable(app)
                        case 'no'
                            app.saveflag = false;
                            app.changelog = {};
                    end
                end
                app.currentmodel = app.ModelsListBox.Value;
                fillOverview(app);
            end
        end

        % Selection changed function: CategoryTree
        function CategoryTreeSelectionChanged(app, event)
            selectedNodes = app.CategoryTree.SelectedNodes;
            app.category = selectedNodes.Text;
            % Transfer changed data into table
            app.parameterTable(app.idx,3) = app.UITable.Data(:,2);
            fillListView(app);
            fillTableView(app);
        end

        % Value changed function: ModelnameEditField
        function ModelnameEditFieldValueChanged(app, event)
            value = app.ModelnameEditField.Value;
            if any(app.modelTable.name == value)
                box = msgbox("This model name is already taken. Choose a different name.", "Name Error","error","modal");
                app.ModelnameEditField.Value = app.currentmodel;
            else
                conn = sqlite(app.dbpath, "connect");
                sqlquery = sprintf('UPDATE modelTab SET name = "%s" WHERE name = "%s"', value, app.currentmodel);
                exec(conn,sqlquery);
                close(conn)
                updateModelList(app)
                app.ModelsListBox.Value = value;
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            saveTable(app)
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            data = app.UITable.Data;
            parameter = table2array(data(indices(1),1));
            app.changelog(end+1,1:2) = {parameter, newData};
            app.ValueEditField{indices(1)}.Value = newData;
            app.saveflag = true;
            app.SaveButton.Enable = "on";
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            if ~isempty(app.ModelsListBox.Value)
                currentvalue = app.ModelsListBox.Value;
                pos = find(currentvalue == app.modelTable.name);
                if pos(1) ~= 1
                    currentposition = app.modelTable.display_rank(pos(1));
                    abovevalue = app.modelTable.name(pos(1)-1);
                    aboveposition = app.modelTable.display_rank(pos(1)-1);
                    conn = sqlite(app.dbpath, "connect");
                    sqlquery = sprintf('UPDATE modelTab SET display_rank = %g WHERE name = ''%s''', aboveposition, currentvalue);
                    exec(conn,sqlquery);
                    sqlquery = sprintf('UPDATE modelTab SET display_rank = %g WHERE name = ''%s''', currentposition, abovevalue);
                    exec(conn,sqlquery);
                    close(conn)
                    % Update model listbox
                    updateModelList(app)
                    app.ModelsListBox.Value = currentvalue;
                end
            end
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            if ~isempty(app.ModelsListBox.Value)
                currentvalue = app.ModelsListBox.Value;
                pos = find(currentvalue == app.modelTable.name);
                if pos(1) ~= length(app.modelTable.name)
                    currentposition = app.modelTable.display_rank(pos(1));
                    belowvalue = app.modelTable.name(pos(1)+1);
                    belowposition = app.modelTable.display_rank(pos(1)+1);
                    conn = sqlite(app.dbpath, "connect");
                    sqlquery = sprintf('UPDATE modelTab SET display_rank = %g WHERE name = ''%s''', belowposition, currentvalue);
                    exec(conn,sqlquery);
                    sqlquery = sprintf('UPDATE modelTab SET display_rank = %g WHERE name = ''%s''', currentposition, belowvalue);
                    exec(conn,sqlquery);
                    close(conn)
                    % Update model listbox
                    updateModelList(app)
                    app.ModelsListBox.Value = currentvalue;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ModelMenu = uimenu(app.UIFigure);
            app.ModelMenu.Text = 'Model';
            app.SavechangesMenu = uimenu(app.ModelMenu);
            app.SavechangesMenu.Text = 'Save changes';
            app.CreateMenu = uimenu(app.UIFigure);
            app.CreateMenu.Text = 'Create';
            app.ProcessMenu = uimenu(app.CreateMenu);
            app.ProcessMenu.Text = 'Process';
            app.BioreactorMenu = uimenu(app.CreateMenu);
            app.BioreactorMenu.Text = 'Bioreactor';
            app.ModeMenu = uimenu(app.UIFigure);
            app.ModeMenu.Text = 'Mode';
            app.NormalMenu = uimenu(app.ModeMenu);
            app.NormalMenu.Tooltip = {'Basic rights to change values'};
            app.NormalMenu.Checked = 'on';
            app.NormalMenu.Text = 'Normal';
            app.AdvancedMenu = uimenu(app.ModeMenu);
            app.AdvancedMenu.Tooltip = {'Advanced rights to change variables'};
            app.AdvancedMenu.Text = 'Advanced';
            app.BasicGrid = uigridlayout(app.UIFigure);
            app.SelectaModelPanel = uipanel(app.BasicGrid);
            app.SelectaModelPanel.Title = 'Select a Model:';
            app.ModelsListBox = uilistbox(app.SelectaModelPanel);
            app.ModelsListBox.DoubleClickedFcn = createCallbackFcn(app, @ModelsListBoxDoubleClicked, true);
            app.SelectButton = uibutton(app.SelectaModelPanel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed2, true);
            app.SelectButton.Text = 'Select';
            app.CreateNewModelButton = uibutton(app.SelectaModelPanel, 'push');
            app.CreateNewModelButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNewModelButtonPushed, true);
            app.CreateNewModelButton.Text = 'Create New Model';
            app.UpButton = uibutton(app.SelectaModelPanel, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Tooltip = {'Move up'};
            app.UpButton.Text = '⯅';
            app.DownButton = uibutton(app.SelectaModelPanel, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Tooltip = {'Move down'};
            app.DownButton.Text = '⯆';
            app.DeleteButton = uibutton(app.SelectaModelPanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.CancelSavePanel = uipanel(app.BasicGrid);
            app.EditModelPanel = uipanel(app.BasicGrid);
            app.EditModelPanel.Title = 'Edit Model';
            app.OrganismEditFieldLabel = uilabel(app.EditModelPanel);
            app.OrganismEditFieldLabel.Text = 'Organism:';
            app.OrganismEditField = uieditfield(app.EditModelPanel, 'text');
            app.OrganismEditField.Editable = 'off';
            app.DescriptionTextAreaLabel = uilabel(app.EditModelPanel);
            app.DescriptionTextAreaLabel.Text = 'Description:';
            app.DescriptionTextArea = uitextarea(app.EditModelPanel);
            app.CategoryTree = uitree(app.EditModelPanel);
            app.CategoryTree.SelectionChangedFcn = createCallbackFcn(app, @CategoryTreeSelectionChanged, true);
            app.CategoriesLabel = uilabel(app.EditModelPanel);
            app.CategoriesLabel.Text = 'Categories:';
            app.CloseButton = uibutton(app.EditModelPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.TabGroup = uitabgroup(app.EditModelPanel);
            app.ListView = uitab(app.TabGroup);
            app.ListView.Title = 'List View';
            app.ParameterGrid = uigridlayout(app.ListView);
            app.TableView = uitab(app.TabGroup);
            app.TableView.Title = 'Table View';
            app.UITable = uitable(app.TableView);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.BioreactorEditFieldLabel = uilabel(app.EditModelPanel);
            app.BioreactorEditFieldLabel.Text = 'Bioreactor:';
            app.BioreactorEditField = uieditfield(app.EditModelPanel, 'text');
            app.BioreactorEditField.Editable = 'off';
            app.ModelnameLabel = uilabel(app.EditModelPanel);
            app.ModelnameLabel.Text = 'Model name:';
            app.ModelnameEditField = uieditfield(app.EditModelPanel, 'text');
            app.ModelnameEditField.ValueChangedFcn = createCallbackFcn(app, @ModelnameEditFieldValueChanged, true);
            app.SaveButton = uibutton(app.EditModelPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModelConfigurator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end