classdef PhasePanelEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        RightButton                   matlab.ui.control.Button
        LeftButton                    matlab.ui.control.Button
        ApplyButton                   matlab.ui.control.Button
        CancelButton                  matlab.ui.control.Button
        TypeDropDownLabel             matlab.ui.control.Label
        TypeDropDown                  matlab.ui.control.DropDown
        NameEditFieldLabel            matlab.ui.control.Label
        NameEditField                 matlab.ui.control.EditField
        StartDropDownLabel            matlab.ui.control.Label
        StartDropDown                 matlab.ui.control.DropDown
        StartConditionsDropDownLabel  matlab.ui.control.Label
        StartConditionsDropDown       matlab.ui.control.DropDown
        StartOperatorDropDown         matlab.ui.control.DropDown
        StartUnitEditField            matlab.ui.control.NumericEditField
        StartUnitEditFieldLabel       matlab.ui.control.Label
        EndDropDownLabel              matlab.ui.control.Label
        EndDropDown                   matlab.ui.control.DropDown
        EndConditionsDropDownLabel    matlab.ui.control.Label
        EndConditionsDropDown         matlab.ui.control.DropDown
        EndOperatorDropDown           matlab.ui.control.DropDown
        EndUnitEditField              matlab.ui.control.NumericEditField
        EndUnitEditFieldLabel         matlab.ui.control.Label
        ReservoirDropDownLabel        matlab.ui.control.Label
        ReservoirDropDown             matlab.ui.control.DropDown
        ParametersButton              matlab.ui.control.Button
        TimerEditFieldLabel           matlab.ui.control.Label
        TimerEditField                matlab.ui.control.NumericEditField
        TimerUnitLabel                matlab.ui.control.Label
    end

    
    properties (Access = public)
        CallingApp % Description
        phaseNum
        start_types = [""]
        phase_types = ["Manual", "Stop", "Update Parameter Set", "Pulse Feed", "Exponential Feed"];
        startobj = {'StartConditionsDropDownLabel', 'StartConditionsDropDown', 'StartOperatorDropDown', 'StartUnitEditField', 'StartUnitEditFieldLabel'};
        endobj = {'EndConditionsDropDownLabel', 'EndConditionsDropDown', 'EndOperatorDropDown', 'EndUnitEditField', 'EndUnitEditFieldLabel'};
        timerobj = {'TimerEditFieldLabel', 'TimerEditField', 'TimerUnitLabel'};
        PTable % Copy of the PhaseTable
    end
    
    methods (Access = private)
        
        function fillFigure(app, phaseNum)
            % Read basic information from table
            fields = {'process_typeID', 'name', 'start_typeID', 'end_typeID'};
            obj = {'TypeDropDown', 'NameEditField', 'StartDropDown', 'EndDropDown'};
            table = app.PTable;

            for i = 1:length(fields)
                if ~ismissing(table.(fields{i})(phaseNum))
                    app.(obj{i}).Value = table.(fields{i})(phaseNum);
                end
            end

            % Set condition objects
            StartDropDownValueChanged(app, 1)
            EndDropDownValueChanged(app, 1)

            % Set reservoir and parameter objects
            switch app.TypeDropDown.Value
                case 1 
                    reservoirObjects(app, 1)
                case 2
                    reservoirObjects(app, 1)
                    type = 2;
                case 3
                    reservoirObjects(app, 3)
                otherwise
                    reservoirObjects(app, 2)
                    type = 1;
                    app.ReservoirDropDown.Value = table.reservoirID(phaseNum);
            end
                   
            % Fill varible condition objects
            if app.StartDropDown.Value == 1
                if ~isnan(table.start_variableID(phaseNum))
                app.StartConditionsDropDown.Value = table.start_variableID(phaseNum);
                app.StartOperatorDropDown.Value = table.start_operatorID(phaseNum);
                app.StartUnitEditField.Value = table.start_value(phaseNum);
                new_unit = app.CallingApp.process_variable.tex_unit(app.StartConditionsDropDown.ValueIndex);
                app.StartUnitEditFieldLabel.Text = append(app.CallingApp.texinterpreter,"\fontsize{10}", new_unit);
                end
            end

            % If end variable condition was selected
            if app.EndDropDown.Value == 6
                if ~isnan(table.end_variableID(phaseNum))
                app.EndConditionsDropDown.Value = table.end_variableID(phaseNum);
                app.EndOperatorDropDown.Value = table.end_operatorID(phaseNum);
                app.EndUnitEditField.Value = table.end_value(phaseNum);
                new_unit = app.CallingApp.process_variable.tex_unit(app.EndConditionsDropDown.ValueIndex);
                app.EndUnitEditFieldLabel.Text = append(app.CallingApp.texinterpreter,"\fontsize{10}", new_unit);
                end
            elseif app.EndDropDown.Value == 7
                if isnan(table.end_value(phaseNum))
                    app.TimerEditField.Value = 0;
                else
                    app.TimerEditField.Value = table.end_value(phaseNum);
                end
            end


            % Add parameter button callback
           app.ParametersButton.ButtonPushedFcn = @(event) app.EditParameters(app, type);
            
        end

        function name = PhaseNameGenerator(app, typeID)
            names = app.PTable.name;
            default = app.CallingApp.process_type.default_title(typeID);
            f =  find(default == names);
            if ~isempty(f)
                n = length(f);
                name = sprintf('%s %d', default, n+1);
            else
                name = default;
            end
            
        end

        function reservoirObjects(app, type)
            switch type
                case 1
                    app.ReservoirDropDown.Visible = 'off';
                    app.ReservoirDropDownLabel.Visible = 'off';
                    app.ParametersButton.Visible = 'off';
                case 2
                    app.ReservoirDropDown.Visible = 'off';
                    app.ReservoirDropDownLabel.Visible = 'off';
                    app.ParametersButton.Visible = 'on';
                    app.ParametersButton.Layout.Column = [5, 10];

                case 3
                    app.ReservoirDropDown.Visible = 'on';
                    app.ReservoirDropDownLabel.Visible = 'on';
                    app.ParametersButton.Visible = 'on';
                    app.ParametersButton.Layout.Column = [7, 10];
            end
        end

        function shiftObjects(app, objIdx, offset)
            % 25 objects on the grid
            % Indices:
            % 8 rigid objects
            % Start: 9 - 15
            % End: 16 - 22
            % Reservoir: 23 - 25
            obj = flipud(app.GridLayout.Children);
            for i = objIdx
                current_pos = obj(i).Layout.Row;
                new_pos = current_pos + (offset);
                obj(i).Layout.Row = new_pos;
            end
            
        end

        function EditParameters(app, type)
            % Open parameters window
            reservoir = app.ReservoirDropDown.Value;
            
        
        end
        function applyChanges(app)
            type = app.TypeDropDown.Value;
            name = app.NameEditField.Value;
            
            starttype  = app.StartDropDown.Value;
            if starttype == 1
                startcondition = app.StartConditionsDropDown.Value;
                startoperator = app.StartOperatorDropDown.Value;
                startvalue = app.StartUnitEditField.Value;
            else
                startcondition = missing;
                startoperator = missing;
                startvalue = 0;
            end

            endtype = app.EndDropDown.Value;
            if endtype == 6
                endcondition = app.EndConditionsDropDown.Value;
                endoperator = app.EndOperatorDropDown.Value;
                endvalue = app.EndUnitEditField.Value;
            elseif endtype == 7
                endcondition = missing;
                endvalue = app.TimerEditField.Value;
                endoperator = missing;
            else
                endcondition = missing;
                endvalue = 0;
                endoperator = missing;
            end

            reservoir = app.ReservoirDropDown.Value;
            
            app.PTable(app.phaseNum, 4:end) = ...
                {type, name, starttype, startcondition, startoperator, startvalue,...
                missing, endtype, endcondition, endoperator, endvalue, missing, reservoir};
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, CallingApp, phaseNum)
            app.CallingApp = CallingApp;
            app.phaseNum = phaseNum;
            app.UIFigure.Name = sprintf('Edit Phase %d', app.phaseNum);
            app.PTable = app.CallingApp.PhaseTable;
            centerWindow(app.UIFigure);

            % Fill objects
            app.TypeDropDown.Items = app.CallingApp.process_type.type;
            app.TypeDropDown.ItemsData = app.CallingApp.process_type.process_typeID;

            app.StartDropDown.Items = app.CallingApp.start_conditiontype.conditiontype;
            app.StartDropDown.ItemsData = app.CallingApp.start_conditiontype.process_conditiontypeID;

            app.EndDropDown.Items = app.CallingApp.end_conditiontype.conditiontype;
            app.EndDropDown.ItemsData = app.CallingApp.end_conditiontype.process_conditiontypeID;
            
            app.StartConditionsDropDown.Items = app.CallingApp.process_variable.name;
            app.StartConditionsDropDown.ItemsData = app.CallingApp.process_variable.variableID;
            app.StartOperatorDropDown.Items = app.CallingApp.process_operator.condition;
            app.StartOperatorDropDown.ItemsData = app.CallingApp.process_operator.process_operatorID;

            app.EndConditionsDropDown.Items = app.CallingApp.process_variable.name;
            app.EndConditionsDropDown.ItemsData = app.CallingApp.process_variable.variableID;
            app.EndOperatorDropDown.Items = app.CallingApp.process_operator.condition;
            app.EndOperatorDropDown.ItemsData = app.CallingApp.process_operator.process_operatorID;

            % Define Reservoir DropDown
            app.ReservoirDropDown.Items = {'R1', 'R2', 'R3'};
            app.ReservoirDropDown.ItemsData = 1:3;

            % Correct Unit Interpreter Value
            app.StartUnitEditFieldLabel.Text = append(app.CallingApp.texinterpreter,"\fontsize{10}", app.StartUnitEditFieldLabel.Text);
            app.EndUnitEditFieldLabel.Text = append(app.CallingApp.texinterpreter,"\fontsize{10}", app.EndUnitEditFieldLabel.Text);
            
            % Fill all objects with phase information
            fillFigure(app, phaseNum)

            % Disable buttons
            if phaseNum == 1
                app.LeftButton.Enable = "off";
            elseif phaseNum == size(app.PTable, 1)
                app.RightButton.Enable = "off";
            end


        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            value = app.TypeDropDown.Value;
            switch value
                case 1 % Blank
                    reservoirObjects(app, 1)
                case 2
                    reservoirObjects(app, 1)
                case 3
                    reservoirObjects(app, 2)
                case 4
                    reservoirObjects(app, 3)
                case 5
                    reservoirObjects(app, 3)
            end

            app.NameEditField.Value = PhaseNameGenerator(app, value);

        end

        % Value changed function: StartConditionsDropDown
        function StartConditionsDropDownValueChanged(app, event)
            new_unit = app.CallingApp.process_variable.tex_unit(event.ValueIndex);
                app.StartUnitEditFieldLabel.Text = append(app.CallingApp.texinterpreter,"\fontsize{10}", new_unit);

        end

        % Value changed function: EndConditionsDropDown
        function EndConditionsDropDownValueChanged(app, event)
                new_unit = app.CallingApp.process_variable.tex_unit(event.ValueIndex);
                app.EndUnitEditFieldLabel.Text = append(app.CallingApp.texinterpreter,"\fontsize{10}", new_unit);
        end

        % Value changed function: StartDropDown
        function StartDropDownValueChanged(app, event)
            value = app.StartDropDown.Value;
            switch value
                case 1
                    for i = 1:length(app.startobj)
                        app.(app.startobj{i}).Visible = 'on';
                    end
                case 2
                    for i = 1:length(app.startobj)
                        app.(app.startobj{i}).Visible = 'off';
                    end
                case 3
                    for i = 1:length(app.startobj)
                        app.(app.startobj{i}).Visible = 'off';
                    end
                case 4
                    for i = 1:length(app.startobj)
                        app.(app.startobj{i}).Visible = 'off';
                    end
            end
        end

        % Value changed function: EndDropDown
        function EndDropDownValueChanged(app, event)
            value = app.EndDropDown.Value;
            switch value
                case 5
                    for i = 1:length(app.endobj)
                        app.(app.endobj{i}).Visible = 'off';
                    end
                    for j = 1:length(app.timerobj)
                        app.(app.timerobj{j}).Visible = 'off';
                    end
                case 6
                    for i = 1:length(app.endobj)
                        app.(app.endobj{i}).Visible = 'on';
                    end
                    for j = 1:length(app.timerobj)
                        app.(app.timerobj{j}).Visible = 'off';
                    end

                case 7
                    for i = 1:length(app.endobj)
                        app.(app.endobj{i}).Visible = 'off';
                    end
                    for j = 1:length(app.timerobj)
                        app.(app.timerobj{j}).Visible = 'on';
                    end
                    
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            applyChanges(app)
            app.CallingApp.PhaseTable = app.PTable;
            delete(app)

        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            if app.phaseNum <= size(app.PTable,1)
                applyChanges(app)
                app.phaseNum = app.phaseNum + 1;
                fillFigure(app, app.phaseNum)
                app.UIFigure.Name = sprintf('Edit Phase %d', app.phaseNum);
                if app.phaseNum == size(app.PTable,1)
                    app.RightButton.Enable = "off";
                else
                    app.RightButton.Enable = "on";
                end
                app.LeftButton.Enable = "on";
            end
            
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            if app.phaseNum > 1
                applyChanges(app)
                app.phaseNum = app.phaseNum - 1;
                fillFigure(app, app.phaseNum)
                app.UIFigure.Name = sprintf('Edit Phase %d', app.phaseNum);
                if app.phaseNum == 1
                    app.LeftButton.Enable = "off";
                else
                    app.LeftButton.Enable = "on";
                end
                app.RightButton.Enable = "on";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TimerUnitLabel = uilabel(app.GridLayout);
            app.TimerUnitLabel.Visible = 'off';
            app.TimerUnitLabel.Text = 'h';
            app.TimerEditField = uieditfield(app.GridLayout, 'numeric');
            app.TimerEditField.Limits = [0 1000];
            app.TimerEditField.Visible = 'off';
            app.TimerEditFieldLabel = uilabel(app.GridLayout);
            app.TimerEditFieldLabel.Visible = 'off';
            app.TimerEditFieldLabel.Text = 'Timer:';
            app.ParametersButton = uibutton(app.GridLayout, 'push');
            app.ParametersButton.Visible = 'off';
            app.ParametersButton.Text = 'Parameters';
            app.ReservoirDropDown = uidropdown(app.GridLayout);
            app.ReservoirDropDown.Items = {'R1', 'R2', 'R3'};
            app.ReservoirDropDown.ItemsData = [1 2 3];
            app.ReservoirDropDown.Visible = 'off';
            app.ReservoirDropDown.Value = 1;
            app.ReservoirDropDownLabel = uilabel(app.GridLayout);
            app.ReservoirDropDownLabel.Visible = 'off';
            app.ReservoirDropDownLabel.Text = 'Reservoir:';
            app.EndUnitEditFieldLabel = uilabel(app.GridLayout);
            app.EndUnitEditFieldLabel.Visible = 'off';
            app.EndUnitEditFieldLabel.Interpreter = 'tex';
            app.EndUnitEditFieldLabel.Text = 'h';
            app.EndUnitEditField = uieditfield(app.GridLayout, 'numeric');
            app.EndUnitEditField.Visible = 'off';
            app.EndOperatorDropDown = uidropdown(app.GridLayout);
            app.EndOperatorDropDown.Visible = 'off';
            app.EndConditionsDropDown = uidropdown(app.GridLayout);
            app.EndConditionsDropDown.ValueChangedFcn = createCallbackFcn(app, @EndConditionsDropDownValueChanged, true);
            app.EndConditionsDropDown.Visible = 'off';
            app.EndConditionsDropDownLabel = uilabel(app.GridLayout);
            app.EndConditionsDropDownLabel.Visible = 'off';
            app.EndConditionsDropDownLabel.Text = 'Conditions:';
            app.EndDropDown = uidropdown(app.GridLayout);
            app.EndDropDown.ItemsData = [1 2 3];
            app.EndDropDown.ValueChangedFcn = createCallbackFcn(app, @EndDropDownValueChanged, true);
            app.EndDropDown.Value = 1;
            app.EndDropDownLabel = uilabel(app.GridLayout);
            app.EndDropDownLabel.Text = 'End:';
            app.StartUnitEditFieldLabel = uilabel(app.GridLayout);
            app.StartUnitEditFieldLabel.Interpreter = 'tex';
            app.StartUnitEditFieldLabel.Text = 'h';
            app.StartUnitEditField = uieditfield(app.GridLayout, 'numeric');
            app.StartOperatorDropDown = uidropdown(app.GridLayout);
            app.StartConditionsDropDown = uidropdown(app.GridLayout);
            app.StartConditionsDropDown.ValueChangedFcn = createCallbackFcn(app, @StartConditionsDropDownValueChanged, true);
            app.StartConditionsDropDownLabel = uilabel(app.GridLayout);
            app.StartConditionsDropDownLabel.Text = 'Conditions:';
            app.StartDropDown = uidropdown(app.GridLayout);
            app.StartDropDown.ItemsData = [1 2 3];
            app.StartDropDown.ValueChangedFcn = createCallbackFcn(app, @StartDropDownValueChanged, true);
            app.StartDropDown.Value = 1;
            app.StartDropDownLabel = uilabel(app.GridLayout);
            app.StartDropDownLabel.Text = 'Start:';
            app.NameEditField = uieditfield(app.GridLayout, 'text');
            app.NameEditFieldLabel = uilabel(app.GridLayout);
            app.NameEditFieldLabel.Text = 'Name:';
            app.TypeDropDown = uidropdown(app.GridLayout);
            app.TypeDropDown.Items = {'Blank', 'Stop', 'Update Parameter Set', 'Pulse Feed', 'Exponential Feed'};
            app.TypeDropDown.ItemsData = [1 2 3 4 5];
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 1;
            app.TypeDropDownLabel = uilabel(app.GridLayout);
            app.TypeDropDownLabel.Text = 'Type:';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ApplyButton = uibutton(app.GridLayout, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.LeftButton = uibutton(app.GridLayout, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Icon = fullfile(pathToMLAPP, 'figures', 'leftarrow.png');
            app.LeftButton.Text = '';
            app.RightButton = uibutton(app.GridLayout, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Icon = fullfile(pathToMLAPP, 'figures', 'rightarrow.png');
            app.RightButton.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PhasePanelEditor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end