classdef SelectProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        DeleteSelectedProjectButton  matlab.ui.control.Button
        DatabasespaceLabel           matlab.ui.control.Label
        CreateNewProjectButton       matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        SelectaProjectLabel          matlab.ui.control.Label
        SelectButton                 matlab.ui.control.Button
        ReturnButton                 matlab.ui.control.Button
        ContextMenu                  matlab.ui.container.ContextMenu
        DeleteProject                matlab.ui.container.Menu
    end

    
    properties (Access = public)
        CallingApp % Starting Screen
        CreateProjectApp
        projectID
        projectname
        projecttable
        
    end
    
    properties (Access = public)
        selectedproject % This will be filled by the create project app
        dbpath % Databankfile name
    end

    methods (Access = private)
        
        function fillProject(app)
            app.UITable.ColumnName = {"Title", "Description", "Last used on", "Author", "Organism", "Bioreactor"};
            sqlquery = sprintf(['SELECT p.projectID, p.name, p.description, p.recent_use, p.author, o.name, b.name ' ...
                'FROM projectTab p ' ...
                'JOIN organismTab o ON p.organismID = o.organismID ' ...
                'JOIN bioreactorTab b ON p.bioreactorID = b.bioreactorID ' ...
                'ORDER BY p.projectID DESC']);
            conn = sqlite(app.dbpath, "readonly");
            app.projecttable = fetch(conn,sqlquery);
            close(conn)
            if ~isempty(app.projecttable)
                app.UITable.Data = app.projecttable(:,2:end);
            else
                disp("Databank not found or empty!");
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, CallingApp)
            app.UIFigure.Name = "Load Project";
            app.CallingApp = CallingApp;
            app.dbpath = app.CallingApp.dbpath;
            fillProject(app)

            %% Display database file size
            % Access file info
            fileInfo = dir(app.dbpath);

            % Size of file in bytes
            dbSizeBytes = fileInfo.bytes;

            % Conversion in MB
            dbSizeMB = dbSizeBytes / (1024 * 1024);

            % Print on label
            app.DatabasespaceLabel.Text = sprintf('Database size: %.2f MB\n', dbSizeMB);
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            app.CallingApp.selectedproject = app.projectname;
            app.CallingApp.projectID = app.projectID;
            delete(app)
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                app.SelectButton.Enable = "on";
                app.SelectButton.Tooltip = "";
                app.DeleteSelectedProjectButton.Enable = "on";
                app.projectID =  app.projecttable.projectID(indices(1,1));
                app.projectname =  app.projecttable.name(indices(1,1));
                app.DeleteProject.Visible = "on";
                app.DeleteProject.Text = sprintf('Delete %s', app.projectname);
            else
                app.SelectButton.Enable = "off";
                app.SelectButton.Tooltip = "Select a project";
                app.DeleteSelectedProjectButton.Enable = "off";
                app.projectID;
                app.projectname = [];
                app.DeleteProject.Text = [];
                app.DeleteProject.Visible = "off";
            end
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            delete(app)
        end

        % Menu selected function: DeleteProject
        function DeleteProjectMenuSelected(app, event)
            choice = questdlg(sprintf("Are you sure you want to delete %s?",app.projectname), "Delete Project", "Cancel", "Delete", "Cancel");
        switch choice
            case "Delete"
                oldprojects = app.projecttable.projectID;
                row = find(app.projectID == oldprojects);
                conn = sqlite(app.dbpath, "connect");
                exec(conn, 'PRAGMA foreign_keys = ON;');
                sqlquery = sprintf('DELETE FROM projectTab WHERE projectID = %d', app.projectID);
                exec(conn, sqlquery);
                close(conn)
                fillProject(app);
                newprojects = app.projecttable.projectID;
                newprojectnames = app.projecttable.name;
                if row > length(newprojects)
                    app.projectID = newprojects(end);
                    app.projectname = newprojectnames(end);
                else
                    app.projectID = newprojects(row);
                    app.projectname = newprojectnames(row);
                end
                app.DeleteProject.Text = sprintf('Delete %s', app.projectname);
            case "Cancel"
                % Do nothing — dialog closes itself
        end

        end

        % Button pushed function: CreateNewProjectButton
        function CreateNewProjectButtonPushed(app, event)
            app.UIFigure.Visible = "off";
            cp = CreateProject(app);
            waitfor(cp);
            app.UIFigure.Visible = "on";
            if ~isempty(app.projectID)
                fillProject(app)
            end
        end

        % Button pushed function: DeleteSelectedProjectButton
        function DeleteSelectedProjectButtonPushed(app, event)
            DeleteProjectMenuSelected(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.ReturnButton = uibutton(app.UIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Enable = 'off';
            app.SelectButton.Tooltip = {'Select a project'};
            app.SelectButton.Text = 'Select';
            app.SelectaProjectLabel = uilabel(app.UIFigure);
            app.SelectaProjectLabel.Text = 'Select a Project:';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Title'; 'Description'; 'Last used on'; 'Author'; 'Organism'; 'Bioreactor'};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.Multiselect = 'off';
            app.CreateNewProjectButton = uibutton(app.UIFigure, 'push');
            app.CreateNewProjectButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNewProjectButtonPushed, true);
            app.CreateNewProjectButton.Text = 'Create New Project';
            app.DatabasespaceLabel = uilabel(app.UIFigure);
            app.DatabasespaceLabel.Text = 'Database space';
            app.DeleteSelectedProjectButton = uibutton(app.UIFigure, 'push');
            app.DeleteSelectedProjectButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSelectedProjectButtonPushed, true);
            app.DeleteSelectedProjectButton.Enable = 'off';
            app.DeleteSelectedProjectButton.Text = 'Delete Selected Project';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.DeleteProject = uimenu(app.ContextMenu);
            app.DeleteProject.MenuSelectedFcn = createCallbackFcn(app, @DeleteProjectMenuSelected, true);
            app.DeleteProject.Accelerator = 'd';
            app.DeleteProject.Text = 'Delete Project';
            % Assign app.ContextMenu
            app.UITable.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SelectProject(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end