classdef Settings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        MaxsimulationspeedEditField  matlab.ui.control.NumericEditField
        MaxspeedEditFieldLabel       matlab.ui.control.Label
        ApplyButton                  matlab.ui.control.Button
        SignificantfiguresEditField  matlab.ui.control.NumericEditField
        EditFieldLabel               matlab.ui.control.Label
        SettingsLabel                matlab.ui.control.Label
        StudentmodeCheckBox          matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, CallingApp)
            app.CallingApp = CallingApp;
            app.StudentmodeCheckBox.Value = app.CallingApp.studentmode;
            app.MaxsimulationspeedEditField.Value = app.CallingApp.maxspeedfactor;
            app.SignificantfiguresEditField.Value = app.CallingApp.sigfig;
        end

        % Value changed function: StudentmodeCheckBox
        function StudentmodeCheckBoxValueChanged(app, event)
            app.CallingApp.studentmode = app.StudentmodeCheckBox.Value;
            
        end

        % Value changed function: SignificantfiguresEditField
        function SignificantfiguresEditFieldValueChanged(app, event)
            app.CallingApp.sigfig = app.SignificantfiguresEditField.Value;
            
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            delete(app)
        end

        % Value changed function: MaxsimulationspeedEditField
        function MaxsimulationspeedEditFieldValueChanged(app, event)
            app.CallingApp.maxspeedfactor = app.MaxsimulationspeedEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StudentmodeCheckBox = uicheckbox(app.UIFigure);
            app.StudentmodeCheckBox.ValueChangedFcn = createCallbackFcn(app, @StudentmodeCheckBoxValueChanged, true);
            app.StudentmodeCheckBox.Tooltip = {'Disables time laps options.'};
            app.StudentmodeCheckBox.Text = 'Student mode';
            app.SettingsLabel = uilabel(app.UIFigure);
            app.SettingsLabel.Text = 'Settings';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Significant figures:';
            app.SignificantfiguresEditField = uieditfield(app.UIFigure, 'numeric');
            app.SignificantfiguresEditField.Limits = [0 10];
            app.SignificantfiguresEditField.ValueChangedFcn = createCallbackFcn(app, @SignificantfiguresEditFieldValueChanged, true);
            app.SignificantfiguresEditField.Tooltip = {'Significant figures of variable data for exports and uploads into the database.'};
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.MaxspeedEditFieldLabel = uilabel(app.UIFigure);
            app.MaxspeedEditFieldLabel.Text = 'Max simulation speed:';
            app.MaxsimulationspeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxsimulationspeedEditField.Limits = [0 500];
            app.MaxsimulationspeedEditField.ValueChangedFcn = createCallbackFcn(app, @MaxsimulationspeedEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Settings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end