classdef StartingScreen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ExitButton                 matlab.ui.control.Button
        ConfigureModelsButton      matlab.ui.control.Button
        LoadProjectButton          matlab.ui.control.Button
        StartNewProjectButton      matlab.ui.control.Button
        QuickStartButton           matlab.ui.control.Button
        Version20Label             matlab.ui.control.Label
        BioprocessSimulationLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        SelectProjectApp
        CreateProjectApp
        CalculationApp
        ConfigureModel
        ControlApp

    end

    properties (Access = public)
        %% Databank related
        dbpath = 'SimulationAppDB.db' % Databankfile name

        %% Project related
        selectedproject % The project that is going to be called by the calculation app
    end

    methods (Access = public)

        function logMessage(app, projectID, message)
            timestamp = datetime("now","Format","uuuu-MM-dd HH:mm:ss.SSS");
            sqlquery = sprintf(['INSERT INTO logTab (projectID, datetime, message) ' ...
                'VALUES (%g, ''%s'', ''%s'')'], projectID, timestamp, message);
            execpy(app.dbpath,sqlquery);
        end

        function uniquename = uniqueProjectname(app, name)
            sqlquery = 'SELECT name FROM projectTab ';
            projectnames = table2array(fetchpy(app.dbpath, sqlquery));
            if any(strcmp(projectnames, name))
                suffix = 1;
                uniquename = name;
                while any(strcmp(projectnames, uniquename))
                    uniquename = sprintf('%s_%d', name, suffix);
                    suffix = suffix + 1;
                    if suffix > 100
                        disp("Name is taken over 100 times.");
                        break;
                    end
                end
            else
                uniquename = name;
            end
        end

        % Python is needed for the SQLite databank connection
        function initializePython(app)
    % % Finden der systemweiten Python-Installation
    % [status, pythonPath] = system('which python3');
    % if status ~= 0
    %     [status, pythonPath] = system('which python');
    % end
    % 
    % if status ~= 0
    %     error('Keine Python-Installation auf dem System gefunden.');
    % end
    % 
    % pythonPath = strtrim(pythonPath);  % Entfernen von Whitespace
    % disp(['Gefundene Python-Installation: ', pythonPath]);
    % 
    % % Überprüfen der Python-Version
    % [status, result] = system([pythonPath ' --version']);
    % if status == 0
    %     disp(['Python-Version: ', strtrim(result)]);
    % else
    %     warning('Konnte Python-Version nicht ermitteln.');
    % end
    % 
    % % Setzen der Python-Umgebung in MATLAB
    % try
    %     pe = pyenv('Version', pythonPath, 'ExecutionMode', 'OutOfProcess');
    %     if ~pe.Status
    %         error('Fehler beim Setzen der Python-Umgebung.');
    %     end
    % catch ME
    %     error('Fehler beim Initialisieren der Python-Umgebung: %s', ME.message);
    % end
    
    % Pfad zu Ihren eigenen Python-Skripten
    pythonScriptPath = fullfile(pwd, 'python_scripts');
    if ~exist(pythonScriptPath, 'dir')
        mkdir(pythonScriptPath);
        warning('Ordner "python_scripts" wurde erstellt. Bitte legen Sie Ihre .py-Dateien dort ab.');
    end
    
    % Hinzufügen des Skript-Pfads zum Python-Suchpfad
    % Korrigierte Syntax für py.sys.path().insert
    if ~any(strcmp(cell(py.sys.path()), pythonScriptPath))
        %py.sys.path().insert(int32(0), pythonScriptPath);
        insert(py.sys.path, int32(0), pythonScriptPath);
    end
    
    % Überprüfen, ob Pandas installiert ist
    try
        py.importlib.import_module('pandas');
        disp('Pandas ist installiert und verwendbar.');
    catch
        warning('Pandas ist nicht installiert. Bitte installieren Sie es mit "pip install pandas".');
    end

    % Importieren Ihrer eigenen Module
    try
        py.importlib.import_module('sqlite_query');
        py.importlib.import_module('sqlite_modify');
        py.importlib.import_module('sqlite_append');
        disp('Eigene Python-Module erfolgreich importiert.');
    catch ME
        warning('Fehler beim Importieren der eigenen Python-Module: %s', string(ME.message));
        disp('Stellen Sie sicher, dass die .py-Dateien im Ordner "python_scripts" liegen.');
    end

    disp('Python-Umgebung erfolgreich initialisiert.');
        end


        function intialization(app)
            pythonScriptPath = 'python_scripts';
            if ismac
                % 2. Versuchen Sie, Pandas zu importieren und die Version zu überprüfen
                try
                    pyenv
                    if ~any(strcmp(cell(py.sys.path()), pythonScriptPath))
                        insert(py.sys.path, int32(0), pythonScriptPath);
                    end
                    system('pip3 install pandas');
                    pandas_path = py.sys.executable;
                    pandas_dir = fileparts(char(pandas_path));
                    site_packages = fullfile(pandas_dir, 'lib', 'python3.9', 'site-packages');
                    py.sys.path().append(site_packages);
                    py.importlib.import_module('pandas');
                    py.importlib.import_module('sqlite_query');
                    py.importlib.import_module('sqlite_modify');
                    py.importlib.import_module('sqlite_append');
                catch ME
                    disp('Python initialization on mac failed.');
                    disp(ME.message);
                end
            else
                try
                    pyenv
                    if ~any(strcmp(cell(py.sys.path()), pythonScriptPath))
                        insert(py.sys.path, int32(0), pythonScriptPath);
                    end
                    system('pip3 install pandas');
                    pandas_path = py.sys.executable;
                    pandas_dir = fileparts(char(pandas_path));
                    site_packages = fullfile(pandas_dir, 'lib', 'python3.9', 'site-packages');
                    py.sys.path().append(site_packages);
                    py.importlib.import_module('pandas');
                    py.importlib.import_module('sqlite_query');
                    py.importlib.import_module('sqlite_modify');
                    py.importlib.import_module('sqlite_append');
                catch ME
                    disp('Python initialization on windodws failed.');
                    disp(ME.message);
                end
            end
        end
    end
    methods (Access = private)

        function startProcess(app)
            fprintf("Selected model: %s\n", app.selectedproject);
            % Calculation app
            app.CalculationApp = CalculationClass1(app, app.selectedproject);
            app.UIFigure.Visible = "off";
            % Control app
            app.ControlApp = ControlApp_ODE_Luttmann_pHTEMP_new_3(app, app.CalculationApp, app.selectedproject);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = "Bioprocess Simulation";
            centerWindow(app.UIFigure)
            %initializePython_mac(app)
            intialization(app)
            app.dbpath = fullfile(pwd, 'SimulationAppDB.db'); % Save full db file path
            
        end

        % Button pushed function: StartNewProjectButton
        function StartNewProjectButtonPushed(app, event)
            app.UIFigure.Visible = "off";
            app.CreateProjectApp = CreateProject(app);
            waitfor(app.CreateProjectApp);
            app.UIFigure.Visible = "on";
            if ~isempty(app.selectedproject)
                startProcess(app)
            end
        end

        % Button pushed function: LoadProjectButton
        function LoadProjectButtonPushed(app, event)
            app.UIFigure.Visible = "off";
            app.SelectProjectApp = SelectProject(app);
            waitfor(app.SelectProjectApp);
            app.UIFigure.Visible = "on";
            if ~isempty(app.selectedproject)
                startProcess(app)
            end
        end

        % Button pushed function: ConfigureModelsButton
        function ConfigureModelsButtonPushed(app, event)
            %app.ConfigureModelsButton.Enable = "off";
            app.ConfigureModel = ModelConfigurator(app);
            waitfor(app.ConfigureModel)
            %app.ConfigureModelsButton.Enable = 'on';
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app.UIFigure);
        end

        % Button pushed function: QuickStartButton
        function QuickStartButtonPushed(app, event)
            name = "MyProject";
            author = "John Doe";
            date = datetime("now","Format","uuuu-MM-dd HH:mm:ss.SSS");
            description = sprintf("This is a quick start project created on: %s.", date);
            organism = 1;
            bioreactor = 1;
            name = uniqueProjectname(app, name);
            if ~isempty(name)
                % Read model data from first model (display_rank)
                sqlquery = ['SELECT m.modelID, mp.propertyID, mp.value, mp.description ' ...
                    'FROM model_parameterTab mp ' ...
                    'JOIN modelTab m ON m.modelID = mp.modelID ' ...
                    'WHERE mp.modelID = (SELECT modelID FROM modelTab ORDER BY display_rank ASC LIMIT 1);'];
                modelparameterTable = fetchpy(app.dbpath, sqlquery);
                modelID = table2array(modelparameterTable(1,1));
                modelparameter = modelparameterTable(:,2:end); % Modelparameter without modelID
                % Create new project entry
                sqlquery = sprintf(['INSERT INTO projectTab (name, description, author, created_on, recent_use, organismID, bioreactorID, modelID) ' ...
                    'VALUES (''%s'', ''%s'', ''%s'', ''%s'', ''%s'', %i, %i, %i)'], name, description,author, date, date, organism, bioreactor, modelID);
                execpy(app.dbpath, sqlquery);
                % Read projectID of the new project
                sqlquery = sprintf('SELECT projectID FROM projectTab WHERE name = ''%s''', name);
                projectID = double(table2array(fetchpy(app.dbpath, sqlquery)));
                % Create a column with the new projectID
                projectIDcolumn = table(ones(height(modelparameter(:,1)),1).*projectID);
                data = [projectIDcolumn, modelparameter]; % cut modelID
                % Write parameter information into project_parameter table 
                data.Properties.VariableNames = {'projectID', 'propertyID', 'value', 'description'};
                sqlwritepy(app.dbpath, 'project_parameterTab', data);
                app.selectedproject = name;
                startProcess(app)
            else
                disp("Project couldn´t be generated.");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.BioprocessSimulationLabel = uilabel(app.UIFigure);
            app.BioprocessSimulationLabel.Text = 'Bioprocess Simulation';
            app.Version20Label = uilabel(app.UIFigure);
            app.Version20Label.Text = 'Version 2.0';
            app.QuickStartButton = uibutton(app.UIFigure, 'push');
            app.QuickStartButton.ButtonPushedFcn = createCallbackFcn(app, @QuickStartButtonPushed, true);
            app.QuickStartButton.Text = 'Quick Start';
            app.StartNewProjectButton = uibutton(app.UIFigure, 'push');
            app.StartNewProjectButton.ButtonPushedFcn = createCallbackFcn(app, @StartNewProjectButtonPushed, true);
            app.StartNewProjectButton.Text = 'Start New Project';
            app.LoadProjectButton = uibutton(app.UIFigure, 'push');
            app.LoadProjectButton.ButtonPushedFcn = createCallbackFcn(app, @LoadProjectButtonPushed, true);
            app.LoadProjectButton.Text = 'Load Project';
            app.ConfigureModelsButton = uibutton(app.UIFigure, 'push');
            app.ConfigureModelsButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureModelsButtonPushed, true);
            app.ConfigureModelsButton.Text = 'Configure Models';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StartingScreen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end