classdef ClosingScreen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SaveandExportButton        matlab.ui.control.Button
        ReturnButton               matlab.ui.control.Button
        SaveProjectButton          matlab.ui.control.Button
        DeleteProjectButton        matlab.ui.control.Button
        DescriptionTextArea        matlab.ui.control.TextArea
        DescriptionTextAreaLabel   matlab.ui.control.Label
        AuthorEditField            matlab.ui.control.EditField
        AuthorEditFieldLabel       matlab.ui.control.Label
        ProjectnameEditField       matlab.ui.control.EditField
        ProjectnameEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        CallingApp % ControlApp
        MainApp % CalculationApp
        projectID
    end
    
    methods (Access = private)

        function closeAllWindows(app)
            try
                delete(app.CallingApp)
                %delete(app.MainApp)
                delete(app)
            catch ME
                disp(ME.message)
                delete(app.MainApp)
                delete(app)
            end
        end

        function saveProjectFcn(app) % This function mainly interacts with the calculation app
            try
            % This function uploads missing variable values at the end of a process to allow reloading
            if app.MainApp.t(end) > 0
                % Delete previous end variables
                sqlquery = sprintf(['DELETE FROM dataTab ' ...
                 'WHERE timeID IN (SELECT timeID FROM timeTab WHERE projectID = %i) ' ...
                 'AND variableID IN (' ...
                 '  SELECT d.variableID ' ...
                 '  FROM dataTab d ' ...
                 '  JOIN variable_handlingTab vh ON vh.variableID = d.variableID ' ...
                 '  WHERE vh.upload_rate = ''end'' AND vh.organismID = %i ' ...
                 ')'], app.projectID, app.MainApp.organismID);
                conn = sqlite(app.MainApp.dbpath, "connect");
                exec(conn, 'PRAGMA foreign_keys = ON;');
                exec(conn,sqlquery);
                close(conn)
                % Upload new end variables
                app.MainApp.uploadLatestVariables(app.MainApp.end_variables)
            end
            % Update recent use in profile
            timestamp = datetime("now","Format","dd.MM.uuuu HH:mm:ss.SSS");
            sqlquery = sprintf('UPDATE projectTab SET recent_use = ''%s'' WHERE projectID = %g', timestamp, app.projectID);
            conn = sqlite(app.MainApp.dbpath, "connect");
            exec(conn,sqlquery);
            close(conn)

            disp("Project saved.")
            catch ME
                disp("Upload incomplete.");
            end
        end

        function deleteProject(app)
            choice = questdlg( ...
                "Are you sure you want to delete the project?", ...
                "Delete Project", "Cancel", "Delete", "Cancel");  % default = Cancel

            if ~strcmp(choice, "Delete")
                return;  % Cancel, closed dialog, or empty — do nothing
            end

            f = waitbar(0, "Deleting data...");
            conn = sqlite(app.MainApp.dbpath, "connect");

            try
                % Manual cascade for speed (dataTab is too large for FK cascade)
                exec(conn, 'PRAGMA foreign_keys = OFF');

                sqlquery = sprintf([ ...
                    'DELETE FROM dataTab WHERE timeID IN ' ...
                    '(SELECT timeID FROM timeTab WHERE projectID = %i)'], app.projectID);
                exec(conn, sqlquery);
                waitbar(0.25, f, "Deleting time stamps...");

                sqlquery = sprintf('DELETE FROM timeTab WHERE projectID = %i', app.projectID);
                exec(conn, sqlquery);

                sqlquery = sprintf('DELETE FROM project_parameterTab WHERE projectID = %i', app.projectID);
                exec(conn, sqlquery);
                waitbar(0.5, f, "Deleting remaining project data...");

                % Re-enable FK before deleting project row so other cascades fire
                exec(conn, 'PRAGMA foreign_keys = ON');

                sqlquery = sprintf('DELETE FROM projectTab WHERE projectID = %i', app.projectID);
                exec(conn, sqlquery);
                waitbar(0.75, f, "Finishing...");

                close(conn);
                close(f);
                returnToStartingScreen(app);

            catch ME
                exec(conn, 'PRAGMA foreign_keys = ON');  % Always restore FK state
                close(conn);
                close(f);
                uialert(app.UIFigure, ME.message, "Error deleting project");
            end
        end

        function returnToStartingScreen(app)
            %start = app.MainApp.Startingscreen; 
            app.MainApp.UIFigure.Visible = "on";
            closeAllWindows(app)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, CallingApp, MainApp)
            app.CallingApp = CallingApp;
            app.MainApp = MainApp;
            app.projectID = app.CallingApp.projectID;
            centerWindow(app.UIFigure)
            app.UIFigure.Name = "Close Project";

            % Fill objects
            app.ProjectnameEditField.Value = app.CallingApp.projectName;
            app.AuthorEditField.Value = app.CallingApp.author;
            app.DescriptionTextArea.Value = app.CallingApp.projectDescription;

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: ProjectnameEditField
        function ProjectnameEditFieldValueChanged(app, event)
            value = app.ProjectnameEditField.Value;
            app.CallingApp.projectName = value;
            sqlquery = sprintf('UPDATE projectTab SET "name" = "%s" WHERE projectID = %i', value, app.CallingApp.projectID);
            conn = sqlite(app.MainApp.dbpath, "connect");
            exec(conn,sqlquery);
            close(conn)
        end

        % Value changed function: AuthorEditField
        function AuthorEditFieldValueChanged(app, event)
            value = app.AuthorEditField.Value;
            app.CallingApp.author = value;
            sqlquery = sprintf('UPDATE projectTab SET "author" = "%s" WHERE projectID = %i', value, app.CallingApp.projectID);
            conn = sqlite(app.MainApp.dbpath, "connect");
            exec(conn,sqlquery);
            close(conn)
        end

        % Value changed function: DescriptionTextArea
        function DescriptionTextAreaValueChanged(app, event)
            value = app.DescriptionTextArea.Value;
            app.CallingApp.projectDescription = value;
            sqlquery = sprintf('UPDATE projectTab SET "description" = "%s" WHERE projectID = %i', value, app.CallingApp.projectID);
            conn = sqlite(app.MainApp.dbpath, "connect");
            exec(conn,sqlquery);
            close(conn)
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: DeleteProjectButton
        function DeleteProjectButtonPushed(app, event)
            deleteProject(app)
            returnToStartingScreen(app)
        end

        % Button pushed function: SaveProjectButton
        function SaveProjectButtonPushed(app, event)
            saveProjectFcn(app)
            returnToStartingScreen(app)
        end

        % Button pushed function: SaveandExportButton
        function SaveandExportButtonPushed(app, event)
            saveProjectFcn(app)
            exportproject = ExportProject(app.CallingApp); % This function runs with control app information
            waitfor(exportproject)
            returnToStartingScreen(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ProjectnameEditFieldLabel = uilabel(app.UIFigure);
            app.ProjectnameEditFieldLabel.Text = 'Project name:';
            app.ProjectnameEditField = uieditfield(app.UIFigure, 'text');
            app.ProjectnameEditField.ValueChangedFcn = createCallbackFcn(app, @ProjectnameEditFieldValueChanged, true);
            app.AuthorEditFieldLabel = uilabel(app.UIFigure);
            app.AuthorEditFieldLabel.Text = 'Author:';
            app.AuthorEditField = uieditfield(app.UIFigure, 'text');
            app.AuthorEditField.ValueChangedFcn = createCallbackFcn(app, @AuthorEditFieldValueChanged, true);
            app.DescriptionTextAreaLabel = uilabel(app.UIFigure);
            app.DescriptionTextAreaLabel.Text = 'Description:';
            app.DescriptionTextArea = uitextarea(app.UIFigure);
            app.DescriptionTextArea.ValueChangedFcn = createCallbackFcn(app, @DescriptionTextAreaValueChanged, true);
            app.DeleteProjectButton = uibutton(app.UIFigure, 'push');
            app.DeleteProjectButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteProjectButtonPushed, true);
            app.DeleteProjectButton.Tooltip = {'Delete the project from the database and close simulator'};
            app.DeleteProjectButton.Text = 'Delete Project';
            app.SaveProjectButton = uibutton(app.UIFigure, 'push');
            app.SaveProjectButton.ButtonPushedFcn = createCallbackFcn(app, @SaveProjectButtonPushed, true);
            app.SaveProjectButton.Tooltip = {'Save project in database and close simulator'};
            app.SaveProjectButton.Text = 'Save Project';
            app.ReturnButton = uibutton(app.UIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Tooltip = {'Return and do not close the simulator'};
            app.ReturnButton.Text = 'Return';
            app.SaveandExportButton = uibutton(app.UIFigure, 'push');
            app.SaveandExportButton.ButtonPushedFcn = createCallbackFcn(app, @SaveandExportButtonPushed, true);
            app.SaveandExportButton.Tooltip = {'Save project and export project data to the desktop'};
            app.SaveandExportButton.Text = 'Save and Export';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ClosingScreen(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end