classdef CalculationClass1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ProcesstimehEditField       matlab.ui.control.NumericEditField
        ProcesstimehEditFieldLabel  matlab.ui.control.Label
        InoculationCheckBox         matlab.ui.control.CheckBox
        SpeedfactorxEditField       matlab.ui.control.NumericEditField
        SpeedfactorxEditFieldLabel  matlab.ui.control.Label
        ChangeButton                matlab.ui.control.Button
        DeltatEditField             matlab.ui.control.NumericEditField
        fontnameHelveticaDeltatsEditFieldLabel  matlab.ui.control.Label
        calctimesEditField          matlab.ui.control.NumericEditField
        calctimesEditFieldLabel     matlab.ui.control.Label
        ProjectnameEditField        matlab.ui.control.EditField
        ProjectnameEditFieldLabel   matlab.ui.control.Label
        ExitButton                  matlab.ui.control.Button
        RunButton                   matlab.ui.control.Button
    end


    properties (Access = public)
        
        CallingApp % Define main app

        %% Properties
        deltapGw
        FHrelw
        FnAIRw
        FnCO2w
        FnGw
        FnN2w
        FnO2w
        FRw
        LWw
        NStw
        pHw
        pO2w
        thetaLw
        cOL0
        cS1L0
        cS2L0
        cS3L0
        cXL0
        pH0
        pO20
        thetaD0
        thetaL0
        VL0
        VR0
        pGcal
        xCGcal
        xOGcal
        CCR1tot
        CCR2tot
        CCR3tot
        cOR1
        cOR2
        cOR3
        CPR1tot
        CPR2tot
        CPR3tot
        cS1R1
        cS1R2
        cS1R3
        cS2R1
        cS2R2
        cS2R3
        cS3R1
        cS3R2
        cS3R3
        cXR1
        cXR2
        cXR3
        FR1max
        FR2max
        FR3max
        rhoR1
        rhoR2
        rhoR3
        VR10
        VR20
        VR30
        kR1
        kR2
        kR3
        qxpx1w
        qxpx2w
        qxpx3w
        VT20
        CAcLtot0
        CAcT1tot
        CAlLtot0
        CAlT2tot
        CB1Ltot0
        CB2Ltot0
        CCT1tot
        CCT2tot
        cOT1
        cOT2
        CPLtot0
        KAc1
        KAc2
        KAl
        KAlvol
        KB1
        KB2
        KB3
        KP
        MAc
        MAl
        VT10
        f_acid
        f_aeration
        f_air
        f_alkali
        f_antifoam
        f_CO2
        f_cooling
        f_feed
        f_FR1
        f_FR2
        f_FR3
        f_harvest
        f_heating
        f_InocStart
        f_LW_harvest
        f_mode_harvest
        f_mode_pH
        f_mode_pO2
        f_mode_temp
        f_motor
        f_N2
        f_O2
        f_pH_mode
        f_pO2_aeration
        f_pO2_agi
        f_pO2_feed
        f_pO2_gasmix
        f_temp_mode
        KD_aeration
        KD_agi
        KD_feed
        KD_gasmix
        KD_LW
        KI_aeration
        KI_agi
        KI_feed
        KI_gasmix
        KI_LW
        KI_temp1
        KP_aeration
        KP_agi
        KP_feed
        KP_gasmix
        KP_LW
        KP_pH
        KP_pH2a
        KP_pH2b
        KP_temp1
        KP_temp2c
        KP_temp2h
        ypH_SET
        alpha
        beta
        CH0
        cH2O
        cXLeta
        deltahv
        etaH2O
        etaXL
        gamma
        HCO20
        HnCO2
        HnO2
        HO20
        K1HCO2
        K1HO2
        K2HCO2
        K2HO2
        K3HCO2
        K3HO2
        K4HCO2
        K4HO2
        KC1
        KC2
        MCO2
        mdotT
        MO2
        pnG
        R
        TnG
        VnM
        xCAIR
        xOAIR
        deltaCpO
        kI21
        kI31
        kI32
        kIPO
        kO
        kS1
        kS2
        kS3
        MP
        my1opt
        my2opt
        my3opt
        qOpXm
        qS1pXm
        qS2pXm
        qS3pXm
        yAcpXgr
        yAlpXgr
        yCpO
        yPpS1
        yPpS2
        yPpS3
        yXpO
        yXpOgr
        yXpS
        yXpS1gr
        yXpS2gr
        yXpS3gr
        thetaDJ_WP
        thetaLmaxgr
        thetaLmingr
        thetaLoptgr
        AAFtast
        KAF
        KFpX
        qhpX
        tauF0
        Ttast
        VAF
        pHLmaxgr
        pHLmingr
        pHLoptgr
        kLamax
        kLamin
        VLmax
        VLmin
        FFmax
        FHmax
        FnAIRmax
        FnCO2max
        FnGmax
        FnN2max
        FnO2max
        FT1max
        FT2max
        NStmax
        TMpH
        TMpO2
        TMxCO2
        TMxO2
        ACT
        ADL
        ADU
        AHTh
        alphaC
        alphaD
        alphaH
        alphaL
        alphaT
        alphaTh
        alphaU
        ALU
        cW
        deltaCT
        deltaDL
        deltaDU
        deltaHTh
        deltaLU
        KHM
        KHSt
        lambdaCT
        lambdaDL
        lambdaDU
        lambdaHTh
        lambdaLU
        mC
        mD
        mdotCmax
        mdotHmax
        mTc
        mWD
        mWL
        PHmax
        rhoH
        rhoH2O
        rhoL
        thetaCin
        thetaHin
        thetaU
        VH
        deltatsec
        f_Inoc
        speedfactor
        tmax


        %% Variables
        cXL
        cS1L
        cS2L
        cS3L
        qXpX
        CPLtot
        pO2
        NSt
        FnG
        FnAIR
        FnO2
        FnN2
        FnCO2
        pHL
        thetaL
        pG
        xOGin
        xOG
        xCG
        xOL
        cOL
        RQ
        QO2max
        QCO2max
        OTR
        CTR
        OUR
        kLa
        OURm
        FR
        FH
        CAlLtot
        Vacid
        Vbase
        AAF
        Vfeed
        VL
        FT1
        FT2
        CAcLtot
        pHLm
        thetaLm
        pO2m
        hF
        CCLtot
        CB1Ltot
        CB2Ltot
        pH
        thetaD
        xCGin
        FR1
        FR2
        FR3
        FR1j
        FR2j
        FR3j
        VR1
        VR2
        VR3
        t1j
        t2j
        t3j
        cXL1j
        cXL2j
        cXL3j
        

        %% Additional properties needed for calculation
        cCLmax0 % Maximum concentration of CO2 in the liquid phase at time t = 0 h [g/l]
        xO2 % Molar concentration of oxygen in off gas at time t [-]
        xCO2 % Molar concentration of carbondioxide in off gas at time t [-]

        % Quasistationary balances of the temperature system
        thetaC % Temperature of cooling system at time t [°C]
        thetaTh % Temperature of heating system at time t [°C]
        thetaTc % Temperature of cooling down system at time t [°C]
        thetaDJ = 32.0; % Temperature of double jacket [°C]

        phiTcC
        mHmax

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Parameters calculated based on set constants
        % Growth parameters
        mySm % Substrate maintenance growth rate [1/h]
        myOm % Oxygen maintenance growth rate [1/h]

        qS1pXmax % Maximum glucose uptake rate of cells at time t [1/h]
        qS2pXmax % Maximum glycerine uptake rate of cells at time t [1/h]
        qS3pXmax % Maximum acetate uptake rate of cells at time t [1/h]

        qOpXmax % Maximum oxygen uptake rate of cells at time t [1/h]
        cOL100
        cOLmax

        % Temperature system parameters
        kCT % Coefficient of heat transmission cooling system - exchange system [W/(m2K)]
        tauCTc % Time constant cooling system

        RT % Heating resistance [K/W]
        Qny % Maximum amount of evaporation heat [kJ]

        DTc % Dilution rate of temperature flux [1/h]
        tauTcC % Time constant cooling system [h]
        CHmax % Maximum volumetric heat capacity of heating system [Ws/K]
        kHTh % Coefficient of heat transmission [kJ/m^2]

        DD % Diution rate of temperature flux [1/h]
        CD % Volumetric heat capacity of double jacket content and share of wall [Wh/K]
        kDL % Heat transmission coefficient double  jacket - liquid phase [W/(m^2*K)]
        kDU % Heat transmission coefficient double jacket - environment [W/(m^2*K)]
        kLU % Heat transmission coefficient liquid phase - environment [W/m^2*K)]
        tauDU % Time constant double jacket - environment [h]
        tauDL % Time constant double jacket - liquid phase [h]
        tauD % Time constant of double jacket system [h]

        % pH system parameters: dimensionless dissociation constants
        ApH
        BpH
        CpH
        DpH
        EpH
        FpH
        GpH
        HpH
        IpH

        deltapG % Pressure difference to normal pressure [N*m/kg]

        OTRmax % Theoretical maximum oxygen transfer rate at time t [g/(l*h)]
        OURmax % Theoretical maximum oxygen uptake rate at time t [g/(l*h)]

        cE      = 0; % Difference between temperature setpoint and measured temperature [°C]
        ce      = 0; % Normalized difference cE [%]

        cEpH    = 0; % Difference between pH setpoint and measured pH filtered through a time delay of first order

        cP_Part      % P part of temperature master controller
        cI_Part = 0; % I part of temperature master controller

        cE_agi = 0; % Difference between pO2 setpoint and measured pO2 [%]
        ce_agi = 0; % Normalized difference cE_agi [-]
        cP_agi      % P part of pO2-agitation master controller [-]
        cI_agi = 0; % I part of pO2-agitation master controller [-]
        cD_agi      % D part of pO2-agitation master controller [-]

        cE_feed = 0; % Difference between feed rate setpoint and current feed rate [l/h]
        ce_feed = 0; % Normalized difference cE_feed [-]
        cP_feed      % P part of feed rate controller [-]
        cI_feed = 0; % I part of feed rate controller [-]
        cD_feed      % D part of feed rate controller [-]
        yfeed   = 0; % Output of feed controller

        cE_aeration = 0; % Difference between pO2 setpoint and measured pO2 [%]
        ce_aeration = 0; % Normalized difference cE_aeration [-]
        cP_aeration      % P part of aeration controller [-]
        cI_aeration = 0; % I part of aeration controller [-]
        cD_aeration      % D part of aeration controller [-]

        cE_gasmix = 0; % Difference between pO2 setpoint and measured pO2 [%]
        ce_gasmix = 0; % Normalized difference cE_gasmix [-]
        cP_gasmix      % P part of pO2-gasmix controller [-]
        cI_gasmix = 0; % I part of pO2-gasmix controller [-]
        cD_gasmix      % D part of pO2-gasmix controller [-]

        cE_LW = 0; % Difference between liquid weight setpoint and actual liquid weight [kg]
        ce_LW = 0; % Normalized difference cE_LW [-]
        cI_LW = 0; % I part of liquid weight controller [-]

        pGw % Pressure Set-point [N*m/kg]

        VT1 % Volume of acid reservoir at time point [l]
        VT2 % Volume of base reservoir at time point [l]
        VR % Volume in Feed reservoir [l]
        t % Time Stamp for Calculation and Plot functions [h]
        ToI        = 0; % Time of inoculation [h]
        VLflag     = 0; % Flag that activates once VLmax was reached once
        VolumeFlag = 0; % Returns 1 if VLmax is reached


        % DEBUG
        HO2
        HCO2
        cCL
        CTRmax
        cCLmax
        StC
        xCL
        CHL
        xpHkm1
        QuotpH
        mdotC
        mdotH % steam heating flux [kg/h] Check if this parameter can be function internal and change location of use

        Timer

        % SQL related properties
        dbpath = 'SimulationAppDB.db'

        % List of variables and separated into when to upload
        all_variables string
        cyclic_variables string
        end_variables string
        trend_variables string

        projectName
        projectID
        projectdescription
        author
        createdon
        recentuse
        organismname
        bioreactorname
        inoc_occ = 0; % Flag that activates once inoculation has happened
        deltat % delta t in h
        calculation_time = 0
        %variabledata

        func_file % .m file with organism specific calculation functions
        calculation_func % function of the func_file

        PhaseTable % table containing process information
        % Databank tables for process automation
        process_status
        process_type
        process_variable
        process_operator
        process_conditiontype

        % Phase switches
        switch_pulse = false
        switch_exp = false

        currentPhase
        currentReservoir
    end

    methods (Access = private)

        function loadProject(app)
            sqlquery = sprintf(['SELECT p.projectID, p.description, p.author, p.created_on, p.recent_use, o.name, b.name, o.function_file ' ...
                'FROM projectTab p ' ...
                'JOIN organismTab o ON o.organismID = p.organismID ' ...
                'JOIN bioreactorTab b ON b.bioreactorID = p.bioreactorID ' ...
                'WHERE p.name = ''%s'''], app.projectName);
            projectinfo             = fetchpy(app.dbpath, sqlquery);
            app.projectID           = table2array(projectinfo(1,1));
            app.projectdescription  = table2array(projectinfo(1,2));
            app.author              = table2array(projectinfo(1,3));
            app.createdon           = table2array(projectinfo(1,4));
            app.recentuse           = table2array(projectinfo(1,5));
            app.organismname        = table2array(projectinfo(1,6));
            app.bioreactorname      = table2array(projectinfo(1,7));
            app.func_file           = table2array(projectinfo(1,8));

            %% Assign variables
            sqlquery = sprintf(['SELECT v.variableID, v.name, vh.initial_assignment ' ...
                'FROM variableTab v '...
                'JOIN variable_handlingTab vh ON vh.variableID = v.variableID '...
                ], app.projectID);
            app.all_variables = table2cell(fetchpy(app.dbpath, sqlquery));

            % Variables that are uploaded every cycle
            sqlquery = sprintf(['SELECT v.variableID, v.name ' ...
                'FROM variableTab v ' ...
                'JOIN variable_handlingTab vh ON v.variableID = vh.variableID ' ...
                'JOIN projectTab p ON p.organismID = vh.organismID ' ...
                'WHERE vh.upload_rate = ''cyclic'' AND p.projectID = %g'], app.projectID);
            app.cyclic_variables = table2cell(fetchpy(app.dbpath, sqlquery));

            % Variables that at only uploaded in the end of the process
            sqlquery = sprintf(['SELECT v.variableID, v.name ' ...
                'FROM variableTab v ' ...
                'JOIN variable_handlingTab vh ON v.variableID = vh.variableID ' ...
                'JOIN projectTab p ON p.organismID = vh.organismID ' ...
                'WHERE vh.upload_rate = ''end'' AND p.projectID = %g'], app.projectID);
            app.end_variables = table2cell(fetchpy(app.dbpath, sqlquery));

            %% Determine the current time value
            sqlquery = sprintf(['SELECT process_time ' ...
                'FROM timeTab ' ...
                'WHERE projectID = %g'], app.projectID);
            table = fetchpy(app.dbpath, sqlquery);

            %% Refer to .m calculation function
            app.calculation_func = str2func(app.func_file);

            %% Load all parameter values
            readInitalParameters(app)

            %% Determine if the process starts at zero or is loaded
            if isempty(table)
                app.t = 0; % t = 0 if there was no time entry for this project yet
            else
                app.t = double(table2array(table)); % Load time entries into array
                app.ProcesstimehEditField.Value = app.t(end);
                %% Load cyclic variables
                selectClause = 'SELECT t.process_time as time, ';
                ID = app.cyclic_variables(:,1);
                variables = app.cyclic_variables(:,2);
                for i = 1:length(ID)
                    selectClause = append(selectClause, ...
                        'MAX(CASE WHEN d.variableID = ', ID(i), ' THEN d.value END) AS "', variables(i),'"');
                    if i ~= length(variables)
                        selectClause = append(selectClause, ', '); % add comma
                    end
                end
                fromClause = sprintf([' FROM dataTab d ' ...
                    'JOIN timeTab t ON d.timeID = t.timeID ' ...
                    'JOIN variableTab v ON v.variableID = d.variableID ' ...
                    'WHERE t.projectID = %g '], app.projectID);
                groupByClause = 'GROUP BY t.process_time ORDER BY t.process_time;';
                query = append(selectClause, fromClause, groupByClause);
                T = fetchpy(app.dbpath, query);
               
                % Load variable data into arrays
                for i = 2:size(T,2)
                    if ~isempty(table2array(T(:,i)))
                        app.(variables(i-1)) = table2array(T(:,i));
                    end
                end
                %% Load end variables
                sqlquery = sprintf(['SELECT v.name, d.value ' ...
                    'FROM dataTab d ' ...
                    'JOIN variableTab v ON v.variableID = d.variableID ' ...
                    'JOIN variable_handlingTab vh ON vh.variableID = d.variableID ' ...
                    'WHERE d.projectID = %g AND vh.upload_rate = "end"'], app.projectID);
                T = fetchpy(app.dbpath, sqlquery);
                for i = 1:size(T,1)
                    variable = table2array(T(i,1));
                    value = table2array(T(i,2));
                    app.(variable) = value;
                end
                logMessage(app.CallingApp, app.projectID, "Project loaded")
            end
        end

        function readInitalParameters(app)
            % Read parameter values
            sqlquery = sprintf(['SELECT prop.name, ppara.value, ppara.description ' ...
                'FROM project_parameterTab ppara ' ...
                'JOIN projectTab proj ON proj.projectID = ppara.projectID ' ...
                'JOIN propertyTab prop ON ppara.propertyID = prop.propertyID ' ...
                'JOIN categoryTab c ON prop.categoryID = c.categoryID ' ...
                'WHERE proj.name = ''%s'''], app.projectName);
            T = fetchpy(app.dbpath, sqlquery);
            properties = table2array(T(:,1));
            values = double(table2array(T(:,2)));
            for i = 1:length(properties)
                % app.(properties(i)) = add_uncertainty(app, values(i), 1); % Add random uncertainty of 1%
                app.(properties(i)) = values(i);
            end
        end

        function readCyclicParameters(app)
            %% Read cyclic parameters
            sqlquery = sprintf(['SELECT prop.name, ppara.value, ppara.description ' ...
                'FROM project_parameterTab ppara ' ...
                'JOIN projectTab proj ON proj.projectID = ppara.projectID ' ...
                'JOIN propertyTab prop ON ppara.propertyID = prop.propertyID ' ...
                'JOIN categoryTab c ON prop.categoryID = c.categoryID ' ...
                'WHERE proj.name = ''%s'' AND (c.reading_rate = ''%s'' OR c.reading_rate = ''%s'')'], app.projectName, "cyclic", "invisible");
            table = fetchpy(app.dbpath, sqlquery);
            
            properties = table2array(table(:,1));
            values = double(table2array(table(:,2)));
            for i = 1:length(properties)
                app.(properties(i)) = values(i);
            end
            %% Read only changed parameters (not 100% reliable)
            % sqlquery = sprintf(['SELECT p.name, l.new_value ' ...
            %     'FROM logTab l ' ...
            %     'JOIN propertyTab p ON p.propertyID = l.propertyID ' ...
            %     'JOIN categoryTab c ON p.categoryID = c.categoryID ' ...
            %     'WHERE l.projectID = %g ' ...
            %     'AND (c.reading_rate = ''cyclic'' OR c.reading_rate = ''invisible'') ' ...
            %     'AND l.datetime > ''%s'''], app.projectID, app.last_cycle_time);
            % T = fetchpy(app.dbpath, sqlquery);
            % app.last_cycle_time = datetime("now", "Format","uuuu-MM-dd HH:mm:ss.SSS");
            % 
            % properties = table2array(T(:,1));
            % values = double(table2array(T(:,2)));
            % for i = 1:length(properties)
            %     app.(properties(i)) = values(i);
            % end
        end


        function TimerFcn(app,varargin)
            % Read parameters
            readCyclicParameters(app)

            % Cyclically call calculation and plot function via timer
            calculationFcn(app)
        end

        function InitialisationFcn(app)
            % Create timer object
            app.Timer = timer(...
                'ExecutionMode', 'fixedRate', ...            % Run timer repeatedly
                'Period', app.deltatsec, ...                 % Period is dependent on delta t
                'BusyMode', 'queue',...                      % Queue timer callbacks when busy
                'TimerFcn', @app.TimerFcn);                  % Specify callback function
            % Conversion of deltat in sec to deltat in h for calculations
            app.deltat = app.deltatsec/3600;

            % Calculate growth rates
            app.mySm = app.yXpS1gr*app.qS1pXm; % Specific substrate-maintenance-growth rate [1/h]
            app.myOm = app.yXpOgr*app.qOpXm+app.mySm; % Specific oxygen-maintenance-growth rate [1/h]

            app.qS1pXmax = (app.my1opt+app.mySm)/app.yXpS1gr; % Maximum specific glucose uptake rate [1/h]
            app.qS2pXmax = (app.my2opt+app.mySm)/app.yXpS2gr; % Maximum specific glycerol uptake rate [1/h]
            app.qS3pXmax = (app.my3opt+app.mySm)/app.yXpS3gr; % Maximum specific acetate uptake rate [1/h]

            app.qOpXmax  = (app.my1opt+app.mySm)/app.yXpOgr+app.qOpXm; % Maximum specific oxygen uptake rate [1/h] INFO: INSTEAD OF MY1MAX WHICH IS NOT DEFINED YET I USED MY1OPT (change later maybe)

            % Calculate parameters of temperature system
            app.kCT      = 1/(1/app.alphaC+app.deltaCT/app.lambdaCT+1/app.alphaT); % Heat passing coefficient cooling system - exchange system [W/(m^2*K)]
            app.tauCTc   = app.mC*app.cH2O/(app.kCT*app.ACT); % Time constant cooling system - cooling down system [h]
            app.RT       = 1/(app.mdotT*app.cH2O); % Heat resistance [K/W]
            app.DTc      = app.mdotT/app.mTc; % Flow through rate of temperature stream in cooling down system [1/h]
            app.tauTcC   = app.mTc*app.cH2O/(app.kCT*app.ACT); % Time constant cooling system [h]
            app.phiTcC   = app.DTc*app.tauTcC; % Time constant of cooling down system
            app.DD       = app.mdotT/app.mD; % Flow through rate of temperature stream in double jacket system [1/h]
            app.CD       = app.mD*app.cH2O+app.mWD*app.cW; % Volumetric heat capacity double jacket [Wh/K]
            app.kDU      = 1/(1/app.alphaD+app.deltaDU/app.lambdaDU+1/app.alphaU); % Heat transmission coefficient double jacket - environment [W/(m^2*K)]
            app.tauDU    = app.CD/(app.kDU*app.ADU); % Time constant double jacket - environment [h]
            app.kDL      = 1/(1/app.alphaD+app.deltaDL/app.lambdaDL+1/app.alphaL); % Heat transmissio  coefficient double jacket - liquid phase [W/(m^2*K)]
            app.tauDL    = app.CD/(app.kDL*app.ADL); % Time constant double jacket - liquid phase [h]
            app.tauD     = 1/(app.DD+1/app.tauDU+1/app.tauDL); % Time constant of double jacket system [h]
            app.kLU      = 1/(1/app.alphaL+app.deltaLU/app.lambdaLU+1/app.alphaU); % Heat transmission coefficient liquid - environment [W/(m^2*K)]
            app.mHmax    = app.VH*app.rhoH2O; % Maximum possible mass in heating system H [kg]
            app.CHmax    = app.mHmax*app.cH2O; % Maximum volumetricheat capacity of the heating system [Ws/K]
            app.kHTh     = 1/(1/app.alphaH+app.deltaHTh/app.lambdaHTh+1/app.alphaTh); % Coefficient of heat transmission [kJ/m^2]
            app.Qny      = app.deltahv*app.mHmax; % Maximum amount of heat of evaporation [kJ]

            % Absolute pressure set-point calculation [N/m^2]
            app.pGw = app.deltapGw*10^5+app.pnG;

            % Calculate parameters of pH system
            app.cCLmax0  = app.pGcal/app.HCO20;

            % Dimensionless dissociation constants
            app.ApH = app.KB1/app.CH0;
            app.BpH = app.ApH*app.KB2/app.CH0;
            app.CpH = app.BpH*app.KB3/app.CH0;
            app.DpH = app.KC1/app.CH0;
            app.EpH = app.DpH*app.KC2/app.CH0;
            app.FpH = app.KP/app.CH0;
            app.GpH = app.KAl/app.CH0;
            app.HpH = app.KAc1/app.CH0;
            app.IpH = app.HpH*app.KAc2/app.CH0;

            % Calculation of O2 Henry constant [Nm/kg]
            app.HO2 = app.HnO2/(1+app.K1HO2*app.thetaL0+app.K2HO2*app.thetaL0^2+app.K3HO2*app.thetaL0^3+app.K4HO2*app.thetaL0^4); %

            % O2-concentration in liquid phase at 100 % pO2-indication
            app.cOL100 = app.pGcal*app.xOGcal/app.HO2; %

            % Maximum potential O2 concentration in liquid phase [g/l]
            app.cOLmax = (app.pGcal+app.deltapGw*10^5)/app.HO2; %

            app.xO2(1)     = app.xOAIR*100; %
            app.xCO2(1)    = app.xCAIR*100; %?

            % Check if project was started for the first time
            if app.t == 0
            % Constructor
            if app.f_feed == 1
                app.FR(1)  = app.FRw;
            else
                app.FR(1)  = 0;
            end

            app.VL(1)      = app.VL0;
            %app.VR(1)      = app.VR0;
            app.VT1(1)     = app.VT10;
            app.VT2(1)     = app.VT20;
            app.Vfeed(1)   = 0;
            app.Vacid(1)   = 0;
            app.Vbase(1)   = 0;

            if app.f_InocStart == 0
                app.cXL(1) = 0;
            else
                app.cXL(1) = app.cXL0;
            end
            app.cS1L(1)    = app.cS1L0;
            app.cS2L(1)    = app.cS2L0;
            app.cS3L(1)    = app.cS3L0;

            app.thetaL(1)  = app.thetaL0;
            app.thetaD(1)  = app.thetaD0;
            app.pG(1)      = app.pGcal+app.deltapGw*10^5;

            app.pO2(1)     = app.pO20;
            app.cOL(1)     = (app.pO20/100)*app.cOL100;
            app.pO2w(1)    = app.pO2w;
            app.xOL(1)     = app.cOL100/app.cOLmax;

            app.xOG(1)     = app.xOGcal;
            app.xOGin(1)   = app.xOGcal;
            app.xCGin(1)   = app.xCGcal; 
            app.xCG(1)     = app.xCGcal;

            app.CPLtot(1)  = 0; % Here maybe introduce app.CPLtot0?

            app.CB1Ltot(1) = app.CB1Ltot0;
            app.CB2Ltot(1) = app.CB2Ltot0;
            app.CAlLtot(1) = app.CAlLtot0;
            app.CAcLtot(1) = app.CAcLtot0;

            app.hF(1)      = 0;
            app.AAF(1)     = 0;

            app.pHL(1)     = app.pH0;
            app.pH(1)      = app.pH0;

            app.RQ(1)      = 0.1;

            app.CCLtot(1)  = (1+app.KC1*10^app.pH0+app.KC1*app.KC2*10^(2*app.pH0))*app.xCAIR*app.cCLmax0/app.MCO2;

            if app.f_motor == 1
                app.NSt(1) = app.NStw;
            else
                app.NSt(1) = 0;
            end
            if app.f_aeration == 1
                if app.f_air == 1
                    app.FnAIR(1) = app.FnAIRw;
                else
                    app.FnAIR(1) = 0;
                end

                if app.f_O2 == 1
                    app.FnO2(1) = app.FnO2w;
                else
                    app.FnO2(1) = 0;
                end

                if app.f_N2 == 1
                    app.FnN2(1) = app.FnN2w;
                else
                    app.FnN2(1) = 0;
                end

                if app.f_CO2 == 1
                    app.FnCO2(1) = app.FnCO2w;
                else
                    app.FnCO2(1) = 0;
                end
                app.FnG(1) = app.FnAIR(1)+app.FnO2(1)+app.FnN2(1)+app.FnCO2(1);
            else
                app.FnG(1)  = 0;
            end
            if app.f_harvest == 1
                app.FH(1)  = app.FHrelw/100*app.FHmax;
            else
                app.FH(1)  = 0;
            end
            app.OURm(1)    = 0;
            app.OURmax(1)  = 0;
            app.QO2max(1)  = app.FnG(1)*60*app.MO2/(app.VL(1)*app.VnM);
            app.QCO2max(1) = app.QO2max(1)*app.MCO2/app.MO2;
            app.kLa(1)     = app.kLamin+app.kLamax*((app.FnGw/app.FnGmax)^app.beta)*((app.NStw/app.NStmax)^(3*app.alpha))/((app.VL0/app.VLmin)^app.alpha);
            app.OTRmax(1)  = (app.kLamin+app.kLamax*((app.FnGw/app.FnGmax)^app.beta)*((app.NStw/app.NStmax)^(3*app.alpha))/((app.VL0/app.VLmin)^app.alpha))*app.cOLmax;
            app.OUR(1)     = 0;
            app.OTR(1)     = app.OTRmax(end)*(app.xOAIR-app.cOL(end)/app.cOLmax);
            app.CTR(1)     = 0;
            app.qXpX(1)    = 0;

            app.pHLm = app.pH0;
            app.thetaLm = app.thetaL0;
            app.pO2m = app.pO20;
            app.FT1 = 0;
            app.FT2 = 0;

            % Reservoir feed
            app.FR1 = 0;
            app.FR2 = 0;
            app.FR3 = 0;
            app.VR1 = app.VR10;
            app.VR2 = app.VR20;
            app.VR3 = app.VR30;
            end

            % Inoculation at start
            if app.f_InocStart == 1
                app.InoculationCheckBox.Value = true;
            else
                app.InoculationCheckBox.Value = false;
            end

        end


        % Function can be used for single uploads. Upload in the
        % calculation function is optimised to lower database transactions
        function uploadLatestVariables(app, variables)
            num_variables = size(variables, 1);
            data_table = table('Size', [num_variables 4], ...
                'VariableTypes', {'double', 'string', 'double', 'double'}, ...
                'VariableNames', {'projectID', 'variableID', 'timeID', 'value'});
            row_index = 1;
            % Read timeID of the latest timeTab entry
            sqlquery = sprintf(['SELECT timeID ' ...
                'FROM timeTab ' ...
                'WHERE projectID = %g AND process_time = %1.4f LIMIT 1'],app.projectID, app.t(end));
            timeID = double(table2array(fetchpy(app.dbpath, sqlquery)));
            % Create variable table in loop
            for j = 1:num_variables
                variableID = variables(j, 1);
                variable = variables(j, 2);
                if any(~ismissing(app.(variable))) && any(~isempty(app.(variable)))
                    value = app.(variable)(end);
                    % Fill data_table
                    data_table.projectID(row_index) = app.projectID;
                    data_table.variableID(row_index) = variableID;
                    data_table.timeID(row_index) = timeID;
                    data_table.value(row_index) = str2double(num2str(value, 6));
                    row_index = row_index + 1;
                else
                    fprintf("%s is empty or doesn´t exist.\n", variable(j));
                end
            end
            sqlwritepy(app.dbpath,'dataTab',data_table);
        end

        function calculationFcn(app)
            operationT = tic;
            if app.t == 0
                % Upload data for t = 0
                readInitalParameters(app) % Read parameters again, in case something was adjusted
                % Insert first timeTab entry
                currentTimestamp = datetime("now", "Format","uuuu-MM-dd HH:mm:ss.SSS");
                time_query= sprintf(['INSERT INTO timeTab (projectID, datetime, process_time, calculation_time) ' ...
                    'VALUES (%g, ''%s'', %1.4f, %1.4f)'], app.projectID, currentTimestamp, app.t(end), 0);
                execpy(app.dbpath, time_query); % Execute array of queries
                uploadLatestVariables(app, app.cyclic_variables)
                logMessage(app.CallingApp, app.projectID, "Process started")
            end

            % Check process phases
            checkStartCondition(app)

            if app.f_Inoc == 0
                % % sqlquery = sprintf('SELECT inoculated FROM projectTab WHERE projectID = %g',app.projectID);
                % % app.inoculum = double(table2array(fetchpy(app.dbpath,sqlquery)));
                % % 
                if app.f_Inoc == 1
                    % Disable inoculation checkbox
                    app.InoculationCheckBox.Value = 1;
                    app.InoculationCheckBox.Enable = "off";
                end
            end
            
            data_table = table('Size', [app.speedfactor * size(app.cyclic_variables, 1), 4], ...
                'VariableTypes', {'double', 'string', 'double', 'double'}, ...
                'VariableNames', {'projectID', 'variableID', 'timeID', 'value'});
            app.calculation_time = zeros(app.speedfactor,1); % Calc. time initialization
            row_index = 1;

            % Run calculation x times
            for i = 1:app.speedfactor
                calculationT = tic; % Start measuring calculation time
                app = app.calculation_func(app); % Execute calculations
                app.calculation_time(i) = toc(calculationT); % Measure over

                % Prepare timeTab query
                currentTimestamp = datetime("now", "Format","uuuu-MM-dd HH:mm:ss.SSS");
                time_query= sprintf(['INSERT INTO timeTab (projectID, datetime, process_time, calculation_time) ' ...
                    'VALUES (%g, ''%s'', %1.4f, %1.4f)'], app.projectID, currentTimestamp, app.t(end), app.calculation_time(i));
                execpy(app.dbpath, time_query); % Execute array of queries

                % Read latest timeID
                sqlquery = sprintf(['SELECT timeID ' ...
                    'FROM timeTab ' ...
                    'WHERE projectID = %g AND process_time = %1.4f ' ...
                    'LIMIT 1'],app.projectID, app.t(end));
                timeID = table2array(fetchpy(app.dbpath,sqlquery));
                % Prepare dataTab temporary table
                % Collect latest variable data
                num_variables = size(app.cyclic_variables, 1);
                for j = 1:num_variables
                variableID = app.cyclic_variables(j, 1);
                variable = app.cyclic_variables(j, 2);
                    if any(~ismissing(app.(variable))) &&  any(~isempty(app.(variable)))
                        value = app.(variable)(end);
                        % Fill data_table
                        data_table.projectID(row_index) = app.projectID;
                        data_table.variableID(row_index) = variableID;
                        data_table.timeID(row_index) = timeID;
                        data_table.value(row_index) = str2double(num2str(value, 6));

                        row_index = row_index + 1;
                    else
                        fprintf("%s is empty or doesn't exist.\n", variable);
                    end
                end
                
            end
            %data_table = data_table(1:row_index-1, :);
            sqlwritepy(app.dbpath,'dataTab',data_table); % Append dataTab with generated values

            % App related
            app.ProcesstimehEditField.Value = app.t(end);
            app.DeltatEditField.Value = app.deltatsec;
            app.SpeedfactorxEditField.Value = app.speedfactor;
            app.calctimesEditField.Value = mean(app.calculation_time);
            operation_time = toc(operationT);
            fprintf('Mean calc. t[sec]: %1.4f. Total t[sec]: %2.4f.\n', mean(app.calculation_time), operation_time);

            % Check process phase ending
            checkEndCondition(app)
        end

        function result = add_uncertainty(~, value, percentage)
            uncertainty = value * (percentage / 100.0);
            random_value = value + (2 * uncertainty * rand() - uncertainty);
            % Determine number of decimal places
            extra_places = 3;
            value_str = num2str(value, '%.15g');  % Conversion to string
            decimal_pos = find(value_str == '.', 1); % Find position of decimal
            if isempty(decimal_pos)
                current_decimal_places = 0;
            else
                current_decimal_places = length(value_str) - decimal_pos;
            end
            % Calculate
            new_decimal_places = current_decimal_places + extra_places;
            format = ['%.' num2str(new_decimal_places) 'f'];
            result = double(sprintf(format, random_value));
        end

        function deleteFcn(app)
            try
            % This function uploads missing variable values at the end of a process to allow reloading
            if app.t(end) > 0
                % Delete previous end variables
                sqlquery = sprintf(['DELETE FROM dataTab ' ...
                 'WHERE projectID = %d ' ...
                 'AND variableID IN (' ...
                 '  SELECT d.variableID ' ...
                 '  FROM dataTab d ' ...
                 '  JOIN variable_handlingTab vh ON vh.variableID = d.variableID ' ...
                 '  WHERE d.projectID = %d ' ...
                 '  AND vh.upload_rate = ''end''' ...
                 ')'], app.projectID, app.projectID);
                execpy(app.dbpath, sqlquery);
                % Upload new end variables
                uploadLatestVariables(app, app.end_variables)
            end
            % Update recent use in profile
            timestamp = datetime("now","Format","uuuu-MM-dd HH:mm:ss.SSS");
            sqlquery = sprintf('UPDATE projectTab SET recent_use = ''%s'' WHERE projectID = %g', timestamp, app.projectID);
            execpy(app.dbpath, sqlquery);
            % Change name, author und description if this was a quick start
            % project
            if any(contains(app.projectdescription, 'quick start'))
                quickstart = questdlg("This was a quick start process. Do you want to specify process information?", "Quick Start Project", "Close", "Change Info", "Close");
                switch quickstart
                    case "Change Info"
                        info = ProjectInformation(app, app.projectID);
                        waitfor(info);
                    case "Cancel"
                        return;
                end
            end
            disp("Project saved.")
            app.CallingApp.UIFigure.Visible = "on";
            delete(app)
            catch ME
                disp("Upload incomplete.");
                delete(app)
            end
        end

        function check = conditionTypeCheck(app, time, phaseNum)
            arguments
                app
                time (1,:) char {mustBeMember(time, {'start', 'end'})}
                phaseNum 
            end

            table = app.PhaseTable;
            flag = false;
            type = table.([time '_typeID'])(phaseNum);
            if time == "start"
            switch type % Start condition types
                case 1 % Variable condition
                    % Create a string containing variable condition
                    v_idx = find(table.start_variableID(phaseNum) == app.process_variable.variableID);
                    variable = app.process_variable.name(v_idx);
                    o_idx = find(table.start_operatorID(phaseNum) == app.process_operator.process_operatorID);
                    operator = app.process_operator.operator(o_idx);
                    value = table.start_value(phaseNum);
                    start_condition_text = sprintf('app.%s(end) %s %d', variable, operator, value);
                    % Check if condition is true
                    if eval(string(start_condition_text))
                        flag = true; % set flag to true
                    end
                case 2 % End of previous phase
                    flag = true; % set flag to true
                case 3 % Batch end: pO2 Slope
                    flag = true; % set flag to true
                case 4 % Batch end: Off Gas
                    flag = true; % set flag to true
                otherwise
                    disp("Start phase contains an end condition type.")
                    return
            end
                elseif time == "end"
            switch type % End condition types
                case 5 % Next phase condition
                    if size(table,1) < phaseNum
                        nextPhase = phaseNum + 1;
                        % Create a string containing variable condition
                        v_idx = find(table.start_variableID(nextPhase) == app.process_variable.variableID);
                        variable = app.process_variable.name(v_idx);
                        o_idx = find(table.start_operatorID(nextPhase) == app.process_operator.process_operatorID);
                        operator = app.process_operator.operator(o_idx);
                        value = table.start_value(nextPhase);
                        start_condition_text = sprintf('app.%s(end) %s %d', variable, operator, value);
                        % Check if condition is true
                        if eval(start_condition_text)
                            flag = true; % set flag to true
                        end
                    end
                case 6 % Variable condition
                    % Create a string containing variable condition
                    v_idx = find(table.end_variableID(phaseNum) == app.process_variable.variableID);
                    variable = app.process_variable.name(v_idx);
                    o_idx = find(table.end_operatorID(phaseNum) == app.process_operator.process_operatorID);
                    operator = app.process_operator.operator(o_idx);
                    value = table.end_value(phaseNum);
                    end_condition_text = sprintf('app.%s(end) %s %d', variable, operator, value);
                    % Check if condition is true
                    if eval(end_condition_text)
                        flag = true; % set flag to true
                    end
                case 7 % Timer
                    end_time = table.end_time(phaseNum);
                    if app.t(end) >= end_time
                        flag = true; % set flag to true
                    end
                otherwise
                    disp("No start type was selected for the first phase.")
                    return
            end
            end
            % Output value
            check = flag;
        end

        function checkStartCondition(app)
            % Checks if a phase is ongoing
            if isempty(app.currentPhase) % Check next phase condition if no phase is active
                table = app.PhaseTable;
                if ~isempty(table)
                    %% Check phase status in table
                    idx = find(table.process_statusID == 2); % Find pending phase
                    if isempty(idx) % Find alternative phase if no phase is marked as active
                        % Find latest phase that ended
                        latest_end = find(table.process_statusID == 4);
                        if latest_end == size(table,1)
                            return % End function if last phase has ended
                        end
                        if ~isempty(latest_end)
                            % Activate phase
                            idx = latest_end(end) + 1;
                        else
                            % If no phase was active before, check condition of first phase
                            idx = 1; % first phase number
                        end
                        % Set next phase as pending
                        table.process_statusID(idx) = 2;
                    end
                    start_flag = conditionTypeCheck(app, 'start', idx); % Check start condition of next potential phase

                    if start_flag % If condition is true
                        % Note start time
                        table.start_time(idx) = app.t(end);
                        % Check if timer is set as ending
                        if table.end_typeID(idx) == 7 % Timer
                            % Calculate end time
                            duration = table.end_value(idx);
                            table.end_time(idx) = table.start_time(idx) + duration;
                        end
                        % Set phase as active
                        table.process_statusID(idx) = 3;
                        % Make a phase start log entry
                        name = table.name(idx);
                        msg = sprintf("%s [Phase %i] just started at t = %1.3f h.", name, idx, app.t(end));
                        logMessage(app.CallingApp, app.projectID, msg)
                    else
                        % If condition was incorrect, end phase function
                        return
                    end

                    %% Once an active phase was found
                    %'phaseID', 'projectID', 'process_statusID', 'process_typeID', 'name', 'start_typeID', 'start_variableID', 'start_operatorID', 'start_value', 'end_typeID', 'end_variableID', 'end_operatorID', 'end_value', 'reservoirID'
                    process_typeID = table.process_typeID(idx);
                    switch process_typeID
                        case 1 % Blank phase
                            % Do nothing
                        case 2
                            % Stop process by pushing pause button
                            RunButtonPushed(app, 0)
                        case 3
                            % Update set of parameters
                            % follows
                        case 4
                            % Start pulse feed
                            app.switch_pulse = true;
                            app.currentReservoir = table.reservoirID(idx);
                            n = num2str(app.currentReservoir);
                            app.(['f_FR' n]) = 1;
                            % Upload changes
                            ParameterChangedFcn(app, (['f_FR' n]), 1)
                        case 5
                            % Start exponential feed
                            app.switch_exp = true;
                            app.currentReservoir = table.reservoirID(idx);
                            n = num2str(app.currentReservoir);
                            app.(['f_FR' n]) = 1;
                            app.(['t' n 'j'])  = app.t(end);
                            app.(['cXL' n 'j']) = app.cXL(end);

                            % Calculate initial feeding rate FRwj
                            qxpxw = app.(['qxpx' n 'w']);
                            qSpXm = app.(['qS' n 'pXm']);
                            yXpSgr = app.(['yXpS' n 'gr']);
                            CSR = app.(['cS' n 'R' n]);
                            app.(['FR' n 'j']) = ((qxpxw+qSpXm*yXpSgr)*app.VL(end)*app.cXL(end))/(yXpSgr*CSR);
                            % Upload changes
                            ParameterChangedFcn(app, (['f_FR' n]), 1)
                        otherwise
                            disp("Process_typeID error. ID not available!")
                    end
                    app.currentPhase = idx; % Log current phase in
                    app.PhaseTable = table;
                    UploadProcessInformation(app) % Upload process changes
                end
            end
        end

        function checkEndCondition(app)
            if ~isempty(app.currentPhase)
                end_flag = conditionTypeCheck(app, 'end', app.currentPhase);
                if end_flag
                    % Set current phase to finished
                    app.PhaseTable.process_statusID(app.currentPhase) = 4;
                    % Note ending time
                    app.PhaseTable.end_time(app.currentPhase) = app.t(end);
                    % Make a phase end log entry
                    name = app.PhaseTable.name(app.currentPhase);
                    msg = sprintf("%s [Phase %i] just ended at t = %1.3f h.", name, app.currentPhase, app.t(end));
                    logMessage(app.CallingApp, app.projectID, msg)
                    if size(app.PhaseTable,1) > app.currentPhase
                        % If final phase isnt reached yet, set following to pending
                        app.PhaseTable.process_statusID(app.currentPhase + 1) = 2;
                        % Log out past phase
                        app.currentPhase = []; % Delete active phase entry once recent phase ended
                        % Turn off all switches
                        app.switch_exp = false;
                        app.switch_pulse = false;
                        app.currentReservoir = [];
                        app.f_FR1 = 0;
                        app.f_FR2 = 0;
                        app.f_FR3 = 0;
                    else
                        disp("Last phase just ended.")
                    end
                    UploadProcessInformation(app) % Upload process changes
                end
            end
        end
end
    
methods (Access = private)
        function UploadProcessInformation(app)
            % This function uploads data, which is written in the process panels
            % Delete previous entry
            sqlquery = sprintf('DELETE FROM processTab WHERE projectID = %g', app.projectID);
            execpy(app.dbpath, sqlquery)

            % Upload new process table
            sqlwritepy(app.dbpath, "processTab", app.PhaseTable)
        end

        function DownloadProcessInformation(app)
            % This function downloads data, which is written in the process panels
            % Download process table
            sqlquery = sprintf('SELECT * FROM processTab WHERE projectID = %g', app.projectID);
            T = fetchpy(app.dbpath, sqlquery);
            % Convert all empty string "" entries to NaN
            imax = size(T,1);
            headers = T.Properties.VariableNames(6:end);
            for h = headers
                header = char(h);
                % Empty strings lead columns to turn to cell arrays
                if iscell(T.(header))
                    % Eliminate cell arrays and keep numeric entries
                    new_column = zeros(imax,1);
                    for i = 1:imax
                        value = T.(header){i};
                        if isnumeric(value)
                            new_column(i) = value;
                        else
                            new_column(i) = NaN;
                        end
                    end
                    T.(header) = new_column;
                elseif ~isnumeric(T.(header)) && any(T.(header) == "")
                    % Turn empty columns to NaN
                    new_column = nan(imax, 1);
                    T.(header) = new_column;
                end
            end
            app.PhaseTable = T;
        end

        function ParameterChangedFcn(app, parameter, value)
            % Change value in parameter table
            sqlquery = sprintf(['UPDATE project_parameterTab ' ...
                'SET value = %g ' ...
                'WHERE projectID = %g ' ...
                'AND propertyID IN ' ...
                '(SELECT propertyID ' ...
                'FROM propertyTab ' ...
                'WHERE name = ''%s'')'], value, app.projectID, parameter);
                execpy(app.dbpath, sqlquery);
        end
    end

    methods (Access = public)
        %% Functions to manipulate from outside (e.g. control app)
        function RunProcessFcn(app)
            RunButtonPushed(app)
        end

        function ChangeTimeIntervalFcn(app, timeinsec)
            app.deltatsec = timeinsec;
            app.deltat = app.deltatsec / 3600;
        end

        function ChangeSpeedFactorFcn(app, factor)
            app.speedfactor = factor;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, CallingApp, projectName)
            app.UIFigure.Name = "Simulator";
            centerWindow(app.UIFigure)
            app.CallingApp = CallingApp;
            app.projectName = projectName;
            app.dbpath = app.CallingApp.dbpath;
            app.ProjectnameEditField.Value = app.projectName;
            loadProject(app)
            
            InitialisationFcn(app)

            % Download process automation data
            sqlquery = "SELECT * FROM process_statusTab";
            app.process_status = fetchpy(app.dbpath, sqlquery);

            sqlquery = "SELECT * FROM process_typeTab";
            app.process_type = fetchpy(app.dbpath, sqlquery);

            sqlquery = "SELECT pv.process_variableID, pv.variableID, v.name, v.shorttex, v.tex_unit FROM process_variableTab pv JOIN variableTab v ON v.variableID = pv.variableID";
            app.process_variable = fetchpy(app.dbpath, sqlquery);            

            sqlquery = "SELECT * FROM process_operatorTab";
            app.process_operator = fetchpy(app.dbpath, sqlquery);

            sqlquery = "SELECT * FROM process_conditiontypeTab";
            app.process_conditiontype = fetchpy(app.dbpath, sqlquery);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if app.RunButton.Text == "Run"
                DownloadProcessInformation(app)
                start(app.Timer);
                app.RunButton.Text = "Pause";
            else
                stop(app.Timer);
                app.RunButton.Text = "Run";
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            deleteFcn(app)
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            deleteFcn(app)
        end

        % Button pushed function: ChangeButton
        function ChangeButtonPushed(app, event)
            SelectProject(app);
        end

        % Value changed function: InoculationCheckBox
        function InoculationCheckBoxValueChanged(app, event)
            value = app.InoculationCheckBox.Value;
            if value == 1
                app.InoculationCheckBox.Enable = "off";
                app.f_Inoc = 1;
            end
        end

        % Value changed function: SpeedfactorxEditField
        function SpeedfactorxEditFieldValueChanged(app, event)
            value = app.SpeedfactorxEditField.Value;
            app.speedfactor = value;
        end

        % Value changed function: DeltatEditField
        function DeltatEditFieldValueChanged(app, event)
            value = app.DeltatEditField.Value;
            app.deltatsec = value;
            app.deltat = value / 3600;
        end

        % Value changed function: ProcesstimehEditField
        function ProcesstimehEditFieldValueChanged(app, event)
            value = app.ProcesstimehEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.ProjectnameEditFieldLabel = uilabel(app.UIFigure);
            app.ProjectnameEditFieldLabel.Text = 'Project name:';
            app.ProjectnameEditField = uieditfield(app.UIFigure, 'text');
            app.calctimesEditFieldLabel = uilabel(app.UIFigure);
            app.calctimesEditFieldLabel.Text = 'calc. time [s]:';
            app.calctimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.calctimesEditField.ValueDisplayFormat = '%11.3g';
            app.calctimesEditField.Editable = 'off';
            app.calctimesEditField.Tooltip = {'Time needed for the calculation of variable values in seconds.'};
            app.fontnameHelveticaDeltatsEditFieldLabel = uilabel(app.UIFigure);
            app.fontnameHelveticaDeltatsEditFieldLabel.Interpreter = 'tex';
            app.fontnameHelveticaDeltatsEditFieldLabel.Text = '\fontname{Helvetica}\Deltat [s]:';
            app.DeltatEditField = uieditfield(app.UIFigure, 'numeric');
            app.DeltatEditField.Limits = [0 3600];
            app.DeltatEditField.ValueDisplayFormat = '%4.3g';
            app.DeltatEditField.ValueChangedFcn = createCallbackFcn(app, @DeltatEditFieldValueChanged, true);
            app.ChangeButton = uibutton(app.UIFigure, 'push');
            app.ChangeButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeButtonPushed, true);
            app.ChangeButton.Text = 'Change';
            app.SpeedfactorxEditFieldLabel = uilabel(app.UIFigure);
            app.SpeedfactorxEditFieldLabel.Text = 'Speed factor [x]:';
            app.SpeedfactorxEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpeedfactorxEditField.Limits = [1 100];
            app.SpeedfactorxEditField.RoundFractionalValues = 'on';
            app.SpeedfactorxEditField.ValueDisplayFormat = '%11.2g';
            app.SpeedfactorxEditField.ValueChangedFcn = createCallbackFcn(app, @SpeedfactorxEditFieldValueChanged, true);
            app.SpeedfactorxEditField.Value = 1;
            app.InoculationCheckBox = uicheckbox(app.UIFigure);
            app.InoculationCheckBox.ValueChangedFcn = createCallbackFcn(app, @InoculationCheckBoxValueChanged, true);
            app.InoculationCheckBox.Text = 'Inoculation';
            app.ProcesstimehEditFieldLabel = uilabel(app.UIFigure);
            app.ProcesstimehEditFieldLabel.Text = 'Process time [h]:';
            app.ProcesstimehEditField = uieditfield(app.UIFigure, 'numeric');
            app.ProcesstimehEditField.Limits = [0 100000];
            app.ProcesstimehEditField.ValueDisplayFormat = '%11.3g';
            app.ProcesstimehEditField.ValueChangedFcn = createCallbackFcn(app, @ProcesstimehEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CalculationClass1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end