classdef CreateProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SelectaModelLabel           matlab.ui.control.Label
        CreateButton                matlab.ui.control.Button
        ReturnButton                matlab.ui.control.Button
        CreateaNewProjectLabel      matlab.ui.control.Label
        ProjectdescriptionoptionalTextArea  matlab.ui.control.TextArea
        ProjectdescriptionoptionalLabel  matlab.ui.control.Label
        AuthoroptionalEditField     matlab.ui.control.EditField
        AuthorLabel                 matlab.ui.control.Label
        ProjecttitleEditField       matlab.ui.control.EditField
        ProjecttitleEditFieldLabel  matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
    end

    
    properties (Access = public)
        CallingApp
        modelIdx
        modelID
        organismID
        bioreactorID
        nameList
        rank
        display_rank
    end
    
    properties (Access = public)
        projectname % Name of the just created project
    end
   
    
    methods (Access = private)

        function fillModel(app)
            app.UITable.ColumnName = {"Model name", "Organism", "Bioreactor","Description"};
            % Read information of available organism models in database
            sqlquery = sprintf(['SELECT m.modelID, m.name, o.name, b.name, m.description, m.organismID, m.bioreactorID, m.display_rank ' ...
                'FROM modelTab m ' ...
                'JOIN organismTab o ON m.organismID = o.organismID ' ...
                'JOIN bioreactorTab b ON m.bioreactorID = b.bioreactorID']);
            conn = sqlite(app.CallingApp.dbpath, "readonly");
            table = fetch(conn,sqlquery);
            close(conn)
            app.modelID = table.modelID;
            app.organismID = table.organismID;
            app.bioreactorID = table.bioreactorID;
            app.rank = table.display_rank;
            [~, app.display_rank] = sort(sort(app.rank, 'descend'), 'ascend');
            app.UITable.Data = table(app.display_rank,2:5);
        end

        function uniquename = uniqueProjectname(app, name)
            % Check if the name is already taken. If this is the case, add
            % an index behind the name.
            sqlquery = 'SELECT name FROM projectTab ';
            conn = sqlite(app.CallingApp.dbpath, "readonly");
            projectnames = table2array(fetch(conn,sqlquery));
            close(conn)
            if any(strcmp(projectnames, name))
                suffix = 1;
                uniquename = name;
                while any(strcmp(projectnames, uniquename))
                    uniquename = sprintf('%s_%d', name, suffix);
                    suffix = suffix + 1;
                    if suffix > 100
                        disp("Name is taken over 100 times.");
                        break;
                    end
                end
            else
                uniquename = name;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, CallingApp)
            app.UIFigure.Name = "Create Project";
            app.CallingApp = CallingApp;
            centerWindow(app.UIFigure)
            fillModel(app)
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            name = app.ProjecttitleEditField.Value;
            author = app.AuthoroptionalEditField.Value;
            if isempty(author)
                author = "";
            end
            description = cell2mat(app.ProjectdescriptionoptionalTextArea.Value);
            if isempty(description)
                description = "";
            end
            % Convert selection index back to modelTab order
            app.modelIdx = find(app.display_rank == app.modelIdx);
            organism = app.organismID(app.modelIdx);
            bioreactor = app.bioreactorID(app.modelIdx);
            model = app.modelID(app.modelIdx);
            date = datetime("now","Format","dd.MM.uuuu HH:mm:ss.SSS");
            name = uniqueProjectname(app, name);
            if ~isempty(name)
                % Insert new project table entry
                sqlquery = sprintf('INSERT INTO projectTab (name, description, author, created_on, recent_use, organismID, bioreactorID, modelID) VALUES (''%s'', ''%s'', ''%s'', ''%s'', ''%s'', %i, %i, %i)', name, description,author, date, date, organism, bioreactor, model);
                conn = sqlite(app.CallingApp.dbpath, "connect");
                exec(conn,sqlquery);
                % Retrieve default parameters based on organism model
                sqlquery = sprintf('SELECT parameterID, value, description FROM model_parameterTab WHERE modelID = %g',model);
                modelparameterTable = fetch(conn,sqlquery);
                % Retrieve generated project ID based on project name
                sqlquery = sprintf('SELECT projectID FROM projectTab WHERE name = ''%s''', name);
                projectID = double(table2array(fetch(conn,sqlquery)));
                % Prepare table with parameter information of newly created
                % project
                projectIDcolumn = table(ones(height(modelparameterTable(:,1)),1).*projectID);
                data = [projectIDcolumn, modelparameterTable];
                data.Properties.VariableNames = {'projectID', 'parameterID', 'value', 'description'};
                sqlwrite(conn, 'project_parameterTab', data);
                close(conn)
                app.CallingApp.selectedproject = name;
                app.CallingApp.projectID = projectID;
                delete(app);
            else
                disp("Project couldn´t be generated.");
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                app.modelIdx = indices(1, 1);
                app.CreateButton.Enable = "on";
                app.CreateButton.Tooltip = "";
            else
                app.modelIdx = [];
                app.CreateButton.Enable = "off";
                app.CreateButton.Tooltip = "Select a model";
            end
                
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.Multiselect = 'off';
            app.UITable.Tooltip = {'Click to select a model'};
            app.ProjecttitleEditFieldLabel = uilabel(app.UIFigure);
            app.ProjecttitleEditFieldLabel.Text = 'Project title:';
            app.ProjecttitleEditField = uieditfield(app.UIFigure, 'text');
            app.ProjecttitleEditField.CharacterLimits = [1 50];
            app.ProjecttitleEditField.Value = 'MyProject';
            app.AuthorLabel = uilabel(app.UIFigure);
            app.AuthorLabel.Text = 'Author (optional):';
            app.AuthoroptionalEditField = uieditfield(app.UIFigure, 'text');
            app.AuthoroptionalEditField.CharacterLimits = [0 50];
            app.ProjectdescriptionoptionalLabel = uilabel(app.UIFigure);
            app.ProjectdescriptionoptionalLabel.Text = 'Project description (optional):';
            app.ProjectdescriptionoptionalTextArea = uitextarea(app.UIFigure);
            app.CreateaNewProjectLabel = uilabel(app.UIFigure);
            app.CreateaNewProjectLabel.Text = 'Create a New Project:';
            app.ReturnButton = uibutton(app.UIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = 'Return';
            app.CreateButton = uibutton(app.UIFigure, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Enable = 'off';
            app.CreateButton.Tooltip = {'Select a process'};
            app.CreateButton.Text = 'Create';
            app.SelectaModelLabel = uilabel(app.UIFigure);
            app.SelectaModelLabel.Text = 'Select a Model:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CreateProject(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end