classdef DataTable < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        AutoupdateButton           matlab.ui.control.StateButton
        RefreshButton              matlab.ui.control.Button
        EntriesLabel               matlab.ui.control.Label
        ProjectnameEditField       matlab.ui.control.EditField
        ProjectnameEditFieldLabel  matlab.ui.control.Label
        ExporttableButton          matlab.ui.control.Button
        CloseButton                matlab.ui.control.Button
        UITable                    matlab.ui.control.Table
        VariableSelectionPanel     matlab.ui.container.Panel
        Tree                       matlab.ui.container.CheckBoxTree
        AllNode                    matlab.ui.container.TreeNode
    end

    
    properties (Access = private)
        CallingApp
        selectedproject
        significant_figures
        dbpath
        projectID
        selectedvariables = [1 2 3 4 9 10 12 13 14 15 16];
        descriptions
        preselected
        table
        variableTable
        autoupdate = true
    end

    
    methods (Access = private)

        function fillTree(app)
            delete(app.AllNode.Children);
            app.Tree.CheckedNodes = [];
            n = 1;
                % Add nodes to the second level
                variableNames = fieldnames(app.CallingApp.v_prop);
                for i = 1:numel(variableNames)
                    variable = variableNames{i};
                    childnode = uitreenode(app.AllNode, 'Text', variable, 'NodeData', app.CallingApp.v_prop.(variable).variableID);
                    if any(i == app.selectedvariables)
                        checkednodes(n) = childnode;
                        n = n + 1;
                    end
                end
                app.Tree.CheckedNodes = checkednodes;
                expand(app.AllNode);
        end
    end
    methods (Access = public)
        
        function ChangeProject(app, projectID)
            app.projectID = projectID;

            
        end

        function fillTable(app)
            if ~isempty(app.selectedvariables)
                children = app.AllNode.Children;
                selection = {children.Text};
                T = table();
                T.("time in h") = (app.CallingApp.v.t(1:app.CallingApp.nxtidx))';
                for i = 1:numel(selection)
                    if any(children(i).NodeData == app.selectedvariables)
                        variableName = selection{i};
                        headerName = append(variableName, " in ", app.CallingApp.v_prop.(variableName).unit);
                        data = app.CallingApp.v.(variableName)(1:app.CallingApp.nxtidx);
                        T.(headerName) = round2sig(app.CallingApp, data', app.significant_figures);
                    end
                end
                app.UITable.Data = T;
                app.UITable.ColumnName = fieldnames(T);
                app.EntriesLabel.Text = sprintf("%g rows",app.CallingApp.nxtidx);
            else
                app.UITable.Data = [];
                app.UITable.ColumnName = "No Variable Selected";
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, CallingApp, projectID, significant_figures)
            app.UIFigure.Name = "Data Table";
            centerWindow(app.UIFigure)
            app.CallingApp = CallingApp;
            app.significant_figures = significant_figures;
            app.projectID = double(projectID);
            ChangeProject(app, app.projectID)
            app.AutoupdateButton.Value = app.autoupdate;
            fillTree(app)
            fillTable(app)
            app.selectedproject = app.CallingApp.projectName;
            app.ProjectnameEditField.Value = app.selectedproject;
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            checkedNodes = app.Tree.CheckedNodes;
            app.selectedvariables = [];
            if ~isempty(checkedNodes)
                if checkedNodes(1).Text == "All"
                for i = 1:numel(checkedNodes)-1
                    app.selectedvariables(i) = checkedNodes(i+1).NodeData;
                end
                else
                for i = 1:numel(checkedNodes)
                    app.selectedvariables(i) = checkedNodes(i).NodeData;
                end
                end
            end
            fillTable(app)
        end

        % Button pushed function: ExporttableButton
        function ExporttableButtonPushed(app, event)
            % Der Wert von app.selectedproject als Standarddateiname
            defaultFileName = append(app.selectedproject, '.xlsx');

            % Dateifilter für .xml als Standard und andere Tabellenformate als Alternativen
            [file, path] = uiputfile({'*.xlsx', 'Excel Files (*.xlsx)'; ...
                '*.txt', 'Text Files (*.txt)'; ...
                '*.csv', 'CSV Files (*.csv)'; ...
                '*.*', 'All Files (*.*)'}, ...
                'Save as', defaultFileName);

            % Überprüfen, ob der Benutzer eine Datei ausgewählt hat
            if isequal(file, 0)
                disp('User selected Cancel');
            else
                fullFileName = fullfile(path, file);
                disp(['User selected ', fullFileName]);

                % Dateierweiterung extrahieren
                [~, ~, ext] = fileparts(fullFileName);
                try
                % Tabelle basierend auf der Dateierweiterung speichern
                switch ext
                    case '.txt'
                        % Speichern als Textdatei
                        writetable(app.UITable.Data, fullFileName, 'FileType', 'text');
                    case '.csv'
                        % Speichern als CSV-Datei
                        writetable(app.UITable.Data, fullFileName);
                    case '.xlsx'
                        % Speichern als Excel-Datei
                        writetable(app.UITable.Data, fullFileName, 'FileType', 'spreadsheet');
                    otherwise
                        msgbox('Unsupported file type',"Error","error");
                end
                disp('Datei erfolgreich gespeichert.');
                catch ME
                    msgbox(ME.message,"Error","error");
                end
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            fillTable(app)
        end

        % Value changed function: AutoupdateButton
        function AutoupdateButtonValueChanged(app, event)
            app.autoupdate = app.AutoupdateButton.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VariableSelectionPanel = uipanel(app.UIFigure);
            app.VariableSelectionPanel.Title = 'Variable Selection';
            app.Tree = uitree(app.VariableSelectionPanel, 'checkbox');
            app.AllNode = uitreenode(app.Tree);
            app.AllNode.Text = 'All';
            % Assign Checked Nodes
            app.Tree.CheckedNodes = [app.AllNode];
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.ColumnRearrangeable = 'on';
            app.UITable.RowName = {};
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ExporttableButton = uibutton(app.UIFigure, 'push');
            app.ExporttableButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttableButtonPushed, true);
            app.ExporttableButton.Text = 'Export table';
            app.ProjectnameEditFieldLabel = uilabel(app.UIFigure);
            app.ProjectnameEditFieldLabel.Text = 'Project name:';
            app.ProjectnameEditField = uieditfield(app.UIFigure, 'text');
            app.ProjectnameEditField.Editable = 'off';
            app.EntriesLabel = uilabel(app.UIFigure);
            app.EntriesLabel.Text = 'Entries';
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.AutoupdateButton = uibutton(app.UIFigure, 'state');
            app.AutoupdateButton.ValueChangedFcn = createCallbackFcn(app, @AutoupdateButtonValueChanged, true);
            app.AutoupdateButton.Text = 'Auto update';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataTable(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end