classdef DialogBox1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        StartingVariablesLabel  matlab.ui.control.Label
        tmaxEditField           matlab.ui.control.NumericEditField
        tmaxhEditFieldLabel     matlab.ui.control.Label
        VR10EditField           matlab.ui.control.NumericEditField
        VR10lLabel              matlab.ui.control.Label
        ConfirmButton           matlab.ui.control.Button
        cS1R1EditField          matlab.ui.control.NumericEditField
        cS1R1glLabel            matlab.ui.control.Label
        VLmaxEditField          matlab.ui.control.NumericEditField
        VLmaxlEditFieldLabel    matlab.ui.control.Label
        cS1L0EditField          matlab.ui.control.NumericEditField
        cS1L0glEditFieldLabel   matlab.ui.control.Label
        cXL0EditField           matlab.ui.control.NumericEditField
        cXL0glEditFieldLabel    matlab.ui.control.Label
    end

    
    properties (Access = public)
        CallingApp   % ControlApp
        MainApp      % Starting Screen
        cXL0
        cS1L0
        VLmax
        cS1R1
        VR10
        tmax
        variables = ["cXL0", "cS1L0", "VLmax", "cS1R1", "VR10", "tmax"];
    end
    
    methods (Access = private)
        
        % function downloadProperties(app)
        %     for i = 1:length(app.variables)
        %         sqlquery = sprintf(['SELECT value ' ...
        %             'FROM project_parameterTab pp ' ...
        %             'JOIN parameterTab p ON p.parameterID = pp.parameterID ' ...
        %             'WHERE pp.projectID = %g AND p.name = ''%s'''], app.CallingApp.projectID, app.variables(i));
        %         T = fetchpy(app.CallingApp.dbpath, sqlquery);
        %         app.(app.variables(i)) = table2array(T);
        %     end
        % end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, CallingApp, MainApp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = CallingApp;

            centerWindow(app.UIFigure)

            % Apply tex interpreter
            app.cXL0glEditFieldLabel.Interpreter     = 'tex';
            app.cS1L0glEditFieldLabel.Interpreter    = 'tex';
            app.VLmaxlEditFieldLabel.Interpreter    = 'tex';
            app.cS1R1glLabel.Interpreter     = 'tex';
            app.VR10lLabel.Interpreter      = 'tex';
            app.tmaxhEditFieldLabel.Interpreter     = 'tex';

            % Apply tex label
            font = "\fontname{Helvetica}\fontsize{10}";
            app.cXL0glEditFieldLabel.Text = append(font, "c_{XL0} [g/l]");
            app.cS1L0glEditFieldLabel.Text = append(font, "c_{S1L0} [g/l]");
            app.VLmaxlEditFieldLabel.Text = append(font, "V_{Lmax} [l]");
            app.cS1R1glLabel.Text = append(font, "c_{S1R1} [g/l]");
            app.VR10lLabel.Text = append(font, "V_{R0} [l]");
            app.tmaxhEditFieldLabel.Text = append(font, "t_{max} [h]");

            % Update UI with input values
            app.cXL0EditField.Value     = app.CallingApp.p.cXL0;
            app.cS1L0EditField.Value    = app.CallingApp.p.cS1L0;
            app.VLmaxEditField.Value     = app.CallingApp.p.VLmax;
            app.cS1R1EditField.Value     = app.CallingApp.p.cS1R1;
            app.VR10EditField.Value       = app.CallingApp.p.VR10;
            app.tmaxEditField.Value      = app.CallingApp.p.tmax;

            % Define window name
            app.UIFigure.Name = "Starting Variables Edit Window";
            centerWindow(app.UIFigure)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Enable the Plot Opions button in main app
            app.CallingApp.EditStartingVariablesButton.Enable = 'on';
            
            % Delete the dialog box 
            delete(app)
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            
            % Save new values in the control app
            app.CallingApp.p.cXL0 = app.cXL0EditField.Value;
            app.CallingApp.p.cS1L0 = app.cS1L0EditField.Value;
            app.CallingApp.p.VLmax = app.VLmaxEditField.Value;
            app.CallingApp.p.cS1R1 = app.cS1R1EditField.Value;
            app.CallingApp.p.VR10 = app.VR10EditField.Value;
            app.CallingApp.p.tmax = app.tmaxEditField.Value;

            % Call close request function
            UIFigureCloseRequest(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.cXL0glEditFieldLabel = uilabel(app.UIFigure);
            app.cXL0glEditFieldLabel.Text = 'cXL0 [g/l]';
            app.cXL0EditField = uieditfield(app.UIFigure, 'numeric');
            app.cXL0EditField.Limits = [0.05 10];
            app.cXL0EditField.Value = 0.5;
            app.cS1L0glEditFieldLabel = uilabel(app.UIFigure);
            app.cS1L0glEditFieldLabel.Text = 'cS1L0 [g/l]';
            app.cS1L0EditField = uieditfield(app.UIFigure, 'numeric');
            app.cS1L0EditField.Limits = [0 10];
            app.cS1L0EditField.Value = 0.5;
            app.VLmaxlEditFieldLabel = uilabel(app.UIFigure);
            app.VLmaxlEditFieldLabel.Text = 'VLmax [l]';
            app.VLmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.VLmaxEditField.Limits = [1 50];
            app.VLmaxEditField.Value = 6;
            app.cS1R1glLabel = uilabel(app.UIFigure);
            app.cS1R1glLabel.Text = 'cS1R1 [g/l]';
            app.cS1R1EditField = uieditfield(app.UIFigure, 'numeric');
            app.cS1R1EditField.Limits = [20 700];
            app.cS1R1EditField.Value = 150;
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.VR10lLabel = uilabel(app.UIFigure);
            app.VR10lLabel.Text = 'VR10 [l]';
            app.VR10EditField = uieditfield(app.UIFigure, 'numeric');
            app.VR10EditField.Limits = [0.1 10];
            app.VR10EditField.Value = 5;
            app.tmaxhEditFieldLabel = uilabel(app.UIFigure);
            app.tmaxhEditFieldLabel.Text = 'tmax [h]';
            app.tmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.tmaxEditField.Limits = [0.1 50];
            app.tmaxEditField.Value = 1;
            app.StartingVariablesLabel = uilabel(app.UIFigure);
            app.StartingVariablesLabel.Text = 'Starting Variables';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogBox1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end