classdef DialogBox2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        KP_slavebaseEditField       matlab.ui.control.NumericEditField
        KP_slavebaseEditFieldLabel  matlab.ui.control.Label
        ConfirmButton               matlab.ui.control.Button
        KP_slaveacidEditField       matlab.ui.control.NumericEditField
        KP_slaveacidEditFieldLabel  matlab.ui.control.Label
        KP_masterEditField          matlab.ui.control.NumericEditField
        KP_masterEditFieldLabel     matlab.ui.control.Label
        SlaveControllerLabel        matlab.ui.control.Label
        MasterControllerLabel       matlab.ui.control.Label
        ParametersofthepHControllerLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        CallingApp   % Main app object
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;

            % Apply tex label
            font = "\fontname{Helvetica}\fontsize{10}";
            app.KP_masterEditFieldLabel.Text     = append(font,"K_P_{,Master}");
            app.KP_slaveacidEditFieldLabel.Text = append(font,"K_P_{,Slave acid}");
            app.KP_slavebaseEditFieldLabel.Text  = append(font,"K_P_{,Slave base}");
            
            % Update UI with input values
            app.KP_masterEditField.Value     = app.CallingApp.p.KP_pH;
            app.KP_slaveacidEditField.Value  = app.CallingApp.p.KP_pH2a;
            app.KP_slavebaseEditField.Value  = app.CallingApp.p.KP_pH2b;

            % Define window name
            app.UIFigure.Name = "pH Controller Parameters";
            centerWindow(app.UIFigure)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Enable the Plot Opions button in main app
            app.CallingApp.pHParametersButton.Enable = 'on';
            
            % Delete the dialog box 
            delete(app)
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            % Upload parameter changes
            app.CallingApp.p.KP_pH = app.KP_masterEditField.Value;
            app.CallingApp.p.KP_pH2a = app.KP_slaveacidEditField.Value;
            app.CallingApp.p.KP_pH2b = app.KP_slavebaseEditField.Value;

            % Call close request function
            UIFigureCloseRequest(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ParametersofthepHControllerLabel = uilabel(app.UIFigure);
            app.ParametersofthepHControllerLabel.Text = {'Parameters of the'; 'pH Controller'};
            app.MasterControllerLabel = uilabel(app.UIFigure);
            app.MasterControllerLabel.Text = 'Master Controller';
            app.SlaveControllerLabel = uilabel(app.UIFigure);
            app.SlaveControllerLabel.Text = 'Slave Controller';
            app.KP_masterEditFieldLabel = uilabel(app.UIFigure);
            app.KP_masterEditFieldLabel.Interpreter = 'tex';
            app.KP_masterEditFieldLabel.Text = 'KP_master';
            app.KP_masterEditField = uieditfield(app.UIFigure, 'numeric');
            app.KP_masterEditField.ValueDisplayFormat = '%.4f';
            app.KP_slaveacidEditFieldLabel = uilabel(app.UIFigure);
            app.KP_slaveacidEditFieldLabel.Interpreter = 'tex';
            app.KP_slaveacidEditFieldLabel.Text = 'KP_slave acid';
            app.KP_slaveacidEditField = uieditfield(app.UIFigure, 'numeric');
            app.KP_slaveacidEditField.ValueDisplayFormat = '%.4f';
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.KP_slavebaseEditFieldLabel = uilabel(app.UIFigure);
            app.KP_slavebaseEditFieldLabel.Interpreter = 'tex';
            app.KP_slavebaseEditFieldLabel.Text = 'KP_slave base';
            app.KP_slavebaseEditField = uieditfield(app.UIFigure, 'numeric');
            app.KP_slavebaseEditField.ValueDisplayFormat = '%.4f';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogBox2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end