classdef DialogBox5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        KDEditField       matlab.ui.control.NumericEditField
        KDEditFieldLabel  matlab.ui.control.Label
        KIEditField       matlab.ui.control.NumericEditField
        KIEditFieldLabel  matlab.ui.control.Label
        ParametersoftheLiquidWeightControllerLabel  matlab.ui.control.Label
        KPEditField       matlab.ui.control.NumericEditField
        KPEditFieldLabel  matlab.ui.control.Label
        ConfirmButton     matlab.ui.control.Button
    end

    
    properties (Access = public)
        CallingApp   % Main app object
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            % Apply tex label
            font = "\fontname{Helvetica}\fontsize{10}";
            
            % Update UI with input values
            app.KPEditFieldLabel.Text = append(font,"K_P");
            app.KIEditFieldLabel.Text = append(font,"K_I");
            app.KDEditFieldLabel.Text = append(font,"K_D");

            % Update UI with input values
            app.KPEditField.Value = app.CallingApp.p.KP_LW;
            app.KIEditField.Value = app.CallingApp.p.KI_LW;
            app.KDEditField.Value = app.CallingApp.p.KD_LW;

            % Define window name
            app.UIFigure.Name = "Liquid Weight Controller Parameters";
            centerWindow(app.UIFigure)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Enable the Plot Opions button in main app
            app.CallingApp.LWParametersButton.Enable = 'on';
            
            % Delete the dialog box 
            delete(app)
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            % Upload changes to the control app
            app.CallingApp.p.KP_LW = app.KPEditField.Value;
            app.CallingApp.p.KI_LW = app.KIEditField.Value;
            app.CallingApp.p.KD_LW = app.KDEditField.Value;

            % Call close request function
            UIFigureCloseRequest(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.KPEditFieldLabel = uilabel(app.UIFigure);
            app.KPEditFieldLabel.Interpreter = 'tex';
            app.KPEditFieldLabel.Text = 'KP';
            app.KPEditField = uieditfield(app.UIFigure, 'numeric');
            app.KPEditField.ValueDisplayFormat = '%.4f';
            app.ParametersoftheLiquidWeightControllerLabel = uilabel(app.UIFigure);
            app.ParametersoftheLiquidWeightControllerLabel.Text = {'Parameters of the'; 'Liquid Weight Controller'};
            app.KIEditFieldLabel = uilabel(app.UIFigure);
            app.KIEditFieldLabel.Interpreter = 'tex';
            app.KIEditFieldLabel.Text = 'KI';
            app.KIEditField = uieditfield(app.UIFigure, 'numeric');
            app.KIEditField.ValueDisplayFormat = '%.4f';
            app.KDEditFieldLabel = uilabel(app.UIFigure);
            app.KDEditFieldLabel.Interpreter = 'tex';
            app.KDEditFieldLabel.Text = 'KD';
            app.KDEditField = uieditfield(app.UIFigure, 'numeric');
            app.KDEditField.ValueDisplayFormat = '%.4f';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogBox5(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end