classdef FigureAppSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ApplyButton                     matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        TitleTab                        matlab.ui.container.Tab
        DescriptionTextArea             matlab.ui.control.TextArea
        DescriptionTextAreaLabel        matlab.ui.control.Label
        TitleSwitch                     matlab.ui.control.Switch
        TitleSwitchLabel                matlab.ui.control.Label
        TitleEditField                  matlab.ui.control.EditField
        TitleEditFieldLabel             matlab.ui.control.Label
        NameEditField                   matlab.ui.control.EditField
        NameLabel                       matlab.ui.control.Label
        TemplatesettingsLabel           matlab.ui.control.Label
        GraphTab                        matlab.ui.container.Tab
        ResettostandardButton_2         matlab.ui.control.Button
        GeneralgraphsettingsLabel       matlab.ui.control.Label
        TitlefontsizeEditField          matlab.ui.control.NumericEditField
        TitlefontsizeLabel              matlab.ui.control.Label
        LinewidthEditField              matlab.ui.control.NumericEditField
        LinewidthEditFieldLabel         matlab.ui.control.Label
        VerticallinewidthEditField      matlab.ui.control.NumericEditField
        VerticallinewidthEditFieldLabel  matlab.ui.control.Label
        FontsizeEditField               matlab.ui.control.NumericEditField
        FontsizeEditFieldLabel          matlab.ui.control.Label
        AxesTab                         matlab.ui.container.Tab
        AxislinewidthEditField          matlab.ui.control.NumericEditField
        AxislinewidthEditFieldLabel     matlab.ui.control.Label
        AxislabelfontsizeEditField      matlab.ui.control.NumericEditField
        AxislabelfontsizeEditFieldLabel  matlab.ui.control.Label
        xAxisunitEditField              matlab.ui.control.EditField
        xAxisunitEditFieldLabel         matlab.ui.control.Label
        xAxislabelEditField             matlab.ui.control.EditField
        xAxislabelEditFieldLabel        matlab.ui.control.Label
        xAxistickEditField              matlab.ui.control.NumericEditField
        xAxistickEditFieldLabel         matlab.ui.control.Label
        yAxisoffsetEditField            matlab.ui.control.NumericEditField
        yAxisoffsetEditFieldLabel       matlab.ui.control.Label
        yAxislabeloffsetaboveEditField  matlab.ui.control.NumericEditField
        yAxislabeloffsetaboveEditFieldLabel  matlab.ui.control.Label
        yAxistickEditField              matlab.ui.control.NumericEditField
        yAxistickEditFieldLabel         matlab.ui.control.Label
        yAxislabeloffsetbelowEditField  matlab.ui.control.NumericEditField
        yAxislabeloffsetbelowEditFieldLabel  matlab.ui.control.Label
        AxessettingsLabel               matlab.ui.control.Label
        ResettostandardButton           matlab.ui.control.Button
        FlagsTab                        matlab.ui.container.Tab
        FlaglinewidthEditField          matlab.ui.control.NumericEditField
        FlaglinewidthEditFieldLabel     matlab.ui.control.Label
        FlaglinelengthEditField         matlab.ui.control.NumericEditField
        FlaglinelengthEditFieldLabel    matlab.ui.control.Label
        FlaglineangleinEditField        matlab.ui.control.NumericEditField
        FlaglineangleinEditFieldLabel   matlab.ui.control.Label
        FlagfontsizeEditField           matlab.ui.control.NumericEditField
        FlagfontsizeEditFieldLabel      matlab.ui.control.Label
        FlagsettingsLabel               matlab.ui.control.Label
        ResettostandardButton_3         matlab.ui.control.Button
    end

    
    properties (Access = public)

        templateID
        xaxisTitle

        name
        description
        plottitle
        titlebool

        % Settings


        % Graph
        graphmarkersize; %Size of Marker
        graphlinewidth; % Line width
        graphvlinewidth; % Linewidth vertical lines
        graphfontsize; % Font size
        graphtitlefontsize % Title font size

        % Axes
        axisxlabel;
        axisxunit;
        axisytick; %Number of Ticks y-axis
        axisxtick; %Number of Ticks x-axis
        axisyoffset; % Offset Y−axis
        axisylabeloffsetabove; %Y label offset above
        axisylabeloffsetbelow; %Y label offset below
        axislabelfontsize; %Fontsize
        axislinewidth; % Linewidth Axes


        % Flags
        flaglength; % Length of Flag handle
        flagangle; % Angle of Flag handle in °
        flaglinewidth; % Width of Flag handle
        flagfontsize; %Fontsize

        refreshrate
        tstart
        tend

        last_changed

        setting_names
    end
    
    properties (Access = public)
        plotUIApp;
        CallingApp
    end
    
    methods (Access = private)

        function readSettings(app)
            try
                % Read plot profile information and settings
                sqlquery = sprintf('SELECT * FROM plot_templateTab WHERE templateID = %g', app.templateID);
                conn = sqlite(app.CallingApp.dbpath, "readonly");
                T = fetch(conn, sqlquery);
                close(conn)
                app.setting_names = T.Properties.VariableNames;
                for i = 2:length(app.setting_names)
                    app.(app.setting_names{i}) = table2array(T(1,i));
                end
            catch ME
                errordlg(['Error reading profile: ' ME.message], 'Profile Error', 'replace');
            end
        end

        function updateAllProperties(app)
            % List of all properties to update
            %numproperties = {'graphmarkersize', 'graphlinewidth', 'graphvlinewidth', 'graphfontsize', ...
            %    'graphtitlefontsize','axisytick', 'axisxtick', 'axisyoffset',...
            %    'axisylabeloffsetabove', 'axisylabeloffsetbelow', 'axislabelfontsize', 'axislinewidth',...
            %    'flaglength', 'flagangle', 'flaglinewidth', 'flagfontsize'};
            strindex = [2, 3, 4, 10, 11, 26]; % Indices of string entries
            numindex = 1:numel(app.setting_names);
            numindex(strindex) = []; % Delete string indices from numindices
            numproperties = app.setting_names(numindex);
            
            %strproperties = {'name', 'description', 'plottitle', 'axisxlabel', 'axisxunit'};
            strproperties = app.setting_names(strindex);
            try
                numquery = [];
                strquery = [];

                for i = 1:numel(numproperties)
                    if i ~= numel(numproperties)
                        setquery = sprintf('%s = %g, ', numproperties{i},app.(numproperties{i}));
                    end
                    numquery = [numquery setquery];
                end

                for i = 1:numel(strproperties)
                    if ismissing(app.(strproperties{i}))
                        app.(strproperties{i}) = "";
                    end
                    if i ~= numel(strproperties)
                        setquery = sprintf('%s = ''%s'', ', strproperties{i},app.(strproperties{i}));
                    else
                        setquery = sprintf('%s = ''%s''', strproperties{i},app.(strproperties{i}));
                    end
                    strquery = [strquery setquery];
                end

                % Prepare the base SQL query
                sumquery = [numquery strquery];
                baseQuery = sprintf('UPDATE plot_templateTab SET %s WHERE templateID = %g', sumquery, app.templateID);
                conn = sqlite(app.CallingApp.dbpath, "connect");
                exec(conn,baseQuery);
                close(conn)
            catch ME
                errordlg(['Error updating settings: ' ME.message], 'Profile Error', 'replace');
            end
        end

        function setStandard(app, setting)
            switch setting
                case 'windows'
                    app.figurelength0 = 1500;
                    app.figureheight = 800;
                case 'graph'
                    app.graphmarkersize = 8;
                    app.graphlinewidth = 1.5;
                    app.graphvlinewidth = 2.0;
                    app.graphfontsize = 12;
                    app.graphtitlefontsize = 18;
                case 'axes'
                    app.axisxlabel = "Process time";
                    app.axisxunit = "h";
                    app.axisytick = 5;
                    app.axisxtick = 6;
                    app.axisyoffset = 50;
                    app.axisylabeloffsetabove = 0.02;
                    app.axisylabeloffsetbelow = 0.1;
                    app.axislabelfontsize = 12;
                    app.axislinewidth = 1.75;
                case 'flags'
                    app.flaglength = 0.04;
                    app.flagangle = 45; 
                    app.flaglinewidth = 1.25;
                    app.flagfontsize = 16;
            end
            FillSettings(app);
        end

        function FillSettings(app)
            % Graph
            app.FontsizeEditField.Value = app.graphfontsize;
            app.TitlefontsizeEditField.Value = app.graphtitlefontsize;
            app.LinewidthEditField.Value = app.graphlinewidth;
            app.VerticallinewidthEditField.Value = app.graphvlinewidth;
            % Axes
            app.xAxislabelEditField.Value = app.axisxlabel;
            app.xAxisunitEditField.Value = app.axisxunit;
            app.xAxistickEditField.Value = app.axisxtick;
            app.yAxistickEditField.Value = app.axisytick;
            app.AxislabelfontsizeEditField.Value = app.axislabelfontsize;
            app.AxislinewidthEditField.Value = app.axislinewidth;
            app.yAxisoffsetEditField.Value = app.axisyoffset;
            app.yAxislabeloffsetaboveEditField.Value = app.axisylabeloffsetabove;
            app.yAxislabeloffsetbelowEditField.Value = app.axisylabeloffsetbelow;
            % Flags
            app.FlagfontsizeEditField.Value = app.flagfontsize;
            app.FlaglinewidthEditField.Value = app.flaglinewidth;
            app.FlaglinelengthEditField.Value = app.flaglength;
            app.FlaglineangleinEditField.Value = app.flagangle;
            % Template
            app.NameEditField.Value = app.name;
            app.DescriptionTextArea.Value = app.description;
            app.TitleEditField.Value = app.plottitle;
            app.TitleSwitch.Value = app.titlebool;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, CallingApp, templateID)
            app.CallingApp = CallingApp;
            app.templateID = templateID;
            app.UIFigure.Name = "Plot Settings";
            centerWindow(app.UIFigure)
            readSettings(app);
            FillSettings(app);
        end

        % Button pushed function: ResettostandardButton_2
        function ResettostandardButton_2Pushed(app, event)
            setStandard(app, 'graph')
        end

        % Button pushed function: ResettostandardButton
        function ResettostandardButtonPushed(app, event)
            setStandard(app, 'axes')
        end

        % Button pushed function: ResettostandardButton_3
        function ResettostandardButton_3Pushed(app, event)
            setStandard(app, 'flags')
        end

        % Callback function
        function ResettostandardButton_4Pushed(app, event)
            setStandard(app, 'windows')
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
            
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            updateAllProperties(app)
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            delete(app);
            
        end

        % Value changed function: FontsizeEditField
        function FontsizeEditFieldValueChanged(app, event)
            value = app.FontsizeEditField.Value;
            app.graphfontsize = value;
        end

        % Value changed function: TitlefontsizeEditField
        function TitlefontsizeEditFieldValueChanged(app, event)
            value = app.TitlefontsizeEditField.Value;
            app.graphtitlefontsize = value;
        end

        % Value changed function: LinewidthEditField
        function LinewidthEditFieldValueChanged(app, event)
            value = app.LinewidthEditField.Value;
            app.graphlinewidth = value;
        end

        % Value changed function: VerticallinewidthEditField
        function VerticallinewidthEditFieldValueChanged(app, event)
            value = app.VerticallinewidthEditField.Value;
            app.graphvlinewidth = value;
        end

        % Callback function
        function MarkersizeEditFieldValueChanged(app, event)
            value = app.MarkersizeEditField.Value;
            app.graphmarkersize = value;
        end

        % Value changed function: xAxistickEditField
        function xAxistickEditFieldValueChanged(app, event)
            value = app.xAxistickEditField.Value;
            app.axisxtick = value;
        end

        % Value changed function: yAxistickEditField
        function yAxistickEditFieldValueChanged(app, event)
            value = app.yAxistickEditField.Value;
            app.axisytick = value;
        end

        % Value changed function: AxislabelfontsizeEditField
        function AxislabelfontsizeEditFieldValueChanged(app, event)
            value = app.AxislabelfontsizeEditField.Value;
            app.axislabelfontsize = value;
        end

        % Value changed function: AxislinewidthEditField
        function AxislinewidthEditFieldValueChanged(app, event)
            value = app.AxislinewidthEditField.Value;
            app.axislinewidth = value;
        end

        % Value changed function: yAxisoffsetEditField
        function yAxisoffsetEditFieldValueChanged(app, event)
            value = app.yAxisoffsetEditField.Value;
            app.axisyoffset = value;
        end

        % Value changed function: yAxislabeloffsetaboveEditField
        function yAxislabeloffsetaboveEditFieldValueChanged(app, event)
            value = app.yAxislabeloffsetaboveEditField.Value;
            app.axisylabeloffsetabove = value;
        end

        % Value changed function: yAxislabeloffsetbelowEditField
        function yAxislabeloffsetbelowEditFieldValueChanged(app, event)
            value = app.yAxislabeloffsetbelowEditField.Value;
            app.axisylabeloffsetbelow = value;
        end

        % Value changed function: FlagfontsizeEditField
        function FlagfontsizeEditFieldValueChanged(app, event)
            value = app.FlagfontsizeEditField.Value;
            app.flagfontsize = value;
        end

        % Value changed function: FlaglinewidthEditField
        function FlaglinewidthEditFieldValueChanged(app, event)
            value = app.FlaglinewidthEditField.Value;
            app.flaglinewidth = value;
        end

        % Value changed function: FlaglinelengthEditField
        function FlaglinelengthEditFieldValueChanged(app, event)
            value = app.FlaglinelengthEditField.Value;
            app.flaglength = value;
        end

        % Value changed function: FlaglineangleinEditField
        function FlaglineangleinEditFieldValueChanged(app, event)
            value = app.FlaglineangleinEditField.Value;
            app.flagangle = value;
        end

        % Callback function
        function WindowlengthEditFieldValueChanged(app, event)
            value = app.WindowlengthEditField.Value;
            app.figurelength0 = value;
        end

        % Callback function
        function WindowheightEditFieldValueChanged(app, event)
            value = app.WindowheightEditField.Value;
            app.figureheight = value;
        end

        % Value changed function: NameEditField
        function NameEditFieldValueChanged(app, event)
            app.name = app.NameEditField.Value;
            
        end

        % Value changed function: DescriptionTextArea
        function DescriptionTextAreaValueChanged(app, event)
            app.description = app.DescriptionTextArea.Value;
            
        end

        % Value changed function: TitleEditField
        function TitleEditFieldValueChanged(app, event)
            app.plottitle = app.TitleEditField.Value;
            
        end

        % Value changed function: TitleSwitch
        function TitleSwitchValueChanged(app, event)
            app.titlebool = app.TitleSwitch.Value;
            
        end

        % Value changed function: xAxisunitEditField
        function xAxisunitEditFieldValueChanged(app, event)
            app.axisxunit = app.xAxisunitEditField.Value;
            
        end

        % Value changed function: xAxislabelEditField
        function xAxislabelEditFieldValueChanged(app, event)
            app.axisxlabel = app.xAxislabelEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TitleTab = uitab(app.TabGroup);
            app.TitleTab.Title = 'Title';
            app.TemplatesettingsLabel = uilabel(app.TitleTab);
            app.TemplatesettingsLabel.Text = 'Template settings';
            app.NameLabel = uilabel(app.TitleTab);
            app.NameLabel.Text = 'Name:';
            app.NameEditField = uieditfield(app.TitleTab, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @NameEditFieldValueChanged, true);
            app.TitleEditFieldLabel = uilabel(app.TitleTab);
            app.TitleEditFieldLabel.Text = 'Title:';
            app.TitleEditField = uieditfield(app.TitleTab, 'text');
            app.TitleEditField.ValueChangedFcn = createCallbackFcn(app, @TitleEditFieldValueChanged, true);
            app.TitleSwitchLabel = uilabel(app.TitleTab);
            app.TitleSwitchLabel.Text = 'Title Switch:';
            app.TitleSwitch = uiswitch(app.TitleTab, 'slider');
            app.TitleSwitch.ItemsData = [0 1];
            app.TitleSwitch.ValueChangedFcn = createCallbackFcn(app, @TitleSwitchValueChanged, true);
            app.TitleSwitch.Value = 0;
            app.DescriptionTextAreaLabel = uilabel(app.TitleTab);
            app.DescriptionTextAreaLabel.Text = 'Description:';
            app.DescriptionTextArea = uitextarea(app.TitleTab);
            app.DescriptionTextArea.ValueChangedFcn = createCallbackFcn(app, @DescriptionTextAreaValueChanged, true);
            app.GraphTab = uitab(app.TabGroup);
            app.GraphTab.Title = 'Graph';
            app.FontsizeEditFieldLabel = uilabel(app.GraphTab);
            app.FontsizeEditFieldLabel.Text = 'Font size:';
            app.FontsizeEditField = uieditfield(app.GraphTab, 'numeric');
            app.FontsizeEditField.Limits = [1 50];
            app.FontsizeEditField.RoundFractionalValues = 'on';
            app.FontsizeEditField.ValueChangedFcn = createCallbackFcn(app, @FontsizeEditFieldValueChanged, true);
            app.FontsizeEditField.Value = 1;
            app.VerticallinewidthEditFieldLabel = uilabel(app.GraphTab);
            app.VerticallinewidthEditFieldLabel.Text = 'Vertical line width:';
            app.VerticallinewidthEditField = uieditfield(app.GraphTab, 'numeric');
            app.VerticallinewidthEditField.Limits = [1 50];
            app.VerticallinewidthEditField.ValueDisplayFormat = '%.1f';
            app.VerticallinewidthEditField.ValueChangedFcn = createCallbackFcn(app, @VerticallinewidthEditFieldValueChanged, true);
            app.VerticallinewidthEditField.Value = 1;
            app.LinewidthEditFieldLabel = uilabel(app.GraphTab);
            app.LinewidthEditFieldLabel.Text = 'Line width:';
            app.LinewidthEditField = uieditfield(app.GraphTab, 'numeric');
            app.LinewidthEditField.Limits = [1 50];
            app.LinewidthEditField.ValueDisplayFormat = '%.1f';
            app.LinewidthEditField.ValueChangedFcn = createCallbackFcn(app, @LinewidthEditFieldValueChanged, true);
            app.LinewidthEditField.Value = 1;
            app.TitlefontsizeLabel = uilabel(app.GraphTab);
            app.TitlefontsizeLabel.Text = 'Title font size:';
            app.TitlefontsizeEditField = uieditfield(app.GraphTab, 'numeric');
            app.TitlefontsizeEditField.Limits = [1 50];
            app.TitlefontsizeEditField.RoundFractionalValues = 'on';
            app.TitlefontsizeEditField.ValueChangedFcn = createCallbackFcn(app, @TitlefontsizeEditFieldValueChanged, true);
            app.TitlefontsizeEditField.Value = 1;
            app.GeneralgraphsettingsLabel = uilabel(app.GraphTab);
            app.GeneralgraphsettingsLabel.Text = 'General graph settings';
            app.ResettostandardButton_2 = uibutton(app.GraphTab, 'push');
            app.ResettostandardButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResettostandardButton_2Pushed, true);
            app.ResettostandardButton_2.Text = 'Reset to standard';
            app.AxesTab = uitab(app.TabGroup);
            app.AxesTab.Title = 'Axes';
            app.ResettostandardButton = uibutton(app.AxesTab, 'push');
            app.ResettostandardButton.ButtonPushedFcn = createCallbackFcn(app, @ResettostandardButtonPushed, true);
            app.ResettostandardButton.Text = 'Reset to standard';
            app.AxessettingsLabel = uilabel(app.AxesTab);
            app.AxessettingsLabel.Text = 'Axes settings';
            app.yAxislabeloffsetbelowEditFieldLabel = uilabel(app.AxesTab);
            app.yAxislabeloffsetbelowEditFieldLabel.Text = 'y-Axis label offset (below):';
            app.yAxislabeloffsetbelowEditField = uieditfield(app.AxesTab, 'numeric');
            app.yAxislabeloffsetbelowEditField.Limits = [0 1000];
            app.yAxislabeloffsetbelowEditField.ValueDisplayFormat = '%.3f';
            app.yAxislabeloffsetbelowEditField.ValueChangedFcn = createCallbackFcn(app, @yAxislabeloffsetbelowEditFieldValueChanged, true);
            app.yAxislabeloffsetbelowEditField.Value = 1;
            app.yAxistickEditFieldLabel = uilabel(app.AxesTab);
            app.yAxistickEditFieldLabel.Text = 'y-Axis tick:';
            app.yAxistickEditField = uieditfield(app.AxesTab, 'numeric');
            app.yAxistickEditField.Limits = [1 100];
            app.yAxistickEditField.RoundFractionalValues = 'on';
            app.yAxistickEditField.ValueChangedFcn = createCallbackFcn(app, @yAxistickEditFieldValueChanged, true);
            app.yAxistickEditField.Value = 1;
            app.yAxislabeloffsetaboveEditFieldLabel = uilabel(app.AxesTab);
            app.yAxislabeloffsetaboveEditFieldLabel.Text = 'y-Axis label offset (above):';
            app.yAxislabeloffsetaboveEditField = uieditfield(app.AxesTab, 'numeric');
            app.yAxislabeloffsetaboveEditField.Limits = [0 1000];
            app.yAxislabeloffsetaboveEditField.ValueDisplayFormat = '%.3f';
            app.yAxislabeloffsetaboveEditField.ValueChangedFcn = createCallbackFcn(app, @yAxislabeloffsetaboveEditFieldValueChanged, true);
            app.yAxislabeloffsetaboveEditField.Value = 1;
            app.yAxisoffsetEditFieldLabel = uilabel(app.AxesTab);
            app.yAxisoffsetEditFieldLabel.Text = 'y-Axis offset:';
            app.yAxisoffsetEditField = uieditfield(app.AxesTab, 'numeric');
            app.yAxisoffsetEditField.Limits = [0 1000];
            app.yAxisoffsetEditField.RoundFractionalValues = 'on';
            app.yAxisoffsetEditField.ValueDisplayFormat = '%.0f';
            app.yAxisoffsetEditField.ValueChangedFcn = createCallbackFcn(app, @yAxisoffsetEditFieldValueChanged, true);
            app.yAxisoffsetEditField.Value = 1;
            app.xAxistickEditFieldLabel = uilabel(app.AxesTab);
            app.xAxistickEditFieldLabel.Text = 'x-Axis tick:';
            app.xAxistickEditField = uieditfield(app.AxesTab, 'numeric');
            app.xAxistickEditField.Limits = [1 100];
            app.xAxistickEditField.RoundFractionalValues = 'on';
            app.xAxistickEditField.ValueChangedFcn = createCallbackFcn(app, @xAxistickEditFieldValueChanged, true);
            app.xAxistickEditField.Value = 1;
            app.xAxislabelEditFieldLabel = uilabel(app.AxesTab);
            app.xAxislabelEditFieldLabel.Text = 'x-Axis label:';
            app.xAxislabelEditField = uieditfield(app.AxesTab, 'text');
            app.xAxislabelEditField.CharacterLimits = [1 30];
            app.xAxislabelEditField.ValueChangedFcn = createCallbackFcn(app, @xAxislabelEditFieldValueChanged, true);
            app.xAxislabelEditField.Value = 'Processtime';
            app.xAxisunitEditFieldLabel = uilabel(app.AxesTab);
            app.xAxisunitEditFieldLabel.Text = 'x-Axis unit:';
            app.xAxisunitEditField = uieditfield(app.AxesTab, 'text');
            app.xAxisunitEditField.CharacterLimits = [1 20];
            app.xAxisunitEditField.ValueChangedFcn = createCallbackFcn(app, @xAxisunitEditFieldValueChanged, true);
            app.xAxisunitEditField.Value = 'h';
            app.AxislabelfontsizeEditFieldLabel = uilabel(app.AxesTab);
            app.AxislabelfontsizeEditFieldLabel.Text = 'Axis label font size:';
            app.AxislabelfontsizeEditField = uieditfield(app.AxesTab, 'numeric');
            app.AxislabelfontsizeEditField.Limits = [1 50];
            app.AxislabelfontsizeEditField.RoundFractionalValues = 'on';
            app.AxislabelfontsizeEditField.ValueChangedFcn = createCallbackFcn(app, @AxislabelfontsizeEditFieldValueChanged, true);
            app.AxislabelfontsizeEditField.Value = 1;
            app.AxislinewidthEditFieldLabel = uilabel(app.AxesTab);
            app.AxislinewidthEditFieldLabel.Text = 'Axis line width:';
            app.AxislinewidthEditField = uieditfield(app.AxesTab, 'numeric');
            app.AxislinewidthEditField.Limits = [1 50];
            app.AxislinewidthEditField.ValueDisplayFormat = '%.1f';
            app.AxislinewidthEditField.ValueChangedFcn = createCallbackFcn(app, @AxislinewidthEditFieldValueChanged, true);
            app.AxislinewidthEditField.Value = 1;
            app.FlagsTab = uitab(app.TabGroup);
            app.FlagsTab.Title = 'Flags';
            app.ResettostandardButton_3 = uibutton(app.FlagsTab, 'push');
            app.ResettostandardButton_3.ButtonPushedFcn = createCallbackFcn(app, @ResettostandardButton_3Pushed, true);
            app.ResettostandardButton_3.Text = 'Reset to standard';
            app.FlagsettingsLabel = uilabel(app.FlagsTab);
            app.FlagsettingsLabel.Text = 'Flag settings';
            app.FlagfontsizeEditFieldLabel = uilabel(app.FlagsTab);
            app.FlagfontsizeEditFieldLabel.Text = 'Flag font size:';
            app.FlagfontsizeEditField = uieditfield(app.FlagsTab, 'numeric');
            app.FlagfontsizeEditField.Limits = [1 50];
            app.FlagfontsizeEditField.RoundFractionalValues = 'on';
            app.FlagfontsizeEditField.ValueChangedFcn = createCallbackFcn(app, @FlagfontsizeEditFieldValueChanged, true);
            app.FlagfontsizeEditField.Value = 1;
            app.FlaglineangleinEditFieldLabel = uilabel(app.FlagsTab);
            app.FlaglineangleinEditFieldLabel.Text = 'Flag line angle [in °]:';
            app.FlaglineangleinEditField = uieditfield(app.FlagsTab, 'numeric');
            app.FlaglineangleinEditField.Limits = [0 45];
            app.FlaglineangleinEditField.RoundFractionalValues = 'on';
            app.FlaglineangleinEditField.ValueChangedFcn = createCallbackFcn(app, @FlaglineangleinEditFieldValueChanged, true);
            app.FlaglineangleinEditField.Value = 1;
            app.FlaglinelengthEditFieldLabel = uilabel(app.FlagsTab);
            app.FlaglinelengthEditFieldLabel.Text = 'Flag line length:';
            app.FlaglinelengthEditField = uieditfield(app.FlagsTab, 'numeric');
            app.FlaglinelengthEditField.Limits = [0 50];
            app.FlaglinelengthEditField.ValueDisplayFormat = '%.2f';
            app.FlaglinelengthEditField.ValueChangedFcn = createCallbackFcn(app, @FlaglinelengthEditFieldValueChanged, true);
            app.FlaglinelengthEditField.Value = 1;
            app.FlaglinewidthEditFieldLabel = uilabel(app.FlagsTab);
            app.FlaglinewidthEditFieldLabel.Text = 'Flag line width:';
            app.FlaglinewidthEditField = uieditfield(app.FlagsTab, 'numeric');
            app.FlaglinewidthEditField.Limits = [0 50];
            app.FlaglinewidthEditField.ValueDisplayFormat = '%.2f';
            app.FlaglinewidthEditField.ValueChangedFcn = createCallbackFcn(app, @FlaglinewidthEditFieldValueChanged, true);
            app.FlaglinewidthEditField.Value = 1;
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FigureAppSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end