classdef FigureAppTemplateManager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SelectTemplateButton            matlab.ui.control.Button
        DeleteTemplateButton            matlab.ui.control.Button
        CloseButton                     matlab.ui.control.Button
        TemplatesListBox                matlab.ui.control.ListBox
        TemplatesListBoxLabel           matlab.ui.control.Label
        CreateNewTemplateButton         matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        InformationTab                  matlab.ui.container.Tab
        ResettodefaultButton            matlab.ui.control.Button
        SelectedVariablesTextArea       matlab.ui.control.TextArea
        SelectedVariablesTextAreaLabel  matlab.ui.control.Label
        DescriptionTextArea             matlab.ui.control.TextArea
        DescriptionTextAreaLabel        matlab.ui.control.Label
        TemplateNameEditField           matlab.ui.control.EditField
        TemplateNameEditFieldLabel      matlab.ui.control.Label
        SelectedVariablesTab            matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        ContextMenu                     matlab.ui.container.ContextMenu
        EditMenu                        matlab.ui.container.Menu
        DeleteMenu                      matlab.ui.container.Menu
    end

    
    properties (Access = private)
        CallingApp %
        MainApp
        templateID %
        plot_templateTab
        plot_variableTab
        active_template
        templates
        
    end

    methods (Access = private)
        
        function refreshInformation(app)
            conn = sqlite(app.CallingApp.dbpath, "readonly");
            app.plot_templateTab = fetch(conn, "SELECT * FROM plot_templateTab");

            sqlquery = sprintf([ ...
                'SELECT pv.plot_variableID, pv.templateID, pv.variableID, v.name, pv.selected_axis ' ...
                'FROM plot_variableTab pv ' ...
                'JOIN variableTab v ON v.variableID = pv.variableID ' ...
                'JOIN variable_handlingTab vh ON vh.variableID = v.variableID ' ...
                'WHERE vh.visible = 1 AND vh.organismID = %i'], app.MainApp.organismID);
            app.plot_variableTab = fetch(conn, sqlquery);
            close(conn);

            if ~isempty(app.plot_templateTab) && height(app.plot_templateTab) > 0
                app.TemplatesListBox.Items     = string(app.plot_templateTab.name(:))';
                app.TemplatesListBox.ItemsData = num2cell(app.plot_templateTab.templateID(:))';
            else
                app.TemplatesListBox.Items     = {};
                app.TemplatesListBox.ItemsData = {};
            end
        end

        function extractTemplate(app)
            % Find the profile by ID
            idx = find(app.templateID == app.plot_templateTab.templateID);

            if ~isempty(idx)
                profileInfo = app.plot_templateTab(idx, :);

                % Set the template name in the edit field
                app.TemplateNameEditField.Value = profileInfo.name{1}; % Ensure correct indexing with cell array

                % Set the description in the text area
                app.DescriptionTextArea.Value = profileInfo.description{1}; % Ensure correct indexing with cell array

                % Handle selected variables field
                if ~isempty(app.templateID)
                    profile_vars = app.plot_variableTab(app.plot_variableTab.templateID == app.templateID, :);
                    selected = profile_vars(profile_vars.selected_axis == 1, :);

                    if ~isempty(selected)
                        selected_vars = "";

                        % Collect all selected variables as strings
                        for i = 1:height(selected)
                            name = selected.name{i}; % Access table cell content properly
                            variable = sprintf('%s\n', name);
                            selected_vars = append(selected_vars, variable);
                        end

                        % List them in the text area
                        app.SelectedVariablesTextArea.Value = selected_vars;
                    end
                end
            end
        end

        function uniquename = uniqueProfileName(app, name)
            sqlquery = 'SELECT name FROM plot_templateTab ';
            conn = sqlite(app.CallingApp.dbpath, "readonly");
            projectnames = table2array(fetch(conn, sqlquery));
            close(conn)
            if any(strcmp(projectnames, name))
                suffix = 1;
                uniquename = name;
                while any(strcmp(projectnames, uniquename))
                    uniquename = sprintf('%s%d', name, suffix);
                    suffix = suffix + 1;
                    if suffix > 100
                        disp("Name is taken over 100 times.");
                        break;
                    end
                end
            else
                uniquename = name;
            end
        end

        function updateSelectedVariables(app)
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, CallingApp, MainApp, templateID)
            app.CallingApp = CallingApp;
            app.MainApp = MainApp;
            app.templateID = templateID;
            centerWindow(app.UIFigure)
            refreshInformation(app)
            extractTemplate(app)
            app.TemplatesListBox.Value = app.templateID;
        end

        % Value changed function: TemplatesListBox
        function TemplatesListBoxValueChanged(app, event)
            app.templateID = app.TemplatesListBox.Value;
            app.TabGroup.SelectedTab = app.InformationTab;
            refreshInformation(app)
            extractTemplate(app)
        end

        % Button pushed function: CreateNewTemplateButton
        function CreateNewTemplateButtonPushed(app, event)
            conn = sqlite(app.CallingApp.dbpath, "connect");
            try
                % Base new template on first existing template
                baseTemplate = fetch(conn, ...
                    "SELECT * FROM plot_templateTab ORDER BY templateID LIMIT 1");
                baseVars = fetch(conn, sprintf( ...
                    "SELECT * FROM plot_variableTab WHERE templateID = %i", ...
                    baseTemplate.templateID(1)));

                % New templateID
                res = fetch(conn, "SELECT MAX(templateID) AS maxID FROM plot_templateTab");
                if isempty(res) || isnan(double(res.maxID))
                    newID = 1;
                else
                    newID = double(res.maxID) + 1;
                end

                % New plot_variableIDs
                res2 = fetch(conn, "SELECT MAX(plot_variableID) AS maxVID FROM plot_variableTab");
                if isempty(res2) || isnan(double(res2.maxVID))
                    startVID = 1;
                else
                    startVID = double(res2.maxVID) + 1;
                end

                % Build new rows
                newTemplate                 = baseTemplate;
                newTemplate.templateID(1)   = newID;
                newTemplate.name(1)         = string(uniqueProfileName(app, "NewTemplate"));
                newTemplate.description(1)  = "";
                newTemplate.last_changed(1) = string(datetime("now", "Format", "dd.MM.uuuu HH:mm:ss"));

                newVars                     = baseVars;
                newVars.templateID          = repmat(newID, height(newVars), 1);
                newVars.plot_variableID     = (startVID : startVID + height(newVars) - 1)';

                sqlwrite(conn, "plot_templateTab", newTemplate);
                sqlwrite(conn, "plot_variableTab", newVars);
                close(conn);

            catch ME
                try
                    close(conn)
                catch
                end
                errordlg(sprintf('Create failed: %s', ME.message), 'Error');
                return;
            end

            app.templateID = newID;
            refreshInformation(app);
            extractTemplate(app);
            app.TemplatesListBox.Value = app.templateID;
        end

        % Button pushed function: DeleteTemplateButton
        function DeleteTemplateButtonPushed(app, event)
                response = questdlg( ...
                    sprintf('Do you really want to delete the template "%s"?', ...
                    string(app.plot_templateTab.name( ...
                    app.plot_templateTab.templateID == app.templateID))), ...
                    'Delete Template', 'Delete', 'Cancel', 'Cancel');

                switch response
                    case 'Delete'
                        if app.templateID ~= 1
                            conn = sqlite(app.CallingApp.dbpath, "connect");
                            try
                                exec(conn, "PRAGMA foreign_keys = ON");
                                exec(conn, sprintf( ...
                                    'DELETE FROM plot_variableTab WHERE templateID = %i', ...
                                    app.templateID));
                                exec(conn, sprintf( ...
                                    'DELETE FROM plot_templateTab WHERE templateID = %i', ...
                                    app.templateID));
                                close(conn);
                            catch ME
                                try; close(conn); catch; end
                                errordlg(sprintf('Delete failed: %s', ME.message), 'Error');
                                return;
                            end
                            % Fall back to first available template
                            app.templateID = app.plot_templateTab.templateID(1);
                            refreshInformation(app);
                            extractTemplate(app);
                            app.TemplatesListBox.Value = app.templateID;
                        else
                            msgbox("The default template cannot be deleted.", "modal");
                        end
                    case 'Cancel'
                        return;
                end
        end

        % Value changed function: TemplateNameEditField
        function TemplateNameEditFieldValueChanged(app, event)
            value = app.TemplateNameEditField.Value;
            if ~isempty(value) 
                uniquename = uniqueProfileName(app, value);
                sqlquery = sprintf('UPDATE plot_templateTab SET name = "%s" WHERE templateID = %i', uniquename,app.templateID);
                conn = sqlite(app.CallingApp.dbpath, "connect");
                exec(conn,sqlquery);
                close(conn)
                refreshInformation(app)
                extractTemplate(app)
            end

        end

        % Value changed function: DescriptionTextArea
        function DescriptionTextAreaValueChanged(app, event)
            value = string(app.DescriptionTextArea.Value);
            if ~isempty(value) 
                sqlquery = sprintf('UPDATE plot_templateTab SET description = "%s" WHERE templateID = %i', value,app.templateID);
                conn = sqlite(app.CallingApp.dbpath, "connect");
                exec(conn,sqlquery);
                close(conn)
                refreshInformation(app)
                extractTemplate(app)
            end
        end

        % Button down function: SelectedVariablesTab
        function SelectedVariablesTabButtonDown(app, event)
                % Fill table
                data = table;
                idx = (app.plot_variableTab.templateID == app.templateID);
                data.Variable = app.plot_variableTab.name(idx);
                data.Selected = logical(app.plot_variableTab.selected_axis(idx));
                app.UITable.Data = data;
                app.UITable.ColumnEditable = [false true];
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            % Find the rows where templateID matches
            matching_rows = app.plot_variableTab.templateID == app.templateID;
            temp_table = app.plot_variableTab(matching_rows,:);

            % Use indices(1) to find the specific row within the matching rows
            temp_table.selected_axis(indices(1)) = double(newData);
            if sum(temp_table.selected_axis) > 6
                temp_table.selected_axis(indices(1)) = double(false);
                app.UITable.Data.Selected(indices(1)) = false;
                msg = msgbox("You can only select up to six variables at a time.","Warning","non-modal");
                pause(1)
                if isvalid(msg)
                    close(msg)
                end
            else
                sqlquery = sprintf(['UPDATE plot_variableTab ' ...
                    'SET selected_axis = %i ' ...
                    'WHERE templateID = %i AND variableID = %i'], ...
                    double(newData),app.templateID,temp_table.variableID(indices(1)));
                conn = sqlite(app.CallingApp.dbpath, "connect");
                exec(conn,sqlquery);
                close(conn)
            end
            app.plot_variableTab(matching_rows,:) = temp_table;
        end

        % Button down function: InformationTab
        function InformationTabButtonDown(app, event)
            refreshInformation(app)
            extractTemplate(app)
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: SelectTemplateButton
        function SelectTemplateButtonPushed(app, event)
            app.CallingApp.TemplateSelected = app.templateID;
            delete(app);
        end

        % Button pushed function: ResettodefaultButton
        function ResettodefaultButtonPushed(app, event)
                % Confirm before overwriting
                response = questdlg( ...
                    sprintf('Reset template "%s" to default variable settings?', ...
                    string(app.plot_templateTab.name( ...
                    app.plot_templateTab.templateID == app.templateID))), ...
                    'Reset to Default', ...
                    'Reset', 'Cancel', 'Cancel');

                if ~strcmp(response, 'Reset')
                    return;
                end

                conn = sqlite(app.CallingApp.dbpath, "connect");
                try
                    % Load default variable settings
                    defaults = fetch(conn, [ ...
                        'SELECT variableID, selected_variable, limit_type, ' ...
                        'ymin, ymax, decimalID, colorID, linestyleID ' ...
                        'FROM default_plot_variableTab ' ...
                        'ORDER BY default_plot_variableID']);

                    if isempty(defaults)
                        close(conn);
                        msgbox('No default settings found in default_plot_variableTab.', 'Warning');
                        return;
                    end

                    % Load current plot_variableTab rows for this template
                    % to get the existing plot_variableIDs (we keep them, just update values)
                    current = fetch(conn, sprintf( ...
                        'SELECT plot_variableID, variableID FROM plot_variableTab WHERE templateID = %i ORDER BY plot_variableID', ...
                        app.templateID));

                    % Update each row that has a matching variableID in defaults
                    for i = 1:height(current)
                        vid = current.variableID(i);
                        idx = find(defaults.variableID == vid, 1);
                        if isempty(idx)
                            continue;
                        end
                        exec(conn, sprintf([ ...
                            'UPDATE plot_variableTab SET ' ...
                            'selected_axis = %i, ' ...
                            'limit_type    = %i, ' ...
                            'ymin          = %g, ' ...
                            'ymax          = %g, ' ...
                            'decimalID     = %i, ' ...
                            'colorID       = %i, ' ...
                            'linestyleID   = %i ' ...
                            'WHERE plot_variableID = %i'], ...
                            defaults.selected_variable(idx), ...
                            defaults.limit_type(idx), ...
                            defaults.ymin(idx), ...
                            defaults.ymax(idx), ...
                            defaults.decimalID(idx), ...
                            defaults.colorID(idx), ...
                            defaults.linestyleID(idx), ...
                            current.plot_variableID(i)));
                    end

                    close(conn);

                catch ME
                    try 
                        close(conn)
                    catch
                    end
                    errordlg(sprintf('Reset failed: %s', ME.message), 'Error');
                    return;
                end

                % Refresh UI — reload FigureApp Table too if possible
                refreshInformation(app);
                extractTemplate(app);

                % Also update FigureApp's app.Table so the change is visible immediately
                % without needing to reopen the plot window
                try
                    readTemplate(app.CallingApp, app.templateID);
                    createVariableList(app.CallingApp);
                    createLimitsGrid(app.CallingApp);
                    app.CallingApp.PlotObjects = {};
                catch
                    % CallingApp may not have these functions if opened from a different context
                end

                msgbox(sprintf('Template "%s" has been reset to default settings.', ...
                    string(app.plot_templateTab.name( ...
                    app.plot_templateTab.templateID == app.templateID))), ...
                    'Reset Complete');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InformationTab = uitab(app.TabGroup);
            app.InformationTab.Title = 'Information';
            app.InformationTab.ButtonDownFcn = createCallbackFcn(app, @InformationTabButtonDown, true);
            app.TemplateNameEditFieldLabel = uilabel(app.InformationTab);
            app.TemplateNameEditFieldLabel.Text = 'Template Name:';
            app.TemplateNameEditField = uieditfield(app.InformationTab, 'text');
            app.TemplateNameEditField.ValueChangedFcn = createCallbackFcn(app, @TemplateNameEditFieldValueChanged, true);
            app.DescriptionTextAreaLabel = uilabel(app.InformationTab);
            app.DescriptionTextAreaLabel.Text = 'Description:';
            app.DescriptionTextArea = uitextarea(app.InformationTab);
            app.DescriptionTextArea.ValueChangedFcn = createCallbackFcn(app, @DescriptionTextAreaValueChanged, true);
            app.SelectedVariablesTextAreaLabel = uilabel(app.InformationTab);
            app.SelectedVariablesTextAreaLabel.Text = 'Selected Variables:';
            app.SelectedVariablesTextArea = uitextarea(app.InformationTab);
            app.SelectedVariablesTextArea.Editable = 'off';
            app.ResettodefaultButton = uibutton(app.InformationTab, 'push');
            app.ResettodefaultButton.ButtonPushedFcn = createCallbackFcn(app, @ResettodefaultButtonPushed, true);
            app.ResettodefaultButton.Text = 'Reset to default';
            app.SelectedVariablesTab = uitab(app.TabGroup);
            app.SelectedVariablesTab.Title = 'Selected Variables';
            app.SelectedVariablesTab.ButtonDownFcn = createCallbackFcn(app, @SelectedVariablesTabButtonDown, true);
            app.UITable = uitable(app.SelectedVariablesTab);
            app.UITable.ColumnName = {'Variable'; 'Selected'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.CreateNewTemplateButton = uibutton(app.UIFigure, 'push');
            app.CreateNewTemplateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNewTemplateButtonPushed, true);
            app.CreateNewTemplateButton.Text = 'Create New Template';
            app.TemplatesListBoxLabel = uilabel(app.UIFigure);
            app.TemplatesListBoxLabel.Text = 'Templates';
            app.TemplatesListBox = uilistbox(app.UIFigure);
            app.TemplatesListBox.ValueChangedFcn = createCallbackFcn(app, @TemplatesListBoxValueChanged, true);
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.DeleteTemplateButton = uibutton(app.UIFigure, 'push');
            app.DeleteTemplateButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteTemplateButtonPushed, true);
            app.DeleteTemplateButton.Text = 'Delete Template';
            app.SelectTemplateButton = uibutton(app.UIFigure, 'push');
            app.SelectTemplateButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTemplateButtonPushed, true);
            app.SelectTemplateButton.Text = 'Select Template';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.EditMenu = uimenu(app.ContextMenu);
            app.EditMenu.Accelerator = 'e';
            app.EditMenu.Text = 'Edit';
            app.DeleteMenu = uimenu(app.ContextMenu);
            app.DeleteMenu.Accelerator = 'd';
            app.DeleteMenu.Text = 'Delete';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FigureAppTemplateManager(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end