classdef FigureAppVariableEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        SaveButton          matlab.ui.control.Button
        Panel               matlab.ui.container.Panel
        GridLayout          matlab.ui.container.GridLayout
        yAxisLimitmaxLabel  matlab.ui.control.Label
        LinestyleLabel      matlab.ui.control.Label
        ColorLabel          matlab.ui.control.Label
        DecimalsLabel       matlab.ui.control.Label
        yAxisLimitminLabel  matlab.ui.control.Label
        AutoLimitLabel      matlab.ui.control.Label
        SelectedLabel       matlab.ui.control.Label
        VariableNameLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        templateID
        CallingApp % Description
        T
        ObjectBox cell
        decimals
        linestyles
        colors
        changesMade cell
        MainApp
    end
    
    methods (Access = private)
        
        function constructLayoutObjects(app)
            try
                % Load variable settings for the active template
                sqlquery = sprintf([ ...
                    'SELECT p.variableID, v.longtex as "tex", v.name, p.selected_axis, ' ...
                    'v.tex_unit, p.limit_type, p.ymin, ' ...
                    'p.ymax, p.decimalID, p.colorID, p.linestyleID ' ...
                    'FROM plot_variableTab p ' ...
                    'JOIN variableTab v ON v.variableID = p.variableID ' ...
                    'JOIN variable_handlingTab vh ON vh.variableID = v.variableID ' ...
                    'WHERE p.templateID = %i AND vh.visible = 1 AND vh.organismID = %i'], ...
                    app.CallingApp.templateID, app.MainApp.organismID);

                conn = sqlite(app.CallingApp.dbpath, "readonly");
                app.T = fetch(conn, sqlquery);
                close(conn);   % Fix: was close(app) — closed the entire app window

                row = 2;       % Start at second row (row 1 = header labels)
                for i = 1:height(app.T)
                    ID = app.T.variableID(i);

                    % Variable name label
                    NameLbl = uilabel(app.GridLayout, "Interpreter", 'tex');
                    NameLbl.Layout.Column = 1;
                    NameLbl.Layout.Row    = row;
                    NameLbl.Text = append('\fontname{Helvetica}', '\fontsize{10}', ...
                        string(app.T.tex(i)), " [", string(app.T.tex_unit(i)), "]:");

                    % Selected checkbox
                    SelectedCb = uicheckbox(app.GridLayout, "Text", "");
                    SelectedCb.Layout.Column    = 2;
                    SelectedCb.Layout.Row       = row;
                    SelectedCb.Value            = logical(app.T.selected_axis(i));
                    SelectedCb.UserData         = ID;
                    SelectedCb.ValueChangedFcn  = @(src, event) SelectedValueChanged(app, src, event);

                    % Auto limit checkbox
                    AutoLimitCb = uicheckbox(app.GridLayout, "Text", "");
                    AutoLimitCb.Layout.Column    = 3;
                    AutoLimitCb.Layout.Row       = row;
                    AutoLimitCb.Value            = logical(app.T.limit_type(i));
                    AutoLimitCb.UserData         = ID;
                    AutoLimitCb.ValueChangedFcn  = @(src, event) AutoLimitValueChanged(app, src, event);

                    % Color dropdown
                    ColorDD = uidropdown(app.GridLayout);
                    ColorDD.Layout.Column    = 4;
                    ColorDD.Layout.Row       = row;
                    ColorDD.Items            = app.colors.color_name;
                    ColorDD.ItemsData        = num2cell(app.colors.colorID);
                    ColorDD.Value            = app.T.colorID(i);
                    ColorDD.UserData         = ID;
                    ColorDD.ValueChangedFcn  = @(src, event) ColorValueChanged(app, src, event);

                    % Color preview label
                    ColorLbl = uilabel(app.GridLayout, "Text", "");
                    ColorLbl.Layout.Column = 5;
                    ColorLbl.Layout.Row    = row;
                    idx = find(app.colors.colorID == app.T.colorID(i), 1);
                    if ~isempty(idx)
                        ColorLbl.BackgroundColor = [app.colors.R(idx), app.colors.G(idx), app.colors.B(idx)];
                    end

                    % y-axis min edit field
                    yminEF = uieditfield(app.GridLayout, 'numeric');
                    yminEF.Layout.Column    = 6;
                    yminEF.Layout.Row       = row;
                    yminEF.Value            = app.T.ymin(i);
                    yminEF.UserData         = ID;
                    yminEF.ValueChangedFcn  = @(src, event) yminValueChanged(app, src, event, i);

                    % y-axis max edit field
                    ymaxEF = uieditfield(app.GridLayout, 'numeric');
                    ymaxEF.Layout.Column    = 7;
                    ymaxEF.Layout.Row       = row;
                    ymaxEF.Value            = app.T.ymax(i);
                    ymaxEF.UserData         = ID;
                    ymaxEF.ValueChangedFcn  = @(src, event) ymaxValueChanged(app, src, event, i);

                    % Decimals dropdown
                    DecimalsDD = uidropdown(app.GridLayout);
                    DecimalsDD.Layout.Column    = 8;
                    DecimalsDD.Layout.Row       = row;
                    DecimalsDD.Items            = app.decimals.decimal_name;
                    DecimalsDD.ItemsData        = num2cell(app.decimals.decimalID);
                    DecimalsDD.Value            = app.T.decimalID(i);
                    DecimalsDD.UserData         = ID;
                    DecimalsDD.ValueChangedFcn  = @(src, event) DecimalValueChanged(app, src, event);

                    % Linestyle dropdown
                    LineStyleDD = uidropdown(app.GridLayout);
                    LineStyleDD.Layout.Column    = 9;
                    LineStyleDD.Layout.Row       = row;
                    LineStyleDD.Items            = app.linestyles.linestyle_symbol;
                    LineStyleDD.ItemsData        = num2cell(app.linestyles.linestyleID);
                    LineStyleDD.Value            = app.T.linestyleID(i);
                    LineStyleDD.UserData         = ID;
                    LineStyleDD.ValueChangedFcn  = @(src, event) LineStyleValueChanged(app, src, event);

                    % Store object references — index i (1-based), not row (2-based)
                    app.ObjectBox(i, :) = {NameLbl, SelectedCb, AutoLimitCb, ...
                        ColorDD, ColorLbl, yminEF, ymaxEF, DecimalsDD, LineStyleDD};

                    row = row + 1;
                    % Add row height if needed
                    if row <= numel(app.GridLayout.RowHeight)
                        app.GridLayout.RowHeight{row} = 25;
                    else
                        app.GridLayout.RowHeight{end+1} = 25;
                    end
                end

            catch ME
                fprintf('[VariableEditor] constructLayoutObjects: %s\n', ME.message);
            end
        end

        function SelectedValueChanged(app, src, event)
            checkboxValues = cellfun(@(cb) cb.Value, app.ObjectBox(:,2)); % Extract all cb values
            checkedCount = sum(checkboxValues); % Count checked boxes
            if checkedCount <= 6
                variableID = src.UserData;
                value = src.Value;
                app.changesMade(end+1, :) = {variableID, 1, value};
            else
                src.Value = 0;
                src.Tooltip = "You can only select up to six variables.";
                pause(2)
                src.Tooltip = "";
            end

        end

        function AutoLimitValueChanged(app, src, event)
            variableID = src.UserData;
            value = src.Value;
            app.changesMade(end+1, :) = {variableID, 2, value};
        end

        function ColorValueChanged(app, src, event)
            variableID = src.UserData;
            value      = src.Value;
            app.changesMade(end+1, :) = {variableID, 3, value};

            % Find which row in ObjectBox this variable belongs to
            rowIdx = find(cellfun(@(cb) isequal(cb.UserData, variableID), app.ObjectBox(:,2)), 1);
            if ~isempty(rowIdx)
                idx = find(app.colors.colorID == value, 1);
                if ~isempty(idx)
                    app.ObjectBox{rowIdx, 5}.BackgroundColor = ...
                        [app.colors.R(idx), app.colors.G(idx), app.colors.B(idx)];
                end
            end
        end

        function yminValueChanged(app, src, event, rowIdx)
            variableID = src.UserData;
            value      = src.Value;
            ymaxVal    = app.ObjectBox{rowIdx, 7}.Value;
            if value < ymaxVal
                app.changesMade(end+1, :) = {variableID, 4, value};
            else
                msgbox("Min limit must be smaller than max limit!");
                src.Value = event.PreviousValue;
            end
        end

        function ymaxValueChanged(app, src, event, rowIdx)
            variableID = src.UserData;
            value      = src.Value;
            yminVal    = app.ObjectBox{rowIdx, 6}.Value;
            if value > yminVal
                app.changesMade(end+1, :) = {variableID, 5, value};
            else
                msgbox("Max limit must be larger than min limit!");
                src.Value = event.PreviousValue;
            end
        end

        function DecimalValueChanged(app, src, event)
            variableID = src.UserData;
            value = src.Value;
            app.changesMade(end+1, :) = {variableID, 6, value};
        end

        function LineStyleValueChanged(app, src, event)
            variableID = src.UserData;
            value = src.Value;
            app.changesMade(end+1, :) = {variableID, 7, value};
        end
    
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, CallingApp, MainApp)
            app.CallingApp = CallingApp;
            app.MainApp = MainApp;
            centerWindow(app.UIFigure)
            bar = waitbar(0.25,"Load information...");
            % Download dropdown items
            waitbar(0.5,bar,"Construct objects...")
            conn = sqlite(app.CallingApp.dbpath, "readonly");
            sqlquery = "SELECT * FROM plot_decimalTab";
            app.decimals = fetch(conn,sqlquery);

            sqlquery = "SELECT * FROM plot_colorTab";
            app.colors = fetch(conn,sqlquery);

            sqlquery = "SELECT * FROM plot_linestyleTab";
            app.linestyles = fetch(conn,sqlquery);
            close(conn)
            constructLayoutObjects(app)
            waitbar(1,bar,"Finished.")
            close(bar)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if ~isempty(app.changesMade)
                column = ["selected_axis", "limit_type", "colorID", "ymin", "ymax", "decimalID", "linestyleID"];
                for i = 1:size(app.changesMade,1)
                    variableID = app.changesMade{i, 1};
                    type = app.changesMade{i, 2};
                    value = app.changesMade{i, 3};
                    sqlquery = sprintf('UPDATE plot_variableTab SET "%s" = %g WHERE variableID = %i AND templateID = %i', column(type), value, variableID, app.CallingApp.templateID);
                    conn = sqlite(app.CallingApp.dbpath, "connect");
                    exec(conn,sqlquery);
                    close(conn)
                end
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.VariableNameLabel = uilabel(app.GridLayout);
            app.VariableNameLabel.Text = 'Variable Name';
            app.SelectedLabel = uilabel(app.GridLayout);
            app.SelectedLabel.Text = 'Selected';
            app.AutoLimitLabel = uilabel(app.GridLayout);
            app.AutoLimitLabel.Text = 'Auto Limit';
            app.yAxisLimitminLabel = uilabel(app.GridLayout);
            app.yAxisLimitminLabel.Text = 'y-Axis Limit (min)';
            app.DecimalsLabel = uilabel(app.GridLayout);
            app.DecimalsLabel.Text = 'Decimals';
            app.ColorLabel = uilabel(app.GridLayout);
            app.ColorLabel.Text = 'Color';
            app.LinestyleLabel = uilabel(app.GridLayout);
            app.LinestyleLabel.Text = 'Linestyle';
            app.yAxisLimitmaxLabel = uilabel(app.GridLayout);
            app.yAxisLimitmaxLabel.Text = 'y-Axis Limit (max)';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FigureAppVariableEditor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end