classdef ModelCreator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        CancelButton              matlab.ui.control.Button
        CreateButton              matlab.ui.control.Button
        DescriptionTextArea       matlab.ui.control.TextArea
        DescriptionTextAreaLabel  matlab.ui.control.Label
        BioreactorDropDown        matlab.ui.control.DropDown
        BioreactorDropDownLabel   matlab.ui.control.Label
        OrganismDropDown          matlab.ui.control.DropDown
        OrganismDropDownLabel     matlab.ui.control.Label
        ModelNameEditField        matlab.ui.control.EditField
        ModelNameEditFieldLabel   matlab.ui.control.Label
        CreateaNewModelLabel      matlab.ui.control.Label
    end

    
    properties (Access = public)
        CallingApp
        MainApp
        organisms
        bioreactors
        description
        modelname
        bioreactor
        organism
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, CallingApp)
            app.UIFigure.Name = "Model Creator";
            centerWindow(app.UIFigure)
            app.CallingApp = CallingApp;
            % Read all available organisms and bioreactor
            conn = sqlite(app.CallingApp.dbpath, "readonly");
            sqlquery = 'SELECT organismID, name FROM organismTab';
            app.organisms = fetch(conn,sqlquery);
            sqlquery = 'SELECT bioreactorID, name FROM bioreactorTab';
            app.bioreactors = fetch(conn,sqlquery);
            close(conn)
            app.OrganismDropDown.Items = app.organisms.name;
            app.OrganismDropDown.ItemsData = app.organisms.organismID;
            app.BioreactorDropDown.Items = app.bioreactors.name;
            app.BioreactorDropDown.ItemsData = app.bioreactors.bioreactorID;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            value = app.ModelNameEditField.Value;
            if value == "" || value == " "
                box = msgbox("Type a model name.","Error","error","replace");
            elseif ~isempty(app.CallingApp.models) && any(value == app.CallingApp.models)
                box = msgbox(sprintf("The name '%s' is already taken. Choose a different name.",value),"Error","error","replace");
            elseif startsWith(value, ' ') || endsWith(value, ' ')
                box = msgbox("There is a space before or after the name.","Error","error","replace");
            else
                % Determine index of selected organism and bioreactor
                % (equal to primary key of them)
                organismIdx = app.OrganismDropDown.Value;
                bioreactorIdx = app.BioreactorDropDown.Value;

                % Create a new row in the modelTab
                conn = sqlite(app.CallingApp.dbpath, "connect");
                sqlquery = sprintf('INSERT INTO modelTab (bioreactorID, organismID, name, description) VALUES (%g, %g, ''%s'', ''%s'')',bioreactorIdx,organismIdx,value, string(app.DescriptionTextArea.Value));
                exec(conn, sqlquery);
                % Determine primary key of model in modelTab
                sqlquery = sprintf('SELECT modelID FROM modelTab WHERE name = ''%s''',value);
                idx = double(table2array(fetch(conn,sqlquery)));
                % Create table out of selected parameters
                % Read values from the default_modelTab
                tab1 = sprintf(['SELECT parameterID, value, description ', ...
                    'FROM default_modelTab ', ...
                    'WHERE organismID = %g'], organismIdx);
                tabdata1 = fetch(conn, tab1);
                % Read values from the default_bioreactorTab
                tab2 = sprintf(['SELECT parameterID, value, description ', ...
                    'FROM default_bioreactorTab ', ...
                    'WHERE bioreactorID = %g'], bioreactorIdx);
                tabdata2 = fetch(conn, tab2);
                % Connect tables of model/organism and bioreactor
                tabdata = [tabdata1;tabdata2];
                % Create a column only with the primary key of the current
                % model
                modelIDcolumn = table(ones(height(tabdata(:,1)),1).*idx);
                data = [modelIDcolumn,tabdata];
                data.Properties.VariableNames = {'modelID', 'parameterID', 'value', 'description'};
                sqlwrite(conn, 'model_parameterTab', data);
                close(conn)
                app.CallingApp.updateModelList;
                app.CallingApp.currentmodel = value;
                delete(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.CreateaNewModelLabel = uilabel(app.UIFigure);
            app.CreateaNewModelLabel.Text = 'Create a New Model:';
            app.ModelNameEditFieldLabel = uilabel(app.UIFigure);
            app.ModelNameEditFieldLabel.Text = 'Model Name:';
            app.ModelNameEditField = uieditfield(app.UIFigure, 'text');
            app.OrganismDropDownLabel = uilabel(app.UIFigure);
            app.OrganismDropDownLabel.Text = 'Organism:';
            app.OrganismDropDown = uidropdown(app.UIFigure);
            app.BioreactorDropDownLabel = uilabel(app.UIFigure);
            app.BioreactorDropDownLabel.Text = 'Bioreactor:';
            app.BioreactorDropDown = uidropdown(app.UIFigure);
            app.DescriptionTextAreaLabel = uilabel(app.UIFigure);
            app.DescriptionTextAreaLabel.Text = 'Description:';
            app.DescriptionTextArea = uitextarea(app.UIFigure);
            app.CreateButton = uibutton(app.UIFigure, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModelCreator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end