classdef PhaseFeedEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        CancelButton              matlab.ui.control.Button
        SaveButton                matlab.ui.control.Button
        EditField5                matlab.ui.control.NumericEditField
        EditField5Label           matlab.ui.control.Label
        EditField4                matlab.ui.control.NumericEditField
        EditField4Label           matlab.ui.control.Label
        EditField3                matlab.ui.control.NumericEditField
        EditField3Label           matlab.ui.control.Label
        OpenLoopFeedControlLabel  matlab.ui.control.Label
        ParametersLabel           matlab.ui.control.Label
        EditField2                matlab.ui.control.NumericEditField
        fontsize10fontnameHelvetical_lLabel  matlab.ui.control.Label
        ReservoirEditField        matlab.ui.control.NumericEditField
        ReservoirLabel            matlab.ui.control.Label
    end

    
    properties (Access = public)
        CallingApp % Calling App
        MainApp % Control App
        dbpath
        parameters cell
        editfields cell
        original_values
    end
    
    methods (Access = private)
        function fillEditFields(app, obj, objLabel, parameterName, editable)
            pretex = "\fontsize{10}\fontname{Helvetica}";

            % Read base value from parameter struct
            if isfield(app.MainApp.p, parameterName)
                value = app.MainApp.p.(parameterName);
            else
                fprintf('[PhaseFeedEditor] "%s" not found in app.p.\n', parameterName);
                value = 0;
            end

            % Read display labels from metadata
            idx = find(string(app.MainApp.p_meta.parametername) == string(parameterName), 1);
            if ~isempty(idx)
                tex  = string(app.MainApp.p_meta.tex(idx));
                unit = string(app.MainApp.p_meta.unit(idx));
            else
                tex  = parameterName;
                unit = "";
            end

            % Override with phase-specific value if one exists
            phaseNum = app.CallingApp.phaseNum;
            ph       = app.CallingApp.CallingApp.Phases(phaseNum);
            if isfield(ph.parameters, parameterName)
                value = ph.parameters.(parameterName);
            end

            obj.Value        = value;
            obj.UserData     = value;  % Store original to detect changes on save
            obj.Editable     = editable;
            obj.Visible      = "on";
            objLabel.Text    = append(pretex, sprintf("%s [%s]:", tex, unit));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, CallingApp, MainApp, mode, reservoir)
            app.CallingApp = CallingApp;  % PhasePanelEditor
            app.MainApp    = MainApp;     % ControlApp
            app.dbpath     = app.MainApp.dbpath;
            centerWindow(app.UIFigure);
            app.UIFigure.Name = "Edit Feed Parameters";

            n = num2str(reservoir);
            app.ReservoirEditField.Value = reservoir;

            switch mode
                case 1  % Pulse feed
                    app.OpenLoopFeedControlLabel.Text = "Pulse Feed";
                    app.parameters = {['kR' n]};
                    app.editfields = {app.EditField2};

                    fillEditFields(app, app.EditField2, ...
                        app.fontsize10fontnameHelvetical_lLabel, ['kR' n], "on");
                    fillEditFields(app, app.EditField3, ...
                        app.EditField3Label, ['FR' n 'max'], "off");  % Reference only

                    app.EditField4.Visible      = "off";
                    app.EditField4Label.Visible = "off";
                    app.EditField5.Visible      = "off";
                    app.EditField5Label.Visible = "off";

                case 2  % Exponential / open-loop feed
                    app.OpenLoopFeedControlLabel.Text = "Open-Loop Feed Control";
                    app.parameters = {['qXpX' n 'w'], ['qS' n 'pXm'], ...
                        ['yXpS' n 'gr'], ['cS' n 'R' n]};
                    app.editfields = {app.EditField2, app.EditField3, ...
                        app.EditField4,  app.EditField5};

                    fillEditFields(app, app.EditField2, ...
                        app.fontsize10fontnameHelvetical_lLabel, ['qXpX' n 'w'], "on");
                    fillEditFields(app, app.EditField3, ...
                        app.EditField3Label, ['qS' n 'pXm'], "on");
                    fillEditFields(app, app.EditField4, ...
                        app.EditField4Label, ['yXpS' n 'gr'], "on");
                    fillEditFields(app, app.EditField5, ...
                        app.EditField5Label, ['cS' n 'R' n], "on");

                otherwise
                    uialert(app.UIFigure, 'Unknown feed mode.', 'Error');
                    delete(app);
                    return;
            end

        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            phaseNum   = app.CallingApp.phaseNum;
            ControlApp = app.CallingApp.CallingApp;  % PhasePanelEditor → ControlApp

            for i = 1:numel(app.parameters)
                ef    = app.editfields{i};
                pname = app.parameters{i};
                if ef.Value ~= ef.UserData  % Only save changed values
                    ControlApp.Phases(phaseNum).parameters.(pname) = ef.Value;
                end
            end

            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ReservoirLabel = uilabel(app.UIFigure);
            app.ReservoirLabel.Text = 'Reservoir:';
            app.ReservoirEditField = uieditfield(app.UIFigure, 'numeric');
            app.ReservoirEditField.Editable = 'off';
            app.fontsize10fontnameHelvetical_lLabel = uilabel(app.UIFigure);
            app.fontsize10fontnameHelvetical_lLabel.Interpreter = 'tex';
            app.fontsize10fontnameHelvetical_lLabel.Text = 'Edit Field2';
            app.EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.ParametersLabel = uilabel(app.UIFigure);
            app.ParametersLabel.Text = 'Parameters';
            app.OpenLoopFeedControlLabel = uilabel(app.UIFigure);
            app.OpenLoopFeedControlLabel.Text = 'Open Loop Feed Control';
            app.EditField3Label = uilabel(app.UIFigure);
            app.EditField3Label.Interpreter = 'tex';
            app.EditField3Label.Text = 'Edit Field3';
            app.EditField3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField4Label = uilabel(app.UIFigure);
            app.EditField4Label.Interpreter = 'tex';
            app.EditField4Label.Text = 'Edit Field4';
            app.EditField4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField5Label = uilabel(app.UIFigure);
            app.EditField5Label.Interpreter = 'tex';
            app.EditField5Label.Text = 'Edit Field5';
            app.EditField5 = uieditfield(app.UIFigure, 'numeric');
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PhaseFeedEditor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end