classdef PhaseParameterEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ChangesmadeTextArea       matlab.ui.control.TextArea
        ChangesmadeTextAreaLabel  matlab.ui.control.Label
        CancelButton              matlab.ui.control.Button
        SaveButton                matlab.ui.control.Button
        Panel                     matlab.ui.container.Panel
        ParameterGridLayout       matlab.ui.container.GridLayout
    end

    
    properties (Access = public)
        CallingApp % Caller
        MainApp % MainApp
        phaseNum % Calling Phase
        dbpath
        projectID
        T
        Modes
        ValueEditField cell
        process_parameterTable = table('Size', [0, 2], 'VariableTypes', {'double', 'double'}, 'VariableNames', {'parameterID', 'value'})
        changeLogString string
        ResetButtons cell
    end
    
    methods (Access = private)
        
        function fillListView(app)
            delete(app.ParameterGridLayout.Children);
            clear app.ValueEditField;
            % Create the list through a loop
            section = "";
            category = "";
            row_index = 1;
            i = 1;
            while i <= size(app.T,1)
                if section == app.T.categorysection(i)
                    if category == app.T.categoryname(i)

                        switch app.T.type(i)
                            case "switch"
                                fswitch = uiswitch(app.ParameterGridLayout);
                                fswitch.Orientation = 'horizontal';
                                fswitch.Items = ["Off", "On"];
                                fswitch.ItemsData = [0, 1];
                                fswitch.Value = app.T.value(i);
                                fswitch.Layout.Row = row_index;
                                fswitch.Layout.Column = 2;
                                fswitch.Tooltip = app.T.description(i);
                                fswitch.ValueChangedFcn = @(src, event) updateParameter(app, src, event, i);
                                app.ValueEditField(i) = {fswitch};
                            case "editfield"
                                editfield = uieditfield(app.ParameterGridLayout, 'numeric');
                                editfield.Value = app.T.value(i);
                                editfield.Layout.Row = row_index;
                                editfield.Layout.Column = 2;
                                editfield.Tooltip = app.T.description(i);
                                editfield.ValueChangedFcn = @(src, event) updateParameter(app, src, event, i);
                                app.ValueEditField(i) = {editfield};
                            case "dropdown"
                                idx = find(app.T.parametername(i) == app.Modes.parametername);
                                dropdown = uidropdown(app.ParameterGridLayout);
                                dropdown.Items = app.Modes.mode(idx);
                                dropdown.ItemsData = app.Modes.value(idx);
                                dropdown.Value = app.T.value(i);
                                dropdown.Tooltip = app.T.description(i);
                                dropdown.Layout.Row = row_index;
                                dropdown.Layout.Column = 2;
                                dropdown.ValueChangedFcn = @(src, event) updateParameter(app, src, event, i);
                                app.ValueEditField(i) = {dropdown};
                            otherwise
                                i = i+1;
                                continue % Ignore parameter
                        end

                        parametername = uilabel(app.ParameterGridLayout);
                        parametername.Text = append("\fontsize{10}\fontname{Helvetica}", app.T.tex(i));
                        parametername.Layout.Row = row_index;
                        parametername.Layout.Column = 1;
                        parametername.Interpreter = "tex";
                        parametername.FontSize = 12;

                        if ~isempty(app.T.unit(i))
                            label2 = uilabel(app.ParameterGridLayout);
                            label2.Text = app.T.unit(i);
                            label2.Layout.Row = row_index;
                            label2.Layout.Column = 3;
                        end

                        resetbutton = uibutton(app.ParameterGridLayout);
                        resetbutton.Text = "reset";
                        resetbutton.Layout.Row = row_index;
                        resetbutton.Layout.Column = 4;
                        resetbutton.ButtonPushedFcn = @(src, event) resetButtonFcn(app, src, event, i);
                        resetbutton.Visible = 'off';
                        app.ResetButtons(i) = {resetbutton};

                        app.ParameterGridLayout.RowHeight = repmat({25}, row_index, 1);
                        app.ParameterGridLayout.ColumnWidth = {'1x', '1x', '0.5x', '1x'};%{'0.5x'};
                        i = i+1;
                        row_index = row_index + 1;
                    else
                        category = app.T.categoryname(i);
                        category_label = uilabel(app.ParameterGridLayout);
                        category_label.Text = category;
                        category_label.Layout.Row = row_index;
                        category_label.Layout.Column = [1 2];
                        category_label.FontSize = 12;
                        category_label.FontWeight = 'bold';
                        row_index = row_index + 1;
                    end
                else
                    section = app.T.categorysection(i);
                    section_label = uilabel(app.ParameterGridLayout);
                    section_label.Text = section;
                    section_label.Layout.Row = row_index;
                    section_label.Layout.Column = [1 2];
                    section_label.FontSize = 14;
                    section_label.FontWeight = 'bold';
                    row_index = row_index + 1;
                end
            end
            %changeLog(app)
        end

        function updateParameter(app, src, ~, parameterIDx)
            value = src.Value;
            app.ResetButtons{parameterIDx}.Visible = 'on';
            c = class(src);

            if value == app.T.value(parameterIDx)
                % Value restored to original — remove highlight
                if strcmp(c, 'matlab.ui.control.Switch')
                    src.FontColor = 'black';
                else
                    src.BackgroundColor = [0.96, 0.96, 0.96];
                end
                parameterID = app.T.parameterID(parameterIDx);
                del = find(parameterID == app.process_parameterTable.parameterID);
                app.process_parameterTable(del, :) = [];
                app.ResetButtons{parameterIDx}.Visible = 'off';
            else
                % Value changed — apply highlight
                if strcmp(c, 'matlab.ui.control.Switch')
                    src.FontColor = [0.13, 0.70, 0.13];
                else
                    src.BackgroundColor = 'green';
                end
                parameterID = app.T.parameterID(parameterIDx);
                existingRow = find(parameterID == app.process_parameterTable.parameterID);
                newRow = table(parameterID, value, 'VariableNames', {'parameterID', 'value'});
                if isempty(existingRow)
                    app.process_parameterTable = vertcat(app.process_parameterTable, newRow);
                else
                    app.process_parameterTable(existingRow, :) = newRow;  % Update, not duplicate
                end
            end

            changeLog(app);
        end

        function resetButtonFcn(app, src, ~, idx)
            app.ValueEditField{idx}.Value = app.T.value(idx);
            src.Visible = 'off';
            c = class(app.ValueEditField{idx});
            if strcmp(c, 'matlab.ui.control.Switch')
                app.ValueEditField{idx}.FontColor = 'black';
            else
                app.ValueEditField{idx}.BackgroundColor = [0.96, 0.96, 0.96];
            end
            parameterID = app.T.parameterID(idx);
            del = find(parameterID == app.process_parameterTable.parameterID);
            app.process_parameterTable(del, :) = [];
            changeLog(app);
        end

        function changeLog(app)
            app.changeLogString = [];
            if ~isempty(app.process_parameterTable)
                for i = 1:size(app.process_parameterTable,1)
                    parameterID = app.process_parameterTable.parameterID(i);
                    idx = find(parameterID == app.T.parameterID);
                    parameter = app.T.parametername(idx);
                    before = app.T.value(idx);
                    after = app.process_parameterTable.value(i);
                    unit = app.T.unit(idx);
                    if ismissing(unit)
                        unit = "";
                    end
                    log = sprintf("%s from %g to %g %s\n", parameter, before, after, unit);
                    app.changeLogString(i) = log;
                end
                app.ChangesmadeTextArea.Value = [app.changeLogString];
            else
                app.ChangesmadeTextArea.Value = "";
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, CallingApp, MainApp, phaseNum)
            app.CallingApp = CallingApp;  % PhasePanelEditor
            app.MainApp    = MainApp;     % ControlApp
            app.phaseNum   = phaseNum;
            app.dbpath     = app.MainApp.dbpath;
            app.projectID  = app.MainApp.projectID;
            app.UIFigure.Name = "Parameter Update";
            centerWindow(app.UIFigure);

            % Build app.T from memory — p_meta_cyclic has the exact columns
            % that fillListView expects: parameterID, parametername, tex, value,
            % type, unit, description, categoryname, categorysection
            meta = app.MainApp.p_meta_cyclic;

            % Refresh values from app.p (may have changed since session start)
            for i = 1:height(meta)
                pname = string(meta.parametername(i));
                if isfield(app.MainApp.p, pname)
                    meta.value(i) = app.MainApp.p.(pname);
                end
            end
            app.T = meta;

            % Control mode table from memory
            app.Modes = app.MainApp.p_modes;

            % Build the parameter list UI
            fillListView(app);

            % Pre-load existing phase-specific parameter overrides from memory
            ph = app.CallingApp.CallingApp.Phases(phaseNum);
            if ~isempty(fieldnames(ph.parameters))
                paramNames = fieldnames(ph.parameters);
                for k = 1:numel(paramNames)
                    pname = paramNames{k};
                    idx   = find(string(app.T.parametername) == string(pname), 1);
                    if isempty(idx) || isempty(app.ValueEditField) || ...
                            idx > numel(app.ValueEditField) || isempty(app.ValueEditField{idx})
                        continue;
                    end
                    app.ValueEditField{idx}.Value = ph.parameters.(pname);
                    updateParameter(app, app.ValueEditField{idx}, [], idx);
                end
            end

            changeLog(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
          phaseNum   = app.phaseNum;
                ControlApp = app.CallingApp.CallingApp;  % PhasePanelEditor → ControlApp

                if isempty(app.process_parameterTable)
                    delete(app);
                    return;
                end

                for i = 1:height(app.process_parameterTable)
                    paramID = app.process_parameterTable.parameterID(i);
                    idx     = find(paramID == app.T.parameterID, 1);
                    if isempty(idx); continue; end
                    pname = string(app.T.parametername(idx));
                    ControlApp.Phases(phaseNum).parameters.(pname) = ...
                        app.process_parameterTable.value(i);
                end

                delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.ParameterGridLayout = uigridlayout(app.Panel);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ChangesmadeTextAreaLabel = uilabel(app.UIFigure);
            app.ChangesmadeTextAreaLabel.Text = 'Changes made:';
            app.ChangesmadeTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PhaseParameterEditor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end