classdef ProjectInformation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ModelNameEditField         matlab.ui.control.EditField
        ModelNameEditFieldLabel    matlab.ui.control.Label
        ApplyButton                matlab.ui.control.Button
        DescriptionTextArea        matlab.ui.control.TextArea
        DescriptionTextAreaLabel   matlab.ui.control.Label
        BioreactorEditField        matlab.ui.control.EditField
        BioreactorEditFieldLabel   matlab.ui.control.Label
        OrganismEditField          matlab.ui.control.EditField
        OrganismEditFieldLabel     matlab.ui.control.Label
        AuthorEditField            matlab.ui.control.EditField
        AuthorEditFieldLabel       matlab.ui.control.Label
        ProjectNameEditField       matlab.ui.control.EditField
        ProjectNameEditFieldLabel  matlab.ui.control.Label
        ProjectInformationLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Description
        projectID % Description
        name
        author
        model
        organism
        bioreactor
        description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, CallingApp, projectID)
            app.CallingApp = CallingApp;
            app.projectID = projectID;
            app.UIFigure.Name = "Project";
            centerWindow(app.UIFigure)
            sqlquery = sprintf(['SELECT p.name, p.description, p.author, o.name, b.name, m.name ' ...
                'FROM projectTab p ' ...
                'JOIN organismTab o ON o.organismID = p.organismID ' ...
                'JOIN bioreactorTab b ON b.bioreactorID = p.bioreactorID ' ...
                'JOIN modelTab m ON m.modelID = p.modelID ' ...
                'WHERE projectID = %g'], app.projectID);
            conn = sqlite(app.CallingApp.dbpath, "readonly");
            T = table2array(fetch(conn,sqlquery));

            app.name = T(1);
            app.description = T(2);
            app.author = T(3);
            app.organism = T(4);
            app.bioreactor = T(5);
            app.model = T(6);
            app.ProjectNameEditField.Value = app.name;
            app.AuthorEditField.Value = app.author;
            app.ModelNameEditField.Value = app.model;
            app.OrganismEditField.Value = app.organism;
            app.BioreactorEditField.Value = app.bioreactor;
            app.DescriptionTextArea.Value = app.description;

        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            sqlquery = sprintf(['UPDATE projectTab ' ...
                'SET name = %s, description = ''%s'', author = ''%s'' ' ...
                'WHERE projectID = %g'], app.ProjectNameEditField.Value, string(app.DescriptionTextArea.Value), app.AuthorEditField.Value, app.projectID);
            conn = sqlite(app.CallingApp.dbpath, "connect");
            exec(conn,sqlquery);
            close(conn)
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ProjectInformationLabel = uilabel(app.UIFigure);
            app.ProjectInformationLabel.Text = 'Project Information';
            app.ProjectNameEditFieldLabel = uilabel(app.UIFigure);
            app.ProjectNameEditFieldLabel.Text = 'Project Name:';
            app.ProjectNameEditField = uieditfield(app.UIFigure, 'text');
            app.AuthorEditFieldLabel = uilabel(app.UIFigure);
            app.AuthorEditFieldLabel.Text = 'Author:';
            app.AuthorEditField = uieditfield(app.UIFigure, 'text');
            app.OrganismEditFieldLabel = uilabel(app.UIFigure);
            app.OrganismEditFieldLabel.Text = 'Organism:';
            app.OrganismEditField = uieditfield(app.UIFigure, 'text');
            app.OrganismEditField.Editable = 'off';
            app.BioreactorEditFieldLabel = uilabel(app.UIFigure);
            app.BioreactorEditFieldLabel.Text = 'Bioreactor:';
            app.BioreactorEditField = uieditfield(app.UIFigure, 'text');
            app.BioreactorEditField.Editable = 'off';
            app.DescriptionTextAreaLabel = uilabel(app.UIFigure);
            app.DescriptionTextAreaLabel.Text = 'Description:';
            app.DescriptionTextArea = uitextarea(app.UIFigure);
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.ModelNameEditFieldLabel = uilabel(app.UIFigure);
            app.ModelNameEditFieldLabel.Text = 'Model Name:';
            app.ModelNameEditField = uieditfield(app.UIFigure, 'text');
            app.ModelNameEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjectInformation(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end